classdef cbarCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        binBox                matlab.ui.control.NumericEditField
        BinsEditFieldLabel    matlab.ui.control.Label
        InvertButton          matlab.ui.control.Button
        IncludemiddleininterpolationSwitchLabel  matlab.ui.control.Label
        includeMid            matlab.ui.control.Switch
        updateonbarButton     matlab.ui.control.Button
        saveAndButton         matlab.ui.control.Button
        bBox                  matlab.ui.control.NumericEditField
        gBox                  matlab.ui.control.NumericEditField
        rBox                  matlab.ui.control.NumericEditField
        b                     matlab.ui.control.Slider
        BSliderLabel          matlab.ui.control.Label
        g                     matlab.ui.control.Slider
        GSliderLabel          matlab.ui.control.Label
        r                     matlab.ui.control.Slider
        RSliderLabel          matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        RightmostcolorButton  matlab.ui.control.RadioButton
        MiddlecolorButton     matlab.ui.control.RadioButton
        LeftmostcolorButton   matlab.ui.control.RadioButton
        colormapSelector      matlab.ui.control.DropDown
        savecolormapButton    matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        box                   matlab.ui.control.UIAxes
        bar                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %os = []; % Description
        CallingApp
    end
    
    properties (Access = private)
        actionType = []; % Description
        props = [];
        im = [];
        im2 = [];
        cMap = [];
        cMap2 = [];
        bins = [];
        tog = [];
        hlf1 = [];
        hlf2 = [];
    end
    
    methods (Access = private)
        
        function [app] = cmapget(app)
            if ~strcmpi(app.colormapSelector.Value,app.colormapSelector.Items{1})
                cV = find(ismember(app.colormapSelector.Items,app.colormapSelector.Value));

                if app.CallingApp.userColor{cV} == 1
                    switch app.colormapSelector.Value
                        case 'jet'
                            app.cMap = jet(app.bins);
                        case 'parula'
                            app.cMap = parula(app.bins);
                        case 'hsv'
                            app.cMap = hsv(app.bins);
                        case 'hot'
                            app.cMap = hot(app.bins);
                        case 'cool'
                            app.cMap = cool(app.bins);
                        case 'spring'
                            app.cMap = spring(app.bins);
                        case 'summer'
                            app.cMap = summer(app.bins);
                        case 'autumn'
                            app.cMap = autumn(app.bins);
                        case 'winter'
                            app.cMap = winter(app.bins);
                        case 'gray'
                            app.cMap = gray(app.bins);
                        case 'bone'
                            app.cMap = bone(app.bins);
                        case 'copper'
                            app.cMap = copper(app.bins);
                        case 'pink'
                            app.cMap = pink(app.bins);
                        case 'lines'
                            app.cMap = lines(app.bins);
                        case 'colorcube'
                            app.cMap = colorcube(app.bins);
                        case 'prism'
                            app.cMap = prism(app.bins);
                        case 'Spectral'
                            app.cMap=cbrewer('div', app.colormapSelector.Value, app.bins);
                        case 'RdYlBu'
                            app.cMap = cbrewer('div', app.colormapSelector.Value, app.bins);
                        case 'RdGy'
                            app.cMap = cbrewer('div', app.colormapSelector.Value, app.bins);
                        case 'RdBu'
                            app.cMap = cbrewer('div', app.colormapSelector.Value, app.bins);
                        case 'PuOr'
                            app.cMap = cbrewer('div', app.colormapSelector.Value, app.bins);
                        case 'PRGn'
                            app.cMap = cbrewer('div', app.colormapSelector.Value, app.bins);
                        case 'PiYG'
                            app.cMap = cbrewer('div', app.colormapSelector.Value, app.bins);
                        case 'BrBG'
                            app.cMap = cbrewer('div', app.colormapSelector.Value, app.bins);
                        case 'YlOrRd'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'YlOrBr'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'YlGnBu'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'YlGn'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'Reds'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'RdPu'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'Purples'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'PuRd'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'PuBuGn'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'PuBu'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'OrRd'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'Oranges'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'Greys'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'Greens'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'GnBu'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'BuPu'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'BuGn'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'Blues'
                            app.cMap = cbrewer('seq', app.colormapSelector.Value, app.bins);
                        case 'Set3'
                            app.cMap = cbrewer('qual', app.colormapSelector.Value, app.bins);
                        case 'Set2'
                            app.cMap = cbrewer('qual', app.colormapSelector.Value, app.bins);
                        case 'Set1'
                            app.cMap = cbrewer('qual', app.colormapSelector.Value, app.bins);
                        case 'Pastel2'
                            app.cMap = cbrewer('qual', app.colormapSelector.Value, app.bins);
                        case 'Pastel1'
                            app.cMap = cbrewer('qual', app.colormapSelector.Value, app.bins);
                        case 'Paired'
                            app.cMap = cbrewer('qual', app.colormapSelector.Value, app.bins);
                        case 'Dark2'
                            app.cMap = cbrewer('qual', app.colormapSelector.Value, app.bins);
                        case 'Accent'
                            app.cMap = cbrewer('qual', app.colormapSelector.Value, app.bins);
                        case 'inferno'
                            app.cMap = inferno(app.bins);
                        case 'plasma'
                            app.cMap = plasma(app.bins);
                        case 'vega10'
                            app.cMap = vega10(app.bins);
                        case 'vega20b'
                            app.cMap = vega20b(app.bins);
                        case 'vega20c'
                            app.cMap = vega20c(app.bins);
                        case 'viridis'
                            app.cMap = viridis(app.bins);
                        case 'thermal'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'haline'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'solar'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'ice'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'oxy'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'deep'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'dense'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'algae'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'matter'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'turbid'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'speed'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'amp'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'tempo'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'balance'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'delta'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'curl'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'phase'
                            app.cMap = cmocean(app.colormapSelector.Value, app.bins);
                        case 'perceptually distinct'
                            app.cMap = distinguishable_colors(app.bins);
                    end
                else
                    app.cMap = app.CallingApp.userColor{cV};
                end

            else
                app.cMap = ones(app.bins,3);
            end

            app = updateBar(app);
        end
        
        function [app] = updateBox(app)
            app.cMap2(:,1,1) = app.rBox.Value;
            app.cMap2(:,1,2) = app.gBox.Value;
            app.cMap2(:,1,3) = app.bBox.Value;
            app.im2 = image(app.cMap2,'Parent',app.box,'XData', [1 app.box.Position(3)],'YData', [1 app.box.Position(4)]); 
        end
        
        function [app] = updateBar(app)
            app.cMap = reshape(app.cMap,[size(app.cMap,1),1,3]);
            app.cMap = imrotate(app.cMap,90);
            app.im = image(app.cMap,'Parent',app.bar,'XData', [1 app.bar.Position(3)],'YData', [1 app.bar.Position(4)]);
        end
        
        function [app] = radioBarUpdateBox(app)
            cMapTmp = squeeze(app.cMap);
            switch app.ButtonGroup.SelectedObject.Text
                case 'Leftmost color'
                    app.rBox.Value = cMapTmp(1,1);
                    app.gBox.Value = cMapTmp(1,2);
                    app.bBox.Value = cMapTmp(1,3);
                case 'Rightmost color'
                    app.rBox.Value = cMapTmp(end,1);
                    app.gBox.Value = cMapTmp(end,2);
                    app.bBox.Value = cMapTmp(end,3);
                case 'Middle color'
                    app.rBox.Value = cMapTmp(ceil(length(cMapTmp)/2),1);
                    app.gBox.Value = cMapTmp(ceil(length(cMapTmp)/2),2);
                    app.bBox.Value = cMapTmp(ceil(length(cMapTmp)/2),3);
            end

            [app] = updateBox(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, labelAdd)
            app.CallingApp = mainapp;
            app.colormapSelector.Items = app.CallingApp.colormapSelector.Items;
            app.colormapSelector.Value = app.CallingApp.colormapSelector.Value;
            
            [s,~,si] = intersect(app.CallingApp.overlayListBox.Value,app.CallingApp.overlayListBox.Items);

            if ~isempty(si)
                if si ~= 1
                    app.bins = app.CallingApp.overlay.settings{si}.binBox;
                else
                    app.bins = 1000;
                end
            else
                app.bins = 1000;
            end
            app.binBox.Value = app.bins;
            app.hlf1 = round(app.bins/2);
            app.hlf2 = app.bins - app.hlf1;
            
            [app] = cmapget(app);
            
            app.cMap2 = zeros(2,1,3);
            [app] = updateBox(app);        
        end

        % Button pushed function: savecolormapButton
        function saveColorButtonPress(app, event)
            customColor = squeeze(app.cMap);
            [oFile, oPath] = uiputfile({'*.mat'},'Pick a name for your colormap',[app.CallingApp.pths.clrmpPth app.CallingApp.pths.sep 'colormap']);
            save([oPath oFile]','customColor');
            app.CallingApp.colormapSelector.Items = [app.CallingApp.colormapSelector.Items oFile(1:end-4)];
            app.CallingApp.colormapSelectorSurf.Items = [app.CallingApp.colormapSelectorSurf.Items oFile(1:end-4)];
            app.CallingApp.colormapSelectAtlas.Items = [app.CallingApp.colormapSelectAtlas.Items oFile(1:end-4)];
            app.CallingApp.userColor = [app.CallingApp.userColor customColor];
        end

        % Close request function: UIFigure
        function oSelectCloseRequest(app, event)
            delete(app)
        end

        % Selection changed function: ButtonGroup
        function rgbSelection(app, event)
            [app] = radioBarUpdateBox(app);
        end

        % Value changed function: r
        function rSlider(app, event)
            %value = app.r.Value;
            app.rBox.Value = app.r.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: g
        function gSlider(app, event)
            app.gBox.Value = app.g.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: b
        function bSliders(app, event)
            app.bBox.Value = app.b.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: rBox
        function rBoxChange(app, event)
            app.r.Value = app.rBox.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: gBox
        function gBoxChange(app, event)
            app.g.Value = app.gBox.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: bBox
        function bBoxChange(app, event)
            app.b.Value = app.bBox.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: includeMid
        function inclMidButton(app, event)
            cMapTmp = squeeze(app.cMap);
            if strcmpi(app.includeMid.Value,'Yes')
                tmp1 = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(app.hlf1,1) cMapTmp(app.hlf1,2) cMapTmp(app.hlf1,3)],app.hlf1);
                tmp2 = customColorMapInterp([cMapTmp(app.hlf2,1) cMapTmp(app.hlf2,2) cMapTmp(app.hlf2,3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.hlf2);
                app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
            else
                app.cMap = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.bins);
            end
            app = updateBar(app);
            [app] = radioBarUpdateBox(app);
        end

        % Button pushed function: updateonbarButton
        function updateBoxButton(app, event)
            cMapTmp = squeeze(app.cMap);            
            switch app.ButtonGroup.SelectedObject.Text
                case 'Leftmost color'
                    if strcmpi(app.includeMid.Value,'No')
                        app.cMap = customColorMapInterp([app.rBox.Value app.gBox.Value app.bBox.Value; cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.bins);
                    else
                        tmp1 = customColorMapInterp([app.rBox.Value app.gBox.Value app.bBox.Value; cMapTmp(ceil(length(cMapTmp)/2),1) cMapTmp(ceil(length(app.cMap)/2),2) cMapTmp(ceil(length(app.cMap)/2),3)],app.hlf1);
                        tmp2 = customColorMapInterp([cMapTmp(ceil(length(cMapTmp)/2),1) cMapTmp(ceil(length(cMapTmp)/2),2) cMapTmp(ceil(length(cMapTmp)/2),3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.hlf2);
                        app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
                    end
                    
                case 'Rightmost color'
                    if strcmpi(app.includeMid.Value,'No')
                       app.cMap = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); app.rBox.Value app.gBox.Value app.bBox.Value],app.bins);
                    else
                        tmp1 = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(ceil(length(cMapTmp)/2),1) cMapTmp(ceil(length(cMapTmp)/2),2) cMapTmp(ceil(length(cMapTmp)/2),3)],app.hlf1);
                        tmp2 = customColorMapInterp([cMapTmp(ceil(length(cMapTmp)/2),1) cMapTmp(ceil(length(cMapTmp)/2),2) cMapTmp(ceil(length(cMapTmp)/2),3); app.rBox.Value app.gBox.Value app.bBox.Value],app.hlf2);
                        app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
                    end
                    
                case 'Middle color'
                    tmp1 = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); app.rBox.Value app.gBox.Value app.bBox.Value],app.hlf1);
                    tmp2 = customColorMapInterp([app.rBox.Value app.gBox.Value app.bBox.Value; cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.hlf2);
                    app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
                    if strcmpi(app.includeMid.Value,'No')
                        app.includeMid.Value = 'Yes';
                    end
            end
            app = updateBar(app);
        end

        % Button pushed function: saveAndButton
        function saveApplyButtonPress(app, event)
            customColor = squeeze(app.cMap);
            [oFile, oPath] = uiputfile({'*.mat'},'Pick a name for your colormap',[app.CallingApp.pths.clrmpPth app.CallingApp.pths.sep 'colormap']);
            save([oPath oFile]','customColor');
            app.CallingApp.colormapSelector.Items = [app.CallingApp.colormapSelector.Items oFile(1:end-4)];
            app.CallingApp.colormapSelectorSurf.Items = [app.CallingApp.colormapSelectorSurf.Items oFile(1:end-4)];
            app.CallingApp.colormapSelectAtlas.Items = [app.CallingApp.colormapSelectAtlas.Items oFile(1:end-4)];
            
            app.CallingApp.userColor = [app.CallingApp.userColor customColor];
            app.CallingApp.colormapSelector.Value = app.CallingApp.colormapSelector.Items{end};
            
            selectIndex = [];
            remIdx = 1;
            listBoxName = 'overlayListBox';
            for i = 1:length(app.CallingApp.(listBoxName).Value)
                [st,~,sit] = intersect(app.CallingApp.(listBoxName).Value(i),app.CallingApp.(listBoxName).Items);
                selectIndex = [selectIndex; sit];
            end
            id = find(selectIndex == remIdx);
            selectIndex(id) = [];
            
            for i = 1:length(selectIndex)
                app.CallingApp.overlay.settings{selectIndex(i)}.colormapSelector = app.CallingApp.colormapSelector.Items{end};
            end
            
            [~,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
            if ia ~= 1 | ~isgraphics(app.CallingApp.brainFig,'figure')
                app.CallingApp = overPatch(app.CallingApp,'false','false','true');
                app.CallingApp.binBox.Value = app.bins;
                delete(app)
            else
                uialert(app.CallingApp.brainSurferUIFigure,'Your colormap was saved but you cannot patch a new overlay because either a surface is not loaded or an overlay is not selected.','Action unavailable');
            end
        end

        % Value changed function: colormapSelector
        function colormapSelectorChange(app, event)
            %value = app.colormapSelector.Value;
            [app] = cmapget(app);
            
            [app] = radioBarUpdateBox(app);
        end

        % Button pushed function: InvertButton
        function invertBar(app, event)
            app.cMap = fliplr(app.cMap);
            app.im = image(app.cMap,'Parent',app.bar,'XData', [1 app.bar.Position(3)],'YData', [1 app.bar.Position(4)]);
            
            [app] = radioBarUpdateBox(app);
        end

        % Value changed function: binBox
        function binBoxChange(app, event)
            hlf1_old = app.hlf1;
            hlf2_old = app.hlf2;
            
            app.bins = app.binBox.Value;
            app.hlf1 = round(app.bins/2);
            app.hlf2 = app.bins - app.hlf1;
            
            cMapTmp = squeeze(app.cMap);
            if strcmpi(app.includeMid.Value,'Yes')
                tmp1 = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(hlf1_old,1) cMapTmp(hlf1_old,2) cMapTmp(hlf1_old,3)],app.hlf1);
                tmp2 = customColorMapInterp([cMapTmp(hlf2_old,1) cMapTmp(hlf2_old,2) cMapTmp(hlf2_old,3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.hlf2);
                app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
            else
                app.cMap = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.bins);
            end
            app = updateBar(app);
            
            [app] = radioBarUpdateBox(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @oSelectCloseRequest, true);
            app.bar = uiaxes(app.UIFigure);
            zlabel(app.bar, 'Z')
            app.bar.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.bar.XTickLabel = '';
            app.bar.YTickLabel = '';
            app.box = uiaxes(app.UIFigure);
            zlabel(app.box, 'Z')
            app.box.XTickLabel = '';
            app.box.YTickLabel = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Colormap creator';
            app.savecolormapButton = uibutton(app.UIFigure, 'push');
            app.savecolormapButton.ButtonPushedFcn = createCallbackFcn(app, @saveColorButtonPress, true);
            app.savecolormapButton.Text = 'save colormap';
            app.colormapSelector = uidropdown(app.UIFigure);
            app.colormapSelector.Items = {'Select colormap', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.colormapSelector.ValueChangedFcn = createCallbackFcn(app, @colormapSelectorChange, true);
            app.colormapSelector.Tooltip = {'Change the colormap of the currently selected overlay'};
            app.colormapSelector.Value = 'Select colormap';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @rgbSelection, true);
            app.LeftmostcolorButton = uiradiobutton(app.ButtonGroup);
            app.LeftmostcolorButton.Text = 'Leftmost color';
            app.LeftmostcolorButton.Value = true;
            app.MiddlecolorButton = uiradiobutton(app.ButtonGroup);
            app.MiddlecolorButton.Text = 'Middle color';
            app.RightmostcolorButton = uiradiobutton(app.ButtonGroup);
            app.RightmostcolorButton.Text = 'Rightmost color';
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.r = uislider(app.UIFigure);
            app.r.Limits = [0 1];
            app.r.ValueChangedFcn = createCallbackFcn(app, @rSlider, true);
            app.r.Value = 1;
            app.GSliderLabel = uilabel(app.UIFigure);
            app.GSliderLabel.Text = 'G';
            app.g = uislider(app.UIFigure);
            app.g.Limits = [0 1];
            app.g.ValueChangedFcn = createCallbackFcn(app, @gSlider, true);
            app.g.Value = 1;
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.b = uislider(app.UIFigure);
            app.b.Limits = [0 1];
            app.b.ValueChangedFcn = createCallbackFcn(app, @bSliders, true);
            app.b.Value = 1;
            app.rBox = uieditfield(app.UIFigure, 'numeric');
            app.rBox.Limits = [0 1];
            app.rBox.ValueChangedFcn = createCallbackFcn(app, @rBoxChange, true);
            app.rBox.Value = 1;
            app.gBox = uieditfield(app.UIFigure, 'numeric');
            app.gBox.Limits = [0 1];
            app.gBox.ValueChangedFcn = createCallbackFcn(app, @gBoxChange, true);
            app.gBox.Value = 1;
            app.bBox = uieditfield(app.UIFigure, 'numeric');
            app.bBox.Limits = [0 1];
            app.bBox.ValueChangedFcn = createCallbackFcn(app, @bBoxChange, true);
            app.bBox.Value = 1;
            app.saveAndButton = uibutton(app.UIFigure, 'push');
            app.saveAndButton.ButtonPushedFcn = createCallbackFcn(app, @saveApplyButtonPress, true);
            app.saveAndButton.Text = 'save colormap and apply to currently selected overlay';
            app.updateonbarButton = uibutton(app.UIFigure, 'push');
            app.updateonbarButton.ButtonPushedFcn = createCallbackFcn(app, @updateBoxButton, true);
            app.updateonbarButton.Text = {'update'; 'on bar'};
            app.includeMid = uiswitch(app.UIFigure, 'slider');
            app.includeMid.Items = {'No', 'Yes'};
            app.includeMid.ValueChangedFcn = createCallbackFcn(app, @inclMidButton, true);
            app.includeMid.Value = 'Yes';
            app.IncludemiddleininterpolationSwitchLabel = uilabel(app.UIFigure);
            app.IncludemiddleininterpolationSwitchLabel.Text = 'Include middle in interpolation';
            app.InvertButton = uibutton(app.UIFigure, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @invertBar, true);
            app.InvertButton.Text = 'Invert';
            app.BinsEditFieldLabel = uilabel(app.UIFigure);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.binBox = uieditfield(app.UIFigure, 'numeric');
            app.binBox.ValueChangedFcn = createCallbackFcn(app, @binBoxChange, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cbarCreator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end