classdef brainSurfer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        brainSurferUIFigure             matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Panel_8                         matlab.ui.container.Panel
        AppearanceLabel_2               matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        UnderlayTab                     matlab.ui.container.Tab
        Panel_10                        matlab.ui.container.Panel
        ValuesPanel                     matlab.ui.container.Panel
        valueSelectionSurf              matlab.ui.control.DropDown
        posSwitchSurf                   matlab.ui.control.Switch
        PositiveLabel                   matlab.ui.control.Label
        negSwitchSurf                   matlab.ui.control.Switch
        NegativeLabel                   matlab.ui.control.Label
        ZerosLabel                      matlab.ui.control.Label
        remZeroSwitchSurf               matlab.ui.control.Switch
        reloadButton2                   matlab.ui.control.Button
        BinarizevaluessulciandgyriPanel  matlab.ui.container.Panel
        binSwitchSurf                   matlab.ui.control.Switch
        sgThresh                        matlab.ui.control.Spinner
        ThresholdLabel                  matlab.ui.control.Label
        DefinecolorforsulciandgyriindividuallyLabel  matlab.ui.control.Label
        sulcButton                      matlab.ui.control.Button
        gyrButton                       matlab.ui.control.Button
        SurfacePanel                    matlab.ui.container.Panel
        opacityBoxSurf                  matlab.ui.control.Spinner
        opacityLabelSurf_2              matlab.ui.control.Label
        leftHemiToggle                  matlab.ui.control.StateButton
        rightHemiToggle                 matlab.ui.control.StateButton
        opacityLabelSurf_3              matlab.ui.control.Label
        RangeofvaluestoexcludePanel     matlab.ui.container.Panel
        lowerThreshBoxSurf              matlab.ui.control.Spinner
        upperThreshBoxSurf              matlab.ui.control.Spinner
        LowerlimitLabel                 matlab.ui.control.Label
        UpperlimitLabel                 matlab.ui.control.Label
        OverlayTab                      matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        ValuesPanel_2                   matlab.ui.container.Panel
        valueSelection                  matlab.ui.control.DropDown
        posSwitch                       matlab.ui.control.Switch
        PositiveLabel_2                 matlab.ui.control.Label
        negSwitch                       matlab.ui.control.Switch
        NegativeLabel_2                 matlab.ui.control.Label
        ZerosLabel_2                    matlab.ui.control.Label
        remZeroSwitch                   matlab.ui.control.Switch
        reloadOver                      matlab.ui.control.Button
        pvaluesPanel_2                  matlab.ui.container.Panel
        addpButton                      matlab.ui.control.StateButton
        fdrButton                       matlab.ui.control.StateButton
        bonfButton                      matlab.ui.control.StateButton
        pthresholdLabel_2               matlab.ui.control.Label
        pThreshBox                      matlab.ui.control.Spinner
        RangeofvaluestoexcludePanel_2   matlab.ui.container.Panel
        LowerlimitLabel_2               matlab.ui.control.Label
        UpperlimitLabel_2               matlab.ui.control.Label
        lowerThreshBox                  matlab.ui.control.Spinner
        upperThreshBox                  matlab.ui.control.Spinner
        ClusterthresholdPanel           matlab.ui.container.Panel
        clusterThreshBox                matlab.ui.control.Spinner
        SizethreshLabel                 matlab.ui.control.Label
        clusterSlider                   matlab.ui.control.Slider
        ClustcorrButton                 matlab.ui.control.Button
        LimitsTab                       matlab.ui.container.Tab
        TabGroup_4                      matlab.ui.container.TabGroup
        UnderlayTab_2                   matlab.ui.container.Tab
        IntensityhistogramLabel         matlab.ui.control.Label
        UpperlimitofcolormapLabel       matlab.ui.control.Label
        LowerlimitofcolormapLabel       matlab.ui.control.Label
        limMaxSurf                      matlab.ui.control.Spinner
        limMinSurf                      matlab.ui.control.Spinner
        histSurf                        matlab.ui.control.UIAxes
        OverlayTab_3                    matlab.ui.container.Tab
        IntensityhistogramLabel_2       matlab.ui.control.Label
        UpperlimitofcolormapLabel_2     matlab.ui.control.Label
        LowerlimitofcolormapLabel_2     matlab.ui.control.Label
        limMinOver                      matlab.ui.control.Spinner
        limMaxOver                      matlab.ui.control.Spinner
        histOver                        matlab.ui.control.UIAxes
        ColormapTab                     matlab.ui.container.Tab
        TabGroup_3                      matlab.ui.container.TabGroup
        UnderlayTab_3                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        colormapSelectorSurf            matlab.ui.control.DropDown
        binsLabelSurf                   matlab.ui.control.Label
        opacityLabelSurf                matlab.ui.control.Label
        selectSpacingSurf               matlab.ui.control.DropDown
        binBoxSurf                      matlab.ui.control.Spinner
        InvertcolorsLabel               matlab.ui.control.Label
        invertSwitchSurf                matlab.ui.control.Switch
        opacityBoxSG                    matlab.ui.control.Spinner
        OverlayTab_2                    matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        colormapSelector                matlab.ui.control.DropDown
        BinsLabel_2                     matlab.ui.control.Label
        OpacityLabel_2                  matlab.ui.control.Label
        selectSpacing                   matlab.ui.control.DropDown
        binBox                          matlab.ui.control.Spinner
        opacityBox                      matlab.ui.control.Spinner
        invertSwitch                    matlab.ui.control.Switch
        OutlineDropDownLabel            matlab.ui.control.Label
        outlineDropDown                 matlab.ui.control.DropDown
        GrowShrinkSlider_2Label         matlab.ui.control.Label
        growShrinkSlider                matlab.ui.control.Slider
        growShrinkBox                   matlab.ui.control.Spinner
        binSwitch                       matlab.ui.control.DropDown
        InvertcolorsLabel_5             matlab.ui.control.Label
        BinarizeLabel                   matlab.ui.control.Label
        SmoothingTab                    matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        AreaLabel_2                     matlab.ui.control.Label
        StepsLabel_2                    matlab.ui.control.Label
        assignSwitchUnder               matlab.ui.control.Switch
        smoothAreaUnder                 matlab.ui.control.Spinner
        smoothStepsUnder                matlab.ui.control.Spinner
        smoothButtonSurf                matlab.ui.control.StateButton
        VerticesbeingsmoothedLabel_3    matlab.ui.control.Label
        SmoothsulcigyriLabel            matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        AreaLabel_3                     matlab.ui.control.Label
        StepsLabel_3                    matlab.ui.control.Label
        smoothArea                      matlab.ui.control.Spinner
        smoothSteps                     matlab.ui.control.Spinner
        smoothButton                    matlab.ui.control.StateButton
        assignSwitch                    matlab.ui.control.Switch
        VerticesbeingsmoothedLabel_2    matlab.ui.control.Label
        SmoothoverlayLabel_2            matlab.ui.control.Label
        ClustersTab                     matlab.ui.container.Tab
        clustTable                      matlab.ui.control.Table
        ActiontoperformonlasttooltipPanel  matlab.ui.container.Panel
        deleteClustButton               matlab.ui.control.Button
        deleteClustExceptButton         matlab.ui.control.Button
        assignClustColButton            matlab.ui.control.Button
        assignClustValButton            matlab.ui.control.Button
        Panel_12                        matlab.ui.container.Panel
        clustDataKind                   matlab.ui.control.DropDown
        saveTableButton                 matlab.ui.control.Button
        clustSurf                       matlab.ui.control.UIAxes
        ComplexoverlaysTab              matlab.ui.container.Tab
        TabGroup_5                      matlab.ui.container.TabGroup
        TransparencyTab                 matlab.ui.container.Tab
        modOverlaySelect                matlab.ui.control.DropDown
        MaxopacityLabel                 matlab.ui.control.Label
        MinopacityLabel                 matlab.ui.control.Label
        minAlphaMod                     matlab.ui.control.Spinner
        maxAlphaMod                     matlab.ui.control.Spinner
        SelectoverlaytousetomodulatetransparencyLabel_2  matlab.ui.control.Label
        SeparateposnegLabel             matlab.ui.control.Label
        absToggleMod                    matlab.ui.control.Switch
        MaxlimitmodLabel                matlab.ui.control.Label
        MinlimitmodLabel                matlab.ui.control.Label
        modLimMin                       matlab.ui.control.Spinner
        modLimMax                       matlab.ui.control.Spinner
        histMod                         matlab.ui.control.UIAxes
        DTab_3                          matlab.ui.container.Tab
        TwoD1                           matlab.ui.control.DropDown
        TwoD2                           matlab.ui.control.DropDown
        Dim1Label_4                     matlab.ui.control.Label
        Dim2Label_4                     matlab.ui.control.Label
        ndcolorbaronxaxisButton         matlab.ui.control.StateButton
        rotatecolorsButton              matlab.ui.control.Button
        hist2D                          matlab.ui.control.UIAxes
        DTab_4                          matlab.ui.container.Tab
        ThreeD1                         matlab.ui.control.DropDown
        ThreeD2                         matlab.ui.control.DropDown
        Dim1Label_5                     matlab.ui.control.Label
        Dim2Label_5                     matlab.ui.control.Label
        ThreeD3                         matlab.ui.control.DropDown
        Dim3Label_2                     matlab.ui.control.Label
        RotateeachcolorbinonLabel       matlab.ui.control.Label
        numCubes                        matlab.ui.control.Spinner
        CubesinmapLabel                 matlab.ui.control.Label
        CubesizeLabel                   matlab.ui.control.Label
        cubeSlider                      matlab.ui.control.Slider
        cubeSpacing                     matlab.ui.control.Spinner
        axisDrop                        matlab.ui.control.DropDown
        degreeDrop                      matlab.ui.control.DropDown
        byLabel                         matlab.ui.control.Label
        hist3D                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        Panel_9                         matlab.ui.container.Panel
        reloadButton                    matlab.ui.control.Button
        copyButton                      matlab.ui.control.Button
        deleteButton                    matlab.ui.control.Button
        OverlayselectionLabel           matlab.ui.control.Label
        upButton                        matlab.ui.control.Button
        downButton                      matlab.ui.control.Button
        lightsButton                    matlab.ui.control.Button
        screenshotButton                matlab.ui.control.Button
        dupeButton                      matlab.ui.control.Button
        maskButton                      matlab.ui.control.Button
        LHButton                        matlab.ui.control.Button
        RHButton                        matlab.ui.control.Button
        overlayListBox                  matlab.ui.control.ListBox
        Panel_11                        matlab.ui.container.Panel
        colormapSelectAtlas             matlab.ui.control.DropDown
        loadAtlas                       matlab.ui.control.Button
        LoadedatlasLabel                matlab.ui.control.Label
        loadLabels                      matlab.ui.control.Button
        AreaonlastdatatipLabel          matlab.ui.control.Label
        selectSpacingAtlas              matlab.ui.control.DropDown
        upperLimAtlas                   matlab.ui.control.Spinner
        lowerLimAtlas                   matlab.ui.control.Spinner
        binAtlas                        matlab.ui.control.Spinner
        BinsLabel_3                     matlab.ui.control.Label
        UpperlimitLabel_3               matlab.ui.control.Label
        LowerlimitLabel_3               matlab.ui.control.Label
        opacityAtlas                    matlab.ui.control.Spinner
        OpacityLabel_3                  matlab.ui.control.Label
        GrowoutlineLabel                matlab.ui.control.Label
        shrinkGrowAtlas                 matlab.ui.control.Spinner
        toggleColorbarAtlas             matlab.ui.control.StateButton
        reloadAtlas                     matlab.ui.control.Button
        OutlineLabel                    matlab.ui.control.Label
        borderToggleAtlas               matlab.ui.control.Switch
        InvertcolorsLabel_4             matlab.ui.control.Label
        invertToggleAtlas               matlab.ui.control.Switch
        AtlasLabel                      matlab.ui.control.Label
        FileMenu                        matlab.ui.container.Menu
        LoadsurfaceMenu                 matlab.ui.container.Menu
        NewfigureMenu                   matlab.ui.container.Menu
        FsaveragetemplateMenu_2         matlab.ui.container.Menu
        LHRHMenu                        matlab.ui.container.Menu
        LHMenu                          matlab.ui.container.Menu
        RHMenu                          matlab.ui.container.Menu
        Carettemplatefs_LRMenu          matlab.ui.container.Menu
        FlatMenu_3                      matlab.ui.container.Menu
        LHRHMenu_2                      matlab.ui.container.Menu
        LHMenu_12                       matlab.ui.container.Menu
        RHMenu_12                       matlab.ui.container.Menu
        VeryinflatedMenu_3              matlab.ui.container.Menu
        LHRHMenu_3                      matlab.ui.container.Menu
        LHMenu_13                       matlab.ui.container.Menu
        RHMenu_13                       matlab.ui.container.Menu
        InflatedMenu_3                  matlab.ui.container.Menu
        LHRHMenu_4                      matlab.ui.container.Menu
        LHMenu_14                       matlab.ui.container.Menu
        RHMenu_14                       matlab.ui.container.Menu
        MidthicknessMenu_3              matlab.ui.container.Menu
        LHRHMenu_15                     matlab.ui.container.Menu
        LHMenu_15                       matlab.ui.container.Menu
        RHMenu_15                       matlab.ui.container.Menu
        LoadfilesMenu                   matlab.ui.container.Menu
        ExistingfigureMenu              matlab.ui.container.Menu
        FsaveragetemplateMenu_3         matlab.ui.container.Menu
        LHMenu_7                        matlab.ui.container.Menu
        RHMenu_7                        matlab.ui.container.Menu
        Carettemplatefs_LRMenu_2        matlab.ui.container.Menu
        FlatMenu_2                      matlab.ui.container.Menu
        LHMenu_8                        matlab.ui.container.Menu
        RHMenu_8                        matlab.ui.container.Menu
        VeryinflatedMenu_2              matlab.ui.container.Menu
        LHMenu_9                        matlab.ui.container.Menu
        RHMenu_9                        matlab.ui.container.Menu
        InflatedMenu_2                  matlab.ui.container.Menu
        LHMenu_10                       matlab.ui.container.Menu
        RHMenu_10                       matlab.ui.container.Menu
        MidthicknessMenu_2              matlab.ui.container.Menu
        LHMenu_11                       matlab.ui.container.Menu
        RHMenu_11                       matlab.ui.container.Menu
        AddfilesMenu_2                  matlab.ui.container.Menu
        LoadsulcigyriMenu               matlab.ui.container.Menu
        LoadoverlaysMenu                matlab.ui.container.Menu
        SaveoverlaysMenu                matlab.ui.container.Menu
        CreateoverlayfromatlasareaonlastdatatipMenu  matlab.ui.container.Menu
        CombinedsavedCIFTIoverlaysMenu  matlab.ui.container.Menu
        LoadGUIstateMenu                matlab.ui.container.Menu
        SaveGUIstateMenu                matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        AhemisphereMenu                 matlab.ui.container.Menu
        LeftMenu_2                      matlab.ui.container.Menu
        RightMenu_2                     matlab.ui.container.Menu
        AspecificoverlayMenu            matlab.ui.container.Menu
        AllfiguresexceptthoseactivelypatchedbybrainSurferMenu  matlab.ui.container.Menu
        ConvertMenu                     matlab.ui.container.Menu
        Fsaveragetocaretfs_LRMenu       matlab.ui.container.Menu
        Caretfs_LRtofsaverageMenu       matlab.ui.container.Menu
        Freesurfernativetocaretfs_LRMenu  matlab.ui.container.Menu
        Nativefs_LRtofsaverageMenu      matlab.ui.container.Menu
        OperationsMenu                  matlab.ui.container.Menu
        TakeabsolutevalueMenu           matlab.ui.container.Menu
        InvertpositiveandnegativeMenu   matlab.ui.container.Menu
        AlignclusteridentitiesMenu_2    matlab.ui.container.Menu
        SumcombinemapsMenu              matlab.ui.container.Menu
        AverageMenu                     matlab.ui.container.Menu
        StddeviationMenu                matlab.ui.container.Menu
        StderrorofthemeanMenu           matlab.ui.container.Menu
        SubtractMenu                    matlab.ui.container.Menu
        MultiplyMenu                    matlab.ui.container.Menu
        DivideMenu                      matlab.ui.container.Menu
        PCAMenu                         matlab.ui.container.Menu
        AlignclusteridentitiesMenu      matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        LeftlateralMenu                 matlab.ui.container.Menu
        RightlateralMenu                matlab.ui.container.Menu
        InferiorMenu_3                  matlab.ui.container.Menu
        SuperiorMenu_3                  matlab.ui.container.Menu
        SpecificlocationMenu            matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        ColorsMenu                      matlab.ui.container.Menu
        ColorbarMenu                    matlab.ui.container.Menu
        VisibilityMenu                  matlab.ui.container.Menu
        ShowMenu_4                      matlab.ui.container.Menu
        HideMenu_4                      matlab.ui.container.Menu
        OrientationMenu                 matlab.ui.container.Menu
        VerticalMenu                    matlab.ui.container.Menu
        HorizontalMenu                  matlab.ui.container.Menu
        TitlesonlyformultipleselectionsMenu  matlab.ui.container.Menu
        ShowMenu_5                      matlab.ui.container.Menu
        HideMenu_5                      matlab.ui.container.Menu
        ChangespacingbetweencolorbarsMenu  matlab.ui.container.Menu
        CreatecolormapMenu              matlab.ui.container.Menu
        ChangedefaultsurfacecolorMenu   matlab.ui.container.Menu
        ChangebackgroundofbrainfigureMenu  matlab.ui.container.Menu
        UsecolorsfromannotationCIFTIheaderforatlasMenu  matlab.ui.container.Menu
        GUIMenu                         matlab.ui.container.Menu
        DarkModeMenu_3                  matlab.ui.container.Menu
        LightModeMenu_3                 matlab.ui.container.Menu
        LighterModeMenu                 matlab.ui.container.Menu
        ContextMenu                     matlab.ui.container.ContextMenu
        ContextMenu2                    matlab.ui.container.ContextMenu
        EditlightlocationsMenu          matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        ContextMenu4                    matlab.ui.container.ContextMenu
        TakemultiplestandardizedscreenshotsofselectedoverlaysMenu  matlab.ui.container.Menu
        TakemultiplestandardizedscreenshotsofsomeoverlaysMenu  matlab.ui.container.Menu
        TakemultiplestandardizedscreenshotsofalloverlaysMenu  matlab.ui.container.Menu
        ContextMenu5                    matlab.ui.container.ContextMenu
        DeleteselectoverlayMenu         matlab.ui.container.Menu
        DeletealloverlaysMenu           matlab.ui.container.Menu
        ContextMenu6                    matlab.ui.container.ContextMenu
        ContextMenu9                    matlab.ui.container.ContextMenu
        ViewleftlateralMenu             matlab.ui.container.Menu
        ViewrightlateralMenu            matlab.ui.container.Menu
        ViewinferiorMenu                matlab.ui.container.Menu
        ViewSuperiorMenu                matlab.ui.container.Menu
        ContextMenu10                   matlab.ui.container.ContextMenu
        ContextMenu11                   matlab.ui.container.ContextMenu
        MakedefaultsulcalandgyralcolorsMenu_2  matlab.ui.container.Menu
        InvertsulcalandgyralcolorsMenu_2  matlab.ui.container.Menu
        ContextMenu12                   matlab.ui.container.ContextMenu
        ContextMenu13                   matlab.ui.container.ContextMenu
        ChangeFontSizeMenu              matlab.ui.container.Menu
        TrimoverlaynamelengthsMenu      matlab.ui.container.Menu
        RenameoverlayMenu               matlab.ui.container.Menu
        ContextMenu15                   matlab.ui.container.ContextMenu
        ReloadsomeoverlaysMenu          matlab.ui.container.Menu
        ContextMenu16                   matlab.ui.container.ContextMenu
        RandomizecolorsMenu             matlab.ui.container.Menu
        UsedefaultcolorsfromatlasifavailableMenu  matlab.ui.container.Menu
        SavecurrentcolorbaregrandomizeddefaultfromatlasMenu  matlab.ui.container.Menu
        ContextMenu17                   matlab.ui.container.ContextMenu
        CreateoverlayfromareaonlastdatatipMenu_2  matlab.ui.container.Menu
        ContextMenu18                   matlab.ui.container.ContextMenu
        ReassignvalueonlastdatatipMenu  matlab.ui.container.Menu
        ContextMenu19                   matlab.ui.container.ContextMenu
        AddROIsfromanotheroverlaytothiscolormapaspointsMenu  matlab.ui.container.Menu
        ContextMenu20                   matlab.ui.container.ContextMenu
        ContextMenu21                   matlab.ui.container.ContextMenu
        MakecubeopacityuniformMenu      matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)

    end

    properties (Access = public)
        pths = struct('sep','','brnPth','','scrptPth','','clrmpPth','','gPth','','atlasPth','','wrkbnch','','fsrs',''); % this variable will hold all of our paths
        brainFig = ''; % this variable will hold the brain figure
        sg = struct('left','','right',''); % this variable stores the loaded in sulcal and gyral information
        sgB = struct('left','','right',''); % this variable will store a backup of sulcal/gyral info so that it can be reloaded if necessary
        %matVer = [];
        %lastAct = '';
        underlay = struct('left','','right','','leftCurv','','rightCurv','');
        overlay = struct('names','','paths','','bm','','bmB','','settings','');
        ticksSG = struct('left', struct('ticks', '', 'labels', ''), 'right',struct('ticks', '', 'labels', '')); % this variable will hold ticks for the sulcal/gyral information
        ticks = struct('ticks','', 'labels','');
        colormapSG = struct('left', struct('map', '', 'dataMap', ''), 'right',struct('map', '', 'dataMap', ''));
        colormap = struct('map','', 'dataMap','');
        colormapSG_tmp = [];
        sgTh = [];

        ticksSG_tmp = [];
        %ln = {'left', 'lh', '.l.'};
        %rn = {'right', 'rh','.r.'};
        ln = {'left', 'lh', '.l.','_l.','_l_'};
        rn = {'right', 'rh','.r.','_r.','_r_'};

        dlights = [];
        pMap = [];
        sg_tmp = [];

        curs = [];
        dcm = [];
        info = [];
        surfC = [0.5 0.5 0.5];

        sulcColorDisp = [];
        gyrColorDisp = [];

        userColor = [];

        limMaxLineSurf = [];
        limMinLineSurf = [];
        lowerThreshLineSurf = [];
        upperThreshLineSurf = [];

        limMaxLineOver = [];
        limMinLineOver = [];
        lowerThreshLineOver = [];
        upperThreshLineOver = [];

        axSurf;    % for the axes
        axOver;
        lSurf;     % for the line
        lOver;

        cubeSurf = [];
        currOver = [];
        cBar = [];
        cBarDir = 'vert';
        cBarAx = [];
        cBarUnder = [];
        cBarUnderShow = 'no';
        cBarShow = 'yes';
        cBarUnderDir = 'vert';
        cBarUnderAx = [];
        clusters = [];
        cBarText = [];
        cBarSpacing = 0.07;
        ci = 'true';
        nmL = 115;
        atlas = struct('bm',struct('left','','right',''),'overlay',struct('left','','right',''),'cBar','','cBarText','','cBarAx','','regions',struct('left','','right',''),'clusters',struct('left','','right',''),'borders',struct('left','','right',''),'name',struct('left','','right',''),'path',struct('left','','right',''),'defaultColors',struct('left','','right',''),'defaultCbar',struct('left','','right',''));
        %ign = {'pths';'brainFig';'sg';'sgB';'underlay';'overlay';'ticksSG';'ticks';'colormapSG';'colormap';'colormapSG_tmp';'sgTh';'ticksSG_tmp';'ln';'rn';'dlights';'pMap';'sg_tmp';'curs';'dcm';'info';'surfC';'sulcColorDisp';'gyrColorDisp';'userColor';'limMaxLineSurf';'limMinLineSurf';'lowerThreshLineSurf';'upperThreshLineSurf';'limMaxLineOver';'limMinLineOver';'lowerThreshLineOver';'upperThreshLineOver';'axSurf';'axOver';'lSurf';'lOver';'currOver';'cBar';'cBarDir';'cBarAx';'cBarUnder';'cBarUnderShow';'cBarShow';'cBarUnderDir';'cBarUnderAx';'clusters';'cBarText';'cBarSpacing';'ci';'nmL';'atlas';'overlaySelectionApp';'defaultSetOver'};
        saveProp = {'overlay';'sg';'sgB';'underlay';'atlas';'ticksSG';'ticks';'colormapSG';'colormap';'colormapSG_tmp';'sgTh';'ticksSG_tmp';'surfC';'cBarDir';'cBarUnderShow';'cBarShow';'cBarUnder';'cBarUnderDir';'clusters';'cBarSpacing';'nmL'};
        overlaySelectionApp
        colorbarCreate

        defaultSetOver = struct('colormapSelector','jet','selectSpacing','even','valueSelection','Raw','posSwitch','On','negSwitch','On','remZeroSwitch','Off','smoothButton',0,'smoothArea',0,'smoothSteps',0,'assignSwitch','thresholded','lowerThreshBox',0,'upperThreshBox',0,'binSwitch','Off','binBox',1000,'opacityBox',1,'invertSwitch','Off','clusterThreshBox',0,'clusterSlider',0,'outlineDropDown','None','growShrinkBox',0,'colorSpecial','None','pMap',[],'pMapB',[],'pThresh',1,'pFDR',0,'pBonf',0,'clusters',[],'threshVals',[],'threshSmoothData',[],'modData',[],'multiData',[],'absToggleMod','On','incZeroToggleMod','Off','minAlphaMod',0,'maxAlphaMod',1,'alphaModLims',[0 0],'modName','No overlay','TwoDim1Name','No overlay','TwoDim2Name','No overlay','ThreeDim1Name','No overlay','ThreeDim2Name','No overlay','ThreeDim3Name','No overlay','modCmap','','multiCmap','','modCmapTicks','','multiCmapTicks','','color','','colorTicks','','scndCbarAxis','same','multiDataColorMapped','','multiCbData','');

        tor3D = {'upperThreshBox';'lowerThreshBox';'negSwitch';'posSwitch';'valueSelection';'limMaxOver';'limMinOver';'histOver';'binSwitch';'invertSwitch';'binBox';'selectSpacing';'colormapSelector';'assignSwitch';'smoothButton';'smoothSteps';'smoothArea';'assignClustValButton';'assignClustColButton';'deleteClustExceptButton';'deleteClustButton';'rotatecolorsButton';'ndcolorbaronxaxisButton';'TwoD2';'TwoD1';'hist2D';'maskButton';'CreateoverlayfromatlasareaonlastdatatipMenu';'CreateoverlayfromareaonlastdatatipMenu_2';'ReassignvalueonlastdatatipMenu';'PositiveLabel_2';'NegativeLabel_2';'LowerlimitLabel_2';'UpperlimitLabel_2';'RangeofvaluestoexcludePanel_2';'LowerlimitofcolormapLabel_2';'UpperlimitofcolormapLabel_2';'BinsLabel_2';'InvertcolorsLabel_5';'BinarizeLabel';'AreaLabel_3';'StepsLabel_3';'VerticesbeingsmoothedLabel_2'};
        tor2D = {'upperThreshBox';'lowerThreshBox';'negSwitch';'posSwitch';'valueSelection';'limMaxOver';'limMinOver';'histOver';'binSwitch';'invertSwitch';'binBox';'selectSpacing';'colormapSelector';'assignSwitch';'smoothButton';'smoothSteps';'smoothArea';'assignClustValButton';'assignClustColButton';'deleteClustExceptButton';'deleteClustButton';'ThreeDim3Min_2';'upButton_2';'downButton_2';'leftButton';'rightButton';'ThreeD3';'ThreeD2';'ThreeD1';'hist3D';'maskButton';'CreateoverlayfromatlasareaonlastdatatipMenu';'CreateoverlayfromareaonlastdatatipMenu_2';'ReassignvalueonlastdatatipMenu';'PositiveLabel_2';'NegativeLabel_2';'LowerlimitLabel_2';'UpperlimitLabel_2';'RangeofvaluestoexcludePanel_2';'LowerlimitofcolormapLabel_2';'UpperlimitofcolormapLabel_2';'BinsLabel_2';'InvertcolorsLabel_5';'BinarizeLabel';'AreaLabel_3';'StepsLabel_3';'VerticesbeingsmoothedLabel_2';'numCubes';'CubesinmapLabel';'CubesizeLabel';'cubeSpacing';'axisDrop';'RotateeachcolorbinonLabel';'byLabel';'axisDrop';'degreeDrop'};
        torAll = {'upperThreshBox';'lowerThreshBox';'negSwitch';'posSwitch';'valueSelection';'limMaxOver';'limMinOver';'histOver';'binSwitch';'invertSwitch';'binBox';'selectSpacing';'colormapSelector';'assignSwitch';'smoothButton';'smoothSteps';'smoothArea';'assignClustValButton';'assignClustColButton';'deleteClustExceptButton';'deleteClustButton';'rotatecolorsButton';'ndcolorbaronxaxisButton';'TwoD2';'TwoD1';'hist2D';'ThreeDim3Min_2';'upButton_2';'downButton_2';'leftButton';'rightButton';'ThreeD3';'ThreeD2';'ThreeD1';'hist3D';'maskButton';'CreateoverlayfromatlasareaonlastdatatipMenu';'CreateoverlayfromareaonlastdatatipMenu_2';'ReassignvalueonlastdatatipMenu';'PositiveLabel_2';'NegativeLabel_2';'LowerlimitLabel_2';'UpperlimitLabel_2';'RangeofvaluestoexcludePanel_2';'LowerlimitofcolormapLabel_2';'UpperlimitofcolormapLabel_2';'BinsLabel_2';'InvertcolorsLabel_5';'BinarizeLabel';'AreaLabel_3';'StepsLabel_3';'VerticesbeingsmoothedLabel_2';'numCubes';'CubesinmapLabel';'CubesizeLabel';'cubeSpacing';'axisDrop';'RotateeachcolorbinonLabel';'byLabel';'axisDrop';'degreeDrop'};
        %allButtons = {'TabGroup_2';'limMinOver';'limMaxOver';'LowerlimitofcolormapLabel_2';'UpperlimitofcolormapLabel_2';'Panel_3';'Panel_5';'Panel_12';'ActiontoperformonlasttooltipPanel';'clustSurf';'CreateoverlayfromatlasareaonlastdatatipMenu';'TwoD1';'TwoD2';'ndcolorbaronxaxisButton';'rotatecolorsButton';'Dim1Label_4';'Dim2Label_4';'modOverlaySelect';'SelectoverlaytousetomodulatetransparencyLabel_2';'minAlphaMod';'maxAlphaMod';'minAlphaMod';'maxAlphaMod';'absToggleMod';'ThreeD1';'ThreeD2';'ThreeD3';'Dim1Label_5';'Dim2Label_5';'Dim3Label_2';'ThreeDim3Min_2';'CubesinmapLabel';'RotatecolorsLabel';'leftButton';'rightButton';'upButton_2';'downButton_2'};
        allButtons = {};
    end

    methods (Access = private)

        function txt = dcDisplay(app,src,evnt)
            % updates data tips with information about underlay, overlay,
            % atlas

            sg = NaN;
            ib = [];
            reg = NaN;
            v = [];

            [si,~] = getListSelect(app,'overlayListBox',[1]);

            musi = [];
            for i = 1:length(si)
                if ~isempty(app.overlay.settings{si(i)}.multiData)
                    musi = [musi; i];
                end
            end
            if length(musi) > 1
                musi = musi(1);
            end

            delete(findall(app.hist2D, 'type', 'hggroup'));
            delete(findall(app.hist3D, 'type', 'hggroup'));

            lf = 0;
            if isfield(app.underlay,'left')
                [~,~,ib] = intersect(evnt.Position,app.underlay.left.Vertices,'rows');
                if ~isempty(ib)
                    v = ib;
                    if ~isempty(app.atlas.regions.left)
                        reg = [app.atlas.regions.left{v}];
                    else
                        reg = ['No atlas labels loaded'];
                    end
                    if ~isempty(app.atlas.bm.left)
                        reg = [reg ', value:' num2str(app.atlas.bm.left(v))];
                    end
                    lf = 1;
                    if strcmpi(reg,'NaN')
                        reg = num2str(app.atlas.bm.left(v));
                    end
                    if ~isempty(app.sg.left)
                        sg = num2str(app.sg.left(v));
                    end
                end
            end

            rg = 0;
            if isfield(app.underlay,'right')
                [~,~,ib] = intersect(evnt.Position,app.underlay.right.Vertices,'rows');
                if ~isempty(ib)
                    v = ib;
                    if ~isempty(app.atlas.regions.right)
                        reg = [app.atlas.regions.right{v}];
                    else
                        reg = ['No atlas labels loaded'];
                    end
                    if ~isempty(app.atlas.bm.right)
                        reg = [reg ', value:' num2str(app.atlas.bm.right(v))];
                    end
                    rg = 1;
                    if strcmpi(reg,'NaN')
                        reg = num2str(app.atlas.bm.right(v));
                    end
                    if ~isempty(app.sg.right)
                        sg = num2str(app.sg.right(v));
                    end
                end
            end

            if ~isempty(musi)
                [~,~,ib2] = intersect(evnt.Position,app.currOver{musi}.Vertices,'rows');
                clr = app.currOver{musi}.FaceVertexCData(ib2,:);
                if size(app.overlay.settings{si(musi)}.multiData,2) == 2
                    tmp = (app.hist2D.Children.CData(:,:,1)==clr(1))&(app.hist2D.Children.CData(:,:,2)==clr(2))&(app.hist2D.Children.CData(:,:,3)==clr(3));
                    tmp2 = find(tmp == 1);
                    if length(tmp2) > 1
                        tmp2 = tmp2(1);
                    end
                    datatip(app.hist2D.Children,'DataIndex',tmp2);
                elseif size(app.overlay.settings{si(musi)}.multiData,2) == 3
                    %app.overlay.settings{si(musi)}.multiCmap
                    allclr = cell2mat(get(app.hist3D.Children, 'FaceColor'));
                    
                    tmp = (allclr(:,1)==clr(1))&(allclr(:,2)==clr(2))&(allclr(:,3)==clr(3));
                    tmp2 = find(tmp == 1);
                    tmp3 = find(tmp == 0);
                    if length(tmp2) > 1
                        tmp2 = tmp2(1);
                    end
                    app.hist3D.Children(tmp2).FaceAlpha = 1;
                    test = app.hist3D.Children(tmp3);
                    set(test,'FaceAlpha',0.05);
                    %set(app.hist3D.Children,'EdgeAlpha',0);
                    %datatip(app.hist3D.Children,'DataIndex',tmp2);
                end
            end

            if ~isnan(reg)
                id = strfind(reg,'_');
                reg(id) = ' ';
            end

            [si,s] = getListSelect(app,'overlayListBox',1);

            if ~isempty(si) & ~isempty(v)
                for fi = 1:length(si)

                    if fi == 1
                        ov = num2str(app.overlay.bm{si(fi)}(v));
                    else
                        ov = [ov ', ' num2str(app.overlay.bm{si(fi)}(v))];
                    end
                end
            else
                ov = NaN;
            end

            if ~isempty(v)
                txt = ['coordinate: (' num2str(evnt.Position(1)) ', ' num2str(evnt.Position(2)) ' ,' num2str(evnt.Position(3)) ')' newline 'vertex: ' num2str(v)];
            else
                txt = ['coordinate: (' num2str(evnt.Position(1)) ', ' num2str(evnt.Position(2)) ' ,' num2str(evnt.Position(3)) ')'];
            end

            if (rg+lf) == 1
                txt = [txt newline 'region: ' reg newline 'sulci/gyri: ' sg newline 'overlays: ' ov];
            else
                txt = [txt newline 'Coordinate in current datatip matches both hemispheres'];
            end

            if isfield(app.underlay,'left') & isfield(app.underlay,'right')
                if ~isempty(app.underlay.left) & ~isempty(app.underlay.right)
                    if size(app.underlay.left.Vertices,1) ~= size(app.underlay.right.Vertices,1)
                        txt = ['coordinate: (' num2str(evnt.Position(1)) ', ' num2str(evnt.Position(2)) ' ,' num2str(evnt.Position(3)) ')' newline 'Atlas functionality does not support multiple different spaces'];
                        reg = 'Atlas functionality does not support multiple different spaces';
                    end
                end
            end

            id = strfind(reg,',');
            reg = reg(1:id-1);
            app.AreaonlastdatatipLabel.Text = ['Area on last data tip: ' reg];
        end

        % Function that responds to the movement of the sliders
        function lMoving(app, source)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');

                delete(app.lowerThreshLineSurf)
                delete(app.upperThreshLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.lowerThreshLineSurf = plot(ones(size(l))*app.lowerThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.upperThreshLineSurf = plot(ones(size(l))*app.upperThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
                source.Position(1,1) = 0;
                source.Position(2,1) = 0;
            end
        end

        function lMoving_2(app, source)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})

                [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.lowerThreshBox = app.lowerThreshBox.Value;
                app.overlay.settings{si}.upperThreshBox = app.upperThreshBox.Value;

                [app] = overPatch(app,'false','false','false');

                delete(app.lowerThreshLineOver)
                delete(app.upperThreshLineOver)
                hold(app.histOver,'on');
                l = linspace(app.histOver.YLim(1),app.histOver.YLim(2),5);
                app.lowerThreshLineOver = plot(ones(size(l))*app.lowerThreshBox.Value,l,'k','Parent',app.histOver,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                l = linspace(app.histOver.YLim(1),app.histOver.YLim(2),5);
                app.upperThreshLineOver = plot(ones(size(l))*app.upperThreshBox.Value,l,'k','Parent',app.histOver,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histOver,'off');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in an overlay','Action unavailable');
                source.Position(1,1) = 0;
                source.Position(2,1) = 0;
            end
        end
        function lMoving2(app, source)
            if isgraphics(app.brainFig,'figure')
                app.lowerThreshBoxSurf.Value = min(source.Position(:,1));
                app.upperThreshBoxSurf.Value = max(source.Position(:,1));
            else
                source.Position(1,1) = 0;
                source.Position(2,1) = 0;
            end
        end
        function lMoving2_2(app, source)
            if isgraphics(app.brainFig,'figure')
                app.lowerThreshBox.Value = min(source.Position(:,1));
                app.upperThreshBox.Value = max(source.Position(:,1));
            else
                source.Position(1,1) = 0;
                source.Position(2,1) = 0;
            end
        end

        function [hemi,hemi2,d,d2,sz,ci] = loadBrainData(app,f)
            [p,n,e] = fileparts(f);
            disp(['Trying to load file: ' [n e]])

            % now load
            ci = 0;
            try % try to load a regular nifti file
                disp('Attempting to load as conventional nifti file...')
                ld = load_nifti(f);
                sz = size(ld.vol);
                d = ld.vol;
                d2 = [];
            catch
                disp('NIFTI LOAD FAILED!')
                try % if that did not work, load a gifti file
                    disp('Attempting to load as gifti file...')
                    ld = gifti(f);
                    if isfield(ld,'cdata')
                        sz = size(ld.cdata);
                        d = ld.cdata;
                        d2 = [];
                    else
                        uialert(app.brainSurferUIFigure,['Could not find cdata structure (' f ')'],'Unable to load File');
                        sz = [];
                        d = [];
                        d2 = [];
                    end
                catch
                    disp('GIFTI LOAD FAILED!')
                    try % if gifti did not work, try cifti
                        disp('Attempting to load as cifti file...')
                        ld = cifti_read(f);

                        c = cell2mat(ld.diminfo{1}.models);
                        c = {c.struct};

                        l = find(contains(c,'CORTEX_LEFT'));
                        r = find(contains(c,'CORTEX_RIGHT'));

                        if ~isempty(l)
                            d = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                            d(ld.diminfo{1}.models{l}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                        else
                            d = [];
                        end
                        if ~isempty(r)
                            d2 = zeros([ld.diminfo{1}.models{2}.numvert,1]);
                            d2(ld.diminfo{1}.models{r}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                        else
                            d2 = [];
                        end
                        ci = 1;
                    catch
                        disp('CIFTI LOAD FAILED!')
                        try
                            disp('Attempting to load as annotation file...')
                            [vertices, label, ctab] = read_annotation(f);
                            [~, is2] = ismember(label,ctab.table(:,end));
                            d = is2;
                            d2 = [];
                            sz = size(d);
                        catch
                            disp('ANNOTATION LOAD FAILED!')
                            try
                                disp('Attempting to load as label...')
                                [l_template, coordsTemplate] = read_label([], f);
                                [app,hemi] = idHemi(app,{f});
                                if isempty(hemi{1})
                                    im = uiconfirm(app.brainSurferUIFigure,['Looks like this file had no reference to a hemisphere (' n e '). Which hemisphere would you like to associate it with?'],'Hemisphere assignment to overlay','Options',{'left','right','cancel'},'DefaultOption',1);
                                    hemi = im;
                                else
                                    hemi = hemi{1};
                                end
                                d = zeros(size(app.underlay.(hemi).Vertices,1),1);
                                d(l_template(:,1)+1) = 1;
                                sz = size(d);
                                d2 = [];
                            catch
                                try
                                    disp('Attempting to load as freesurfer morphometry file...')
                                    d = read_curv([p n e]);
                                    d2 = [];
                                    sz = size(d);
                                catch
                                    disp('Uh-oh...your file could not be loaded as nifti, gifti, cifti, annotation, label, or morphometry file')
                                end
                            end
                        end
                    end
                end
            end

            if ci == 1
                if ~isempty(d) & ~isempty(d2)
                    hemi = 'left';
                    hemi2 = 'right';
                elseif ~isempty(d) & isempty(d2)
                    hemi = 'left';
                    hemi2 = [];
                elseif isempty(d) & ~isempty(d2)
                    hemi = 'left';
                    hemi2 = [];
                    d = d2;
                end
                sz = size(d);
            else
                if ~exist('hemi','var') & sz(2) == 1
                    [app,hemi] = idHemi(app,{f});
                    hemi = hemi{1};
                    if isempty(hemi)
                        im = uiconfirm(app.brainSurferUIFigure,['Looks like this file had no reference to a hemisphere (' n e '). Which hemisphere would you like to associate it with?'],'Hemisphere assignment to overlay','Options',{'left','right','cancel'},'DefaultOption',1);
                        hemi = im;
                    end
                elseif sz(2) > 1
                    hemi = [];
                end
                hemi2 = [];
            end
        end

        function [hemi,hemi2,d,d2,tmpOut] = vol2fsaverage(app,f)
            id = strfind(f,'\\');
            f(id) = [];
            [p,n,e] = fileparts(f);
            im = uiconfirm(app.brainSurferUIFigure,['Looks like this particular file was in volume space (' n e '). How do you want to convert it to surface space (fsaverage)?'],'Conversion options','Options',{'Thresholded map','Contains one or more ROIs','Unthresholded map','Cancel'},'DefaultOption',3);
            switch im
                case 'Unthresholded map'
                    tmpOut = convertMNI2FS(f,[]);
                    ld = load_nifti(tmpOut{1});
                    ld2 = load_nifti(tmpOut{2});

                    d = ld.vol;
                    d2 = ld2.vol;

                    hemi = 'left';
                    hemi2 = 'right';
                case 'Thresholded map'
                    smoothing = inputdlg({'Smoothing area', 'Smoothing steps'});
                    if isempty(smoothing{1})
                        smoothing{1} = '0';
                    end
                    if isempty(smoothing{2})
                        smoothing{2} = '0';
                    end

                    tmpOut = convertMNI2FSWithMask(f,str2double(smoothing{1}), str2double(smoothing{2}),p);
                    ld = load_nifti(tmpOut{1});
                    ld2 = load_nifti(tmpOut{2});

                    d = ld.vol;
                    d2 = ld2.vol;

                    hemi = 'left';
                    hemi2 = 'right';
                case 'Contains one or more ROIs'
                    weight = inputdlg('Enter a weight vector for ROIs or leave blank for default no weighting option (1s given preference over 0s ; assuming 1s and 0s are in order of ROI values in map)','Define ROI weights');
                    if strcmp(weight{1},'[]') || isempty(weight)
                        weight = [];
                    else
                        weight = str2double(weight);
                    end

                    try
                        tmpOut = convertROIForFS(f, weight{1});
                    catch
                        tmpOut = convertROIForFS(f, []);
                    end
                    tmpOut = tmpOut(1:2:end); % this removes the confidence files...

                    ld = load_nifti(tmpOut{1});
                    ld2 = load_nifti(tmpOut{2});

                    d = ld.vol;
                    d2 = ld2.vol;

                    hemi = 'left';
                    hemi2 = 'right';
            end
        end

        function [hemi,d,tmpOut] = fsaverage2fsLR(app,f,hemi,d)
            % filepaths in f should either be .nii.gz or .gii. If they are
            % .nii.gz we assume the filename comes from conversion of
            % vol->fsaverage.

            [p,n,e] = fileparts(f);
            gF = f;
            switch e
                case '.gz'
                    if strcmpi(n(end-2:end),'nii')
                        template = gifti([app.pths.scrptPth app.pths.sep 'fileTemplate' app.pths.sep 'template.gii']);
                        template.cdata = d;
                        gF = [f(1:end-7) '.gii'];
                        save(template,gF);
                    else
                        uialert(app.brainSurferUIFigure,['Could not find a .nii.gz or .gii file extension for: ' n e],'Invalid action');
                    end
            end

            [pg,ng,eg] = fileparts(gF);

            if strcmpi(hemi,'left')
                h = 'L';
            elseif strcmpi(hemi,'right')
                h = 'R';
            end

            if sum(logical(~rem(d,1)))/size(d,1) ~= 1
                o = [pg app.pths.sep ng '_to_fs_LR' eg];
                toE = [app.pths.wrkbnch app.pths.sep 'wb_command -metric-resample ' pg app.pths.sep ng eg ' ' app.pths.fsrs app.pths.sep 'fsaverage_std_sphere.' h '.164k_fsavg_' h '.surf.gii' ' ' app.pths.fsrs app.pths.sep 'fs_LR-deformed_to-fsaverage.' h '.sphere.32k_fs_LR.surf.gii' ' ADAP_BARY_AREA ' o ' -area-metrics ' app.pths.fsrs app.pths.sep 'fsaverage.' h '.midthickness_va_avg.164k_fsavg_' h '.shape.gii' ' ' app.pths.fsrs app.pths.sep 'fs_LR.' h '.midthickness_va_avg.32k_fs_LR.shape.gii'];
            else
                o = [pg app.pths.sep ng '_to_fs_LR.label' eg];
                toE = [app.pths.wrkbnch app.pths.sep 'wb_command -label-resample ' pg app.pths.sep ng eg ' ' app.pths.fsrs app.pths.sep 'fsaverage_std_sphere.' h '.164k_fsavg_' h '.surf.gii' ' ' app.pths.fsrs app.pths.sep 'fs_LR-deformed_to-fsaverage.' h '.sphere.32k_fs_LR.surf.gii' ' ADAP_BARY_AREA ' o ' -area-metrics ' app.pths.fsrs app.pths.sep 'fsaverage.' h '.midthickness_va_avg.164k_fsavg_' h '.shape.gii' ' ' app.pths.fsrs app.pths.sep 'fs_LR.' h '.midthickness_va_avg.32k_fs_LR.shape.gii'];
            end
            system(toE);
            tmpOut = o;
            g = gifti(o);
            d = double(g.cdata);
        end

        function [hemi,d,tmpOut] = LR2fsaverage(app,f,hemi,d)
            [pg,ng,eg] = fileparts(f);
            if strcmpi(hemi,'left')
                h = 'L';
            elseif strcmpi(hemi,'right')
                h = 'R';
            end

            if sum(logical(~rem(d,1)))/size(d,1) ~= 1
                o = [pg app.pths.sep ng '_to_fsaverage' eg];
                %toE = [app.pths.wrkbnch app.pths.sep '/wb_command -metric-resample ' pg app.pths.sep ng eg ' ' app.pths.fsrs app.pths.sep 'fsaverage_std_sphere.' h '.164k_fsavg_' h '.surf.gii' ' ' app.pths.fsrs app.pths.sep 'fs_LR-deformed_to-fsaverage.' h '.sphere.32k_fs_LR.surf.gii' ' ADAP_BARY_AREA ' o ' -area-metrics ' app.pths.fsrs app.pths.sep 'fsaverage.' h '.midthickness_va_avg.164k_fsavg_' h '.shape.gii' ' ' app.pths.fsrs app.pths.sep 'fs_LR.' h '.midthickness_va_avg.32k_fs_LR.shape.gii'];
                toE = [app.pths.wrkbnch app.pths.sep 'wb_command -metric-resample ' pg app.pths.sep ng eg ' ' app.pths.fsrs app.pths.sep 'fs_LR-deformed_to-fsaverage.' h '.sphere.32k_fs_LR.surf.gii ' app.pths.fsrs app.pths.sep 'fsaverage_std_sphere.' h '.164k_fsavg_' h '.surf.gii' ' ADAP_BARY_AREA ' o  ' -area-metrics ' app.pths.fsrs app.pths.sep 'fs_LR.' h '.midthickness_va_avg.32k_fs_LR.shape.gii' ' ' app.pths.fsrs app.pths.sep 'fsaverage.' h '.midthickness_va_avg.164k_fsavg_' h '.shape.gii'];

            else
                o = [pg app.pths.sep ng '_to_fsaverage.label' eg];
                %toE = [app.pths.wrkbnch app.pths.sep 'wb_command -label-resample ' pg app.pths.sep ng eg ' ' app.pths.fsrs app.pths.sep 'fsaverage_std_sphere.' h '.164k_fsavg_' h '.surf.gii' ' ' app.pths.fsrs app.pths.sep 'fs_LR-deformed_to-fsaverage.' h '.sphere.32k_fs_LR.surf.gii' ' ADAP_BARY_AREA ' o ' -area-metrics ' app.pths.fsrs app.pths.sep 'fsaverage.' h '.midthickness_va_avg.164k_fsavg_' h '.shape.gii' ' ' app.pths.fsrs app.pths.sep 'fs_LR.' h '.midthickness_va_avg.32k_fs_LR.shape.gii'];
                toE = [app.pths.wrkbnch app.pths.sep 'wb_command -label-resample ' pg app.pths.sep ng eg ' ' app.pths.fsrs app.pths.sep 'fs_LR-deformed_to-fsaverage.' h '.sphere.32k_fs_LR.surf.gii ' app.pths.fsrs app.pths.sep 'fsaverage_std_sphere.' h '.164k_fsavg_' h '.surf.gii' ' ADAP_BARY_AREA ' o  ' -area-metrics ' app.pths.fsrs app.pths.sep 'fs_LR.' h '.midthickness_va_avg.32k_fs_LR.shape.gii' ' ' app.pths.fsrs app.pths.sep 'fsaverage.' h '.midthickness_va_avg.164k_fsavg_' h '.shape.gii'];
            end
            system(toE);
            tmpOut = o;
            g = gifti(o);
            d = double(g.cdata);
        end

        function [app] = fsNative2fsLR(app,con,toCon,currSphereOut,midNewOut,midCurrOut,free_currSphere,fs_pial,fs_white)
            if strcmpi(hemi,'left')
                h = 'L';
            elseif strcmpi(hemi,'right')
                h = 'R';
            end

            if sum(logical(~rem(d,1)))/size(d,1) ~= 1
                o = [pg app.pths.sep ng '_to_fsaverage' eg];
                toE = [app.pths.wrkbnch app.pths.sep 'wb_command -metric-resample ' pg app.pths.sep ng eg ' ' app.pths.fsrs app.pths.sep 'fs_LR-deformed_to-fsaverage.' h '.sphere.32k_fs_LR.surf.gii ' app.pths.fsrs app.pths.sep 'fsaverage_std_sphere.' h '.164k_fsavg_' h '.surf.gii' ' ADAP_BARY_AREA ' o  ' -area-metrics ' app.pths.fsrs app.pths.sep 'fs_LR.' h '.midthickness_va_avg.32k_fs_LR.shape.gii' ' ' app.pths.fsrs app.pths.sep 'fsaverage.' h '.midthickness_va_avg.164k_fsavg_' h '.shape.gii'];

            else
                o = [pg app.pths.sep ng '_to_fsaverage.label' eg];
                toE = [app.pths.wrkbnch app.pths.sep 'wb_command -label-resample ' pg app.pths.sep ng eg ' ' app.pths.fsrs app.pths.sep 'fs_LR-deformed_to-fsaverage.' h '.sphere.32k_fs_LR.surf.gii ' app.pths.fsrs app.pths.sep 'fsaverage_std_sphere.' h '.164k_fsavg_' h '.surf.gii' ' ADAP_BARY_AREA ' o  ' -area-metrics ' app.pths.fsrs app.pths.sep 'fs_LR.' h '.midthickness_va_avg.32k_fs_LR.shape.gii' ' ' app.pths.fsrs app.pths.sep 'fsaverage.' h '.midthickness_va_avg.164k_fsavg_' h '.shape.gii'];
            end
            system(toE);
        end


        function [app] = threeDMap(app,rotate)

            [si,s] = getListSelect(app,'overlayListBox',1);

            if ~isempty(si)
                [~,~,si2] = intersect(app.ThreeD1.Value,app.ThreeD1.Items);
                [~,~,si3] = intersect(app.ThreeD2.Value,app.ThreeD2.Items);
                [~,~,si4] = intersect(app.ThreeD3.Value,app.ThreeD3.Items);

                if length(si) == 1
                    d = uiprogressdlg(app.brainSurferUIFigure,'Title','Making 3D patch','Message','Creating a 2D colormap...','Value',0.5);
                    app.hist3D.Title.String = '';

                    if ~isempty(app.overlay.settings{si2}.threshSmoothData)
                        app.overlay.settings{si}.multiData(:,1) = app.overlay.settings{si2}.threshSmoothData;
                    else
                        app.overlay.settings{si}.multiData(:,1) = app.overlay.bm{si2};
                    end
                    if ~isempty(app.overlay.settings{si3}.threshSmoothData)
                        app.overlay.settings{si}.multiData(:,2) = app.overlay.settings{si3}.threshSmoothData;
                    else
                        app.overlay.settings{si}.multiData(:,2) = app.overlay.bm{si3};
                    end
                    if ~isempty(app.overlay.settings{si3}.threshSmoothData)
                        app.overlay.settings{si}.multiData(:,2) = app.overlay.settings{si3}.threshSmoothData;
                    else
                        app.overlay.settings{si}.multiData(:,2) = app.overlay.bm{si3};
                    end
                    if ~isempty(app.overlay.settings{si4}.threshSmoothData)
                        app.overlay.settings{si}.multiData(:,3) = app.overlay.settings{si4}.threshSmoothData;
                    else
                        app.overlay.settings{si}.multiData(:,3) = app.overlay.bm{si4};
                    end

                    d.Message = 'Mapping 3D colormap onto data...';
                    d.Value = 0.8;

                    app.overlay.settings{si}.multiCmapTicks = [];
                    app.overlay.settings{si}.multiCmapTicks(:,1) = linspace(app.overlay.settings{si2}.limMinOver,app.overlay.settings{si2}.limMaxOver,app.numCubes.Value);
                    app.overlay.settings{si}.multiCmapTicks(:,2) = linspace(app.overlay.settings{si3}.limMinOver,app.overlay.settings{si3}.limMaxOver,app.numCubes.Value);
                    app.overlay.settings{si}.multiCmapTicks(:,3) = linspace(app.overlay.settings{si4}.limMinOver,app.overlay.settings{si4}.limMaxOver,app.numCubes.Value);
                    app.overlay.settings{si}.multiCbData = app.overlay.settings{si}.multiCmapTicks;

                    if strcmpi(rotate,'false')
                        if app.numCubes.Value ~= 8
                            [rgb_out, ~, ~,~, ~] = colorcubes(app.numCubes.Value, app.cubeSpacing.Value, app.overlay.settings{si}.multiData, 'false', [], [],[],'no');
                        else
                            [rgb_out, ~, ~, ~, ~] = colorcubes(app.numCubes.Value, app.cubeSpacing.Value, app.overlay.settings{si}.multiData, 'false', [], [],[],'no');
                        end
                        app.overlay.settings{si}.multiCmap = rgb_out;
                    end

                    app.overlay.settings{si}.ThreeDim1Name = app.overlay.names{si2};
                    app.overlay.settings{si}.ThreeDim2Name = app.overlay.names{si3};
                    app.overlay.settings{si}.ThreeDim3Name = app.overlay.names{si4};

                    d.Value = 1;
                    [app] = overPatch(app,'false','false','false');

                else
                    uialert(app.brainSurferUIFigure,'You cannot setup a 2D colormap for multiple overlays (selected on right side)','Action unavailable');
                end
            else
                uialert(app.brainSurferUIFigure,'No overlay selected (on the right side)','Action unavailable');
            end
        end
    end

    methods (Access = public)

        function [selectIndex,selectNames] = getListSelect(app,listBoxName,remIdx)
            % We cannot use intersect between listbox value and listbox
            % items because it does not allow for repeats and this may
            % cause problems...remIdx can be an index that is
            % automatically removed because it is illegal (e.g., "no
            % overlay".
            selectIndex = [];
            selectNames = [];
            for i = 1:length(app.(listBoxName).Value)
                [st,~,sit] = intersect(app.(listBoxName).Value(i),app.(listBoxName).Items);
                selectIndex = [selectIndex; sit];
                selectNames = [selectNames; st];
            end
            id = find(selectIndex == remIdx);
            selectIndex(id) = [];
            selectNames(id) = [];
        end

        function [app] = sgPatch(app,smth)
            cV = find(ismember(app.colormapSelectorSurf.Items,app.colormapSelectorSurf.Value));
            if isgraphics(app.brainFig,'figure') & (~isempty(app.sgB.left) | ~isempty(app.sgB.right))

                % set up any variables that can't be passed in as they
                % exist in the GUI
                if app.userColor{cV} == 1
                    cm = app.colormapSelectorSurf.Value;
                else
                    cm = app.userColor{cV};
                end

                if strcmp(app.binSwitchSurf.Value,'On')
                    b = app.sgThresh.Value;
                else
                    b = [];
                end

                switch smth
                    case 'true'
                        if app.smoothButtonSurf.Value == 1
                            a = app.smoothAreaUnder.Value;
                            s = app.smoothStepsUnder.Value;

                            sT = app.assignSwitchUnder.Value;
                            if (isfield(app.sg,'right') & ~isempty(app.sg.right)) & (isfield(app.sg,'left') & ~isempty(app.sg.left))
                                tog = [app.sg.left; app.sg.right];
                            elseif isfield(app.sg,'left') & ~isempty(app.sg.left)
                                tog = [app.sg.left]';
                            elseif isfield(app.sg,'right') & ~isempty(app.sg.right)
                                tog = [app.sg.right]';
                            end

                        else
                            a = 0;
                            s = 0;
                            sT = app.assignSwitchUnder.Value;
                            if ~isempty(app.sgB.left)
                                app.sg.left = app.sgB.left;
                            end
                            if ~isempty(app.sgB.right)
                                app.sg.right = app.sgB.right;
                            end
                        end
                    case 'false'
                        a = 0;
                        s = 0;
                        sT = app.assignSwitchUnder.Value;
                end

                if ~isempty(app.sgB.left) & ~isempty(app.sgB.right)
                    [app.underlay, app.colormapSG, app.ticksSG, app.sg, app.sgTh] = patchUnderlaySG(app.underlay, 'lh', app.sg.left,'rh', app.sg.right,'vls',lower(app.valueSelectionSurf.Value),'thresh',[app.lowerThreshBoxSurf.Value app.upperThreshBoxSurf.Value]...
                        ,'pos',lower(app.posSwitchSurf.Value),'neg',lower(app.negSwitchSurf.Value),'colormap',cm,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'limits',[app.limMinSurf.Value app.limMaxSurf.Value],'binarize',b,'surfC',app.surfC,'inclZero',lower(app.remZeroSwitchSurf.Value),'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT);
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end

                    view(tmp,[-90 0])

                elseif ~isempty(app.sgB.left) & isempty(app.sgB.right)
                    [app.underlay, app.colormapSG_tmp, app.ticksSG_tmp, sg_tmp, sgTh_tmp] = patchUnderlaySG(app.underlay, 'lh', app.sg.left,'vls',lower(app.valueSelectionSurf.Value),'thresh',[app.lowerThreshBoxSurf.Value app.upperThreshBoxSurf.Value]...
                        ,'pos',lower(app.posSwitchSurf.Value),'neg',lower(app.negSwitchSurf.Value),'colormap',cm,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'limits',[app.limMinSurf.Value app.limMaxSurf.Value],'binarize',b,'surfC',app.surfC,'inclZero',lower(app.remZeroSwitchSurf.Value),'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT);
                    app.colormapSG.left = app.colormapSG_tmp.left;
                    app.ticksSG.left = app.ticksSG_tmp.left;
                    app.sg.left = sg_tmp.left;
                    app.sgTh.left = sgTh_tmp.left;
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    view(tmp,[-90 0])

                elseif ~isempty(app.sg.right) & isempty(app.sgB.left)
                    [app.underlay, app.colormapSG_tmp, app.ticksSG_tmp, sg_tmp, sgTh_tmp] = patchUnderlaySG(app.underlay,'rh', app.sg.right,'vls',lower(app.valueSelectionSurf.Value),'thresh',[app.lowerThreshBoxSurf.Value app.upperThreshBoxSurf.Value]...
                        ,'pos',lower(app.posSwitchSurf.Value),'neg',lower(app.negSwitchSurf.Value),'colormap',cm,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'limits',[app.limMinSurf.Value app.limMaxSurf.Value],'binarize',b,'surfC',app.surfC,'inclZero',lower(app.remZeroSwitchSurf.Value),'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT);
                    app.colormapSG.right = app.colormapSG_tmp.right;
                    app.ticksSG.right = app.ticksSG_tmp.right;
                    app.sg.right = sg_tmp.right;
                    app.sgTh.right = sgTh_tmp.right;
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    view(tmp,[90 0])
                end

                % if you performed smoothing just now, overwrite data and
                % update histogram; if you didn't, still update histogram
                switch smth
                    case 'true'
                        if app.smoothButtonSurf.Value == 1
                            if ~isempty(app.sgB.left)
                                app.sg.left =  app.sgTh.left;
                            end
                            if ~isempty(app.sgB.right)
                                app.sg.right =  app.sgTh.right;
                            end
                        end
                end

                tmp = fieldnames(app.underlay);
                u = unique(app.underlay.(tmp{1}).FaceVertexCData,'rows');
                m = mean(u,2);
                [~,mIdx] = min(m);
                [~,mmIdx] = max(m);
                app.sulcButton.BackgroundColor = u(mIdx,:);
                app.gyrButton.BackgroundColor = u(mmIdx,:);
            end
        end

        function [app] = overPatch(app,smth,vls,clstr)
            if isgraphics(app.brainFig,'figure')

                % remove previous selection
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                            delete(app.cBar{i})
                            delete(app.cBarAx{i})
                            delete(app.cBarText{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.cBar = [];
                    app.cBarAx = [];
                end

                % get selections
                [si,s] = getListSelect(app,'overlayListBox',1);
                if si ~= 1
                    % now loop over all items so long as you haven't only
                    % selected 'no overlay'
                    for fi = 1:length(si)

                        % get colormap for the selection
                        cV = find(ismember(app.colormapSelectorSurf.Items,app.overlay.settings{si(fi)}.colormapSelector));
                        % set up any variables that can't be passed in as they
                        % exist in the GUI
                        if app.userColor{cV} == 1
                            cm = app.overlay.settings{si(fi)}.colormapSelector;
                        else
                            cm = app.userColor{cV};
                        end

                        % get smoothing parameters
                        switch smth
                            case 'true'
                                if app.overlay.settings{si(fi)}.smoothButton == 1
                                    a = app.overlay.settings{si(fi)}.smoothArea;
                                    s = app.overlay.settings{si(fi)}.smoothSteps;
                                    sT = app.overlay.settings{si(fi)}.assignSwitch;
                                else % resets overlay completely if you are unclicking the smooth button
                                    a = 0;
                                    s = 0;
                                    sT = app.overlay.settings{si(fi)}.assignSwitch;
                                    app.overlay.bm{si(fi)} = app.overlay.bmB{si(fi)};
                                end
                            case 'false'
                                a = 0;
                                s = 0;
                                sT = app.overlay.settings{si(fi)}.assignSwitch;
                        end

                        if strcmpi(vls,'true')
                            app.overlay.settings{si}.clusterThreshBox = 0;
                            app.clusterSlider.Value = 0;
                            app.clusterThreshBox.Value = 0;

                            app.overlay.settings{si}.posSwitch = 'On';
                            app.posSwitch.Value = 'On';

                            app.overlay.settings{si}.negSwitch = 'On';
                            app.negSwitch.Value = 'On';

                            app.overlay.settings{si}.growShrinkBox = 0;
                            app.growShrinkSlider.Value = 0;
                            app.growShrinkBox.Value = 0;

                            app.overlay.settings{si}.outlineDropDown = 'None';
                            app.outlineDropDown.Value = 'None';

                            app.overlay.settings{si}.smoothButton = 0;
                            app.smoothButton.Value = 0;

                            app.overlay.settings{si}.smoothArea = 0;
                            app.smoothArea.Value = 0;

                            app.overlay.settings{si}.smoothSteps = 0;
                            app.smoothSteps.Value = 0;

                            app.overlay.settings{si}.asignSwitch = 'thresholded';
                            app.assignSwitch.Value = 'thresholded';

                            app.overlay.settings{si}.binSwitch = 'Off';
                            app.binSwitch.Value = 'Off';

                            lm = [];
                        else
                            lm = [app.overlay.settings{si(fi)}.limMinOver app.overlay.settings{si(fi)}.limMaxOver];
                        end

                        if strcmp(clstr,'true') && ~isempty(app.overlay.settings{si(fi)}.clusters)
                            priorclustIn = app.overlay.settings{si(fi)}.clusters;
                        else
                            priorclustIn = [];
                        end

                        moddataIn = app.overlay.settings{si(fi)}.modData;
                        modcmapIn = app.overlay.settings{si(fi)}.modCmap;
                        absmodIn = app.overlay.settings{si(fi)}.absToggleMod;
                        inczeromodIn = app.overlay.settings{si(fi)}.incZeroToggleMod;
                        minalphamodIn = app.overlay.settings{si(fi)}.minAlphaMod;
                        maxalphamodIn = app.overlay.settings{si(fi)}.maxAlphaMod;
                        alphamodlimsIn = [app.overlay.settings{si(fi)}.alphaModLims(1) app.overlay.settings{si(fi)}.alphaModLims(2)];

                        if ~isempty(app.overlay.settings{si(fi)}.multiData)
                            dataIn = app.overlay.settings{si(fi)}.multiData;
                            vlsIn = [];
                            threshIn = [];
                            lm = [];
                            posIn = [];
                            negIn = [];
                            cmapIn = [];
                            %cmapIn = cm;
                            cspaceIn = [];
                            cbinIn = [];
                            cspecIn = [];
                            invtIn = [];
                            binIn = [];
                            incZeroIn = lower(app.overlay.settings{si(fi)}.remZeroSwitch);
                            opacityIn = app.overlay.settings{si(fi)}.opacityBox;
                            clustthreshIn = app.overlay.settings{si(fi)}.clusterThreshBox;
                            pmapIn = app.overlay.settings{si(fi)}.pMap;
                            pthreshIn = app.overlay.settings{si(fi)}.pThresh;
                            outlineIn = lower(app.overlay.settings{si(fi)}.outlineDropDown);
                            growIn = app.overlay.settings{si(fi)}.growShrinkBox;
                            multicmapIn = app.overlay.settings{si(fi)}.multiCmap;
                            scndbaraxisIn = app.overlay.settings{si(fi)}.scndCbarAxis;
                            multicmapticksIn = app.overlay.settings{si(fi)}.multiCmapTicks;
                            multicbdataIn = app.overlay.settings{si(fi)}.multiCbData;

                            if size(app.overlay.settings{si(fi)}.multiData,2) == 2
                                for i = 1:length(app.tor2D)
                                    try
                                        app.(app.tor2D{i}).Visible = 'off';
                                    catch
                                    end
                                end
                            elseif size(app.overlay.settings{si(fi)}.multiData,2) == 3
                                for i = 1:length(app.tor3D)
                                    try
                                        app.(app.tor3D{i}).Visible = 'off';
                                    catch
                                    end
                                end
                            end
                            app.lOver.Visible = 'off';
                            app.lSurf.Visible = 'off';

                        else
                            switch smth
                                case 'true'
                                    if app.smoothButton.Value == 1
                                        dataIn = app.overlay.settings{si(fi)}.threshSmoothData;
                                    else
                                        dataIn = app.overlay.bm{si(fi)};
                                    end
                                case 'false'
                                    dataIn = app.overlay.bm{si(fi)};
                            end
                            vlsIn = lower(app.overlay.settings{si(fi)}.valueSelection);
                            threshIn = [app.overlay.settings{si(fi)}.lowerThreshBox app.overlay.settings{si(fi)}.upperThreshBox];
                            posIn = lower(app.overlay.settings{si(fi)}.posSwitch);
                            negIn = lower(app.overlay.settings{si(fi)}.negSwitch);
                            cmapIn = cm;
                            cspaceIn = lower(app.overlay.settings{si(fi)}.selectSpacing);
                            cbinIn = app.overlay.settings{si(fi)}.binBox;
                            cspecIn = app.overlay.settings{si(fi)}.colorSpecial;
                            invtIn = lower(app.overlay.settings{si(fi)}.invertSwitch);
                            binIn = lower(app.overlay.settings{si(fi)}.binSwitch);
                            incZeroIn = lower(app.overlay.settings{si(fi)}.remZeroSwitch);
                            opacityIn = app.overlay.settings{si(fi)}.opacityBox;
                            clustthreshIn = app.overlay.settings{si(fi)}.clusterThreshBox;
                            pmapIn = app.overlay.settings{si(fi)}.pMap;
                            pthreshIn = app.overlay.settings{si(fi)}.pThresh;
                            outlineIn = lower(app.overlay.settings{si(fi)}.outlineDropDown);
                            growIn = app.overlay.settings{si(fi)}.growShrinkBox;

                            multicmapIn = [];
                            scndbaraxisIn = [];
                            multicmapticksIn = [];
                            multicbdataIn = [];

                            for i = 1:length(app.torAll)
                                try
                                    app.(app.torAll{i}).Visible = 'on';
                                catch
                                end
                            end
                            app.lOver.Visible = 'on';
                            app.lSurf.Visible = 'on';

                        end

                        % now patch...but first check if there is an
                        % underlay for the hemisphere that you are trying
                        % to patch
                        if length(app.overlay.bm{si(fi)}) == size(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,1)
                            if isfield(app.underlay,app.overlay.settings{si(fi)}.hemi)
                                [app.currOver{fi},cmap,ticks,app.overlay.settings{si(fi)}.threshVals,smoothed,app.clusters,transp,app.overlay.settings{si(fi)}.modCmap] = patchOverlay(app.brainFig,dataIn,app.underlay,app.overlay.settings{si(fi)}.hemi,'vls',vlsIn,'thresh',threshIn,...
                                    'pos',posIn,'neg',negIn,'colormap',cmapIn,'colorSpacing',cspaceIn,'colorBins',cbinIn,'colorSpecial',cspecIn,'invertColors',invtIn,'limits',lm,'binarize',binIn,...
                                    'inclZero',incZeroIn,'opacity',opacityIn,'UI',app.brainSurferUIFigure,'smoothSteps', s, 'smoothArea', a, 'smoothToAssign', sT,...
                                    'clusterThresh',clustthreshIn,'pMap',pmapIn,'pThresh',pthreshIn,'outline',outlineIn,'grow',growIn,'priorClusters',priorclustIn,...
                                    'modData',moddataIn,'modCmap',modcmapIn,'multiCmap',multicmapIn,'absMod',absmodIn,'incZeroMod',inczeromodIn,'minAlphaMod',minalphamodIn,...
                                    'maxAlphaMod',maxalphamodIn,'alphaModLims',alphamodlimsIn,'scndCbarAxis',scndbaraxisIn,'multiCmapTicks',multicmapticksIn,'multiCbData',multicbdataIn);

                                % make special edge properties for
                                % outlines...
                                if ~strcmpi(app.overlay.settings{si(fi)}.outlineDropDown,'None')
                                    app.currOver{fi}.EdgeColor = 'interp';
                                    app.currOver{fi}.LineWidth = 2;
                                    app.currOver{fi}.EdgeAlpha = 'flat';
                                    app.currOver{fi}.LineStyle = '-.';
                                end

                                % now setup the colorbar
                                if isempty(app.overlay.settings{si(fi)}.multiData)
                                    cb = cat(3, cmap.map(:,1),cmap.map(:,2),cmap.map(:,3));
                                    if length(app.brainFig.Children) > 1
                                        pos = get(app.brainFig.Children(end),'Position');
                                    else
                                        pos = get(app.brainFig.Children,'Position');
                                    end

                                    if strcmpi(app.cBarDir,'vert')
                                        stripe = 0.04;
                                        edge = 0.01;
                                        space = 0.03;
                                        p1 = (pos(1)+pos(3)+space);
                                        rect = [(p1 - app.cBarSpacing*(length(si)-1)) pos(2) stripe*pos(3) pos(4)];
                                        rect(1) = rect(1) + app.cBarSpacing*(fi-1);
                                        transp.map = transp.map';
                                        tlab = 'YTickLabels';
                                        tlab2 = 'YTick';
                                        tr = 'xtick';
                                    elseif strcmpi(app.cBarDir,'horiz')
                                        stripe = (0.075)/1.7;
                                        space = .1;
                                        offs = 0.07;
                                        %rect = [pos(1) (pos(2)+(pos(2) - app.cBarSpacing*(length(si)-1))) pos(3) stripe*pos(4)];
                                        p2 = (pos(2) - offs);
                                        rect = [pos(1) (p2 + app.cBarSpacing*(length(si)-1)) pos(3) stripe*pos(4)];
                                        rect(2) = rect(2) - app.cBarSpacing*(fi-1);
                                        cb = permute(cb,[2 1 3]);
                                        tlab = 'XTickLabels';
                                        tlab2 = 'XTick';
                                        tr = 'ytick';
                                    end
                                    app.cBarAx{fi} = axes('Position', rect);
                                    app.cBar{fi} = image(app.cBarAx{fi},cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);

                                    set(app.cBarAx{fi},[tr 'labelmode'],'manual')
                                    set(app.cBarAx{fi},[tr 'label'],[])
                                    set(app.cBarAx{fi},'Ydir','normal','YAxisLocation','right')
                                    app.cBar{fi}.Parent.TickLength = [0 0];
                                    app.cBar{fi}.Parent.(tlab2) = linspace(0,1,length(ticks.ticks));
                                    try
                                        app.cBar{fi}.Parent.(tlab) = num2str(ticks.labels(:));
                                    catch
                                        app.cBar{fi}.Parent.(tlab(1:end-1)) = num2str(ticks.labels(:));
                                    end

                                    if length(si) > 1
                                        tmpNm = app.overlayListBox.Items{si(fi)};
                                        id = strfind(tmpNm,'_');
                                        tmpNm(id) = ' ';
                                        if length(tmpNm) > 50
                                            tmpNm = tmpNm(1:50);
                                        end
                                        if strcmpi(app.cBarDir,'vert')
                                            app.cBarText{fi} = text(app.cBarAx{fi},0.5,0.5-(0.005*length(tmpNm)),tmpNm,'Rotation',90);
                                        else
                                            app.cBarText{fi} = text(app.cBarAx{fi},0.5-(0.003*length(tmpNm)),0.5,tmpNm);
                                        end
                                    end

                                    if strcmpi(app.cBarShow,'no')
                                        app.cBar{fi}.Visible = 'off';
                                        app.cBarAx{fi}.Visible = 'off';
                                        app.cBarText{fi}.Visible = 'off';
                                    end
                                end
                                % save out clusters
                                %app.clusters = clusters;
                                app.overlay.settings{si(fi)}.clusters = app.clusters;

                                % save out values
                                %app.overlay.settings{si(fi)}.threshVals = values;

                                % if you performed smoothing just now, overwrite data and
                                % update histogram; if you didn't, still update histogram
                                if length(si) == 1
                                    switch vls
                                        case 'true'
                                            app.overlay.bm{si} = values;
                                            app.overlay.settings{si}.limMinOver = min(app.overlay.bm{si});
                                            app.overlay.settings{si}.limMaxOver = max(app.overlay.bm{si});
                                    end
                                    switch smth
                                        case 'true'
                                            if app.overlay.settings{si}.smoothButton == 1
                                                app.overlay.bm{si} = smoothed;
                                            end
                                    end
                                    if isempty(app.overlay.settings{si(fi)}.multiData)
                                        app.overlay.settings{si(fi)}.color = cmap;
                                        app.overlay.settings{si(fi)}.colorTicks = ticks;
                                        app.overlay.settings{si(fi)}.threshSmoothData = smoothed;
                                    else
                                        app.overlay.settings{si(fi)}.multiCmap = cmap;
                                        app.overlay.settings{si(fi)}.multiCmapTicks = ticks;
                                        %app.overlay.settings{si(fi)}.multiCbData = ;
                                    end
                                    for i = 1:length(app.allButtons)
                                        try
                                            app.(app.allButtons{i}).Visible = 'on';
                                        catch
                                        end
                                    end
                                    app.lOver.Visible = 'on';
                                    app.lSurf.Visible = 'on';
                                else
                                    for i = 1:length(app.allButtons)
                                        try
                                            app.(app.allButtons{i}).Visible = 'off';
                                        catch
                                        end
                                    end
                                    app.lOver.Visible = 'off';
                                    app.lSurf.Visible = 'off';
                                end

                                [tog] = updateGUI(app,'overlay','false','true');
                                tmp = findall(app.brainFig.Children,'Type','Axes');

                                if length(tmp) > 1
                                    tmp=tmp(end);
                                end

                                if strcmpi(app.overlay.settings{si(fi)}.hemi,'right')
                                    view(tmp(end),[90 0])
                                end
                                if strcmpi(app.overlay.settings{si(fi)}.hemi,'left')
                                    view(tmp,[-90 0])
                                end
                            else
                                uialert(app.brainSurferUIFigure,['There is no surface to patch this file on :' app.overlay.names{si(fi)} ''],'Invalid action');
                            end
                        else
                            uialert(app.brainSurferUIFigure,['Surface shape does not match the shape of the brainmap that you are trying to patch :' app.overlay.names{si(fi)} ''],'Invalid action');
                        end

                    end

                else
                    [tog] = updateGUI(app,'overlay','true','true');
                end
            end
        end

        function importAtlas(app,f,lg)
            % f is a cell array of file paths to atlas(es). If f is empty,
            % atlas GUI will be made to default values
            % l is a file path to file with labels for atlas

            d = uiprogressdlg(app.brainSurferUIFigure,'Title','Adding atlas','Message','An atlas is being added...change atlas opacity after this finishes if you would like to see it.','Value',0.5);
            datacursormode(app.brainFig,'on');
            dc = datacursormode(app.brainFig);
            dc.Enable = 'on';
            dc.UpdateFcn = @(src,evnt)dcDisplay(app,src,evnt);

            if ~isempty(f)
                if ~isempty(app.atlas.overlay.left)
                    delete(app.atlas.overlay.left)
                    app.atlas.overlay.left = [];
                end

                if ~isempty(app.atlas.overlay.right)
                    delete(app.atlas.overlay.right)
                    app.atlas.overlay.right = [];
                end

                if ~isempty(app.atlas.cBar)
                    delete(app.atlas.cBar)
                    app.atlas.cBar = [];
                end

                if ~isempty(app.atlas.cBarText)
                    delete(app.atlas.cBarText)
                    app.atlas.cBarText = [];
                end

                if ~isempty(app.atlas.cBarAx)
                    delete(app.atlas.cBarAx)
                    app.atlas.cBarAx = [];
                end

                app.atlas.defaultColors.left = [];
                app.atlas.bm.left = [];
                app.atlas.regions.left = [];
                app.atlas.clusters.left = [];
                app.atlas.borders.left = [];
                app.atlas.name.left = [];
                app.atlas.path.left = [];
                app.atlas.defaultColors.right = [];
                app.atlas.bm.right = [];
                app.atlas.regions.right = [];
                app.atlas.clusters.right = [];
                app.atlas.borders.right = [];
                app.atlas.name.right = [];
                app.atlas.path.right = [];

                [app,hemi] = idHemi(app,f);

                for i = 1:length(f)
                    [p,n,e] = fileparts(f{i});
                    app.atlas.path.(hemi{i}) = [p app.pths.sep];
                    app.atlas.name.(hemi{i}) = [n e];

                    disp(['Trying to load atlas: ' f{i}])

                    % now load
                    try % try to load a regular nifti file
                        disp('Attempting to load as conventional nifti file...')
                        ld = load_nifti([p app.pths.sep n e]);
                        app.atlas.bm.(hemi{i}) = ld.vol;
                        sz = size(app.atlas.bm.(hemi{i}));
                    catch
                        disp('NIFTI LOAD FAILED!')
                        try % if that did not work, load a gifti file
                            disp('Attempting to load as gifti file...')
                            ld = gifti([p app.pths.sep n e]);
                            if isfield(ld,'cdata')
                                app.atlas.bm.(hemi{i}) = ld.cdata;
                                %app.atlas.regions.(hemi{i}) = nan(size(app.underlay.(hemi{i}).Vertices,1),1);
                                app.atlas.regions.(hemi{i}) = cell([size(app.underlay.(hemi{i}).Vertices,1),1]);
                                app.atlas.regions.(hemi{i})(:) = {'No region information'};
                                sz = size(app.atlas.bm.(hemi{i}));
                            else
                                uialert(app.brainSurferUIFigure,['Could not find cdata structure (' f{i} ')'],'Unable to load File');
                                sz = [];
                            end
                        catch
                            disp('GIFTI LOAD FAILED!')
                            try % if gifti did not work, try cifti
                                disp('Attempting to load as cifti file...')
                                ld = cifti_read([p app.pths.sep n e]);

                                c = cell2mat(ld.diminfo{1}.models);
                                c = {c.struct};

                                l = find(contains(c,'CORTEX_LEFT'));
                                r = find(contains(c,'CORTEX_RIGHT'));

                                key = [ld.diminfo{2}.maps.table.key]';
                                name = {ld.diminfo{2}.maps.table.name};

                                if isempty(hemi{i})
                                    app.atlas.bm.left = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                    app.atlas.bm.right = zeros([ld.diminfo{1}.models{2}.numvert,1]);
                                    app.atlas.defaultColors.left = zeros([ld.diminfo{1}.models{1}.numvert,3]);
                                    app.atlas.defaultColors.right = zeros([ld.diminfo{1}.models{2}.numvert,3]);

                                    app.atlas.bm.left(ld.diminfo{1}.models{l}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                                    app.atlas.bm.right(ld.diminfo{1}.models{r}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);

                                    cmap = [ld.diminfo{2}.maps.table(:).rgba]';

                                    [~, is2] = ismember(app.atlas.bm.left,key);
                                    app.atlas.regions.left = name(is2);
                                    app.atlas.defaultColors.left = cmap(is2,1:3);
                                    app.atlas.defaultCbar.left = cmap(:,1:3);

                                    [~, is2] = ismember(app.atlas.bm.right,key);
                                    app.atlas.regions.right = name(is2);
                                    app.atlas.defaultColors.right = cmap(is2,1:3);
                                    app.atlas.defaultCbar.right = cmap(:,1:3);

                                    sz = size(app.atlas.bm.left);

                                    if length(f) > 2
                                        uialert(app.brainSurferUIFigure,'You have a CIFTI file with no reference to a hemisphere, meaning it is bilateral. Make sure you are loading in only one atlas file in this case.','Action unavailable');
                                    end

                                elseif strcmpi(hemi{i},'left')
                                    app.atlas.bm.left = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                    app.atlas.bm.left(ld.diminfo{1}.models{l}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                                    app.atlas.defaultColors.left = zeros([ld.diminfo{1}.models{1}.numvert,3]);
                                    sz = size(app.atlas.bm.left);
                                    [~, is2] = ismember(app.atlas.bm.left,key);
                                    app.atlas.regions.left = name(is2);
                                    cmap = [ld.diminfo{2}.maps.table(:).rgba]';
                                    app.atlas.defaultColors.left = cmap(is2,1:3);
                                    app.atlas.defaultCbar.left = cmap(:,1:3);
                                    if i == 2
                                        if ~isequal(app.atlas.defaultCbar.left,app.atlas.defaultCbar.right)
                                            app.atlas.bm.left = app.atlas.bm.left + (max(app.atlas.bm.right) + 1);
                                        end
                                    end

                                elseif strcmpi(hemi{i},'right')
                                    app.atlas.bm.right = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                    app.atlas.bm.right(ld.diminfo{1}.models{r}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                                    sz = size(app.atlas.bm.right);
                                    [~, is2] = ismember(app.atlas.bm.right,key);
                                    app.atlas.regions.right = name(is2);
                                    cmap = [ld.diminfo{2}.maps.table(:).rgba]';
                                    app.atlas.defaultColors.right = cmap(is2,1:3);
                                    app.atlas.defaultCbar.right = cmap(:,1:3);
                                    if i == 2
                                        if ~isequal(app.atlas.defaultCbar.left,app.atlas.defaultCbar.right)
                                            app.atlas.bm.right = app.atlas.bm.right + (max(app.atlas.bm.left) + 1);
                                        end
                                    end
                                end
                            catch
                                disp('CIFTI LOAD FAILED!')
                                try
                                    disp('Attempting to load as annotation file...')
                                    [vertices, label, ctab] = read_annotation([p app.pths.sep n e]);
                                    %app.atlas.bm.(hemi{i}) = zeros(size(app.underlay.(hemi{i}).Vertices,1),1);
                                    [~, is2] = ismember(label,ctab.table(:,end));
                                    %app.atlas.bm.(hemi{i})(vertices+1) = label;
                                    %app.atlas.bm.(hemi{i})(vertices+1) = is2;
                                    app.atlas.bm.(hemi{i}) = is2;
                                    % use the commented out line to trim the
                                    % 'values' for each roi (because these do
                                    % get displayed)
                                    cmap = ctab.table(:,1:3);

                                    z = find(ctab.table(:,end) == 0);
                                    if isempty(find(ctab.table(:,end) == 0)) & ~isempty(find(label == 0))
                                        id = find(is2 == 0);
                                        is2(id) = 1;
                                    else
                                        id = [];
                                    end

                                    app.atlas.defaultColors.(hemi{i}) = cmap(is2,1:3);
                                    cmap(is2(id),1) = 1;cmap(is2(id),2) = 1;cmap(is2(id),3) = 1;
                                    app.atlas.regions.(hemi{i}) = ctab.struct_names(is2);
                                    app.atlas.regions.(hemi{i})(is2(id)) = {'Unknown'};
                                    app.atlas.defaultCbar.(hemi{i}) = [1 1 1; ctab.table(:,1:3)];

                                    if i == 2
                                        if ~isequal(app.atlas.defaultCbar.left,app.atlas.defaultCbar.right)
                                            app.atlas.bm.right = app.atlas.bm.right + (max(app.atlas.bm.left) + 1);
                                        end
                                    end

                                    sz = size(app.atlas.bm.(hemi{i}));
                                catch
                                    disp('ANNOTATION LOAD FAILED!')
                                    try
                                        disp('Attempting to load as label...')
                                        [l_template, coordsTemplate] = read_label([], [p app.pths.sep n e]);
                                        app.atlas.regions.(hemi{i}) = cell(size(app.underlay.(hemi{i}).Vertices,1),1);
                                        app.atlas.regions.(hemi{i})(:) =  {'No region information'};
                                        app.atlas.regions.(hemi{i})(l_template(:,1)+1) = {'ROI'};
                                        app.atlas.bm.(hemi{i}) = zeros(size(app.underlay.(hemi{i}).Vertices,1),1);
                                        app.atlas.bm.(hemi{i})(l_template(:,1)+1,1) = 1;
                                        sz = size(app.atlas.bm.(hemi{i}));
                                    catch
                                        disp('Uh-oh...your file could not be loaded as nifti, gifti, cifti, annotation or label')
                                    end
                                end
                            end
                        end
                    end

                    % check if data size is 3D...if it is, ask if user
                    % wants to import volume to surface
                    if sz(2) > 1
                        uialert(app.brainSurferUIFigure,'You cannot import a volume space atlas at the moment','Action unavailable');

                    end

                    % get atlas names
                    if ~isempty(lg)
                        [~,~,e] = fileparts(lg);
                        switch e
                            case '.xml'
                                if i == 1
                                    xml = xml2struct([p f]);
                                    name = [];
                                    key = [];
                                    for j = 1:length(xml.Children(4).Children)
                                        if ~isempty(xml.Children(4).Children(j).Children)
                                            name = [name; {xml.Children(4).Children(j).Children.Data}];
                                            key = [key; str2double(xml.Children(4).Children(j).Attributes(1).Value)];
                                        end
                                    end
                                end
                                id = find(app.atlas.bm.(hemi{i}) ~= 0);
                                id2 = find(app.atlas.bm.(hemi{i}) == 0);
                                [~, is2] = ismember(app.atlas.bm.(hemi{i}),key);

                                app.atlas.regions.(hemi{i}) = cell(size(app.atlas.bm.(hemi{i})));
                                app.atlas.regions.(hemi{i})(id) = name(is2(id));
                                app.atlas.regions.(hemi{i})(id2) = {'No region information'};
                            case '.txt'
                                if i == 1
                                    fid = fopen(lg);
                                    tline = fgetl(fid);
                                    tlines = cell(0,1);
                                    while ischar(tline)
                                        tlines{end+1,1} = tline;
                                        tline = fgetl(fid);
                                    end
                                    fclose(fid);
                                end
                                id = find(app.atlas.bm.(hemi{i}) ~= 0);
                                id2 = find(app.atlas.bm.(hemi{i}) == 0);
                                app.atlas.regions.(hemi{i}) = cell(size(app.atlas.bm.(hemi{i})));
                                app.atlas.regions.(hemi{i})(id) = tlines(app.atlas.bm.(hemi{i})(id));
                                app.atlas.regions.(hemi{i})(id2) = {'No region information'};;
                        end
                    end

                    % get clusters and borders
                    atv = find(app.atlas.bm.(hemi{i}) ~= 0);
                    inUn = unique(app.atlas.bm.(hemi{i})(atv));
                    for roii = 1:length(inUn)
                        dataClust{roii} = find(app.atlas.bm.(hemi{i}) == inUn(roii));
                    end
                    app.atlas.clusters.(hemi{i}) = dataClust;
                    dataClust = getClusterBoundary(app.atlas.clusters.(hemi{i}), app.underlay.(hemi{i}).Faces);
                    app.atlas.borders.(hemi{i}) = dataClust;
                end

                if ~isempty(app.atlas.bm.left) & ~isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.left; app.atlas.bm.right];
                elseif isempty(app.atlas.bm.left) & ~isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.right];
                elseif ~isempty(app.atlas.bm.left) & isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.left];
                end

                % update GUI
                app.upperLimAtlas.Value = max(tog);
                app.lowerLimAtlas.Value = min(tog);
                app.colormapSelectAtlas.Value = 'jet';
                app.selectSpacingAtlas.Value = 'even';
                app.binAtlas.Value = (max(tog) - min(tog) +1);
                app.shrinkGrowAtlas.Value = 0;
                app.toggleColorbarAtlas.Value = 0;
                app.invertToggleAtlas.Value = 'Off';
                app.opacityAtlas.Value = 0;
                app.borderToggleAtlas.Value = 'Off';

                for i = 1:length(f)
                    % now patch
                    [app.atlas.overlay.(hemi{i}),cmap,ticks,~,~,~,transp,~] = patchOverlay(app.brainFig,app.atlas.bm.(hemi{i}),app.underlay,hemi{i},...
                        'opacity',app.opacityAtlas.Value,'UI',app.brainSurferUIFigure,'colormap',app.colormapSelectAtlas.Value,'colorSpacing',app.selectSpacingAtlas.Value,...
                        'colorBins',app.binAtlas.Value,'limits',[app.lowerLimAtlas.Value app.upperLimAtlas.Value],'priorClusters',app.atlas.clusters.(hemi{i}));
                end

                % now setup the colorbar
                cb = cat(3, cmap.map(:,1),cmap.map(:,2),cmap.map(:,3));

                if length(app.brainFig.Children) > 1
                    pos = get(app.brainFig.Children(end),'Position');
                else
                    pos = get(app.brainFig.Children,'Position');
                end

                if strcmpi(app.cBarDir,'vert')
                    stripe = (0.075)/1.7;
                    space = .1;
                    offs = 0.07;
                    %rect = [pos(1) (pos(2)+(pos(2) - app.cBarSpacing*(length(si)-1))) pos(3) stripe*pos(4)];
                    p2 = (pos(2) - offs);
                    rect = [pos(1) (p2 + app.cBarSpacing*(length(f)-1)) pos(3) stripe*pos(4)];
                    rect(2) = rect(2) - app.cBarSpacing*(i-1);
                    cb = permute(cb,[2 1 3]);
                    tlab = 'XTickLabels';
                    tlab2 = 'XTick';
                    tr = 'ytick';
                elseif strcmpi(app.cBarDir,'horiz')
                    stripe = 0.04;
                    edge = 0.01;
                    space = 0.03;
                    p1 = (pos(1)+pos(3)+space);
                    rect = [(p1 - app.cBarSpacing*(length(f)-1)) pos(2) stripe*pos(3) pos(4)];
                    rect(1) = rect(1) + app.cBarSpacing*(i-1);
                    transp.map = transp.map';
                    tlab = 'YTickLabels';
                    tlab2 = 'YTick';
                    tr = 'xtick';
                end
                app.atlas.cBarAx = axes('Position', rect);
                app.atlas.cBar = image(app.atlas.cBarAx,cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);

                set(app.atlas.cBarAx,[tr 'labelmode'],'manual')
                set(app.atlas.cBarAx,[tr 'label'],[])
                set(app.atlas.cBarAx,'Ydir','normal','YAxisLocation','right')
                app.atlas.cBar.Parent.TickLength = [0 0];
                app.atlas.cBar.Parent.(tlab2) = linspace(0,1,length(ticks.ticks));
                try
                    app.atlas.cBar.Parent.(tlab) = num2str(ticks.labels(:));
                catch
                    app.atlas.cBar.Parent.(tlab(1:end-1)) = num2str(ticks.labels(:));
                end

                if length(f) == 1
                    atNm = app.atlas.name.(hemi{1});
                elseif length(f) == 2
                    [s,id] = setdiff(app.atlas.name.left,app.atlas.name.right,'stable');
                    tmp = app.atlas.name.left;
                    tmp(id) = [];
                    atNm = tmp;
                end

                id = strfind(atNm,'_');
                atNm(id) = ' ';
                if length(atNm) > 50
                    atNm = atNm(1:50);
                end
                if strcmpi(app.cBarDir,'vert')
                    app.atlas.cBarText = text(app.atlas.cBarAx,0.5-(0.003*length(atNm)),0.5,atNm);
                else
                    app.atlas.cBarText = text(app.atlas.cBarAx,0.5,0.5-(0.005*length(atNm)),atNm,'Rotation',90);
                end

                app.atlas.cBar.Visible = 'off';
                app.atlas.cBarAx.Visible = 'off';
                app.atlas.cBarText.Visible = 'off';

                app.LoadedatlasLabel.Text  = ['Loaded atlas: '  atNm];

                d.Value = 1;
            else
                app.atlas = struct('bm',struct('left','','right',''),'overlay',struct('left','','right',''),'cBar','','cBarText','','cBarAx','','regions',struct('left','','right',''),'clusters',struct('left','','right',''),'borders',struct('left','','right',''),'name',struct('left','','right',''),'path',struct('left','','right',''),'defaultColors',struct('left','','right',''),'defaultCbar',struct('left','','right',''));
                app.LoadedatlasLabel.Text = 'Loaded atlas:';
                app.AreaonlastdatatipLabel.Text = 'Area on last data tip:';
                app.colormapSelectAtlas.Value = app.colormapSelectAtlas.Items{1};
                app.selectSpacingAtlas.Value = app.selectSpacingAtlas.Items{1};
                app.invertToggleAtlas.Value = 'Off';
                app.borderToggleAtlas.Value = 'Off';
                app.toggleColorbarAtlas.Value = 0;
                app.lowerLimAtlas.Value = 0;
                app.upperLimAtlas.Value = 0;
                app.binAtlas.Value = 1;
                app.opacityAtlas.Value = 0;
                app.shrinkGrowAtlas.Value = 0;
            end
        end

        function updateAtlas(app,del)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if ~isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)
                    f = 2;
                    hemi{1} = 'left'; hemi{2} = 'right';
                elseif isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)
                    f = 1;
                    hemi{1} = 'right';
                elseif ~isempty(app.atlas.overlay.left) & isempty(app.atlas.overlay.right)
                    f = 1;
                    hemi{1} = 'left';
                end

                for i = 1:f
                    % remove the old stuff
                    if ~isempty(app.atlas.overlay.(hemi{i}))
                        if strcmpi(del,'true')
                            delete(app.atlas.overlay.(hemi{i}))
                            app.atlas.overlay.(hemi{i}) = [];
                        end
                    end

                    bm = app.atlas.bm.(hemi{i});
                    ic = 'on';

                    if strcmpi(app.borderToggleAtlas.Value,'On')
                        atv = unique(vertcat(app.atlas.borders.(hemi{i}){:}));
                        av = [1:length(bm)]';
                        t = setdiff(av,atv);
                        bm(t) = 0;
                        ic = 'off';
                        if app.shrinkGrowAtlas.Value == 0
                            app.shrinkGrowAtlas.Value = 2;
                        end
                    end

                    cV = find(ismember(app.colormapSelectAtlas.Items,app.colormapSelectAtlas.Value));
                    if app.userColor{cV} == 1
                        cm = app.colormapSelectAtlas.Value;
                    else
                        cm = app.userColor{cV};
                    end


                    % now patch
                    [app.atlas.overlay.(hemi{i}),cmap,ticks,~,~,~,transp,~] = patchOverlay(app.brainFig,bm,app.underlay,hemi{i},...
                        'opacity',app.opacityAtlas.Value,'UI',app.brainSurferUIFigure,'colormap',cm,'colorSpacing',app.selectSpacingAtlas.Value,'inclZero',ic,...
                        'invertColors',lower(app.invertToggleAtlas.Value),'colorBins',app.binAtlas.Value,'limits',[app.lowerLimAtlas.Value app.upperLimAtlas.Value],...
                        'priorClusters',app.atlas.clusters.(hemi{i}));

                    if strcmpi(app.borderToggleAtlas.Value,'On')
                        app.atlas.overlay.(hemi{i}).EdgeColor = 'interp';
                        app.atlas.overlay.(hemi{i}).LineWidth = app.shrinkGrowAtlas.Value;
                        app.atlas.overlay.(hemi{i}).EdgeAlpha = 'flat';
                        app.atlas.overlay.(hemi{i}).LineStyle = ':';
                    end
                end

                if ~isempty(app.atlas.cBar)
                    delete(app.atlas.cBar)
                    app.atlas.cBar = [];
                end

                if ~isempty(app.atlas.cBarText)
                    delete(app.atlas.cBarText)
                    app.atlas.cBarText = [];
                end

                if ~isempty(app.atlas.cBarAx)
                    delete(app.atlas.cBarAx)
                    app.atlas.cBarAx = [];
                end

                % now setup the colorbar
                cb = cat(3, cmap.map(:,1),cmap.map(:,2),cmap.map(:,3));

                if length(app.brainFig.Children) > 1
                    pos = get(app.brainFig.Children(end),'Position');
                else
                    pos = get(app.brainFig.Children,'Position');
                end

                if strcmpi(app.cBarDir,'vert')
                    stripe = (0.075)/1.7;
                    space = .1;
                    offs = 0.07;
                    p2 = (pos(2) - offs);
                    rect = [pos(1) (p2 + app.cBarSpacing*(f-1)) pos(3) stripe*pos(4)];
                    rect(2) = rect(2) - app.cBarSpacing*(1-1);
                    cb = permute(cb,[2 1 3]);
                    tlab = 'XTickLabels';
                    tlab2 = 'XTick';
                    tr = 'ytick';
                elseif strcmpi(app.cBarDir,'horiz')
                    stripe = 0.04;
                    edge = 0.01;
                    space = 0.03;
                    p1 = (pos(1)+pos(3)+space);
                    rect = [(p1 - app.cBarSpacing*(f-1)) pos(2) stripe*pos(3) pos(4)];
                    rect(1) = rect(1) + app.cBarSpacing*(1-1);
                    transp.map = transp.map';
                    tlab = 'YTickLabels';
                    tlab2 = 'YTick';
                    tr = 'xtick';
                end
                app.atlas.cBarAx = axes('Position', rect);
                app.atlas.cBar = image(app.atlas.cBarAx,cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);

                set(app.atlas.cBarAx,[tr 'labelmode'],'manual')
                set(app.atlas.cBarAx,[tr 'label'],[])
                set(app.atlas.cBarAx,'Ydir','normal','YAxisLocation','right')
                app.atlas.cBar.Parent.TickLength = [0 0];
                app.atlas.cBar.Parent.(tlab2) = linspace(0,1,length(ticks.ticks));
                try
                    app.atlas.cBar.Parent.(tlab) = num2str(ticks.labels(:));
                catch
                    app.atlas.cBar.Parent.(tlab(1:end-1)) = num2str(ticks.labels(:));
                end

                if strcmpi(app.cBarDir,'vert')
                    app.atlas.cBarText = text(app.atlas.cBarAx,0.5-(0.003*length(app.LoadedatlasLabel.Text(15:end))),0.5,app.LoadedatlasLabel.Text(15:end));
                else
                    app.atlas.cBarText = text(app.atlas.cBarAx,0.5,0.5-(0.005*length(app.LoadedatlasLabel.Text(15:end))),app.LoadedatlasLabel.Text(15:end),'Rotation',90);
                end

                if app.toggleColorbarAtlas.Value == 0
                    app.atlas.cBar.Visible = 'off';
                    app.atlas.cBarAx.Visible = 'off';
                    app.atlas.cBarText.Visible = 'off';
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        function [app] = twoDMap(app,rotate)
            [si,s] = getListSelect(app,'overlayListBox',1);
            if ~isempty(si)
                [~,~,si2] = intersect(app.TwoD1.Value,app.TwoD1.Items);
                [~,~,si3] = intersect(app.TwoD2.Value,app.TwoD2.Items);
                if length(si) == 1
                    d = uiprogressdlg(app.brainSurferUIFigure,'Title','Making 2D patch','Message','Creating a 2D colormap...','Value',0.5);
                    app.hist2D.Title.String = '';
                    if app.ndcolorbaronxaxisButton.Value == 1
                        app.overlay.settings{si}.scndCbarAxis = 'different';
                    else
                        app.overlay.settings{si}.scndCbarAxis = 'same';
                    end

                    if ~isempty(app.overlay.settings{si2}.threshSmoothData)
                        app.overlay.settings{si}.multiData(:,1) = app.overlay.settings{si2}.threshSmoothData;
                    else
                        app.overlay.settings{si}.multiData(:,1) = app.overlay.bm{si2};
                    end
                    if ~isempty(app.overlay.settings{si3}.threshSmoothData)
                        app.overlay.settings{si}.multiData(:,2) = app.overlay.settings{si3}.threshSmoothData;
                    else
                        app.overlay.settings{si}.multiData(:,2) = app.overlay.bm{si3};
                    end

                    % if colormaps for both selections exist, make sure you
                    % don't need to interpolate to match bin size
                    if ~isempty(app.overlay.settings{si2}.color) & ~isempty(app.overlay.settings{si3}.color)
                        if size(app.overlay.settings{si2}.color.map,1) > size(app.overlay.settings{si3}.color.map,1)
                            options.limits = [app.overlay.settings{si3}.limMinOver app.overlay.settings{si3}.limMaxOver];
                            options.thresh = [app.overlay.settings{si3}.lowerThreshBox app.overlay.settings{si3}.upperThreshBox];

                            [colormap.map,cData,colormap.dataMap,ticks.ticks,ticks.labels] = colormapper(app.overlay.settings{si}.multiData(:,2),'colormap',app.overlay.settings{si3}.colormapSelector,'colorSpacing',app.overlay.settings{si3}.selectSpacing,'colorBins',app.overlay.settings{si2}.binBox,'colorSpecial',app.overlay.settings{si3}.colorSpecial,'invertColors',app.overlay.settings{si3}.invertSwitch,'limits',[min(options.limits) max(options.limits)],'thresh',options.thresh);
                            app.overlay.settings{si3}.color = colormap;
                            app.overlay.settings{si3}.colorTicks = ticks;

                        elseif size(app.overlay.settings{si2}.color.map,1) < size(app.overlay.settings{si3}.color.map,1)
                            options.limits = [app.overlay.settings{si2}.limMinOver app.overlay.settings{si2}.limMaxOver];
                            options.thresh = [app.overlay.settings{si2}.lowerThreshBox app.overlay.settings{si2}.upperThreshBox];

                            [colormap.map,cData,colormap.dataMap,ticks.ticks,ticks.labels] = colormapper(app.overlay.settings{si2}.multiData(:,2),'colormap',app.overlay.settings{si2}.colormapSelector,'colorSpacing',app.overlay.settings{si2}.selectSpacing,'colorBins',app.overlay.settings{si3}.binBox,'colorSpecial',app.overlay.settings{si2}.colorSpecial,'invertColors',app.overlay.settings{si2}.invertSwitch,'limits',[min(options.limits) max(options.limits)],'thresh',options.thresh);

                            app.overlay.settings{si2}.color = colormap;
                            app.overlay.settings{si2}.colorTicks = ticks;
                        end
                    end

                    app.overlay.settings{si}.multiCmapTicks = [];
                    if ~isempty(app.overlay.settings{si2}.color)
                        cmap(:,:,1) = app.overlay.settings{si2}.color.map;
                        app.overlay.settings{si}.multiCmapTicks(:,1) = app.overlay.settings{si2}.colorTicks.labels;
                        cbData(:,1) = app.overlay.settings{si2}.color.dataMap;
                    else
                        cmap(:,:,1) = jet(app.overlay.settings{si3}.binBox);
                        ticks = linspace(min(app.overlay.settings{si}.multiData(:,1)),max(app.overlay.settings{si}.multiData(:,1)),11);
                        dec = floor(log10(max(abs([min(app.overlay.settings{si}.multiData(:,1)) max(app.overlay.settings{si}.multiData(:,1))])))); % decade of largest abs value
                        if dec<1
                            ticks = round(ticks*100^(1-dec))*100^(dec-1);
                        elseif dec == 1
                            ticks = round(ticks*100^(2-dec))*100^(dec-2);
                        else
                            ticks = round(ticks);
                        end
                        app.overlay.settings{si}.multiCmapTicks(:,1) = ticks;
                        cbData(:,2) = linspace(min(app.overlay.settings{si}.multiData(:,1)),max(app.overlay.settings{si}.multiData(:,1)),app.overlay.settings{si3}.binBox);
                    end
                    if ~isempty(app.overlay.settings{si3}.color)
                        cmap(:,:,2) = app.overlay.settings{si3}.color.map;
                        app.overlay.settings{si}.multiCmapTicks(:,2) = app.overlay.settings{si3}.colorTicks.labels;
                        cbData(:,2) = app.overlay.settings{si3}.color.dataMap;
                    else
                        cmap(:,:,2) = jet(app.overlay.settings{si2}.binBox);
                        ticks = linspace(min(app.overlay.settings{si}.multiData(:,2)),max(app.overlay.settings{si}.multiData(:,2)),11);
                        dec = floor(log10(max(abs([min(app.overlay.settings{si}.multiData(:,2)) max(app.overlay.settings{si}.multiData(:,2))])))); % decade of largest abs value
                        if dec<1
                            ticks = round(ticks*100^(1-dec))*100^(dec-1);
                        elseif dec == 1
                            ticks = round(ticks*100^(2-dec))*100^(dec-2);
                        else
                            ticks = round(ticks);
                        end
                        app.overlay.settings{si}.multiCmapTicks(:,2) = ticks;
                        cbData(:,2) = linspace(min(app.overlay.settings{si}.multiData(:,2)),max(app.overlay.settings{si}.multiData(:,2)),app.overlay.settings{si2}.binBox);
                    end
                    app.overlay.settings{si}.multiCbData = cbData;

                    customBar = customColorMapInterpBars(cmap,size(cmap,1),app.overlay.settings{si}.scndCbarAxis);
                    switch rotate
                        case 'true'
                            if ~isempty(app.overlay.settings{si}.multiCmap)
                                customBar2 = imrotate(app.overlay.settings{si}.multiCmap,90);
                            else
                                customBar2 = imrotate(customBar,90);
                            end
                            customBar = customBar2;
                    end

                    %app.overlay.settings{si}.multiCmap = flipud(customBar);
                    app.overlay.settings{si}.multiCmap = customBar;
                    app.overlay.settings{si}.TwoDim2Name = app.overlay.names{si3};
                    app.overlay.settings{si}.TwoDim1Name = app.overlay.names{si2};

                    d.Message = 'Mapping 2D colormap onto data...';
                    d.Value = 0.8;

                    tf = app.overlay.settings{si}.multiCbData(:,1)' >= app.overlay.settings{si}.multiData(:,1);
                    [~,m] = max(tf,[],2);
                    id = find(app.overlay.settings{si}.multiCbData(:,1) > max(app.overlay.settings{si}.multiCmapTicks(:,1)));
                    m(id) = size(app.overlay.settings{si}.multiCbData(:,1),1);
                    id = find(app.overlay.settings{si}.multiCbData(:,1) < min(app.overlay.settings{si}.multiCmapTicks(:,1)));
                    m(id) = 1;

                    tf2 = app.overlay.settings{si}.multiCbData(:,2)' >= app.overlay.settings{si}.multiData(:,2);
                    [~,m2] = max(tf2,[],2);
                    id = find(app.overlay.settings{si}.multiCbData(:,2) > max(app.overlay.settings{si}.multiCmapTicks(:,2)));
                    m(id) = size(app.overlay.settings{si}.multiCbData(:,2),1);
                    id = find(app.overlay.settings{si}.multiCbData(:,2) < min(app.overlay.settings{si}.multiCmapTicks(:,2)));
                    m(id) = 1;

                    sz = length(m);
                    m = [m; m; m];
                    m2 = [m2; m2; m2];
                    m3 = ones([sz,1]);
                    m3 = [m3; m3+1; m3+2];

                    ind = sub2ind([size(customBar)],m,m2,m3);
                    colorMapped = customBar(ind);
                    colorMapped = reshape(colorMapped,[sz,3]);
                    app.overlay.settings{si}.multiDataColorMapped = squeeze(colorMapped);

                    d.Value = 1;
                    [app] = overPatch(app,'false','false','false');

                else
                    uialert(app.brainSurferUIFigure,'You cannot setup a 2D colormap for multiple overlays (selected on right side)','Action unavailable');
                end
            else
                uialert(app.brainSurferUIFigure,'No overlay selected (on the right side)','Action unavailable');
            end
        end

        function [app] = transpModInteraction(app)
            [si,s] = getListSelect(app,'overlayListBox',1);

            if ~isempty(si)
                [s2,~,si2] = intersect(app.modOverlaySelect.Value,app.modOverlaySelect.Items);

                if si2 ~= 1
                    % scale the data that will be used to modulate
                    % transparency...
                    if ~isempty(app.overlay.settings{si2}.threshSmoothData)
                        din = app.overlay.settings{si2}.threshSmoothData;
                    else
                        din = app.overlay.bm{si2};
                    end

                    id1 = find(din >= 0);
                    id2 = find(din < 0);

                    if app.modLimMax.Value == 0 & app.modLimMin.Value == 0
                        if strcmpi(app.absToggleMod.Value ,'On')
                            minVal1 = min(din(id1));
                            maxVal1 = max(din(id1));
                            minVal2 = min(din(id2));
                            maxVal2 = max(din(id2));

                            maxVal = max([maxVal1 maxVal2]);
                            minVal = min([minVal1 minVal2]);
                        else
                            minVal = min(din);
                            maxVal = max(din);
                        end

                        app.modLimMin.Value = minVal;
                        app.modLimMax.Value = maxVal;
                    else
                        minVal = app.modLimMin.Value;
                        maxVal = app.modLimMax.Value;
                    end

                    %for fi = 1:length(si)
                    if length(si) == 1
                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si}) & strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1))
                            % if a colormap does not exist, then generate
                            % it...
                            if isempty(app.overlay.settings{si2}.color)
                                if ~isempty(app.overlay.settings{si2}.threshSmoothData)
                                    ind = app.overlay.settings{si2}.threshSmoothData;
                                else
                                    ind = app.overlay.bm{si2};
                                end
                                [app.overlay.settings{si2}.color.map,cData,app.overlay.settings{si2}.color.dataMap,app.overlay.settings{si2}.colorTicks.ticks,app.overlay.settings{si2}.colorTicks.labels] = colormapper(ind,'colormap',app.overlay.settings{si2}.colormapSelector,'colorSpacing',app.overlay.settings{si2}.selectSpacing,'colorBins',app.overlay.settings{si2}.binBox,'colorSpecial',app.overlay.settings{si2}.colorSpecial,'invertColors',app.overlay.settings{si2}.invertSwitch,'limits',[app.overlay.settings{si2}.limMinOver app.overlay.settings{si2}.limMaxOver],'thresh',[app.overlay.settings{si2}.lowerThreshBox app.overlay.settings{si2}.upperThreshBox]);
                            end
                            
                            if ~isempty(app.overlay.settings{si2}.multiCmap)
                                sz = size(app.overlay.settings{si2}.multiCmap,1);
                            elseif ~isempty(app.overlay.settings{si2}.color.map)
                                sz = size(app.overlay.settings{si2}.color.map,1);
                            else
                                sz = 1000;
                            end
                            
                            if strcmpi(app.absToggleMod.Value ,'On')
                                l(:,1) = linspace(0,minVal,sz);
                                l(:,2) = linspace(0,maxVal,sz);
                            else
                                l(:,1) = linspace(minVal,maxVal,sz);
                            end
                            lv = linspace(app.minAlphaMod.Value,app.maxAlphaMod.Value,sz);
                            s = zeros(size(din));
                            if size(l,2) == 2
                                tf = l(:,1)' <= din(id2);
                                [~,m] = max(tf,[],2);
                                cData = lv(m);
                                id = find(din(id2) < minVal);
                                cData(id) = lv(end);
                                id = find(din(id2) > 0);
                                cData(id) = lv(1);
                                s(id2) = cData;

                                tf = l(:,2)' >= din(id1);
                                [~,m] = max(tf,[],2);
                                cData = lv(m);
                                id = find(din(id1) < 0);
                                cData(id) = lv(1);
                                id = find(din(id1) > maxVal);
                                cData(id) = lv(end);
                                s(id1) = cData;
                            else
                                tf = l' >= din;
                                [~,m] = max(tf,[],2);
                                cData = lv(m);
                                id = find(din < minVal);
                                cData(id) = lv(1);
                                id = find(din > maxVal);
                                cData(id) = lv(end);
                                s = cData;
                            end

                            app.overlay.settings{si2}.modData = din;
                            app.overlay.settings{si2}.modName = app.modOverlaySelect.Value;
                            app.overlay.settings{si2}.absToggleMod = app.absToggleMod.Value;
                            app.overlay.settings{si2}.minAlphaMod = app.minAlphaMod.Value;
                            app.overlay.settings{si2}.maxAlphaMod = app.maxAlphaMod.Value;
                            app.overlay.settings{si2}.alphaModLims = [app.modLimMin.Value app.modLimMax.Value];

                            allV = app.underlay.(app.overlay.settings{si2}.hemi).Vertices;
                            id = find(ismember(allV,app.currOver{1}.Vertices,'rows'));

                            if find(size(s) == size(allV,1)) == 2
                                s = s';
                            end
                            app.currOver{1}.FaceVertexAlphaData = s(id).*app.overlay.settings{si}.opacityBox;

                        else
                            disp('The following overlays are differently shaped or belong to different hemispheres or do not match the shape of the underlay...');
                            disp(app.overlay.names{si2});
                            disp(app.overlay.names{si});
                        end

                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si}) & strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) %& isempty(app.overlay.settings{si}.multiData)
                            app.histMod.Title.String = '';
                            
                            if isempty(app.overlay.settings{si}.multiCmap)
                                cmap1 = app.overlay.settings{si2}.color.map;
                                cmap2 = ones(size(cmap1));
                                cmap(:,:,1) = cmap1;
                                cmap(:,:,2) = cmap2;
                                customBar = customColorMapInterpBars(cmap,size(cmap,1),'same');
                                
                                delete(app.histMod.Children)
                                app.overlay.settings{si}.modCmap = flipud(customBar);
                                im = imshow(app.overlay.settings{si}.modCmap,'Parent',app.histMod,'XData', [1 app.histMod.Position(3)],'YData', [1 app.histMod.Position(4)]);
                                im.AlphaData = ones([size(customBar,1) size(customBar,1)]).*app.overlay.settings{si}.opacityBox;

                                app.histMod.XLim = [0 im.XData(2)];
                                app.histMod.YLim = [0 im.YData(2)];

                                tLen = length(app.overlay.settings{si2}.colorTicks.ticks);

                                app.histMod.XTick = linspace(0,im.XData(2),tLen);
                                app.histMod.YTick = linspace(0,im.YData(2),tLen);
                                app.histMod.XTickLabelRotation = 90;

                                im.Parent.XAxis.Visible = 'on';
                                im.Parent.YAxis.Visible = 'on';

                                app.histMod.YTickLabels = app.overlay.settings{si2}.colorTicks.labels;

                                if strcmpi(app.absToggleMod.Value ,'On')
                                    tmpTick(:,1) = linspace(0,minVal,tLen);
                                    tmpTick(:,2) = linspace(0,maxVal,tLen);
                                    app.overlay.settings{si}.modCmapTicks = tmpTick;
                                    tmp = num2str(tmpTick);
                                    try
                                        app.histMod.XTickLabels = tmp;
                                    catch
                                        app.histMod.XTickLabel = tmp;
                                    end
                                else
                                    tmpTick = linspace(minVal,maxVal,tLen);
                                    app.overlay.settings{si}.modCmapTicks = tmpTick;
                                    try
                                        app.histMod.XTickLabels = tmpTick;
                                    catch
                                        app.histMod.XTickLabel = tmpTick;
                                    end
                                end

                                ystr = app.overlay.names{si}(1:45);
                                id = strfind(ystr,'_');
                                ystr(id) = ' ';
                                app.histMod.YLabel.String = ystr;

                                xstr = app.overlay.names{si2}(1:45);
                                id = strfind(xstr,'_');
                                xstr(id) = ' ';
                                app.histMod.XLabel.String = xstr;
                            else
                                app.histMod.Title.String = 'You are modulating either a 2D or 3D overlay';
                                delete(app.histMod.Children)
                            end
                        else
                            uialert(app.brainSurferUIFigure,'You must select a single overlay','Action unavailable');
                        end
                    else
                        app.histMod.Title.String = 'You have multiple overlays selected to patch so no complex colormap can be displayed';
                        delete(app.histMod.Children)
                    end
                else
                    app.overlay.settings{si}.modData = [];
                    app.overlay.settings{si}.modName = 'No overlay';
                    app.overlay.settings{si}.absToggleMod = 'On';
                    app.overlay.settings{si}.incZeroToggleMod = 'Off';
                    app.overlay.settings{si}.minAlphaMod = 0;
                    app.overlay.settings{si}.maxAlphaMod = 1;
                    app.overlay.settings{si}.modCmap = [];
                    app.overlay.settings{si}.alphaModLims = [0 0];
                    app.currOver{1}.FaceVertexAlphaData = ones(size(app.currOver{1}.FaceVertexAlphaData)).*app.overlay.settings{si}.opacityBox;
                    app.histMod.Title.String = 'No overlay was selected to modulate the transparency of the patched overlay(s)';
                    delete(app.histMod.Children);
                    app.histMod.XLabel.String = '';
                    app.histMod.YLabel.String = '';
                    try
                        app.histMod.XTickLabels = [];
                        app.histMod.YTickLabels = [];
                    catch
                        app.histMod.XTickLabel = [];
                        app.histMod.YTickLabel = [];
                    end
                end
            else
                app.histMod.Title.String = 'No overlay was selected to modulate the transparency of the patched overlay(s)';
            end
        end

        function [tog] = updateGUI(app,fT,def,smth)
            %Function for updating GUI based on some selection. fT determines whether update will involve underlay or overlay data in GUI. Def will determine if you would like the update to set Tdefault  values. Note, default means different things for underlay and overlay because of how these types of data are treated. There is no expectation for how many overlays you will be loading, but we do expect you to only ever have two surface files loaded. As a result, underlay sulcal/gyral information is organized in a simple left/right variable (app.sg.left and app.sg.right with backups in app.sgB.left/right) that gets overwritten but overlays are stored in a cell array (app.overlay). Default in the context of underlays refers to GUI information updated based on loaded sulcal/gyral information. Default in the context of overlays refers to the out-of-the-box GUI state and is only used if the user selects “no overlay” patch. Otherwise, the kind of information treated as “default” for underlays is extracted at the time of overlay loading and stored in a settings structure (app.overlay.settings). The final smth argument refers to whether smoothing was applied to the data. This only matters for underlay data because only for this data do we sometimes need to update one portion of the GUI but not another (e.g., to compute ‘defaults’ for a map over again to reset things, or to update one portion of the GUI but not another).
            switch fT
                case 'underlay'
                    % FIRST: update GUI
                    % combine left and right hemisphere data (if it exists) to
                    % update GUI

                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end

                    try
                        app.TabGroup_2.SelectedTab = app.TabGroup_2.Children(1);
                    catch
                        app.TabGroup_2.Children.SelectedTab = app.UnderlayTab;
                    end
                    try
                        app.LimitsTab.SelectedTab = app.LimitsTab.Children(1);
                    catch
                        app.LimitsTab.Children.SelectedTab = app.UnderlayTab_2;
                    end
                    try
                        app.TabGroup_3.SelectedTab = app.TabGroup_3.Children(1);
                    catch
                        app.TabGroup_3.Children.SelectedTab = app.UnderlayTab_3;
                    end

                    if (isfield(app.sg,'right') & ~isempty(app.sg.right)) & (isfield(app.sg,'left') & ~isempty(app.sg.left))
                        tog = [app.sg.left; app.sg.right];
                        if sum(app.underlay.left.Vertices(:,3)) ~= 0 || sum(app.underlay.right.Vertices(:,3)) ~= 0
                            view(tmp,[-90 0])
                        end
                    elseif isfield(app.sg,'left') & ~isempty(app.sg.left)
                        tog = [app.sg.left]';
                        if sum(app.underlay.left.Vertices(:,3)) ~= 0
                            view(tmp,[-90 0])
                        end
                    elseif isfield(app.sg,'right') & ~isempty(app.sg.right)
                        tog = [app.sg.right]';
                        if sum(app.underlay.right.Vertices(:,3)) ~= 0
                            view(tmp,[90 0])
                        end
                    end
                    app.limMinSurf.Value = min(tog);
                    app.limMaxSurf.Value = max(tog);

                    if strcmp(smth,'true') & app.smoothButtonSurf.Value == 0

                    else
                        %% GUI update sequence for underlays
                        app.axSurf.XLim = [min(tog) max(tog)];
                        app.lowerThreshBoxSurf.Limits = app.axSurf.XLim;
                        app.upperThreshBoxSurf.Limits = app.axSurf.XLim;
                        app.sgThresh.Limits = app.axSurf.XLim;
                        app.limMaxSurf.Value = app.axSurf.XLim(2);
                        app.limMinSurf.Value = app.axSurf.XLim(1);

                        ns = 5;
                        tmp = min(tog):(max(tog)-min(tog))/(ns-1):max(tog);
                        if length(tmp) < ns
                            tmp = [tmp max(tog)];
                        end
                        app.axSurf.XTick = tmp;
                    end

                    % update steps
                    app.lowerThreshBoxSurf.Step = range(tog)/20;
                    app.upperThreshBoxSurf.Step = app.lowerThreshBoxSurf.Step;
                    app.limMaxSurf.Step = app.lowerThreshBoxSurf.Step;
                    app.limMinSurf.Step = app.lowerThreshBoxSurf.Step;
                    app.sgThresh.Step = app.lowerThreshBoxSurf.Step;

                    % update intensity histogram
                    f = figure('visible','off');
                    h = histfit(tog,100,'kernel');
                    bar(h(1).XData,h(1).YData,'k','Parent',app.histSurf);
                    hold(app.histSurf,'on')
                    plot(h(2).XData,h(2).YData,'k','Parent',app.histSurf,'Color',[0.941 0.46 0.262],'LineWidth',2);
                    l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                    app.limMinLineSurf = plot(ones(size(l))*app.limMinSurf.Value,l,'k','Parent',app.histSurf,'Color',[0.635 0.078 0.184],'LineWidth',2,'LineStyle','--');
                    l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                    app.limMaxLineSurf = plot(ones(size(l))*app.limMaxSurf.Value,l,'k','Parent',app.histSurf,'Color',[0.635 0.078 0.184],'LineWidth',2,'LineStyle','--');
                    hold(app.histSurf,'off');
                    delete(f)

                    tmp = fieldnames(app.underlay);
                    u = unique(app.underlay.(tmp{1}).FaceVertexCData,'rows');
                    m = mean(u,2);
                    [~,mIdx] = min(m);
                    [~,mmIdx] = max(m);
                    app.sulcButton.BackgroundColor = u(mIdx,:);
                    app.gyrButton.BackgroundColor = u(mmIdx,:);

                    switch def
                        case 'true'
                            app.colormapSelectorSurf.Value = 'gray';
                            app.selectSpacingSurf.Value = 'even';
                            app.valueSelectionSurf.Value = 'Raw';
                            app.posSwitchSurf.Value = 'On';
                            app.negSwitchSurf.Value = 'On';
                            app.remZeroSwitchSurf.Value = 'Off';
                            m = mean([min(tog) max(tog)]);
                            app.lSurf.Position = [m 0; m 0];
                            app.smoothButtonSurf.Value = 0;
                            app.smoothAreaUnder.Value = 0;
                            app.smoothStepsUnder.Value = 0;
                            app.assignSwitchUnder.Value = 'thresholded';

                            app.lowerThreshBoxSurf.Value = m;
                            app.upperThreshBoxSurf.Value = m;

                            hold(app.histSurf,'on')
                            l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                            app.lowerThreshLineSurf = plot(ones(size(l))*app.lowerThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                            l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                            app.upperThreshLineSurf = plot(ones(size(l))*app.upperThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                            hold(app.histSurf,'off')

                            app.binSwitchSurf.Value = 'Off';
                            try
                                app.sgThresh.Value = 0;
                            catch
                                app.sgThresh.Value = min(tog);
                            end
                            app.binBoxSurf.Value = 1000;
                            app.opacityBoxSurf.Value = 1;
                            app.opacityBoxSG.Value = 1;
                            app.invertSwitchSurf.Value = 'Off';
                            app.smoothAreaUnder.Value = 0;
                            app.smoothStepsUnder.Value = 0;
                    end

                case 'overlay'
                    try
                        app.TabGroup_2.SelectedTab = app.TabGroup_2.Children(2);
                    catch
                        app.TabGroup_2.Children.SelectedTab = app.OverlayTab;
                    end
                    try
                        app.LimitsTab.SelectedTab = app.LimitsTab.Children(2);
                    catch
                        app.LimitsTab.Children.SelectedTab = app.OverlayTab_3;
                    end
                    try
                        app.TabGroup_3.SelectedTab = app.TabGroup_3.Children(2);
                    catch
                        app.TabGroup_3.Children.SelectedTab = app.OverlayTab_2;
                    end

                    [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                    tog = cat(1, app.overlay.bm{si}); % we are getting this now in case you want to make a histogram of all the different maps at some point...

                    % check if selections are just 'no overlay' in which
                    % case make everything in GUI default and don't let the data update the GUI
                    if si == 1
                        def = 'true';
                        for i = 1:length(app.torAll)
                            try
                                app.(app.torAll{i}).Visible = 'on';
                            catch
                            end
                        end
                        app.lOver.Visible = 'on';
                        app.lSurf.Visible = 'on';
                    else
                        % update histograms first because for this it does
                        % not matter whether 1 item was selected or
                        % multiple items...

                        if length(si) == 1
                            c1 = [0 0 0 1]; % bar color
                            c2 = [0.941 0.46 0.262 1]; % line color
                            c4 = [1 0 0 1];% threshold color
                            c3 = [0.635 0.078 0.184 1]; % limits colors
                        else
                            c = distinguishable_colors(length(si));
                            c1 = c;
                            c1(:,4) = 0.3;
                            c2 = c;
                            c2(:,4) = 0.6;
                            c4 = c;
                            c4(:,4) = 0.2;
                            c3 = c;
                            c3(:,4) = 0.2;
                        end

                        % clear legend if last slection was multi-overlay
                        leg = legend(app.histOver);
                        delete(leg)

                        mins = [];
                        maxs = [];
                        for fi = 1:length(si)
                            % update intensity histogram
                            f = figure('visible','off');
                            if ~isempty(app.overlay.settings{si(fi)}.threshSmoothData)
                                h = histfit(app.overlay.settings{si(fi)}.threshSmoothData,100,'kernel');
                            else
                                h = histfit(app.overlay.bm{si(fi)},100,'kernel');
                            end
                            if length(si) > 1
                                p = bar(h(1).XData,h(1).YData,'FaceColor',c1(fi,1:3),'EdgeColor','none','FaceAlpha',0.3,'Parent',app.histOver); % have to ignore manually specified opacity because it doesn't work for "bar"
                                p.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            else
                                p = bar(h(1).XData,h(1).YData,'FaceColor',c1(fi,1:3),'EdgeColor','none','FaceAlpha',1,'Parent',app.histOver);
                                p.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            end
                            hold(app.histOver,'on');
                            p = plot(h(2).XData,h(2).YData,'Color',[c2(fi,:)],'Parent',app.histOver,'LineWidth',2);

                            l = linspace(app.histOver.YLim(1),app.histOver.YLim(2),10000);
                            app.limMinLineOver = plot(ones(size(l))*app.overlay.settings{si(fi)}.limMinOver,l,'k','Parent',app.histOver,'Color',[c3(fi,:)],'LineWidth',2,'LineStyle','--');
                            app.limMinLineOver.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            app.limMaxLineOver = plot(ones(size(l))*app.overlay.settings{si(fi)}.limMaxOver,l,'k','Parent',app.histOver,'Color',[c3(fi,:)],'LineWidth',2,'LineStyle','--');
                            app.limMaxLineOver.Annotation.LegendInformation.IconDisplayStyle = 'off';

                            app.lowerThreshLineOver = plot(ones(size(l))*app.overlay.settings{si(fi)}.lowerThreshBox,l,'k','Parent',app.histOver,'Color',[c4(fi,:)],'LineWidth',2,'LineStyle',':');
                            app.lowerThreshLineOver.Annotation.LegendInformation.IconDisplayStyle = 'off';
                            app.upperThreshLineOver = plot(ones(size(l))*app.overlay.settings{si(fi)}.upperThreshBox,l,'k','Parent',app.histOver,'Color',[c4(fi,:)],'LineWidth',2,'LineStyle',':');
                            app.upperThreshLineOver.Annotation.LegendInformation.IconDisplayStyle = 'off';

                            mins = [mins app.overlay.settings{si(fi)}.limMinOver];
                            maxs = [maxs app.overlay.settings{si(fi)}.limMaxOver];

                            delete(f)
                            if fi == length(si)
                                hold(app.histOver,'off');
                            end
                        end

                        if min(mins) == max(maxs)
                            app.axOver.XLim = [min(mins)-0.00001 max(maxs)];
                        else
                            app.axOver.XLim = [min(mins) max(maxs)];
                        end

                        % if multi-overlay setup legend
                        if length(si) > 1
                            leg = legend(app.histOver);
                            tmp = {app.overlay.names{si}};
                            leg.String = cellfun(@(x) x(1:25), tmp, 'un', 0);
                            bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                            app.clustSurf.YLabel.String = 'Multiple overlays selected';
                            app.clustTable.Data = [];
                            app.histMod.Title.String = 'You have multiple overlays selected to patch so no complex colormap can be displayed';
                            delete(app.histMod.Children);
                            app.histMod.XLabel.String = '';
                            app.histMod.YLabel.String = '';
                            app.histMod.Title.String = '';
                            try
                                app.histMod.XTickLabels = [];
                                app.histMod.YTickLabels = [];
                            catch
                                app.histMod.XTickLabel = [];
                                app.histMod.YTickLabel = [];
                            end

                            % else update GUI parameters
                        elseif length(si) == 1
                            if ~isempty(app.overlay.settings{si}.clusters)
                                clst = app.overlay.settings{si}.clusters;
                                cSz = cellfun('length',clst);
                                id = find(cSz < app.overlay.settings{si}.clusterThreshBox);
                                cSz(id) = [];
                                clst(id) = [];
                                dt = app.overlay.bm{si};
                                id = find(ismember(app.underlay.(app.overlay.settings{si}.hemi).Vertices,app.currOver{1}.Vertices,'rows'));
                                s = setdiff(1:size(app.underlay.(app.overlay.settings{si}.hemi).Vertices,1),id);

                                dt(s) = NaN;
                                tb(:,5) = cSz;
                                for clusti = 1:length(cSz)
                                    verts = clst{clusti};
                                    vertsC = app.underlay.((app.overlay.settings{si}.hemi)).Vertices(verts,:);
                                    %dt = app.overlay.bm{si}(clst{clusti});
                                    [mVal,mId] = max(dt(clst{clusti}));
                                    tb(clusti,1) = verts(mId);
                                    tb(clusti,2) = vertsC(mId,1);
                                    tb(clusti,3) = vertsC(mId,2);
                                    tb(clusti,4) = vertsC(mId,3);
                                    tb(clusti,6) = mean(dt(clst{clusti}),'omitnan');
                                    tb(clusti,7) = mVal;
                                end
                                app.clustTable.Data = tb;
                                if strfind(app.clustDataKind.Value,'size')
                                    bar(app.clustSurf,cSz,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                    app.clustSurf.YLabel.String = 'Size of cluster';
                                elseif strfind(app.clustDataKind.Value,'mean')
                                    bar(app.clustSurf,tb(:,5),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                    app.clustSurf.YLabel.String = 'Mean of cluster';
                                elseif strfind(app.clustDataKind.Value,'max')
                                    bar(app.clustSurf,tb(:,6),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                    app.clustSurf.YLabel.String = 'Max of cluster';
                                end
                            else
                                bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Clusters not yet generated for overlay';
                                app.clustTable.Data = [];
                            end

                            app.minAlphaMod.Value = app.overlay.settings{si}.minAlphaMod;
                            app.maxAlphaMod.Value = app.overlay.settings{si}.maxAlphaMod;
                            app.modLimMin.Value = app.overlay.settings{si}.alphaModLims(1);
                            app.modLimMax.Value = app.overlay.settings{si}.alphaModLims(2);
                            app.absToggleMod.Value = app.overlay.settings{si}.absToggleMod;
                            app.modOverlaySelect.Value = app.overlay.settings{si}.modName;

                            if ~isempty(intersect(app.overlay.settings{si}.TwoDim1Name,app.overlayListBox.Items))
                                app.TwoD1.Value = app.overlay.settings{si}.TwoDim1Name;
                            else
                                app.TwoD1.Value = app.overlayListBox.Items{1};
                            end
                            if ~isempty(intersect(app.overlay.settings{si}.TwoDim2Name,app.overlayListBox.Items))
                                app.TwoD2.Value = app.overlay.settings{si}.TwoDim2Name;
                            else
                                app.TwoD2.Value = app.overlayListBox.Items{1};
                            end
                            if ~isempty(intersect(app.overlay.settings{si}.ThreeDim1Name,app.overlayListBox.Items))
                                app.ThreeD1.Value = app.overlay.settings{si}.ThreeDim1Name;
                            else
                                app.ThreeD1.Value = app.overlayListBox.Items{1};
                            end
                            if ~isempty(intersect(app.overlay.settings{si}.ThreeDim2Name,app.overlayListBox.Items))
                                app.ThreeD2.Value = app.overlay.settings{si}.ThreeDim2Name;
                            else
                                app.ThreeD2.Value = app.overlayListBox.Items{1};
                            end
                            if ~isempty(intersect(app.overlay.settings{si}.ThreeDim3Name,app.overlayListBox.Items))
                                app.ThreeD3.Value = app.overlay.settings{si}.ThreeDim3Name;
                            else
                                app.ThreeD3.Value = app.overlayListBox.Items{1};
                            end

                            % also update complex overlay colorbars....
                            delete(app.histMod.Children)
                            if ~isempty(app.overlay.settings{si}.modData) | ~isempty(app.overlay.settings{si}.multiData)
                                if length(si) == 1
                                    if ~isempty(app.overlay.settings{si}.modData) & isempty(app.overlay.settings{si}.multiData)
                                        app.histMod.Title.String = '';
                                        im = imshow(app.overlay.settings{si}.modCmap,'Parent',app.histMod,'XData', [1 app.histMod.Position(3)],'YData', [1 app.histMod.Position(4)]);
                                        app.histMod.XLim = [0 im.XData(2)];
                                        app.histMod.YLim = [0 im.YData(2)];

                                        tLen = length(app.overlay.settings{si}.colorTicks.ticks);
                                        
%                                         if strcmpi(app.cBarDir,'vert')
%                                             tLen = length(app.cBarAx{1}.YTickLabel);
%                                             map1 = app.cBarAx{1}.YTickLabel;
%                                         else
%                                             tLen = length(app.cBarAx{1}.XTickLabel);
%                                             map1 = app.cBarAx{1}.XTickLabel;
%                                         end
                                        app.histMod.XTick = linspace(0,im.XData(2),tLen);
                                        app.histMod.YTick = linspace(0,im.YData(2),tLen);
                                        app.histMod.XTickLabelRotation = 90;

                                        im.Parent.XAxis.Visible = 'on';
                                        im.Parent.YAxis.Visible = 'on';

                                        %app.histMod.YTickLabels = flipud(map1);
                                        app.histMod.YTickLabels = app.overlay.settings{si}.colorTicks.labels;

                                        if strcmpi(app.overlay.settings{si}.absToggleMod ,'On')
                                            tmpTick = app.overlay.settings{si(fi)}.modCmapTicks;
                                            tmp = num2str(tmpTick);
                                            try
                                                app.histMod.XTickLabels = tmp;
                                            catch
                                                app.histMod.XTickLabel = tmp;
                                            end
                                        else
                                            try
                                                app.histMod.XTickLabels = app.overlay.settings{si(fi)}.modCmapTicks;
                                            catch
                                                app.histMod.XTickLabel = app.overlay.settings{si(fi)}.modCmapTicks;
                                            end
                                        end

                                        ystr = app.overlay.names{si}(1:45);
                                        id = strfind(ystr,'_');
                                        ystr(id) = ' ';
                                        app.histMod.YLabel.String = ystr;

                                        xstr = app.overlay.settings{si}.modName(1:45);
                                        id = strfind(xstr,'_');
                                        xstr(id) = ' ';
                                        app.histMod.XLabel.String = xstr;
                                    end
                                    
                                    if ~isempty(app.overlay.settings{si}.modData) & isempty(app.overlay.settings{si}.multiData)
                                        app.histMod.Title.String = 'You are modulating either a 2D or 3D overlay';
                                    end
                                    
                                    if ~isempty(app.overlay.settings{si}.multiData)
                                        if size(app.overlay.settings{si}.multiData,2) == 2
                                            app.hist2D.Title.String = '';
                                            im = imshow(flipud(app.overlay.settings{si}.multiCmap),'Parent',app.hist2D,'XData', [1 app.hist2D.Position(3)],'YData', [1 app.hist2D.Position(4)]);
                                            im.AlphaData = ones([size(app.overlay.settings{si}.multiCmap,1) size(app.overlay.settings{si}.multiCmap,1)]).*app.overlay.settings{si}.opacityBox;

                                            app.hist2D.XLim = [0 im.XData(2)];
                                            app.hist2D.YLim = [0 im.YData(2)];

                                            app.hist2D.XTick = linspace(0,im.XData(2),size(app.overlay.settings{si}.multiCmapTicks,1));
                                            app.hist2D.XTickLabelRotation = 90;
                                            app.hist2D.YTick = linspace(0,im.YData(2),size(app.overlay.settings{si}.multiCmapTicks,1));

                                            im.Parent.XAxis.Visible = 'on';
                                            im.Parent.YAxis.Visible = 'on';

                                            try
                                                app.hist2D.XTickLabels = app.overlay.settings{si}.multiCmapTicks(:,2);
                                                app.hist2D.YTickLabels = flipud(app.overlay.settings{si}.multiCmapTicks(:,1));
                                            catch
                                                app.hist2D.XTickLabel = app.overlay.settings{si}.multiCmapTicks(:,2);
                                                app.hist2D.YTickLabel = flipud(app.overlay.settings{si}.multiCmapTicks(:,1));
                                            end
                                            ystr = app.overlay.settings{si}.TwoDim1Name(1:45);
                                            id = strfind(ystr,'_');
                                            ystr(id) = ' ';
                                            app.hist2D.YLabel.String = ystr;

                                            xstr = app.overlay.settings{si}.TwoDim2Name(1:45);
                                            id = strfind(xstr,'_');
                                            xstr(id) = ' ';
                                            app.hist2D.XLabel.String = xstr;
                                        else
                                           d = uiprogressdlg(app.brainSurferUIFigure,'Title','Making 2D patch','Message','Creating a 3D colormap...','Value',0.9);

                                            if app.numCubes.Value ~= 8
                                                [~, ~, ~,~, ~] = colorcubes(app.numCubes.Value, app.cubeSpacing.Value, app.overlay.settings{si}.multiData, 'true', [], app.hist3D, app.overlay.settings{si}.multiCmap,'no');
                                            else
                                                tmpFig = openfig([app.pths.scrptPth app.pths.sep 'eightCubes.fig']);
                                                copyobj(tmpFig.Children.Children, app.hist3D)
                                                delete(tmpFig)
                                            end
                                            view(app.hist3D,-50,15)

                                            for ii = 1:length(app.hist3D.Children)
                                                app.hist3D.Children(ii).FaceAlpha = app.opacityBox.Value;
                                            end
                                            d.Value = 1;

                                            app.hist3D.XTick = 1:app.numCubes.Value;
                                            app.hist3D.XTickLabel = app.overlay.settings{si}.multiCmapTicks(:,1);
                                            app.hist3D.XTickLabelRotation = -20;

                                            app.hist3D.YTick = 1:app.numCubes.Value;
                                            app.hist3D.YTickLabel = app.overlay.settings{si}.multiCmapTicks(:,2);
                                            app.hist3D.YTickLabelRotation = 20;

                                            app.hist3D.ZTick = 1:app.numCubes.Value;
                                            app.hist3D.ZTickLabel = app.overlay.settings{si}.multiCmapTicks(:,3);
                                            app.hist3D.ZTickLabelRotation = 0;

                                            ystr = app.overlay.settings{si}.ThreeDim2Name(1:35);
                                            id = strfind(ystr,'_');
                                            ystr(id) = ' ';
                                            app.hist3D.YLabel.String = ystr;

                                            xstr = app.overlay.settings{si}.ThreeDim1Name(1:35);
                                            id = strfind(xstr,'_');
                                            xstr(id) = ' ';
                                            app.hist3D.XLabel.String = xstr;

                                            zstr = app.overlay.settings{si}.ThreeDim3Name(1:35);
                                            id = strfind(zstr,'_');
                                            zstr(id) = ' ';
                                            app.hist3D.ZLabel.String = zstr;
                                        end
                                    end
                                else
                                    if ~isempty(app.overlay.settings{si}.modCmap)
                                        app.histMod.Title.String = 'You have multiple overlays selected to patch so no complex colormap can be displayed';
                                        delete(app.histMod.Children);
                                        app.histMod.XLabel.String = '';
                                        app.histMod.YLabel.String = '';
                                        app.histMod.Title.String = '';
                                        try
                                            app.histMod.XTickLabels = [];
                                            app.histMod.YTickLabels = [];
                                        catch
                                            app.histMod.XTickLabel = [];
                                            app.histMod.YTickLabel = [];
                                        end
                                    end
                                    if ~isempty(app.overlay.settings{si}.multiData)
                                        if size(app.overlay.settings{si}.multiData,2) == 2
                                            app.hist2D.Title.String = 'You have multiple overlays selected to patch so no complex colormap can be displayed';
                                            delete(app.hist2D.Children);
                                            app.hist2D.XLabel.String = '';
                                            app.hist2D.YLabel.String = '';
                                            app.hist2D.Title.String = '';
                                            try
                                                app.hist2D.XTickLabels = [];
                                                app.hist2D.YTickLabels = [];
                                            catch
                                                app.hist2D.XTickLabel = [];
                                                app.hist2D.YTickLabel = [];
                                            end
                                        elseif size(app.overlay.settings{si}.multiData,2) == 3
                                            app.hist3D.Title.String = 'You have multiple overlays selected to patch so no complex colormap can be displayed';
                                            delete(app.hist3D.Children);
                                            app.hist3D.XLabel.String = '';
                                            app.hist3D.YLabel.String = '';
                                            app.hist3D.ZLabel.String = '';
                                            app.hist3D.Title.String = '';
                                            try
                                                app.hist3D.XTickLabels = [];
                                                app.hist3D.YTickLabels = [];
                                                app.hist3D.ZTickLabels = [];
                                            catch
                                                app.hist3D.XTickLabel = [];
                                                app.hist3D.YTickLabel = [];
                                                app.hist3D.ZTickLabel = [];
                                            end
                                            app.degreeDrop.Value =  app.degreeDrop.Items{1};
                                            app.axisDrop.Value =  app.axisDrop.Items{1};
                                        end
                                    end
                                end
                            else
                                app.hist2D.Title.String = '';
                                delete(app.hist2D.Children);
                                app.hist2D.XLabel.String = '';
                                app.hist2D.YLabel.String = '';
                                app.hist2D.Title.String = '';
                                
                                try
                                    app.hist2D.XTickLabels = [];
                                    app.hist2D.YTickLabels = [];
                                catch
                                    app.hist2D.XTickLabel = [];
                                    app.hist2D.YTickLabel = [];
                                end

                                app.hist3D.Title.String = '';
                                delete(app.hist3D.Children);
                                app.hist3D.XLabel.String = '';
                                app.hist3D.YLabel.String = '';
                                app.hist3D.ZLabel.String = '';
                                app.hist3D.Title.String = '';
                                try
                                    app.hist3D.XTickLabels = [];
                                    app.hist3D.YTickLabels = [];
                                    app.hist3D.ZTickLabels = [];
                                catch
                                    app.hist3D.XTickLabel = [];
                                    app.hist3D.YTickLabel = [];
                                    app.hist3D.ZTickLabel = [];
                                end
                                app.degreeDrop.Value =  app.degreeDrop.Items{1};
                                app.axisDrop.Value =  app.axisDrop.Items{1};

                                %if ~isempty(app.overlay.settings{si}.modCmap)
                                    app.histMod.Title.String = '';
                                    delete(app.histMod.Children);
                                    app.histMod.XLabel.String = '';
                                    app.histMod.YLabel.String = '';
                                    app.histMod.Title.String = '';
                                    try
                                        app.histMod.XTickLabels = [];
                                        app.histMod.YTickLabels = [];
                                    catch
                                        app.histMod.XTickLabel = [];
                                        app.histMod.YTickLabel = [];
                                    end
                                    %end
                                if ~isempty(app.overlay.settings{si}.multiData)
                                    if size(app.overlay.settings{si}.multiData,2) == 2
                                        app.hist2D.Title.String = '';
                                        delete(app.hist2D.Children);
                                        app.hist2D.XLabel.String = '';
                                        app.hist2D.YLabel.String = '';
                                        app.hist2D.Title.String = '';
                                        try
                                            app.hist2D.XTickLabels = [];
                                            app.hist2D.YTickLabels = [];
                                        catch
                                            app.hist2D.XTickLabel = [];
                                            app.hist2D.YTickLabel = [];
                                        end
                                    elseif size(app.overlay.settings{si}.multiData,2) == 3
                                        app.hist3D.Title.String = '';
                                        delete(app.hist3D.Children);
                                        app.hist3D.XLabel.String = '';
                                        app.hist3D.YLabel.String = '';
                                        app.hist3D.ZLabel.String = '';
                                        app.hist3D.Title.String = '';
                                        try
                                            app.hist3D.XTickLabels = [];
                                            app.hist3D.YTickLabels = [];
                                            app.hist3D.ZTickLabels = [];
                                        catch
                                            app.hist3D.XTickLabel = [];
                                            app.hist3D.YTickLabel = [];
                                            app.hist3D.ZTickLabel = [];
                                        end
                                        app.degreeDrop.Value =  app.degreeDrop.Items{1};
                                        app.axisDrop.Value =  app.axisDrop.Items{1};
                                    end
                                end
                            end

                            app.limMaxOver.Value = double(app.axOver.XLim(2));
                            app.limMinOver.Value = double(app.axOver.XLim(1));
                            app.colormapSelector.Value = app.overlay.settings{si}.colormapSelector;
                            app.selectSpacing.Value = app.overlay.settings{si}.selectSpacing;
                            app.valueSelection.Value = app.overlay.settings{si}.valueSelection;

                            app.smoothButton.Value = app.overlay.settings{si}.smoothButton;
                            app.smoothArea.Value = app.overlay.settings{si}.smoothArea;
                            app.smoothSteps.Value = app.overlay.settings{si}.smoothSteps;
                            app.assignSwitch.Value = app.overlay.settings{si}.assignSwitch;

                            app.posSwitch.Value = app.overlay.settings{si}.posSwitch;
                            app.negSwitch.Value = app.overlay.settings{si}.negSwitch;
                            app.remZeroSwitch.Value = app.overlay.settings{si}.remZeroSwitch;

                            app.binSwitch.Value = app.overlay.settings{si}.binSwitch;
                            app.opacityBox.Value = app.overlay.settings{si}.opacityBox;
                            app.invertSwitch.Value = app.overlay.settings{si}.invertSwitch;
                            app.clusterThreshBox.Value = app.overlay.settings{si}.clusterThreshBox;
                            if app.clusterThreshBox.Value > max(app.clusterSlider.Limits)
                                app.clusterSlider.Value = max(app.clusterSlider.Limits);
                            else
                                app.clusterSlider.Value = app.clusterThreshBox.Value;
                            end

                            app.pThreshBox.Value = app.overlay.settings{si}.pThresh;

                            app.outlineDropDown.Value = app.overlay.settings{si}.outlineDropDown;
                            app.growShrinkBox.Value = app.overlay.settings{si}.growShrinkBox;
                            if app.growShrinkBox.Value > max(app.growShrinkSlider.Limits)
                                app.growShrinkSlider.Value = max(app.growShrinkSlider.Limits);
                            elseif app.growShrinkBox.Value < min(app.growShrinkSlider.Limits)
                                app.growShrinkSlider.Value = min(app.growShrinkSlider.Limits);
                            else
                                app.growShrinkSlider.Value = app.growShrinkBox.Value;
                            end

                            app.pThreshBox.Value = app.overlay.settings{si}.pThresh;

                            ns = 5;
                            tmp = app.axOver.XLim(1):(app.axOver.XLim(2)-app.axOver.XLim(1))/(ns-1):app.axOver.XLim(2);
                            if length(tmp) < ns
                                tmp = [tmp app.axOver.XLim(2)];
                            end
                            app.axOver.XTick = tmp;
                            app.lOver.Position = [app.overlay.settings{si(fi)}.lowerThreshBox 0; app.overlay.settings{si(fi)}.upperThreshBox 0];
                            %app.lOver.Position = [tmp(round(length(tmp)/2)) 0; tmp(round(length(tmp)/2)) 0];

                            app.lowerThreshBox.Value = app.overlay.settings{si(fi)}.lowerThreshBox;
                            app.upperThreshBox.Value = app.overlay.settings{si(fi)}.upperThreshBox;

                            % update steps
                            app.lowerThreshBox.Step = range(tog)/20;
                            app.upperThreshBox.Step = app.lowerThreshBoxSurf.Step;
                            app.limMaxOver.Step = app.lowerThreshBoxSurf.Step;
                            app.limMinOver.Step = app.lowerThreshBoxSurf.Step;

                            if ~isempty(app.overlay.settings{si}.pMap)
                                app.addpButton.Value = 1;
                            else
                                app.addpButton.Value = 0;
                            end

                            app.fdrButton.Value = app.overlay.settings{si}.pFDR;
                            app.bonfButton.Value = app.overlay.settings{si}.pBonf;
                        end
                    end

                    switch def
                        case 'true'
                            app.colormapSelector.Value = 'Select colormap';
                            app.selectSpacing.Value = 'Select color spacing';
                            app.valueSelection.Value = 'Raw';
                            app.posSwitch.Value = 'On';
                            app.negSwitch.Value  = 'On';
                            app.remZeroSwitch.Value = 'On';

                            app.axOver.XLim = [0 100];
                            app.axOver.XTick = 0:100/(11-1):100;

                            app.lOver.Position = [0 0; 0 0];

                            app.smoothButton.Value = 0;
                            app.smoothArea.Value = 0;
                            app.smoothSteps.Value = 0;
                            app.assignSwitch.Value = 'thresholded';

                            app.lowerThreshBox.Value = 0;
                            app.upperThreshBox.Value = 0;

                            leg = legend(app.histOver);
                            delete(leg)
                            plot(0,0,'k','Parent',app.histOver);

                            app.binSwitch.Value = 'Off';
                            app.binBox.Value = 1000;
                            app.opacityBox.Value = 1;

                            app.invertSwitch.Value = 'Off';

                            app.clusterThreshBox.Value = 0;
                            app.clusterSlider.Value = 0;
                            app.pThreshBox.Value = 1;
                            app.outlineDropDown.Value = 'None';
                            app.growShrinkSlider.Value = 0;
                            app.growShrinkBox.Value = 0;

                            app.minAlphaMod.Value = 0;
                            app.maxAlphaMod.Value = 1;
                            app.modLimMin.Value = 0;
                            app.modLimMax.Value = 0;

                            app.absToggleMod.Value = 'On';

                            delete(app.histMod.Children);
                            app.histMod.XLabel.String = '';
                            app.histMod.YLabel.String = '';
                            app.histMod.Title.String = '';
                            
                            try
                                app.histMod.XTickLabels = [];
                                app.histMod.YTickLabels = [];
                            catch
                                app.histMod.XTickLabel = [];
                                app.histMod.YTickLabel = [];
                            end

                            delete(app.hist2D.Children);
                            app.hist2D.XLabel.String = '';
                            app.hist2D.YLabel.String = '';
                            app.hist2D.Title.String = '';
                            
                            try
                                app.hist2D.XTickLabels = [];
                                app.hist2D.YTickLabels = [];
                            catch
                                app.hist2D.XTickLabel = [];
                                app.hist2D.YTickLabel = [];
                            end

                            delete(app.hist3D.Children);
                            app.hist3D.XLabel.String = '';
                            app.hist3D.YLabel.String = '';
                            app.hist3D.ZLabel.String = '';
                            app.hist3D.Title.String = '';
                            try
                                app.hist3D.XTickLabels = [];
                                app.hist3D.YTickLabels = [];
                                app.hist3D.ZTickLabels = [];
                            catch
                                app.hist3D.XTickLabel = [];
                                app.hist3D.YTickLabel = [];
                                app.hist3D.ZTickLabel = [];
                            end
                            app.degreeDrop.Value =  app.degreeDrop.Items{1};
                            app.axisDrop.Value =  app.axisDrop.Items{1};

                            app.modOverlaySelect.Value = 'No overlay';
                            app.TwoD1.Value = 'No overlay';
                            app.TwoD2.Value = 'No overlay';
                            app.ThreeD1.Value = 'No overlay';
                            app.ThreeD2.Value = 'No overlay';
                            app.ThreeD3.Value = 'No overlay';
                    end
            end
        end

        function [app,hemi] = idHemi(app,f)
            for i = 1:length(f)
                f1 = f{i};
                id = strfind(lower(f1),'lr'); % some files will use the LR string, which we need to remove to check for l.
                f1(id:id+1) = [];

                % first id hemis
                fun = @(s)~cellfun('isempty',strfind(lower({f1}),s));
                out = cellfun(fun,app.ln,'UniformOutput',false);
                out2 = cellfun(fun,app.rn,'UniformOutput',false);
                os = sum(vertcat(out{:}));
                os2 = sum(vertcat(out2{:}));

                if os >= 1 & os2 >= 1
                    s = uiconfirm(app.brainSurferUIFigure,['It looks like a file you have loaded references more than one hemisphere. Which hemisphere do you associated with: ' f{i} '?'],'Confirm Hemisphere','Options',{'Left','Right'},'DefaultOption',1);
                    hemi{i,1} = lower(s);
                elseif os > 0 & os2 > 0
                    s = uiconfirm(app.brainSurferUIFigure,['It looks like a file you have loaded does not reference a hemisphere. Which hemisphere do you associated with: ' f{i} '?'],'Confirm Hemisphere','Options',{'Left','Right'},'DefaultOption',1);
                    hemi{i,1} = lower(s);
                elseif os >= 1 & os2 >= 0
                    hemi{i,1} = 'left';
                elseif os >= 0 & os2 >= 1
                    hemi{i,1} = 'right';
                else
                    hemi{i,1} = [];
                end
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %[v,d] = version;
            %app.matVer = v(end-6:end-1);
            
            app.pths.sep = filesep;
            app.pths.gPth = which(mfilename);
            [app.pths.gPth, ~] = fileparts(app.pths.gPth);
            addpath(genpath(app.pths.gPth))

            app.pths.brnPth = [app.pths.gPth app.pths.sep 'brains']; % contains lh and rh fsaveraged brains
            app.pths.scrptPth = [app.pths.gPth app.pths.sep 'scripts']; % contains all the scripts
            app.pths.fsrs = [app.pths.scrptPth app.pths.sep 'HCP' app.pths.sep 'resample_fsaverage'];
            %addpath(genpath(app.pths.scrptPth))
            %addpath(genpath([app.pths.gPth app.pths.sep 'buttons']));
            app.pths.clrmpPth = [app.pths.gPth app.pths.sep 'colormaps']; % contains all the colormaps
            app.pths.atlasPth = [app.pths.gPth app.pths.sep 'atlases'];

            app.dlights = dlmread([app.pths.scrptPth app.pths.sep 'defaultLights.txt']);
            fid = fopen([app.pths.scrptPth app.pths.sep 'pathToWorkBench.txt']);
            app.pths.wrkbnch = fscanf(fid,'%s');

            if isempty(app.pths.wrkbnch)
                fclose(fid);
                fid = fopen([app.pths.scrptPth app.pths.sep 'pathToWorkBench.txt'],'w');
                uialert(app.brainSurferUIFigure,'You do not have a path set for connectome workbench bash commands (e.g., wb_command), which will make it impossible to register data to the caret template. If you want this functionality, click okay and you will be prompted to select the directory that contains the wb_command. ','Do you want to register data to caret?');
                op = uigetdir(pwd,'Select the directory that contains connectome workbench bash commands (e.g., wb_command)');
                app.pths.wrkbnch = op;
                fprintf(fid,'%s',op);
                fclose(fid);
            end

            format longG
            colormapDir = dir([app.pths.clrmpPth app.pths.sep '*.mat']);
            colormaps = {colormapDir.name};

            for colori = 1:length(colormaps)
                colormaps{colori} = colormaps{colori}(1:end-4);
            end

            app.userColor = cell(size(app.colormapSelectorSurf.Items));
            app.userColor(cellfun('isempty',app.userColor)) = {1};
            if ~isempty(colormaps)
                n = length(app.colormapSelectorSurf.Items);
                app.colormapSelectorSurf.Items = horzcat(app.colormapSelectorSurf.Items,colormaps);
                app.colormapSelector.Items = app.colormapSelectorSurf.Items;
                app.colormapSelectAtlas.Items = app.colormapSelectorSurf.Items;
                for i = n+1:length(app.colormapSelectorSurf.Items)
                    tmp = load([app.pths.clrmpPth app.pths.sep app.colormapSelectorSurf.Items{i}]);
                    app.userColor{i} = tmp.customColor;
                end
            end

            % Create the 1D axes
            app.axSurf = axes( ...
                app.RangeofvaluestoexcludePanel,'Color','none','YColor','none','XLim',[0,100],'YTick',[], ...
                'XTick',0:10:100,'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
                'on','Units','pixels','Position',[20,70,300,0] ...
                );
            app.axOver = axes( ...
                app.RangeofvaluestoexcludePanel_2,'Color','none','YColor','none','XLim',[0,100],'YTick',[], ...
                'XTick',0:10:100,'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
                'on','Units','pixels','Position',[20,70,300,0] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axSurf);
            disableDefaultInteractivity(app.axOver);

            app.axSurf.Toolbar.Visible = 'off';
            app.axOver.Toolbar.Visible = 'off';

            % Add the line ROI
            app.lSurf = images.roi.Line(app.axSurf,'Position',[0.9,0;1.1,0],'Color',[0.9 0.4 0.3]);%,'LineWidth',5,'MarkerSize',10);
            app.lOver = images.roi.Line(app.axOver,'Position',[0.9,0;1.1,0],'Color',[0.9 0.4 0.3]);%,'LineWidth',5,'MarkerSize',10);

            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.lSurf,'ROIMoved',@(varargin)lMoving(app,app.lSurf)); % MovingROI will provide continuous feedback
            addlistener(app.lSurf,'MovingROI',@(varargin)lMoving2(app,app.lSurf));

            addlistener(app.lOver,'ROIMoved',@(varargin)lMoving_2(app,app.lOver));
            addlistener(app.lOver,'MovingROI',@(varargin)lMoving2_2(app,app.lOver));
            app.lOver.Deletable = 0;

            app.sulcButton.BackgroundColor = [1 1 1];
            app.gyrButton.BackgroundColor = [1 1 1];

            [c,ia,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
            app.overlay.names{1} = app.overlayListBox.Items{ia};

            app.overlay.paths{1} = '';
            app.overlay.bm{1} = [];
            app.overlay.bmB{1} = [];
            app.overlay.settings{1} = [];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.brainSurferUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {517, 517};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {490, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: LHRHMenu
        function loadfsaveragelhrh(app, event)
            [vert1,face1] = read_surf([app.pths.brnPth app.pths.sep 'lh.inflated']);
            [vert2,face2] = read_surf([app.pths.brnPth app.pths.sep 'rh.inflated']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert1, face1, vert2, face2,'lights',app.dlights);

            app.sg.left = read_curv([app.pths.brnPth app.pths.sep 'lh.curv']);
            app.sg.right = read_curv([app.pths.brnPth app.pths.sep 'rh.curv']);
            app.sg.left = -1*(app.sg.left);
            app.sg.right = -1*(app.sg.right);

            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sgB.left, 'rh', app.sgB.right);
            tog = updateGUI(app,'underlay','true','false');

            %f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
            %importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);
            f{1} = [app.pths.atlasPth app.pths.sep 'lh.HCP-MMP1.annot']; f{2} = [app.pths.atlasPth app.pths.sep 'rh.HCP-MMP1.annot'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu
        function loadfsaveragelh(app, event)
            [vert1,face1] = read_surf([app.pths.brnPth app.pths.sep 'lh.inflated']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert1, face1,'lights',app.dlights);

            app.sg.left = read_curv([app.pths.brnPth app.pths.sep 'lh.curv']);
            app.sg.left = -1*(app.sg.left);
            app.sgB.left = app.sg.left;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay,'lh', app.sgB.left);

            tog = updateGUI(app,'underlay','true','false');
            %             f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];
            %             importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);
            f{1} = [app.pths.atlasPth app.pths.sep 'lh.HCP-MMP1.annot'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: RHMenu
        function loadfsaveragerh(app, event)
            [vert2,face2] = read_surf([app.pths.brnPth app.pths.sep 'rh.inflated']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert2, face2,'lights',app.dlights);

            app.sg.right = read_curv([app.pths.brnPth app.pths.sep 'rh.curv']);
            app.sg.right = -1*(app.sg.right);
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay,'rh',app.sgB.right);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'rh.HCP-MMP1.annot'];
            importAtlas(app,f,[]);
        end

        % Value changed function: upperThreshBoxSurf
        function changeUpperThreshBoxSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                if app.upperThreshBoxSurf.Value < app.lowerThreshBoxSurf.Value
                    t = app.upperThreshBoxSurf.Value;
                    app.upperThreshBoxSurf.Value = app.lowerThreshBoxSurf.Value;
                    app.lowerThreshBoxSurf.Value = t;
                end
                app.lSurf.Position = [app.lowerThreshBoxSurf.Value 0; app.upperThreshBoxSurf.Value 0];

                delete(app.upperThreshLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.upperThreshLineSurf = plot(ones(size(l))*app.upperThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');

                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.upperThreshBoxSurf.Value = 0;
            end
        end

        % Value changed function: lowerThreshBoxSurf
        function changeLowerThreshBoxSurf(app, event)

            if isgraphics(app.brainFig,'figure')
                if app.lowerThreshBoxSurf.Value > app.upperThreshBoxSurf.Value
                    t = app.upperThreshBoxSurf.Value;
                    app.upperThreshBoxSurf.Value = app.lowerThreshBoxSurf.Value;
                    app.lowerThreshBoxSurf.Value = t;
                end
                app.lSurf.Position = [app.lowerThreshBoxSurf.Value 0; app.upperThreshBoxSurf.Value 0];

                delete(app.lowerThreshLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.lowerThreshLineSurf = plot(ones(size(l))*app.lowerThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');

                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.lowerThreshBoxSurf.Value = 0;
            end

        end

        % Value changed function: limMaxSurf
        function limMaxSurfChange(app, event)
            if isgraphics(app.brainFig,'figure')
                delete(app.limMaxLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.limMaxLineSurf = plot(ones(size(l))*app.limMaxSurf.Value,l,'k','Parent',app.histSurf,'Color',[0.635 0.078 0.184],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');

                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.limMaxSurf.Value = 0;
            end
        end

        % Value changed function: limMinSurf
        function limMinSurfChange(app, event)
            if isgraphics(app.brainFig,'figure')
                delete(app.limMinLineSurf)
                hold(app.histSurf,'on');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.limMinLineSurf = plot(ones(size(l))*app.limMinSurf.Value,l,'k','Parent',app.histSurf,'Color',[0.635 0.078 0.184],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off');

                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.limMinSurf.Value = 0;
            end
        end

        % Value changed function: clusterSlider
        function clustSliderChange(app, event)
            %app.clusterThreshBox.Value = app.clusterSlider.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                app.clusterThreshBox.Value = app.clusterSlider.Value;
                [~,~,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{ib}.clusterThreshBox = app.clusterThreshBox.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.clusterSlider.Value = app.clusterThreshBox.Value;
            end
        end

        % Value changed function: upperThreshBox
        function posBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                t2 = app.overlay.settings{ib}.lowerThreshBox;
                if app.upperThreshBox.Value < app.lowerThreshBox.Value
                    app.overlay.settings{ib}.lowerThreshBox = app.upperThreshBox.Value;
                    app.overlay.settings{ib}.upperThreshBox = t2;
                else
                    app.overlay.settings{ib}.upperThreshBox = app.upperThreshBox.Value;
                end

                app.lOver.Position = [app.overlay.settings{ib}.lowerThreshBox 0; app.overlay.settings{ib}.upperThreshBox 0];
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.upperThreshBox.Value = max(app.lOver.Position(:,1));
            end
        end

        % Value changed function: lowerThreshBox
        function negBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                t2 = app.overlay.settings{ib}.upperThreshBox;
                if app.lowerThreshBox.Value > app.upperThreshBox.Value
                    app.overlay.settings{ib}.upperThreshBox = app.lowerThreshBox.Value;
                    app.overlay.settings{ib}.lowerThreshBox = t2;
                else
                    app.overlay.settings{ib}.lowerThreshBox = app.lowerThreshBox.Value;
                end

                app.lOver.Position = [app.overlay.settings{ib}.lowerThreshBox 0; app.overlay.settings{ib}.upperThreshBox 0];
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.lowerThreshBox.Value = min(app.lOver.Position(:,1));
            end
        end

        % Value changed function: clusterThreshBox
        function clustChange(app, event)
            %app.clusterSlider.Value = app.clusterThreshBox.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                if app.clusterThreshBox.Value > max(app.clusterSlider.Limits)
                    app.clusterSlider.Value = max(app.clusterSlider.Limits);
                else
                    app.clusterSlider.Value = app.clusterThreshBox.Value;
                end
                [~,~,ib] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{ib}.clusterThreshBox = app.clusterThreshBox.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.clusterThreshBox.Value = app.clusterSlider.Value;
            end
        end

        % Menu selected function: LHMenu_7
        function addfsaveragelh(app, event)
            if isgraphics(app.brainFig,'figure')
                [vert1,face1] = read_surf([app.pths.brnPth app.pths.sep 'lh.inflated']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                %delete(app.atlas.overlay.left)
                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert1, face1, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);

                %f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];
                %importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);
                f{1} = [app.pths.atlasPth app.pths.sep 'lh.HCP-MMP1.annot'];
                importAtlas(app,f,[]);

                app.sg.left = read_curv([app.pths.brnPth app.pths.sep 'lh.curv']);
                app.sg.left = -1*(app.sg.left);
                app.sgB.left = app.sg.left;

                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_7
        function addfsaveragerh(app, event)
            if isgraphics(app.brainFig,'figure')
                [vert1,face1] = read_surf([app.pths.brnPth app.pths.sep 'rh.inflated']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert1, face1, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);

                %f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
                %importAtlas(app,f,[app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt']);

                f{1} = [app.pths.atlasPth app.pths.sep 'rh.HCP-MMP1.annot']; f{2} = [app.pths.atlasPth app.pths.sep 'rh.HCP-MMP1.annot'];
                importAtlas(app,f,[]);

                app.sg.right = read_curv([app.pths.brnPth app.pths.sep 'rh.curv']);
                app.sg.right = -1*(app.sg.right);
                app.sgB.right = app.sg.right;

                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: LHRHMenu_4
        function loadinflatedlhrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.inflated_MSMAll.32k_fs_LR.surf.gii']);
            g2 = gifti([app.pths.brnPth app.pths.sep 'S900.R.inflated_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, g2.vertices, g2.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            l = find(contains(c,'CORTEX_LEFT'));
            r = find(contains(c,'CORTEX_RIGHT'));

            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);

            app.sg.right = zeros([size(g2.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);

            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left, 'rh', app.sg.right);
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);

            tog = updateGUI(app,'underlay','true','false');
        end

        % Menu selected function: LHRHMenu_15
        function loadmidthicknesslhrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.midthickness_MSMAll.32k_fs_LR.surf.gii']);
            g2 = gifti([app.pths.brnPth app.pths.sep 'S900.R.midthickness_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, g2.vertices, g2.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            l = find(contains(c,'CORTEX_LEFT'));
            r = find(contains(c,'CORTEX_RIGHT'));

            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);

            app.sg.right = zeros([size(g2.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);

            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left, 'rh', app.sg.right);
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);

            tog = updateGUI(app,'underlay','true','false');
        end

        % Menu selected function: LHMenu_15
        function loadmidthicknesslh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.midthickness_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            l = find(contains(c,'CORTEX_LEFT'));

            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            app.sgB.left = app.sg.left;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
            importAtlas(app,f,[]);

            tog = updateGUI(app,'underlay','true','false');
        end

        % Menu selected function: RHMenu_15
        function loadmidthicknessrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.R.midthickness_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            r = find(contains(c,'CORTEX_RIGHT'));

            app.sg.right = zeros([size(g.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);

            tog = updateGUI(app,'underlay','true','false');
        end

        % Menu selected function: LHRHMenu_3
        function loadveryinflatedlhrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.very_inflated_MSMAll.32k_fs_LR.surf.gii']);
            g2 = gifti([app.pths.brnPth app.pths.sep 'S900.R.very_inflated_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, g2.vertices, g2.faces,'lights',app.dlights);

            %             if strcmpi(newFig,'true')
            %                app.atlas = struct('bm',struct('left','','right',''),'overlay',struct('left','','right',''),'cBar',struct('left','','right',''),'cBarText',struct('left','','right',''),'cBarAx',struct('left','','right',''),'regions',struct('left','','right',''),'clusters',struct('left','','right',''),'borders',struct('left','','right',''),'name',struct('left','','right',''),'path',struct('left','','right',''));
            %             end

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            l = find(contains(c,'CORTEX_LEFT'));
            r = find(contains(c,'CORTEX_RIGHT'));

            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);

            app.sg.right = zeros([size(g2.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);

            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left, 'rh', app.sg.right);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHRHMenu_2
        function loadflatlhrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.flat.32k_fs_LR.surf.gii']);
            g2 = gifti([app.pths.brnPth app.pths.sep 'S900.R.flat.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, g2.vertices, g2.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            l = find(contains(c,'CORTEX_LEFT'));
            r = find(contains(c,'CORTEX_RIGHT'));

            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);

            app.sg.right = zeros([size(g2.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);

            app.sgB.left = app.sg.left;
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left, 'rh', app.sg.right);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii']; f{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu_12
        function loadflatlh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.flat.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);
            %app.underlay.left = app.underlay.right;

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            l = find(contains(c,'CORTEX_LEFT'));

            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            app.sgB.left = app.sg.left;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: RHMenu_12
        function loadflatrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.R.flat.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            r = find(contains(c,'CORTEX_RIGHT'));

            app.sg.right = zeros([size(g.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu_13
        function loadveryinflatedlh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.very_inflated_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            l = find(contains(c,'CORTEX_LEFT'));

            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            app.sgB.left = app.sg.left;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: RHMenu_13
        function loadveryinflatedrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.R.very_inflated_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            r = find(contains(c,'CORTEX_RIGHT'));

            app.sg.right = zeros([size(g.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu_14
        function loadinflatedlh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.L.inflated_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            l = find(contains(c,'CORTEX_LEFT'));

            app.sg.left = zeros([size(g.vertices,1),1]);
            app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
            app.sgB.left = app.sg.left;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: RHMenu_14
        function loadinflatedrh(app, event)
            g = gifti([app.pths.brnPth app.pths.sep 'S900.R.inflated_MSMAll.32k_fs_LR.surf.gii']);

            % remove any overlays that might exist...
            if ~isempty(app.currOver)
                for i = 1:length(app.currOver)
                    try
                        delete(app.currOver{i})
                    catch
                    end
                end
                app.currOver = [];
                app.overlayListBox.Value = app.overlayListBox.Items{1};
            end

            [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces,'lights',app.dlights);

            s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

            c = cell2mat(s.diminfo{1}.models);
            c = {c.struct};

            r = find(contains(c,'CORTEX_RIGHT'));

            app.sg.right = zeros([size(g.vertices,1),1]);
            app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
            app.sgB.right = app.sg.right;

            [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);

            tog = updateGUI(app,'underlay','true','false');
            f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
            importAtlas(app,f,[]);
        end

        % Menu selected function: LHMenu_8
        function addflatlh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.L.flat.32k_fs_LR.surf.gii']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);

                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};

                l = find(contains(c,'CORTEX_LEFT'));

                app.sg.left = zeros([size(g.vertices,1),1]);
                app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
                app.sgB.left = app.sg.left;

                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_8
        function addflatrh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.R.flat.32k_fs_LR.surf.gii']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);

                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};

                r = find(contains(c,'CORTEX_RIGHT'));

                app.sg.right = zeros([size(g.vertices,1),1]);
                app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.right = app.sg.right;

                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: LHMenu_9
        function addveryinflatedlh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.L.very_inflated_MSMAll.32k_fs_LR.surf.gii']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);

                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};

                r = find(contains(c,'CORTEX_LEFT'));

                app.sg.left = zeros([size(g.vertices,1),1]);
                app.sg.left(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.left = app.sg.left;

                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_9
        function addveryinflatedrh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.R.very_inflated_MSMAll.32k_fs_LR.surf.gii']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);

                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};

                r = find(contains(c,'CORTEX_RIGHT'));

                app.sg.right = zeros([size(g.vertices,1),1]);
                app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.right = app.sg.right;

                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: LHMenu_10
        function addinflatedlh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.L.inflated_MSMAll.32k_fs_LR.surf.gii']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);

                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};

                r = find(contains(c,'CORTEX_LEFT'));

                app.sg.left = zeros([size(g.vertices,1),1]);
                app.sg.left(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.left = app.sg.left;

                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_10
        function addinflatedrh(app, event)
            if isgraphics(app.brainFig,'figure')

                g = gifti([app.pths.brnPth app.pths.sep 'S900.R.inflated_MSMAll.32k_fs_LR.surf.gii']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);

                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};

                r = find(contains(c,'CORTEX_RIGHT'));

                app.sg.right = zeros([size(g.vertices,1),1]);
                app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.right = app.sg.right;

                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: LHMenu_11
        function addmidthicknesslh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.L.midthickness_MSMAll.32k_fs_LR.surf.gii']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'left', app.brainFig, app.underlay,'lights',app.dlights);

                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};

                r = find(contains(c,'CORTEX_LEFT'));

                app.sg.left = zeros([size(g.vertices,1),1]);
                app.sg.left(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.left = app.sg.left;

                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RHMenu_11
        function addmidthicknessrh(app, event)
            if isgraphics(app.brainFig,'figure')
                g = gifti([app.pths.brnPth app.pths.sep 'S900.R.midthickness_MSMAll.32k_fs_LR.surf.gii']);

                % remove any overlays that might exist...
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.overlayListBox.Value = app.overlayListBox.Items{1};
                end

                [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(g.vertices, g.faces, 'add', 'right', app.brainFig, app.underlay,'lights',app.dlights);

                s = cifti_read([app.pths.brnPth app.pths.sep 'S900.sulc_MSMAll.32k_fs_LR.dscalar.nii']);

                c = cell2mat(s.diminfo{1}.models);
                c = {c.struct};

                r = find(contains(c,'CORTEX_RIGHT'));

                app.sg.right = zeros([size(g.vertices,1),1]);
                app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                app.sgB.right = app.sg.right;

                [app] = sgPatch(app,'false');
                f{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                importAtlas(app,f,[]);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: AddfilesMenu_2
        function addSurface(app, event)
            % Ask for files
            [f,p,~] = uigetfile('*');

            % Check if any file was selected
            if p ~= 0
                [app,hemi] = idHemi(app,{f});

                if sum(cellfun(@isempty,hemi)) ~= length(f)
                    % now load
                    [~,n,e] = fileparts([p app.pths.sep f]);

                    l = 'no';
                    switch e
                        case {'.gii'; '.gii.gz'}
                            g = gifti([p app.pths.sep f]);
                            vert = g.vertices;
                            face = g.faces;
                            l = 'yes';
                    end

                    if strcmp(l,'no')
                        try
                            [vert,face] = read_surf([p app.pths.sep f]);
                        catch
                            uialert(app.brainSurferUIFigure,['File is not gifti and did not appear to be freesurfer compatible (' f ')'],'Unable to load File');
                            vert = [];
                            face = [];
                        end
                    end
                    if ~isempty(vert)
                        % remove any overlays that might exist...
                        if ~isempty(app.currOver)
                            for i = 1:length(app.currOver)
                                try
                                    delete(app.currOver{i})
                                catch
                                end
                            end
                            app.currOver = [];
                            app.overlayListBox.Value = app.overlayListBox.Items{1};
                        end

                        [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert, face, 'add', hemi{1}, app.brainFig, app.underlay,'lights',app.dlights);

                        sz = size(app.underlay.(hemi{1}).Vertices,1);
                        if sz == 163842
                            if strcmpi(hemi{1},'left')
                                %f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];
                                f2{1} = [app.pths.atlasPth app.pths.sep 'lh.HCP-MMP1.annot'];
                            elseif strcmpi(hemi{1},'right')
                                %f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
                                f2{1} = [app.pths.atlasPth app.pths.sep 'rh.HCP-MMP1.annot'];
                            end
                            %lbl = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt'];
                            lbl = [];
                        elseif sz == 32492
                            if strcmpi(hemi{1},'left')
                                f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                            elseif strcmpi(hemi{1},'right')
                                f2{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                            end
                            lbl = [];
                        else
                            f2 = [];
                            lbl = [];
                        end
                        importAtlas(app,f2,lbl);

                        if strcmp(hemi{1},'left')
                            app.sg.left = [];
                            app.sgB.left = [];
                        elseif strcmp(hemi{1},'right')
                            app.sg.left = [];
                            app.sgB.left = [];
                        end
                    end
                else
                    uialert(app.brainSurferUIFigure,['Could not identify hemisphere of file and something about the filename caused the GUI for manual hemisphere selection not to trigger'],'Unable to load File');
                end
            end
        end

        % Menu selected function: LoadfilesMenu
        function loadSurface(app, event)
            % Ask for files
            [f,p,~] = uigetfile('*','MultiSelect','on'); % '*inflated*'; '*pial*'; '*white*' ; '*sphere*'; '*orig*'

            % Check if any file was selected
            if p ~= 0
                if ~iscell(f) % if one file, it will not be in cell array
                    f = {f};
                end

                % Check for basic case where there are > 2 files
                if length(f) > 2
                    uialert(app.brainSurferUIFigure,['You cannot load more than two surface files at once'],'Too many files');
                end

                [app,hemi] = idHemi(app,f);
                if sum(cellfun(@isempty,hemi)) ~= length(f)
                    % For each file, identify hemis and load
                    for i = 1:length(f)
                        f1 = f{i};
                        id = strfind(lower(f1),'lr'); % some files will use the LR string, which we need to remove to check for l.
                        f1(id:id+1) = [];

                        % now load
                        [~,n,e] = fileparts([p app.pths.sep f{i}]);

                        l = 'no';
                        switch e
                            case {'.gii'; '.gii.gz'}
                                g = gifti([p app.pths.sep f{i}]);
                                vert{i} = g.vertices;
                                face{i} = g.faces;
                                l = 'yes';
                        end
                        if strcmp(l,'no')
                            try
                                [vert{i},face{i}] = read_surf([p app.pths.sep f{i}]);
                            catch
                                uialert(app.brainSurferUIFigure,['File is not gifti and did not appear to be freesurfer compatible (' f{i} ')'],'Unable to load File');
                                vert{i} = '';
                                face{i} = '';
                            end
                        end
                    end

                    % now patch
                    id = find(cellfun('isempty',vert));
                    vert(id) = [];
                    face(id) = [];
                    hemi(id) = [];
                    f(id) = [];

                    % remove any overlays that might exist...
                    if ~isempty(app.currOver)
                        for i = 1:length(app.currOver)
                            try
                                delete(app.currOver{i})
                            catch
                            end
                        end
                        app.currOver = [];
                        app.overlayListBox.Value = app.overlayListBox.Items{1};
                    end

                    if length(f) == 2
                        [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert{1}, face{1}, vert{2}, face{2},'lights',app.dlights,'surfC',app.surfC);
                        if ~isempty(app.sgB.left)
                            app.sg.left = [];
                            app.sgB.left = [];
                        end
                        if ~isempty(app.sgB.right)
                            app.sg.right = [];
                            app.sgB.right = [];
                        end
                    elseif length(f) == 1
                        [app.underlay, app.brainFig, newFig, addedHemi] = patchUnderlay(vert{1}, face{1},'lights',app.dlights);
                        if strcmp(hemi,'left')
                            app.sg.left = [];
                            app.sgB.left = [];
                        elseif strcmp(hemi,'right')
                            app.sg.left = [];
                            app.sgB.left = [];
                        end
                    end

                    if strcmp(hemi{1},'left')
                        app.sg.left = [];
                        app.sgB.left = [];
                    elseif strcmp(hemi{1},'right')
                        app.sg.left = [];
                        app.sgB.left = [];
                    end

                else
                    uialert(app.brainSurferUIFigure,['Could not identify hemisphere of file(s) and something about the filename caused the GUI for manual hemisphere selection not to trigger'],'Unable to load File');
                end

                if length(f) == 1
                    if isfield(app.underlay,'left') & ~isfield(app.underlay,'right')
                        sz = size(app.underlay.left.Vertices,1);
                        if sz == 163842
                            %f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];
                            f2{1} = [app.pths.atlasPth app.pths.sep 'lh.HCP-MMP1.annot'];
                            %lbl = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt'];
                            lbl = [];
                        elseif sz == 32492
                            f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];
                            lbl = [];
                        else
                            f2 = [];
                            lbl = [];
                        end
                    elseif ~isfield(app.underlay,'left') & isfield(app.underlay,'right')
                        sz = size(app.underlay.right.Vertices,1);
                        if sz == 163842
                            %f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
                            f2{1} = [app.pths.atlasPth app.pths.sep 'rh.HCP-MMP1.annot'];
                            %lbl = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt'];
                            lbl = [];

                        elseif sz == 32492
                            f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                            lbl = [];
                        else
                            f2 = [];
                            lbl = [];
                        end
                    end
                elseif length(f) == 2
                    sz = size(app.underlay.left.Vertices,1);
                    if sz == 163842
                        %f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_L.nii.gz'];f2{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage_R.nii.gz'];
                        f2{1} = [app.pths.atlasPth app.pths.sep 'lh.HCP-MMP1.annot']; f2{2} = [app.pths.atlasPth app.pths.sep 'rh.HCP-MMP1.annot'];
                        %lbl = [app.pths.atlasPth app.pths.sep 'HCP_MMP1_fsaverage.txt'];
                        lbl = [];

                    elseif sz == 32492
                        f2{1} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_L.dlabel.nii'];f2{2} = [app.pths.atlasPth app.pths.sep 'HCP_MMP1.32k_fs_R.dlabel.nii'];
                        lbl = [];
                    else
                        f2 = [];
                        lbl = [];
                    end
                end
                importAtlas(app,f2,lbl);
            end
        end

        % Menu selected function: LoadsulcigyriMenu
        function loadsg(app, event)
            if isgraphics(app.brainFig,'figure')

                % Set up basic defaults in case this is the first user
                % action related to sulci/gyri...
                if strcmp(app.selectSpacingSurf.Value,'Select color spacing')
                    app.selectSpacingSurf.Value = 'even';
                end
                if strcmp(app.colormapSelectorSurf.Value,'Select colormap')
                    app.colormapSelectorSurf.Value = 'gray';
                end

                % Ask for a file
                [f,p,~] = uigetfile('*','MultiSelect','on');

                % Check if any file was selected
                if p ~= 0
                    if ~iscell(f) % if one file, it will not be in cell array
                        f = {f};
                    end

                    if length(f) > 2
                        uialert(app.brainSurferUIFigure,['You cannot load more than two files at once because there are a maximum of two hemispheres'],'Too many files');
                    end

                    % if two different brains ask which hemisphere to
                    % associated with file...
                    sc = 0;
                    if (isfield(app.underlay,'left') & isvalid(app.underlay.left) & ~isempty(app.underlay.left)) & (isfield(app.underlay,'right') & isvalid(app.underlay.right) & ~isempty(app.underlay.right))
                        if size(app.underlay.left.Vertices,1) ~= size(app.underlay.right.Vertices,1)
                            for i = 1:length(f)
                                tmp = uiconfirm(app.brainSurferUIFigure,['Looks like you have two surfaces with different shapes. Which hemisphere do you want to associate with this sulci/gyri file? ' f(i)],'Select hemisphere',...
                                    'Options',{'left','right','cancel'},...
                                    'DefaultOption',1,'CancelOption',3);
                                if ~strcmp(tmp,'cancel')
                                    hemi{i} = tmp;
                                    sc = 1;
                                else
                                    error('User cancelled action')
                                end
                            end
                        else
                            [app,hemi] = idHemi(app,f);
                        end
                    else
                        [app,hemi] = idHemi(app,f);
                    end

                    % For each file, identify hemis and load
                    for i = 1:length(f)
                        f1 = f{i};
                        [~,~,e] = fileparts([p f{i}]);

                        % if nifti, it can either be nifti format or cifti and
                        % cifti will not reference a hemisphere
                        if (strcmp(e,'.nii') | strcmp(e,'.nii.gz'))
                            try
                                s = cifti_read([p f{i}]);
                                c = cell2mat(s.diminfo{1}.models);
                                c = {c.struct};
                                if isfield(app.underlay,'left') & isvalid(app.underlay.left)
                                    if sc == 0 || (sc == 1 & strcmp(hemi{i},'left'))
                                        l = find(contains(c,'CORTEX_LEFT'));
                                        if isempty(l)
                                            uialert(app.brainSurferUIFigure,['File did not contain reference to left hemisphere sucli/gyri (' f{i} ')'],'Problem with file');
                                        end
                                        app.sg.left = zeros([size(app.underlay.left.Vertices,1),1]);
                                        app.sg.left(s.diminfo{1}.models{l}.vertlist + 1) = s.cdata(s.diminfo{1}.models{l}.start:s.diminfo{1}.models{l}.start+s.diminfo{1}.models{l}.count-1);
                                        app.sgB.left = app.sg.left;
                                    end
                                end
                                if isfield(app.underlay,'right') & isvalid(app.underlay.right)
                                    if sc == 0 || (sc == 1 & strcmp(hemi{i},'right'))
                                        r = find(contains(c,'CORTEX_RIGHT'));
                                        if isempty(r)
                                            uialert(app.brainSurferUIFigure,['File did not contain reference to right hemisphere sucli/gyri (' f{i} ')'],'Problem with file');
                                        end
                                        app.sg.right = zeros([size(app.underlay.right.Vertices,1),1]);
                                        app.sg.right(s.diminfo{1}.models{r}.vertlist + 1) = s.cdata(s.diminfo{1}.models{r}.start:s.diminfo{1}.models{r}.start+s.diminfo{1}.models{r}.count-1);
                                        app.sgB.right = app.sg.right;
                                    end
                                end

                                % tog goes here
                                updateGUI(app,'underlay','false');
                                [app] = sgPatch(app,'false');
                            catch
                                try
                                    s = load_nifti([p f{i}]);
                                    if ndims(s.vol) > 2
                                        uialert(app.brainSurferUIFigure,['Data for this file (' f{i} ') does not appear to be in surface space'],'Problem with file');
                                        error(['Data for this file (' f{i} ') does not appear to be in surface space']);
                                    end

                                    if strcmp(hemi{i},'left')
                                        app.sg.left = s.vol;
                                        app.sgB.left = app.sg.left;
                                    end
                                    if strcmp(hemi{i},'right')
                                        app.sg.right = s.vol;
                                        app.sgB.right = app.sg.right;
                                    end
                                    updateGUI(app,'underlay','false','false');
                                    [app] = sgPatch(app,'false');
                                catch
                                    uialert(app.brainSurferUIFigure,'Could not load your nifti file','Invalid File');
                                end
                            end
                        elseif (strcmp(e,'.gii') | strcmp(e,'.gii.gz'))
                            try
                                s = gifti([p f{i}]);
                                if strcmp(hemi{i},'left')
                                    app.sg.left = s.cdata;
                                    app.sgB.left = app.sg.left;
                                end
                                if strcmp(hemi{i},'right')
                                    app.sg.right = s.cdata;
                                    app.sgB.right = app.sg.right;
                                end
                                updateGUI(app,'underlay','false','false');
                                [app] = sgPatch(app,'false');
                            catch
                                uialert(app.brainSurferUIFigure,'Could not load your gifti file','Invalid File');
                            end
                        else
                            %elseif strcmp(e,'.curv') | strcmp(e,'.sulc') | strcmp(e,'.thickness')
                            try
                                s = read_curv([p f{i}]);
                                % first id hemis
                                if strcmp(hemi{i},'left')
                                    app.sg.left = s;
                                    app.sgB.left = app.sg.left;
                                end
                                if strcmp(hemi{i},'right')
                                    app.sg.right = s;
                                    app.sgB.right = app.sg.right;
                                end
                                updateGUI(app,'underlay','false','false');
                                [app] = sgPatch(app,'false');
                            catch
                                uialert(app.brainSurferUIFigure,'Failed to load your file using read_curv (freesurfer). Maybe this file type is not yet supported (tried: nifti, cifti, gifti, fs morphometry)','Action unavailable');
                            end
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: lightsButton
        function lightsButtonEffect(app, event)
            if isgraphics(app.brainFig,'figure')
                l = findobj(app.brainFig,'Type','Light');
                if strcmpi(l(1).Visible,'on')
                    type = 'off';
                else
                    type = 'on';
                end
                for i = 1:length(l)
                    l(i).Visible = type;
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: reloadButton2
        function reloadUnderlay(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.sgB.left) | ~isempty(app.sgB.right))
                if ~isempty(app.sgB.left)
                    app.sg.left = app.sgB.left;
                    [app.underlay, app.colormapSG_tmp, app.ticksSG_tmp] = patchUnderlaySG(app.underlay, 'lh', app.sg.left);
                    app.colormapSG.left = app.colormapSG_tmp.left;
                    app.ticksSG.left = app.ticksSG_tmp.left;
                end
                if ~isempty(app.sgB.right)
                    app.sg.right = app.sgB.right;
                    [app.underlay, app.colormapSG_tmp, app.ticksSG_tmp] = patchUnderlaySG(app.underlay, 'rh', app.sg.right);
                    app.colormapSG.right = app.colormapSG_tmp.right;
                    app.ticksSG.right = app.ticksSG_tmp.right;
                end
                tog = updateGUI(app,'underlay','true','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Value changed function: valueSelectionSurf
        function changeValueSurf(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.sgB.left) | ~isempty(app.sgB.right))
                if ~isempty(app.sgB.left) & ~isempty(app.sgB.right)
                    [app.underlay, app.colormapSG, app.ticksSG, app.sg] = patchUnderlaySG(app.underlay, 'lh', app.sgB.left,'rh', app.sgB.right,'vls',lower(app.valueSelectionSurf.Value)...
                        ,'colormap',app.colormapSelectorSurf.Value,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'surfC',app.surfC,'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure);
                elseif ~isempty(app.sgB.left) & isempty(app.sgB.right)
                    [app.underlay, app.colormapSG, app.ticksSG, app.sg] = patchUnderlaySG(app.underlay,'lh',app.sgB.left,'vls',lower(app.valueSelectionSurf.Value)...
                        ,'colormap',app.colormapSelectorSurf.Value,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'surfC',app.surfC,'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure);
                    app.colormapSG.left = app.colormapSG_tmp.left;
                    app.ticksSG.left = app.ticksSG_tmp.left;
                    app.sg.left = app.sg_tmp;
                elseif ~isempty(app.sg.right) & isempty(app.sgB.left)
                    [app.underlay, app.colormapSG, app.ticksSG, app.sg] = patchUnderlaySG(app.underlay,'rh',app.sgB.right,'vls',lower(app.valueSelectionSurf.Value)...
                        ,'colormap',app.colormapSelectorSurf.Value,'colorSpacing',lower(app.selectSpacingSurf.Value),'colorBins',app.binBoxSurf.Value,'colorSpecial'...
                        ,'none','invertColors',lower(app.invertSwitchSurf.Value),'surfC',app.surfC,'surfOpacity',app.opacityBoxSurf.Value,'sgOpacity',app.opacityBoxSG.Value,'UI',app.brainSurferUIFigure);
                    app.colormapSG.right = app.colormapSG_tmp.right;
                    app.ticksSG.right = app.ticksSG_tmp.right;
                    app.sg.right = app.sg_tmp;
                end
                tog = updateGUI(app,'underlay','false','false');
                m = mean([min(tog) max(tog)]);
                app.lSurf.Position = [m 0; m 0];
                app.binSwitchSurf.Value = 'Off';
                app.sgThresh.Value = 0;
                app.lowerThreshBoxSurf.Value = m;
                app.upperThreshBoxSurf.Value = m;
                app.remZeroSwitchSurf.Value = 'On';
                app.negSwitchSurf.Value = 'On';
                app.posSwitchSurf.Value = 'On';

                hold(app.histSurf,'on')
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.lowerThreshLineSurf = plot(ones(size(l))*app.lowerThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                l = linspace(app.histSurf.YLim(1),app.histSurf.YLim(2),5);
                app.upperThreshLineSurf = plot(ones(size(l))*app.upperThreshBoxSurf.Value,l,'k','Parent',app.histSurf,'Color',[1 0 0],'LineWidth',2,'LineStyle',':');
                hold(app.histSurf,'off')
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.valueSelectionSurf.Value = 'Raw';
            end
        end

        % Value changed function: posSwitchSurf
        function changePosSwitchSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.posSwitchSurf.Value = 'On';
            end
        end

        % Value changed function: negSwitchSurf
        function changeNegSwitchSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.negSwitchSurf.Value = 'On';
            end
        end

        % Value changed function: remZeroSwitchSurf
        function changeZeroSwitchSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.remZeroSwitchSurf.Value = 'On';
            end
        end

        % Value changed function: binSwitchSurf
        function changeBinSwitchSurf(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
                %[app] = sgPatch_and_smooth(app);
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.binSwitchSurf.Value = 'Off';
            end
        end

        % Callback function
        function changeBinBoxSurf(app, event)
            
        end

        % Value changed function: colormapSelectorSurf
        function colormapSurfChange(app, event)
            if isgraphics(app.brainFig,'figure') & ~strcmp(app.colormapSelectorSurf.Value,'Select colormap')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.colormapSelectorSurf.Value = 'Select colormap';
            end
        end

        % Value changed function: selectSpacingSurf
        function colorSpaceSurfChange(app, event)
            if isgraphics(app.brainFig,'figure') & ~strcmp(app.selectSpacingSurf.Value,'Select color spacing')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.selectSpacingSurf.Value = 'Select color spacing';
            end
        end

        % Value changed function: binBoxSurf
        function binBoxSurfChange(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.binBoxSurf.Value = 1000;
            end
        end

        % Value changed function: opacityBoxSurf
        function opacitySurfChange(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.sgB.left) | ~isempty(app.sgB.right))
                if ~isempty(app.sgB.left)
                    app.underlay.left.FaceVertexAlphaData = ones([size(app.underlay.left.Vertices,1),1])*app.opacityBoxSurf.Value;
                    app.underlay.left.AlphaDataMapping = 'scaled';
                    app.underlay.left.FaceAlpha = 'interp';
                    app.underlay.left.AlphaDataMapping = 'none';
                end
                if  ~isempty(app.sgB.right)
                    app.underlay.right.FaceVertexAlphaData = ones([size(app.underlay.right.Vertices,1),1])*app.opacityBoxSurf.Value;
                    app.underlay.right.AlphaDataMapping = 'scaled';
                    app.underlay.right.FaceAlpha = 'interp';
                    app.underlay.right.AlphaDataMapping = 'none';
                end
            end
        end

        % Value changed function: invertSwitchSurf
        function invertColorSurfChange(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.invertSwitchSurf.Value = 'Off';
            end
        end

        % Menu selected function: ChangedefaultsurfacecolorMenu
        function changeSurfC(app, event)
            surfCtmp = uisetcolor;
            app.surfC = surfCtmp;
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            end
        end

        % Value changed function: sgThresh
        function changeSgThresh(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    [app] = sgPatch(app,'false');
                else
                    app.sgThresh.Value = 0;
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.sgThresh.Value = 0;
            end
        end

        % Button pushed function: sulcButton
        function changeSulcButton(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    ctmp = uisetcolor;
                    if ~isempty(app.sgB.left)
                        id = find(ismember(app.underlay.left.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        app.underlay.left.FaceVertexCData(id,1) = ctmp(1);
                        app.underlay.left.FaceVertexCData(id,2) = ctmp(2);
                        app.underlay.left.FaceVertexCData(id,3) = ctmp(3);
                    end
                    if ~isempty(app.sgB.right)
                        id = find(ismember(app.underlay.right.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        app.underlay.right.FaceVertexCData(id,1) = ctmp(1);
                        app.underlay.right.FaceVertexCData(id,2) = ctmp(2);
                        app.underlay.right.FaceVertexCData(id,3) = ctmp(3);
                    end
                    app.sulcButton.BackgroundColor = ctmp;
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: gyrButton
        function changeGyriButton(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    ctmp = uisetcolor;
                    if ~isempty(app.sgB.left)
                        id = find(ismember(app.underlay.left.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        app.underlay.left.FaceVertexCData(id,1) = ctmp(1);
                        app.underlay.left.FaceVertexCData(id,2) = ctmp(2);
                        app.underlay.left.FaceVertexCData(id,3) = ctmp(3);
                    end
                    if ~isempty(app.sgB.right)
                        id = find(ismember(app.underlay.right.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        app.underlay.right.FaceVertexCData(id,1) = ctmp(1);
                        app.underlay.right.FaceVertexCData(id,2) = ctmp(2);
                        app.underlay.right.FaceVertexCData(id,3) = ctmp(3);
                    end
                    app.gyrButton.BackgroundColor = ctmp;
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Callback function
        function changeSgColormap(app, event)
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.opacityBoxSG.Value = 1;
            end
        end

        % Menu selected function: 
        % MakedefaultsulcalandgyralcolorsMenu_2
        function defaultSGColors(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    if ~isempty(app.sgB.left)
                        id = find(ismember(app.underlay.left.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        app.underlay.left.FaceVertexCData(id,1) = 0.4;
                        app.underlay.left.FaceVertexCData(id,2) = 0.4;
                        app.underlay.left.FaceVertexCData(id,3) = 0.4;
                    end
                    if ~isempty(app.sgB.right)
                        id = find(ismember(app.underlay.right.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));
                        app.underlay.right.FaceVertexCData(id,1) = 0.4;
                        app.underlay.right.FaceVertexCData(id,2) = 0.4;
                        app.underlay.right.FaceVertexCData(id,3) = 0.4;
                    end
                end
                if strcmp(app.binSwitchSurf.Value,'On')
                    if ~isempty(app.sgB.left)
                        id = find(ismember(app.underlay.left.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        app.underlay.left.FaceVertexCData(id,1) = 0.8;
                        app.underlay.left.FaceVertexCData(id,2) = 0.8;
                        app.underlay.left.FaceVertexCData(id,3) = 0.8;
                    end
                    if ~isempty(app.sgB.right)
                        id = find(ismember(app.underlay.right.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        app.underlay.right.FaceVertexCData(id,1) = 0.8;
                        app.underlay.right.FaceVertexCData(id,2) = 0.8;
                        app.underlay.right.FaceVertexCData(id,3) = 0.8;
                    end
                end
                app.gyrButton.BackgroundColor  = [0.4 0.4 0.4];
                app.sulcButton.BackgroundColor = [0.8 0.8 0.8];
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: InvertsulcalandgyralcolorsMenu_2
        function invertSGColors(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmp(app.binSwitchSurf.Value,'On')
                    s = app.sulcButton.BackgroundColor;
                    g = app.gyrButton.BackgroundColor;

                    if ~isempty(app.sgB.left)
                        id1 = find(ismember(app.underlay.left.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        id2 = find(ismember(app.underlay.left.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));

                        app.underlay.left.FaceVertexCData(id1,1) = g(1);
                        app.underlay.left.FaceVertexCData(id1,2) = g(1);
                        app.underlay.left.FaceVertexCData(id1,3) = g(1);

                        app.underlay.left.FaceVertexCData(id2,1) = s(1);
                        app.underlay.left.FaceVertexCData(id2,2) = s(1);
                        app.underlay.left.FaceVertexCData(id2,3) = s(1);
                    end
                    if ~isempty(app.sgB.right)
                        id1 = find(ismember(app.underlay.right.FaceVertexCData,app.sulcButton.BackgroundColor,'rows'));
                        id2 = find(ismember(app.underlay.right.FaceVertexCData,app.gyrButton.BackgroundColor,'rows'));

                        app.underlay.right.FaceVertexCData(id1,1) = g(1);
                        app.underlay.right.FaceVertexCData(id1,2) = g(1);
                        app.underlay.right.FaceVertexCData(id1,3) = g(1);

                        app.underlay.right.FaceVertexCData(id2,1) = s(1);
                        app.underlay.right.FaceVertexCData(id2,2) = s(1);
                        app.underlay.right.FaceVertexCData(id2,3) = s(1);
                    end
                    app.gyrButton.BackgroundColor = s;
                    app.sulcButton.BackgroundColor = g;
                end
            end

        end

        % Menu selected function: DarkModeMenu_3
        function darkMode(app, event)
            props = properties(app);
            for i = 1:length(props)
                thisprop = props{i};
                if ~strcmp(thisprop,'NooverlayPanel')
                    %%%Add logic here if you want to work with select properties
                    if isprop(app.(thisprop),'BackgroundColor') == 1
                        app.(thisprop).BackgroundColor = [0.27 0.27 0.27];
                    end
                    if isprop(app.(thisprop),'ForegroundColor') == 1
                        if ~contains(lower(thisprop),'menu')
                            app.(thisprop).ForegroundColor = [0.94,0.94,0.94];
                        end
                    end
                    if isprop(app.(thisprop),'FontColor') == 1
                        %if ~strfind(thisprop,'menu')
                        app.(thisprop).FontColor = [0.94,0.94,0.94];
                        %end
                    end
                else
                    app.(thisprop).BackgroundColor = [0.4 0.4 0.4];
                    app.(thisprop).ForegroundColor = [0.94,0.94,0.94];
                end
            end
            app.axSurf.XAxis.Color = [1 1 1];
            app.axOver.XAxis.Color = [1 1 1];
            app.histSurf.XColor = [1 1 1];
            app.histSurf.YColor = [1 1 1];
            app.histSurf.Color = [0.4 0.4 0.4];
            set(app.brainFig,'color','k');
        end

        % Menu selected function: LightModeMenu_3
        function lightMode(app, event)
            props = properties(app);

            for i = 1:length(props)
                thisprop = props{i};
                if ~strcmp(thisprop,'NooverlayPanel') || ~strcmp(thisprop,'lowerThreshBoxSurf') || ~strcmp(thisprop,'upperThreshBoxSurf') ||  ~strcmp(thisprop,'sgThresh') || ~strcmp(thisprop,'valueSelectionSurf') || ~strcmp(thisprop,'sulcButton') || ~strcmp(thisprop,'gyrButton') || ~strcmp(thisprop,'limMinSurf') || ~strcmp(thisprop,'limMaxSurf') || ~strcmp(thisprop,'binBoxSurf')|| ~strcmp(thisprop,'opacityBoxSurf')|| ~strcmp(thisprop,'smoothAreaUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')
                    %%%Add logic here if you want to work with select properties
                    if isprop(app.(thisprop),'BackgroundColor') == 1
                        app.(thisprop).BackgroundColor = [0.94,0.94,0.94];
                    end
                    if isprop(app.(thisprop),'ForegroundColor') == 1
                        if ~contains(lower(thisprop),'menu')
                            app.(thisprop).ForegroundColor = [0 0 0];
                        end
                    end
                    if isprop(app.(thisprop),'FontColor') == 1
                        %if ~strfind(thisprop,'menu')
                        app.(thisprop).FontColor = [0 0 0];
                        %end
                    end
                else
                    app.(thisprop).BackgroundColor = [1 1 1];
                    app.(thisprop).ForegroundColor = [0,0,0];
                end
            end
            app.axSurf.XAxis.Color = [0 0 0];
            app.axOver.XAxis.Color = [0 0 0];
            app.histSurf.XColor = [0 0 0];
            app.histSurf.YColor = [0 0 0];
            app.histSurf.Color = [0.94,0.94,0.94];
            set(app.brainFig,'color','w');
        end

        % Menu selected function: ChangebackgroundofbrainfigureMenu
        function changeBackBrain(app, event)
            tmpC = uisetcolor;
            set(app.brainFig,'color',tmpC);
        end

        % Value changed function: opacityBoxSG
        function changeOpacitySG(app, event)
            %value = app.opacityBoxSG.Value;
            if isgraphics(app.brainFig,'figure')
                [app] = sgPatch(app,'false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
                app.opacityBoxSG.Value = 1;
            end
        end

        % Value changed function: leftHemiToggle
        function changeDisplayLH(app, event)
            if isgraphics(app.brainFig,'figure')
                if app.leftHemiToggle.Value == 1
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'off';
                    end
                elseif app.leftHemiToggle.Value == 0
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Value changed function: rightHemiToggle
        function changeDisplayRH(app, event)
            if isgraphics(app.brainFig,'figure')
                if app.rightHemiToggle.Value == 1
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpR = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpR)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'off';
                    end
                elseif app.rightHemiToggle.Value == 0
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpR = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpR)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Value changed function: smoothButtonSurf
        function smoothButtonSurfPress(app, event)
            if isgraphics(app.brainFig,'figure')
                a = app.smoothAreaUnder.Value;
                s = app.smoothStepsUnder.Value;
                sb = app.smoothButtonSurf.Value;
                if app.smoothButtonSurf.Value == 0
                    app.sg.left = app.sgB.left;
                    app.sg.right = app.sgB.right;

                    %tog = updateGUI(app,'underlay','true','false');
                end

                [app] = sgPatch(app,'true');
                tog = updateGUI(app,'underlay','true','true');

                app.smoothAreaUnder.Value = a;
                app.smoothStepsUnder.Value = s;
                app.smoothButtonSurf.Value = sb;
            else
                %app.smoothButtonSurf.Value = 0;
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end

        end

        % Menu selected function: LoadoverlaysMenu
        function loadOverlay(app, event)
            % Ask for files
            %pos = {'*.nii','Nifti (*.nii)';'*.gii','Gifti (*.gii)';'*.nii','CIFTI (*.nii)';'*.label','Label (*.label)';'*.annot','Annotation (*.annot)';'*.curv','Morphometry file (*.curv)'};
            [f,p,~] = uigetfile('*','Select overlay(s) to load in','MultiSelect','on');
            oP = p;

            % Check if any file was selected
            if p ~= 0
                if ~iscell(f) % if one file, it will not be in cell array
                    f = {f};
                end

                % load files now...
                for i = 1:length(f)
                    [~,n,e] = fileparts(f{i});
                    [hemi,hemi2,d,d2,sz,ci] = loadBrainData(app,[p  app.pths.sep f{i}]);

                    % check if data size is 3D...if it is, ask if user
                    % wants to import volume to surface
                    if sz(2) > 1
                        [hemi,hemi2,d,d2,tmpOut] = vol2fsaverage(app,[p  app.pths.sep f{i}]);
                        if isfield(app.underlay,hemi)
                            if size(app.underlay.(hemi).Vertices,1) == 32492
                                [~,d,tmpOut2{1}] = fsaverage2fsLR(app,tmpOut{1},hemi,double(d));
                                [~,d2,tmpOut2{2}] = fsaverage2fsLR(app,tmpOut{2},hemi2,double(d2));
                                tmpOut = tmpOut2;
                            end
                        end
                    end

                    if ~isempty(hemi2)
                        if ~ci
                            [p, n, e] = fileparts(tmpOut{1});
                            nm = [n e];
                        elseif ci
                            nm = [n e ' (LEFT HEMI)'];
                        end
                        %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm(1:app.nmL));
                        if app.nmL < length(nm)
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm(1:app.nmL));
                            tmpNm = nm(1:app.nmL);
                        else
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm);
                            tmpNm = nm;
                        end
                        [c,~,si] = intersect(tmpNm,app.overlayListBox.Items);

                        if ~isempty(si)
                            for j = 2:length(app.overlayListBox.Items)
                                alt{j,1} = app.overlayListBox.Items{j}(end-2:end);
                            end
                            numO = regexp(alt,'\d*','match');
                            numO = str2double(cat(2,numO{:}));

                            if ~isempty(numO)
                                toNumO = max(numO) + 1;
                            else
                                toNumO = 1;
                            end
                            tmpNm = [tmpNm ' (' num2str(toNumO) ')'];
                        end
                        app.overlayListBox.Items = horzcat(app.overlayListBox.Items,tmpNm);
                        app.overlay.settings = horzcat(app.overlay.settings,app.defaultSetOver);
                        app.overlay.bm = horzcat(app.overlay.bm,d);
                        app.overlay.bmB = horzcat(app.overlay.bmB,d);
                        app.overlay.names = horzcat(app.overlay.names,[n e]);
                        app.overlay.paths = horzcat(app.overlay.paths,p);
                        app.overlay.settings{end}.hemi = hemi;
                        app.overlay.settings{end}.limMinOver = min(d);
                        app.overlay.settings{end}.limMaxOver = max(d);

                        if ~ci
                            [p, n, e] = fileparts(tmpOut{2});
                            nm = [n e];
                        elseif ci
                            nm = [n e ' (RIGHT HEMI)'];
                        end

                        %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm(1:app.nmL));
                        if app.nmL < length(nm)
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm(1:app.nmL));
                            tmpNm = nm(1:app.nmL);
                        else
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,nm);
                            tmpNm = nm;
                        end
                        [c,~,si] = intersect(tmpNm,app.overlayListBox.Items);

                        if ~isempty(si)
                            for j = 2:length(app.overlayListBox.Items)
                                alt{j,1} = app.overlayListBox.Items{j}(end-2:end);
                            end
                            numO = regexp(alt,'\d*','match');
                            numO = str2double(cat(2,numO{:}));

                            if ~isempty(numO)
                                toNumO = max(numO) + 1;
                            else
                                toNumO = 1;
                            end
                            tmpNm = [tmpNm ' (' num2str(toNumO) ')'];
                        end
                        app.overlayListBox.Items = horzcat(app.overlayListBox.Items,tmpNm);
                        app.overlay.settings = horzcat(app.overlay.settings,app.defaultSetOver);
                        app.overlay.bm = horzcat(app.overlay.bm,d2);
                        app.overlay.bmB = horzcat(app.overlay.bmB,d2);
                        app.overlay.names = horzcat(app.overlay.names,[n e]);
                        app.overlay.paths = horzcat(app.overlay.paths,p);
                        app.overlay.settings{end}.hemi = hemi2;
                        app.overlay.settings{end}.limMinOver = min(d2);
                        app.overlay.settings{end}.limMaxOver = max(d2);
                    else
                        if app.nmL < length(f{i})
                            tmpNm = f{i}(1:app.nmL);
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,f{i}(1:app.nmL));
                        else
                            tmpNm = f{i};
                            %app.overlayListBox.Items = horzcat(app.overlayListBox.Items,f{i});
                        end

                        [c,~,si] = intersect(tmpNm,app.overlayListBox.Items);

                        if ~isempty(si)
                            for j = 2:length(app.overlayListBox.Items)
                                alt{j,1} = app.overlayListBox.Items{j}(end-2:end);
                            end
                            numO = regexp(alt,'\d*','match');
                            numO = str2double(cat(2,numO{:}));

                            if ~isempty(numO)
                                toNumO = max(numO) + 1;
                            else
                                toNumO = 1;
                            end
                            tmpNm = [tmpNm ' (' num2str(toNumO) ')'];
                        end
                        app.overlayListBox.Items = horzcat(app.overlayListBox.Items,tmpNm);
                        app.overlay.settings = horzcat(app.overlay.settings,app.defaultSetOver);
                        app.overlay.bm = horzcat(app.overlay.bm,d);
                        app.overlay.bmB = horzcat(app.overlay.bmB,d);
                        app.overlay.names = horzcat(app.overlay.names,f{i});
                        app.overlay.paths = horzcat(app.overlay.paths,oP);
                        app.overlay.settings{end}.hemi = hemi;
                        app.overlay.settings{end}.limMinOver = min(d);
                        app.overlay.settings{end}.limMaxOver = max(d);
                    end
                end
                app.modOverlaySelect.Items = app.overlayListBox.Items;
                app.TwoD1.Items = app.overlayListBox.Items;
                app.TwoD2.Items = app.overlayListBox.Items;

                app.ThreeD1.Items = app.overlayListBox.Items;
                app.ThreeD2.Items = app.overlayListBox.Items;
                app.ThreeD3.Items = app.overlayListBox.Items;
            end
        end

        % Value changed function: overlayListBox
        function overlaySelected(app, event)
            if isgraphics(app.brainFig,'figure')

                % remove no overlay only if there are multiple
                % selections....
                if length(app.overlayListBox.Value) > 1
                    [~,ia,~] = intersect(app.overlayListBox.Value,app.overlayListBox.Items(1));
                    if ~isempty(ia)
                        app.overlayListBox.Value(ia) = [];
                    end
                end

                [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);

                % check for overlays that do not match the shape of
                % underlays
                toR = [];
                toRN = [];

                if si ~= 1
                    for i = 1:length(si)
                        if length(app.overlay.bm{si(i)}) ~= size(app.underlay.(app.overlay.settings{si(i)}.hemi).Vertices,1)
                            toR = vertcat(toR,si(i));
                            toRN = vertcat(toRN,s(i));
                        end
                    end
                else

                end

                if ~isempty(toR)
                    uialert(app.brainSurferUIFigure,[sprintf('The following overlays have a number of vertices that do not match the underlay you are trying to plot them on so they will not be patched: \n') strjoin(toRN,'\n')],'Incompatible overlay');
                    app.overlayListBox.Value = app.overlayListBox.Items(setdiff(si,toR));
                    [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                    if isempty(s)
                        app.overlayListBox.Value = app.overlayListBox.Items{1};
                    end
                end

                if isfield(app.underlay,'left')
                    if strcmpi(app.underlay.left.Visible,'off')
                        app.underlay.left.Visible = 'on';
                        app.leftHemiToggle.Value = 0;
                    end
                end
                if isfield(app.underlay,'right')
                    if strcmpi(app.underlay.right.Visible,'off')
                        app.underlay.right.Visible = 'on';
                        app.rightHemiToggle.Value = 0;
                    end
                end

                [app] = overPatch(app,'false','false','true');

            else
                app.overlayListBox.Value = 'No overlay';
                uialert(app.brainSurferUIFigure,'You cannot patch an overlay unless a surface is loaded','Action unavailable');
            end
            %app.overlayListBox.Value;

        end

        % Value changed function: pThreshBox
        function pThreshBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.pThresh = app.pThreshBox.Value;
                if ~isempty(app.overlay.settings{si}.pMap)
                    app = overPatch(app,'false','false','false');
                else
                    uialert(app.brainSurferUIFigure,'Looks like this map does not have p-values loaded in','Action unavailable');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.pThreshBox.Value = 1;
            end
        end

        % Value changed function: valueSelection
        function valueSelectionChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.valueSelection = app.valueSelection.Value;
                app.overlay.settings{si}.lowerThreshBox = 0;
                app.overlay.settings{si}.upperThreshBox = 0;
                app.overlay.bm{si} = app.overlay.bmB{si};
                % change threshold axis
                app = overPatch(app,'false','true','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.valueSelection.Value = 'Raw';
            end
        end

        % Value changed function: posSwitch
        function posSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.posSwitch = app.posSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.posSwitch.Value = 'On';
            end
        end

        % Value changed function: negSwitch
        function negSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.negSwitch = app.negSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.negSwitch.Value = 'On';
            end
        end

        % Value changed function: remZeroSwitch
        function remZeroSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.remZeroSwitch = app.remZeroSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.remZeroSwitch.Value = 'Off';
            end
        end

        % Value changed function: binSwitch
        function binSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.binSwitch = app.binSwitch.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.binSwitch.Value = 'Off';
            end
        end

        % Value changed function: addpButton
        function addpButtonChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                if app.addpButton.Value == 1
                    % load input map...
                    [f,p,~] = uigetfile('*','MultiSelect','off');
                    if p ~= 0
                        [hemi,hemi2,d,d2,sz,ci] = loadBrainData(app,[p  app.pths.sep f]);

                        if ci == 1
                            if strcmpi(app.overlay.hemi{si},'left')
                                app.overlay.settings{si}.pMap = d;
                            elseif strcmpi(app.overlay.hemi{si},'right')
                                app.overlay.settings{si}.pMap = d2;
                            end
                        end

                        % check if data size is 3D...if it is, ask if user
                        % wants to import volume to surface
                        if sz(2) > 1
                            [hemi,hemi2,d,d2,tmpOut] = vol2fsaverage(app,[p  app.pths.sep f]);

                            if strcmpi(app.overlay.hemi{si},'left')
                                app.overlay.settings{si}.pMap = d;
                            elseif strcmpi(app.overlay.hemi{si},'right')
                                app.overlay.settings{si}.pMap = d2;
                            end
                        elseif sz(2) == 1
                            app.overlay.settings{si}.pMap = d;
                        end

                        app.overlay.settings{si}.pMapB = app.overlay.settings{si}.pMap;
                        app.overlay.settings{si}.pThresh = 1;
                        app.pThreshBox.Value = 1;

                    else
                        app.addpButton.Value = 0;
                        app.fdrButton.Value = 0;
                        app.bonfButton.Value = 0;
                    end
                else
                    app.overlay.settings{si}.pMap = [];
                    app.overlay.settings{si}.pMapB = [];
                    app = overPatch(app,'false','false','false');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
            end
        end

        % Button pushed function: reloadButton, reloadOver
        function reloadOverlay(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.bm{si} = app.overlay.bmB{si};
                tmp = app.overlay.settings{si}.hemi;
                app.overlay.settings{si} = app.defaultSetOver;
                app.overlay.settings{si}.hemi = tmp;
                app.overlay.settings{si}.limMinOver = min(app.overlay.bm{si});
                app.overlay.settings{si}.limMaxOver = max(app.overlay.bm{si});
                app = overPatch(app,'false','false','false');

            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
            end
        end

        % Value changed function: limMinOver
        function limMinChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                if app.limMaxOver.Value < app.limMinOver.Value
                    tmp1 = app.limMaxOver.Value;
                    app.limMaxOver.Value = app.limMinOver.Value;
                    app.limMinOver.Value = tmp1;
                end

                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.limMinOver = app.limMinOver.Value;
                app.overlay.settings{si}.limMaxOver = app.limMaxOver.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.limMinOver.Value = 0;
            end
        end

        % Value changed function: limMaxOver
        function limMaxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                if app.limMaxOver.Value < app.limMinOver.Value
                    tmp1 = app.limMaxOver.Value;
                    app.limMaxOver.Value = app.limMinOver.Value;
                    app.limMinOver.Value = tmp1;
                end
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.limMaxOver = app.limMaxOver.Value;
                app.overlay.settings{si}.limMinOver = app.limMinOver.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.limMaxOver.Value = 0;
            end
        end

        % Value changed function: colormapSelector
        function colormapSelectorChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.colormapSelector = app.colormapSelector.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.colormapSelector.Value = 'Select colormap';
            end
        end

        % Value changed function: selectSpacing
        function selectSpacingChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.selectSpacing = app.selectSpacing.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.selectSpacing.Value = 'Select color spacing';
            end
        end

        % Value changed function: binBox
        function binBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.binBox = app.binBox.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.binBox.Value = 1000;
            end
        end

        % Value changed function: opacityBox
        function opacityBoxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.opacityBox = app.opacityBox.Value;

                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        if isempty(app.overlay.settings{si}.modData)
                            app.currOver{i}.FaceVertexAlphaData = ones(size(app.currOver{i}.FaceVertexAlphaData))*app.overlay.settings{si}.opacityBox;
                        else
                            uialert(app.brainSurferUIFigure,'You have selected an overlay to use to modulate transparency. Either change the maximum opacity in the complex overlay tab or change the opacity of this specific overlay, then re-enable transparency modulation.','Action unavailable');
                        end
                    end
                end

                try
                    for ii = 1:length(app.hist3D.Children)
                        app.hist3D.Children(ii).FaceAlpha = app.opacityBox.Value;
                    end
                catch
                end

            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.opacityBox.Value = 1;
            end
        end

        % Value changed function: invertSwitch
        function invertSwitchChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.invertSwitch = app.invertSwitch.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.invertSwitch.Value = 'Off';
            end
        end

        % Value changed function: outlineDropDown
        function outlineDropDownChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.outlineDropDown = app.outlineDropDown.Value;
                app = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.outlineDropDown.Value = 'None';
            end
        end

        % Value changed function: growShrinkSlider
        function growShrinkSliderChange(app, event)
            app.growShrinkSlider.Value = round(app.growShrinkSlider.Value);
            app.growShrinkBox.Value = app.growShrinkSlider.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.growShrinkBox = app.growShrinkBox.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.growShrinkSlider.Value = 0;
                app.growShrinkBox.Value = 0;
            end
        end

        % Value changed function: growShrinkBox
        function growShrinkBoxChange(app, event)
            app.growShrinkBox.Value = round(app.growShrinkBox.Value);

            if app.growShrinkBox.Value > max(app.growShrinkSlider.Limits)
                app.growShrinkSlider.Value = max(app.growShrinkSlider.Limits);
            elseif app.growShrinkBox.Value < min(app.growShrinkSlider.Limits)
                app.growShrinkSlider.Value = min(app.growShrinkSlider.Limits);
            else
                app.growShrinkSlider.Value = app.growShrinkBox.Value;
            end

            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                app.overlay.settings{si}.growShrinkBox = app.growShrinkBox.Value;
                app = overPatch(app,'false','false','false');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function unless you have selected an (one) overlay','Action unavailable');
                app.growShrinkSlider.Value = 0;
                app.growShrinkBox.Value = 0;
            end
        end

        % Menu selected function: ShowMenu_4
        function showColorbar(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                % get selections
                [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                for fi = 1:length(si)
                    app.cBar{fi}.Visible = 'on';
                    app.cBarAx{fi}.Visible = 'on';
                    app.cBarText{fi}.Visible = 'on';
                end
            end
        end

        % Menu selected function: HideMenu_4
        function hideColorbar(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                % get selections
                [s,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                for fi = 1:length(si)
                    app.cBar{fi}.Visible = 'off';
                    app.cBarAx{fi}.Visible = 'off';
                    app.cBarText{fi}.Visible = 'off';
                end
            end
        end

        % Menu selected function: HorizontalMenu
        function makeColorbarHoriz(app, event)
            if strcmpi(app.cBarDir,'vert')
                app.cBarDir = 'horiz';
                if isgraphics(app.brainFig,'figure') & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    %tol = 1:length(app.brainFig.Children)-1;
                    %for i = 1:length(tol)
                    len = length(app.cBarAx);
                    for i = 1:len
                        if length(app.brainFig.Children) > 1
                            pos = get(app.brainFig.Children(end),'Position');
                        else
                            pos = get(app.brainFig.Children,'Position');
                        end

                        stripe = (0.075)/1.7;
                        space = .1;
                        offs = 0.07;
                        p2 = (pos(2) - offs);
                        rect = [pos(1) (p2 + app.cBarSpacing*(len-1)) pos(3) stripe*pos(4)];
                        rect(2) = rect(2) - app.cBarSpacing*(i-1);
                        cb = permute(app.cBar{i}.CData,[2 1 3]);
                        tlab = 'XTickLabels';
                        tr = 'ytick';
                        ticks.labels = app.cBar{i}.Parent.YTickLabel;
                        transp.map = app.cBar{i}.AlphaData';
                        if len > 1
                            tmpNm = app.cBarText{i}.String;
                        end
                        delete(app.cBarAx{i})
                        delete(app.cBar{i})

                        app.cBarAx{i} = axes('Position', rect);
                        app.cBar{i} = image(app.cBarAx{i},cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);

                        set(app.cBarAx{i},[tr 'labelmode'],'manual')
                        set(app.cBarAx{i},[tr 'label'],[])
                        set(app.cBarAx{i},'Ydir','normal','YAxisLocation','right')
                        app.cBar{i}.Parent.TickLength = [0 0];
                        try
                            app.cBar{i}.Parent.(tlab) = ticks.labels;
                        catch
                            app.cBar{i}.Parent.(tlab(1:end-1)) = ticks.labels;
                        end
                        app.cBar{i}.Parent.YTickLabel = [];

                        if len > 1
                            delete(app.cBarText{i});
                            %app.cBarText{i} = text(app.cBarAx{i},app.cBarAx{i}.Position(1)+0.2,app.cBarAx{i}.Position(2)+0.3,tmpNm);
                            app.cBarText{i} = text(app.cBarAx{i},0.5-(0.003*length(tmpNm)),0.5,tmpNm);
                        end
                    end
                end
            end
        end

        % Menu selected function: VerticalMenu
        function makeColorbarVert(app, event)
            if strcmpi(app.cBarDir,'horiz')
                app.cBarDir = 'vert';
                if isgraphics(app.brainFig,'figure') & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    len = length(app.cBarAx);
                    for i = 1:len
                        if length(app.brainFig.Children) > 1
                            pos = get(app.brainFig.Children(end),'Position');
                        else
                            pos = get(app.brainFig.Children,'Position');
                        end

                        stripe = 0.04;
                        edge = 0.01;
                        space = 0.03;
                        p1 = (pos(1)+pos(3)+space);
                        rect = [(p1 - app.cBarSpacing*(len-1)) pos(2) stripe*pos(3) pos(4)];
                        rect(1) = rect(1) + app.cBarSpacing*(i-1);
                        cb = permute(app.cBar{i}.CData,[2 1 3]);
                        tlab = 'YTickLabels';
                        tr = 'xtick';

                        ticks.labels = app.cBar{i}.Parent.XTickLabel;
                        transp.map = app.cBar{i}.AlphaData';
                        if len > 1
                            tmpNm = app.cBarText{i}.String;
                        end
                        delete(app.cBarAx{i})
                        delete(app.cBar{i})

                        app.cBarAx{i} = axes('Position', rect);
                        app.cBar{i} = image(app.cBarAx{i},cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);

                        set(app.cBarAx{i},[tr 'labelmode'],'manual')
                        set(app.cBarAx{i},[tr 'label'],[])
                        set(app.cBarAx{i},'Ydir','normal','YAxisLocation','right')
                        app.cBar{i}.Parent.TickLength = [0 0];
                        app.cBar{i}.Parent.(tlab) = ticks.labels;

                        if len > 1
                            delete(app.cBarText{i});
                            %                             app.cBarText{i} = text(app.cBarAx{i},app.cBarAx{i}.Position(1)-0.3,app.cBarAx{i}.Position(2)-0.01,tmpNm);
                            %                             set(app.cBarText{i},'Rotation',90);
                            app.cBarText{i} = text(app.cBarAx{i},0.5,0.5-(0.005*length(tmpNm)),tmpNm,'Rotation',90);
                        end
                    end
                end
            end
        end

        % Menu selected function: ChangespacingbetweencolorbarsMenu
        function changeSpacingBetweenColorbars(app, event)
            tmp = inputdlg('What should the spacing between multiple colorbars be?','Spacing value',[1],{'0.065'});
            if ~isempty(tmp)
                app.cBarSpacing = str2double(tmp{1});
            end
            if isgraphics(app.brainFig,'figure') & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                len = length(app.cBarAx);
                for i = 1:len
                    if length(app.brainFig.Children) > 1
                        pos = get(app.brainFig.Children(end),'Position');
                    else
                        pos = get(app.brainFig.Children,'Position');
                    end
                    if  strcmpi(app.cBarDir,'vert')
                        stripe = 0.04;
                        edge = 0.01;
                        space = 0.03;
                        p1 = (pos(1)+pos(3)+space);
                        rect = [(p1 - app.cBarSpacing*(len-1)) pos(2) stripe*pos(3) pos(4)];
                        rect(1) = rect(1) + app.cBarSpacing*(i-1);

                        tlab = 'YTickLabels';
                        tr = 'xtick';

                        ticks.labels = app.cBar{i}.Parent.XTickLabel;
                    elseif strcmpi(app.cBarDir,'horiz')
                        stripe = (0.075)/1.7;
                        space = .1;
                        offs = 0.07;
                        p2 = (pos(2) - offs);
                        rect = [pos(1) (p2 + app.cBarSpacing*(len-1)) pos(3) stripe*pos(4)];
                        rect(2) = rect(2) - app.cBarSpacing*(i-1);

                        tlab = 'XTickLabels';
                        tr = 'ytick';

                        ticks.labels = app.cBar{i}.Parent.YTickLabel;
                    end

                    cb = app.cBar{i}.CData;
                    transp.map = app.cBar{i}.AlphaData;

                    if len > 1
                        tmpNm = app.cBarText{i}.String;
                    end
                    delete(app.cBarAx{i})
                    delete(app.cBar{i})

                    app.cBarAx{i} = axes('Position', rect);
                    app.cBar{i} = image(app.cBarAx{i},cb,'XData',[0 1],'YData',[0 1],'AlphaData',transp.map);

                    set(app.cBarAx{i},[tr 'labelmode'],'manual')
                    set(app.cBarAx{i},[tr 'label'],[])
                    set(app.cBarAx{i},'Ydir','normal','YAxisLocation','right')
                    app.cBar{i}.Parent.TickLength = [0 0];
                    app.cBar{i}.Parent.(tlab) = ticks.labels;

                    if len > 1
                        delete(app.cBarText{i});
                        if strcmpi(app.cBarDir,'horiz')
                            app.cBarText{i} = text(app.cBarAx{i},0.5-(0.003*length(tmpNm)),0.5,tmpNm);
                        else
                            app.cBarText{i} = text(app.cBarAx{i},0.5,0.5-(0.005*length(tmpNm)),tmpNm,'Rotation',90);
                        end
                    end
                end
            end
        end

        % Menu selected function: HideMenu_5
        function hideMapTitles(app, event)
            for i = 1:length(app.cBarText)
                app.cBarText{i}.Visible = 'off';
            end
        end

        % Menu selected function: ShowMenu_5
        function showMapTitles(app, event)
            for i = 1: length(app.cBarText)
                app.cBarText{i}.Visible = 'on';
            end
        end

        % Menu selected function: LeftlateralMenu, 
        % ViewleftlateralMenu
        function viewLateralLeft(app, event)
            if isgraphics(app.brainFig,'figure')
                tmp = findall(app.brainFig.Children,'Type','Axes');
                if length(tmp) > 1
                    tmp=tmp(end);
                end
                view(tmp,[-90 0])
            end
        end

        % Menu selected function: RightlateralMenu, 
        % ViewrightlateralMenu
        function viewLateralRight(app, event)
            if isgraphics(app.brainFig,'figure')
                tmp = findall(app.brainFig.Children,'Type','Axes');
                if length(tmp) > 1
                    tmp=tmp(end);
                end
                view(tmp,[90 0])
            end
        end

        % Menu selected function: InferiorMenu_3, ViewinferiorMenu
        function viewInferior(app, event)
            if isgraphics(app.brainFig,'figure')
                tmp = findall(app.brainFig.Children,'Type','Axes');
                if length(tmp) > 1
                    tmp=tmp(end);
                end
                %view(tmp,[90 -90])
                view(tmp,[180 -90])
            end
        end

        % Menu selected function: SuperiorMenu_3, ViewSuperiorMenu
        function viewSuperior(app, event)
            if isgraphics(app.brainFig,'figure')
                tmp = findall(app.brainFig.Children,'Type','Axes');
                if length(tmp) > 1
                    tmp=tmp(end);
                end
                view(tmp,[-90 90])
            end
        end

        % Menu selected function: LeftMenu_2
        function deleteLH(app, event)
            if isgraphics(app.brainFig,'figure')
                if isfield(app.underlay,'left')
                    toR = [];
                    for i = 2:length(app.overlay.settings)
                        if strcmpi(app.overlay.settings{i}.hemi,'left')
                            toR = [toR; i];
                        end
                    end
                    app.overlay.names(toR) = [];
                    app.overlay.paths(toR) = [];
                    app.overlay.bm(toR) = [];
                    app.overlay.bmB(toR) = [];
                    app.overlay.settings(toR) = [];

                    delete(app.underlay.left)
                    app.underlay = rmfield(app.underlay,'left');

                    if ~isempty(intersect(app.overlayListBox.Items(toR),app.overlayListBox.Value))
                        app.overlayListBox.Value = app.overlayListBox.Items(1);
                        for i = 1:length(app.currOver)
                            delete(app.currOver{i})
                        end
                    end
                    app.overlayListBox.Items(toR) = [];
                    [app] = overPatch(app,'false','false','true');

                    %app.sg = rmfield(app.sg,'left');
                    %app.sgB = rmfield(app.sgB,'left');
                    app.sg.left = [];
                    app.sgB.left = [];
                    tog = updateGUI(app,'underlay','true','false');

                    if ~isempty(app.atlas.overlay.left)
                        delete(app.atlas.overlay.left)
                        app.atlas.overlay.left = [];
                    end

                    if ~isempty(app.atlas.cBar)
                        delete(app.atlas.cBar)
                        app.atlas.cBar = [];
                    end

                    if ~isempty(app.atlas.cBarText)
                        delete(app.atlas.cBarText)
                        app.atlas.cBarText = [];
                    end

                    if ~isempty(app.atlas.cBarAx)
                        delete(app.atlas.cBarAx)
                        app.atlas.cBarAx = [];
                    end

                    app.atlas.defaultColors.left = [];
                    app.atlas.bm.left = [];
                    app.atlas.regions.left = [];
                    app.atlas.clusters.left = [];
                    app.atlas.borders.left = [];
                    app.atlas.name.left = [];
                    app.atlas.path.left = [];

                    if isempty(app.atlas.overlay.right)
                        app.colormapSelectAtlas.Value = 'Select colormap';
                        app.LoadedatlasLabel.Text = 'Loaded atlas: ';
                        app.AreaonlastdatatipLabel.Text = 'Area on last data tip: ';
                        app.selectSpacingAtlas = 'Select color spacing';
                        app.borderToggleAtlas.Value = 'Off';
                        app.upperLimAtlas.Value = 0;
                        app.lowerLimAtlas.Value = 0;
                        app.binAtlas.Value = 1;
                        app.opacityAtlas.Value = 0;
                        app.shrinkGrowAtlas.Value = 0;
                        app.invertToggleAtlas.Value = 'Off';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a left hemisphere surface','Action unavailable');
            end
        end

        % Menu selected function: RightMenu_2
        function deleteRH(app, event)
            if isgraphics(app.brainFig,'figure')
                if isfield(app.underlay,'right')
                    toR = [];
                    for i = 2:length(app.overlay.settings)
                        if strcmpi(app.overlay.settings{i}.hemi,'right')
                            toR = [toR; i];
                        end
                    end
                    app.overlay.names(toR) = [];
                    app.overlay.paths(toR) = [];
                    app.overlay.bm(toR) = [];
                    app.overlay.bmB(toR) = [];
                    app.overlay.settings(toR) = [];

                    delete(app.underlay.right)
                    app.underlay = rmfield(app.underlay,'right');

                    if ~isempty(intersect(app.overlayListBox.Items(toR),app.overlayListBox.Value))
                        app.overlayListBox.Value = app.overlayListBox.Items(1);
                        for i = 1:length(app.currOver)
                            delete(app.currOver{i})
                        end
                    end
                    app.overlayListBox.Items(toR) = [];
                    [app] = overPatch(app,'false','false','true');

                    app.sg.right = [];
                    app.sgB.right = [];
                    tog = updateGUI(app,'underlay','true','false');

                    if ~isempty(app.atlas.overlay.left)
                        delete(app.atlas.overlay.right)
                        app.atlas.overlay.right = [];
                    end
                    right
                    if ~isempty(app.atlas.cBar)
                        delete(app.atlas.cBar)
                        app.atlas.cBar = [];
                    end

                    if ~isempty(app.atlas.cBarText)
                        delete(app.atlas.cBarText)
                        app.atlas.cBarText = [];
                    end

                    if ~isempty(app.atlas.cBarAx)
                        delete(app.atlas.cBarAx)
                        app.atlas.cBarAx = [];
                    end

                    app.atlas.defaultColors.right = [];
                    app.atlas.bm.right = [];
                    app.atlas.regions.right = [];
                    app.atlas.clusters.right = [];
                    app.atlas.borders.right = [];
                    app.atlas.name.right = [];
                    app.atlas.path.right = [];

                    if isempty(app.atlas.overlay.left)
                        app.colormapSelectAtlas.Value = 'Select colormap';
                        app.LoadedatlasLabel.Text = 'Loaded atlas: ';
                        app.AreaonlastdatatipLabel.Text = 'Area on last data tip: ';
                        app.selectSpacingAtlas = 'Select color spacing';
                        app.borderToggleAtlas.Value = 'Off';
                        app.upperLimAtlas.Value = 0;
                        app.lowerLimAtlas.Value = 0;
                        app.binAtlas.Value = 1;
                        app.opacityAtlas.Value = 0;
                        app.shrinkGrowAtlas.Value = 0;
                        app.invertToggleAtlas.Value = 'Off';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a left hemisphere surface','Action unavailable');
            end
        end

        % Menu selected function: AspecificoverlayMenu, 
        % DeleteselectoverlayMenu
        function deleteSomeOverlay(app, event)
            % add all other buttons that can summon the GUI here as well
            % and set them to off
            if length(app.overlayListBox.Items) ~= 1
                init = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    init = [init; sit];
                end
                app.overlaySelectionApp = oSelect(app,' to delete');

                post = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    post = [post; sit];
                end
                if init ~= post
                    [app] = overPatch(app,'false','false','true');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in overlays','Action unavailable');
            end
        end

        % Button pushed function: deleteButton
        function deleteOverlay(app, event)
            %[~,ia,~] = intersect(app.overlayListBox.Items,app.overlayListBox.Value);
            if ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                ia = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    ia = [ia; sit];
                end

                id = find(ia == 1);
                ia(id) = [];
                app.overlayListBox.Items(ia) = [];
                app.overlay.names(ia) = [];
                app.overlay.paths(ia) = [];
                app.overlay.bm(ia) = [];
                app.overlay.bmB(ia) = [];
                app.overlay.settings(ia) = [];
                [app] = overPatch(app,'false','false','true');

                s = setdiff(app.modOverlaySelect.Items,app.overlayListBox.Items);
                for i = 2:length(app.overlay.settings)
                    for j = 1:length(s)
                        if strcmpi(app.overlay.settings{i}.modName,s{j})
                            app.overlay.settings{i}.modData = [];
                            app.overlay.settings{i}.modName = 'No overlay';
                            app.overlay.settings{i}.absToggleMod = 'On';
                            app.overlay.settings{i}.incZeroToggleMod = 'Off';
                            app.overlay.settings{i}.minAlphaMod = 0;
                            app.overlay.settings{i}.maxAlphaMod = 1;
                        end
                    end
                end

                app.modOverlaySelect.Items = app.overlayListBox.Items;
                app.TwoD1.Items = app.overlayListBox.Items;
                app.TwoD2.Items = app.overlayListBox.Items;

                app.ThreeD1.Items = app.overlayListBox.Items;
                app.ThreeD2.Items = app.overlayListBox.Items;
                app.ThreeD3.Items = app.overlayListBox.Items;
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load or select an overlay','Action unavailable');
            end
        end

        % Menu selected function: SaveoverlaysMenu
        function saveOverlay(app, event)
            app.overlaySelectionApp = oSelect(app,' to save');
        end

        % Button pushed function: LHButton
        function changeDisplayLHButton(app, event)
            if isgraphics(app.brainFig,'figure')
                if strcmpi(app.underlay.left.Visible,'on')
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'off';
                    end
                    if ~isempty(app.atlas.overlay.left)
                        app.atlas.overlay.left.Visible = 'off';
                    end
                elseif strcmpi(app.underlay.left.Visible,'off')
                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'on';
                    end
                    if ~isempty(app.atlas.overlay.left)
                        app.atlas.overlay.left.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: RHButton
        function changeDisplayRHButton(app, event)
            if isgraphics(app.brainFig,'figure')
                if  strcmpi(app.underlay.right.Visible,'on')
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpR = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpR)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'off';
                    end
                    if ~isempty(app.atlas.overlay.right)
                        app.atlas.overlay.right.Visible = 'off';
                    end
                elseif strcmpi(app.underlay.right.Visible,'off')
                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpR = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpR)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'on';
                    end
                    if ~isempty(app.atlas.overlay.right)
                        app.atlas.overlay.right.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: upButton
        function upButtonPress(app, event)
            old = [];
            s = [];
            for i = 1:length(app.overlayListBox.Value)
                [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                old = [old; sit];
                s = [s; st];
            end

            old = sort(old,'ascend');
            new = old-1;
            id = find(new == 1);
            new(id) = [];
            old(id) = [];

            for i = 1:length(new)
                app.overlayListBox.Items = [app.overlayListBox.Items(1:new(i)-1) app.overlayListBox.Items(old(i)) app.overlayListBox.Items(new(i)) app.overlayListBox.Items(old(i)+1:end)];
                app.overlay.names = [app.overlay.names(1:new(i)-1) app.overlay.names(old(i)) app.overlay.names(new(i)) app.overlay.names(old(i)+1:end)];
                app.overlay.paths = [app.overlay.paths(1:new(i)-1) app.overlay.paths(old(i)) app.overlay.paths(new(i)) app.overlay.paths(old(i)+1:end)];
                app.overlay.bm = [app.overlay.bm(1:new(i)-1) app.overlay.bm(old(i)) app.overlay.bm(new(i)) app.overlay.bm(old(i)+1:end)];
                app.overlay.bmB = [app.overlay.bmB(1:new(i)-1) app.overlay.bmB(old(i)) app.overlay.bmB(new(i)) app.overlay.bmB(old(i)+1:end)];
                app.overlay.settings = [app.overlay.settings(1:new(i)-1) app.overlay.settings(old(i)) app.overlay.settings(new(i)) app.overlay.settings(old(i)+1:end)];
            end

            app.modOverlaySelect.Items = app.overlayListBox.Items;
            app.TwoD1.Items = app.overlayListBox.Items;
            app.TwoD2.Items = app.overlayListBox.Items;

            app.ThreeD1.Items = app.overlayListBox.Items;
            app.ThreeD2.Items = app.overlayListBox.Items;
            app.ThreeD3.Items = app.overlayListBox.Items;
        end

        % Button pushed function: downButton
        function downButtonPress(app, event)
            old = [];
            s = [];
            for i = 1:length(app.overlayListBox.Value)
                [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                old = [old; sit];
                s = [s; st];
            end

            old = sort(old,'descend');
            new = old+1;
            id = find(new == length(app.overlayListBox.Items)+1);
            new(id) = [];
            old(id) = [];

            for i = 1:length(new)
                app.overlayListBox.Items = [app.overlayListBox.Items(1:old(i)-1) app.overlayListBox.Items(new(i)) app.overlayListBox.Items(old(i)) app.overlayListBox.Items(new(i)+1:end)];
                app.overlay.names = [app.overlay.names(1:old(i)-1) app.overlay.names(new(i)) app.overlay.names(old(i)) app.overlay.names(new(i)+1:end)];
                app.overlay.paths = [app.overlay.paths(1:old(i)-1) app.overlay.paths(new(i)) app.overlay.paths(old(i)) app.overlay.paths(new(i)+1:end)];
                app.overlay.bm = [app.overlay.bm(1:old(i)-1) app.overlay.bm(new(i)) app.overlay.bm(old(i)) app.overlay.bm(new(i)+1:end)];
                app.overlay.bmB = [app.overlay.bmB(1:old(i)-1) app.overlay.bmB(new(i)) app.overlay.bmB(old(i)) app.overlay.bmB(new(i)+1:end)];
                app.overlay.settings = [app.overlay.settings(1:old(i)-1) app.overlay.settings(new(i)) app.overlay.settings(old(i)) app.overlay.settings(new(i)+1:end)];
            end

            app.modOverlaySelect.Items = app.overlayListBox.Items;
            app.TwoD1.Items = app.overlayListBox.Items;
            app.TwoD2.Items = app.overlayListBox.Items;

            app.ThreeD1.Items = app.overlayListBox.Items;
            app.ThreeD2.Items = app.overlayListBox.Items;
            app.ThreeD3.Items = app.overlayListBox.Items;
        end

        % Button pushed function: dupeButton
        function dupeButtonPush(app, event)
            if length(app.overlayListBox.Items) ~= 1
                [si,s] = getListSelect(app,'overlayListBox',[]);
                si = sort(si,'ascend');
                new = si+1;
                id = find(new == 1);
                new(id) = [];
                si(id) = [];

                it = 0;
                for i = 1:length(new)
                    tmpNm = [app.overlayListBox.Items{si(i)+it} ' COPY'];
                    ex = 0;
                    while ex == 0
                        [~,~,si_2] = intersect(tmpNm,app.overlayListBox.Items);
                        if ~isempty(si_2)
                            tmpNm = [app.overlayListBox.Items{si(i)+it} ' COPY'];
                        else
                            ex = 1;
                        end
                    end

                    %app.overlayListBox.Items = [app.overlayListBox.Items(1:si(i)+it) app.overlayListBox.Items(si(i)+it) app.overlayListBox.Items(si(i)+1+it:end)];
                    app.overlayListBox.Items = [app.overlayListBox.Items(1:si(i)+it-1) tmpNm app.overlayListBox.Items(si(i)+it) app.overlayListBox.Items(si(i)+1+it:end)];
                    app.overlay.names = [app.overlay.names(1:si(i)+it) app.overlay.names(si(i)+it) app.overlay.names(si(i)+1+it:end)];
                    app.overlay.paths = [app.overlay.paths(1:si(i)+it) app.overlay.paths(si(i)+it) app.overlay.paths(si(i)+1+it:end)];
                    app.overlay.bm = [app.overlay.bm(1:si(i)+it) app.overlay.bm(si(i)+it) app.overlay.bm(si(i)+1+it:end)];
                    app.overlay.bmB = [app.overlay.bmB(1:si(i)+it) app.overlay.bmB(si(i)+it) app.overlay.bmB(si(i)+1+it:end)];
                    app.overlay.settings = [app.overlay.settings(1:si(i)+it) app.overlay.settings(si(i)+it) app.overlay.settings(si(i)+1+it:end)];
                    it=it+1;
                end
                app.modOverlaySelect.Items = app.overlayListBox.Items;
                app.TwoD1.Items = app.overlayListBox.Items;
                app.TwoD2.Items = app.overlayListBox.Items;

                app.ThreeD1.Items = app.overlayListBox.Items;
                app.ThreeD2.Items = app.overlayListBox.Items;
                app.ThreeD3.Items = app.overlayListBox.Items;
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in some overlays','Action unavailable');
            end
        end

        % Button pushed function: screenshotButton
        function screenshotButtonPush(app, event)
            if isgraphics(app.brainFig,'figure')
                [si,s] = getListSelect(app,'overlayListBox',1);

                if isempty(si)
                    nm = 'Untitled';
                    pth = pwd;
                else
                    tmp = app.overlay.names(si);
                    tmp2 = app.overlay.paths(si);
                    for i = 1:length(tmp)
                        [p n e] = fileparts([tmp2{i} tmp{i}]);
                        if i == 1
                            pth = p;
                        end
                        if i ~= length(tmp)
                            tmp3{i} = [n '_AND_'];
                        else
                            tmp3{i} = n;
                        end
                    end
                    nm = strcat(tmp3{:});
                end
                [oFile, oPath] = uiputfile({'*.png'},'Screenshot of current overlay',[pth app.pths.sep nm]);
                saveas(app.brainFig,[oPath oFile]);
                if strcmpi(app.ci,'true')
                    cropImages({[oPath oFile]},'false',1)
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: DeletealloverlaysMenu
        function deleteAllOverlay(app, event)
            if length(app.overlayListBox.Items) > 1
                app.overlayListBox.Items(2:end) = [];
                app.overlay.names(2:end) = [];
                app.overlay.paths(2:end) = [];
                app.overlay.bm(2:end) = [];
                app.overlay.bmB(2:end) = [];
                app.overlay.settings(2:end) = [];
                [app] = overPatch(app,'false','false','true');

                for i = 2:length(app.overlay.settings)
                    app.overlay.settings{i}.modData = [];
                    app.overlay.settings{i}.modName = 'No overlay';
                    app.overlay.settings{i}.absToggleMod = 'On';
                    app.overlay.settings{i}.incZeroToggleMod = 'Off';
                    app.overlay.settings{i}.minAlphaMod = 0;
                    app.overlay.settings{i}.maxAlphaMod = 1;
                end

                app.modOverlaySelect.Items = app.overlayListBox.Items;
                app.TwoD1.Items = app.overlayListBox.Items;
                app.TwoD2.Items = app.overlayListBox.Items;

                app.ThreeD1.Items = app.overlayListBox.Items;
                app.ThreeD2.Items = app.overlayListBox.Items;
                app.ThreeD3.Items = app.overlayListBox.Items;
            end
        end

        % Menu selected function: CombinedsavedCIFTIoverlaysMenu
        function combineCIFTI(app, event)
            [f,p,~] = uigetfile('*.nii','Select exactly two CIFTI files (a left and a right hemisphere)','MultiSelect','on');

            if length(f) == 2
                for i = 1:length(f)
                    try
                        ld = cifti_read([p f{i}]);
                    catch
                        uialert(app.brainSurferUIFigure,'Could not load file--maybe it is not CIFTI?');
                        %error('This is not a CIFTI file')
                    end

                    c = cell2mat(ld.diminfo{1}.models);
                    c = {c.struct};

                    l = find(contains(c,'CORTEX_LEFT'));
                    r = find(contains(c,'CORTEX_RIGHT'));

                    s1 = sum(ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1));
                    s2 = sum(ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1));

                    if i == 1 & ((s1 == 0 & s2 ~= 0) | (s2 == 0 & s1 ~= 0))
                        template = ld;
                        if s1 == 0 & s2 ~= 0
                            rep = 'lh';
                        else
                            rep = 'rh';
                        end
                    elseif i == 2 & ((s1 == 0 & s2 ~= 0) | (s2 == 0 & s1 ~= 0))
                        if strcmp(rep,'lh') & (s1 ~= 0 & s2 == 0)
                            template.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                        elseif strcmp(rep,'rh') & (s1 == 0 & s2 ~= 0)
                            template.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                        else
                            uialert(app.brainSurferUIFigure,['Wrong file? Expecting to replace other hemisphere in file: ' f{i}],'Invalid file selections');
                        end
                    else
                        uialert(app.brainFig,['Both hemispheres have data in file: ' f{i}],'Invalid file selections');
                    end
                end

                [~,f,e] = fileparts([p f{i}]);
                f = strrep(f,'rightHemi','');
                f = strrep(f,'leftHemi','');
                [oFile, oPath] = uiputfile({e},['Save combined CIFTI file: ' f(1:end-1)],[p f(1:end-1) e]);
                cifti_write(template,[oPath oFile]);
            else
                uialert(app.brainSurferUIFigure,'You either less than or more than 2 files!','Invalid file selections');
            end
        end

        % Value changed function: smoothButton
        function smoothButtonPress(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                [~,ia,~] = intersect(app.overlayListBox.Items,app.overlayListBox.Value);
                if ia ~= 1
                    a = app.smoothArea.Value;
                    s = app.smoothSteps.Value;
                    sw = app.smoothButton.Value;
                    as = app.assignSwitch.Value;

                    for i = 1:length(ia)
                        app.overlay.settings{ia(i)}.smoothArea = a;
                        app.overlay.settings{ia(i)}.smoothSteps = s;
                        app.overlay.settings{ia(i)}.smoothButton = sw;
                        app.overlay.settings{ia(i)}.assignSwitch = as;
                        if app.smoothButton.Value == 0
                            app.overlay.bm(ia(i)) = app.overlay.bmB(ia(i));
                        end
                    end

                    app = overPatch(app,'true','false','true');

                    app.smoothArea.Value = a;
                    app.smoothSteps.Value = s;
                    app.smoothButton.Value = sw;

                    if  app.smoothButton.Value == 1
                        app.valueSelection.Value = 'Raw';
                        app.posSwitch.Value = 'On';
                        app.negSwitch.Value = 'On';
                        app.remZeroSwitch.Value = 'Off';
                        app.limMinOver.Value = min(app.overlay.bm{ia(i)});
                        app.limMaxOver.Value = max(app.overlay.bm{ia(i)});
                        mid = mean([app.limMinOver.Value app.limMaxOver.Value]);
                        app.lOver.Position = [mid 0; mid 0]; % but make this midpoint instead...
                        app.lowerThreshBox.Value = mid;
                        app.upperThreshBox.Value = mid;
                        app.clusterSlider.Value = 0;
                        app.clusterThreshBox.Value = 0;

                        app.overlay.settings{ia(i)}.valueSelection = 'Raw';
                        app.overlay.settings{ia(i)}.posSwitch = 'On';
                        app.overlay.settings{ia(i)}.negSwitch = 'On';
                        app.overlay.settings{ia(i)}.remZeroSwitch = 'Off';
                        app.overlay.settings{ia(i)}.limMinOver = app.limMinOver.Value;
                        app.overlay.settings{ia(i)}.limMaxOver = app.limMaxOver.Value;

                        app.overlay.settings{ia(i)}.lowerThreshBox = app.lowerThreshBox.Value;
                        app.overlay.settings{ia(i)}.upperThreshBox = app.upperThreshBox.Value;
                        app.overlay.settings{ia(i)}.clusterSlider = 0;
                        app.overlay.settings{ia(i)}.clusterThreshBox = 0;
                    end
                else
                    app.smoothButtonSurf.Value = 0;
                    uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface and select one overlay','Action unavailable');
                end
            end
        end

        % Menu selected function: ChangeFontSizeMenu
        function changeListBxSz(app, event)
            fntSz = inputdlg('Enter a font size for the list box','Font change',1,{'10'});
            if ~isempty(fntSz)
                app.overlayListBox.FontSize = str2double(fntSz{1});
            end
        end

        % Menu selected function: SpecificlocationMenu
        function viewSpecific(app, event)
            if isgraphics(app.brainFig,'figure')
                v = inputdlg('Change view of brain to specific coordinates','View change',1,{'-90 0'});
                if ~isempty(v)
                    v = str2num(v{1});
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end
                    view(tmp,v)
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: TrimoverlaynamelengthsMenu
        function changeOverlayLngth(app, event)
            tmp = inputdlg('Enter maximum string length','String change',1,{'115'});
            if ~isempty(tmp)
                app.nmL = tmp;
                for i = 1:length(app.overlayListBox.Items)
                    if length(app.overlay.names{i}) > str2double(app.nmL)
                        app.overlayListBox.Items{i} = app.overlay.names{i}(1:str2double(app.nmL));
                    else
                        app.overlayListBox.Items{i} = app.overlay.names{i};
                    end
                end
            end
        end

        % Menu selected function: 
        % TakemultiplestandardizedscreenshotsofalloverlaysMenu
        function screenshotButtonAllPush(app, event)
            if isgraphics(app.brainFig,'figure')
                delete(findall(app.brainFig,'Type','hggroup'));
                for si = 2:length(app.overlayListBox.Items)
                    app.overlayListBox.Value = app.overlayListBox.Items(si);
                    [app] = overPatch(app,'false','false','true');
                    nm = app.overlay.names{si};
                    pth = app.overlay.paths{si};
                    [~, n, e] = fileparts(nm);
                    if strcmp(e,'.gz') & strcmp(n(end-3:end),'.nii')
                        n = n(1:end-4);
                        e = '.png';
                    else
                        e = '.png';
                    end

                    ex = 1;
                    appen = '_copy_';
                    appnd = 0;
                    while ex == 1
                        if ~exist([pth n e],'file')
                            ex=0;
                        else
                            n = [n appen];
                            appnd = 1;
                        end
                    end
                    if appnd == 1
                        n = n(1:end-1);
                    end

                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end

                    view(tmp,[-90 0]);

                    if isfield(app.underlay,'left')
                        tmpO = [pth n '_leftLateralView' e];
                    else
                        tmpO = [pth n '_rightMedialView' e];
                    end
                    saveas(app.brainFig,tmpO);
                    if strcmpi(app.ci,'true')
                        cropImages({tmpO},'false',1)
                    end

                    view(tmp,[90 0]);
                    if isfield(app.underlay,'right')
                        tmpO = [pth n '_rightLateralView' e];
                    else
                        tmpO = [pth n '_leftMedialView' e];
                    end
                    saveas(app.brainFig,tmpO);
                    if strcmpi(app.ci,'true')
                        cropImages({tmpO},'false',1)
                    end

                    view(tmp,[90 -90]);
                    saveas(app.brainFig,[pth n '_inferiorView' e]);
                    if strcmpi(app.ci,'true')
                        cropImages({[pth n '_inferiorView' e]},'false',1)
                    end

                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'off';
                        view(tmp,[-90 0]);
                        saveas(app.brainFig,[pth n '_rightMedialView' e]);
                        if strcmpi(app.ci,'true')
                            cropImages({[pth n '_rightMedialView' e]},'false',1)
                        end
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'on';
                    end

                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'off';

                        view(tmp,[90 0]);
                        saveas(app.brainFig,[pth n '_leftMedialView' e]);
                        if strcmpi(app.ci,'true')
                            cropImages({[pth n '_leftMedialView' e]},'false',1)
                        end
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'on';
                    end
                end
                app.overlayListBox.Value = app.overlayListBox.Items(1);
                [app] = overPatch(app,'false','false','true');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: 
        % TakemultiplestandardizedscreenshotsofsomeoverlaysMenu
        function screenshotButtonSomePush(app, event)
            if isgraphics(app.brainFig,'figure')
                delete(findall(app.brainFig,'Type','hggroup'));
                init = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    init = [init; sit];
                end
                app.overlaySelectionApp = oSelect(app,' to screenshot');

                post = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    post = [post; sit];
                end
                if init ~= post
                    [app] = overPatch(app,'false','false','true');
                end
            end
        end

        % Menu selected function: 
        % TakemultiplestandardizedscreenshotsofselectedoverlaysMenu
        function screenshotButtonSelectedPush(app, event)
            if isgraphics(app.brainFig,'figure')
                delete(findall(app.brainFig,'Type','hggroup'));
                [si,s] = getListSelect(app,'overlayListBox',1);

                if isempty(si)
                    nm = 'Untitled';
                    pth = pwd;
                else
                    tmp = app.overlay.names(si);
                    tmp2 = app.overlay.paths(si);
                    for i = 1:length(tmp)
                        [p n e] = fileparts([tmp2{i} tmp{i}]);
                        if i == 1
                            pth = p;
                        end
                        if i ~= length(tmp)
                            tmp3{i} = [n '_AND_'];
                        else
                            tmp3{i} = n;
                        end
                    end
                    nm = strcat(tmp3{:});
                end
                [oFile, oPath] = uiputfile({'*.png'},'Screenshot of current overlay',[pth app.pths.sep nm]);

                if ~isempty(oPath)
                    tmp = findall(app.brainFig.Children,'Type','Axes');
                    if length(tmp) > 1
                        tmp=tmp(end);
                    end

                    view(tmp,[-90 0]);
                    saveas(app.brainFig,[oPath oFile(1:end-4) '_leftLateralView.png']);
                    if strcmpi(app.ci,'true')
                        cropImages({[oPath oFile(1:end-4) '_leftLateralView.png']},'false',1)
                    end

                    view(tmp,[90 0]);
                    saveas(app.brainFig,[oPath oFile(1:end-4) '_rightLateralView.png']);
                    if strcmpi(app.ci,'true')
                        cropImages({[oPath oFile(1:end-4) '_rightLateralView.png']},'false',1)
                    end

                    view(tmp,[90 -90]);
                    saveas(app.brainFig,[oPath oFile(1:end-4) '_inferiorView.png']);
                    if strcmpi(app.ci,'true')
                        cropImages({[oPath oFile(1:end-4) '_inferiorView.png']},'false',1)
                    end

                    if isfield(app.underlay,'left')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'off';
                        view(tmp,[-90 0]);
                        saveas(app.brainFig,[oPath oFile(1:end-4) '_rightMedialView.png']);
                        if strcmpi(app.ci,'true')
                            cropImages({[oPath oFile(1:end-4) '_rightMedialView.png']},'false',1)
                        end
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'right')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.left.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.left.Visible = 'on';
                    end

                    if isfield(app.underlay,'right')
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'off';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'off';

                        view(tmp,[90 0]);
                        saveas(app.brainFig,[oPath oFile(1:end-4) '_leftMedialView.png']);
                        if strcmpi(app.ci,'true')
                            cropImages({[oPath oFile(1:end-4) '_leftMedialView.png']},'false',1)
                        end
                        for i = 1:length(app.currOver)
                            if isfield(app.underlay,'left')
                                tmpL = intersect(app.currOver{i}.Vertices,app.underlay.right.Vertices,'rows');
                                if ~isempty(tmpL)
                                    app.currOver{i}.Visible = 'on';
                                end
                            end
                        end
                        app.underlay.right.Visible = 'on';
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load in a surface','Action unavailable');
            end
        end

        % Button pushed function: copyButton
        function copyButtonPush(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmp(app.overlayListBox.Value,app.overlayListBox.Items{1})
                init = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    init = [init; sit];
                end
                app.overlaySelectionApp = oSelect(app,' to copy settings into');

                post = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    post = [post; sit];
                end
                if init ~= post
                    [app] = overPatch(app,'false','false','true');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load overlays and select a single overlay.','Action unavailable');
            end
        end

        % Button pushed function: maskButton
        function maskButtonPush(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmp(app.overlayListBox.Value,app.overlayListBox.Items{1})
                init = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    init = [init; sit];
                end
                app.overlaySelectionApp = oSelect(app,' to mask');

                post = [];
                for i = 1:length(app.overlayListBox.Value)
                    [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                    post = [post; sit];
                end
                if init ~= post
                    [app] = overPatch(app,'false','false','true');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load overlays and select a single overlay.','Action unavailable');
            end
        end

        % Context menu opening function: ContextMenu15
        function reloadSomeOverlay(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to reload');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this button until you load overlays and select a single overlay.','Action unavailable');
            end
        end

        % Value changed function: fdrButton
        function fdrCorrButtonPush(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1}) & app.bonfButton.Value ~= 1
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                if app.fdrButton.Value == 1
                    if ~isempty(app.overlay.settings{si}.pMap)
                        [pVecCorr, ~] = mafdr(app.overlay.settings{si}.pMap);
                        app.overlay.settings{si}.pMap = pVecCorr;
                        app.overlay.settings{si}.pFDR = 1;
                    end
                else
                    app.overlay.settings{si}.pMap =  app.overlay.settings{si}.pMapB;
                    app.overlay.settings{si}.pFDR = 0;
                end
                if app.pThreshBox.Value ~= 1
                    app = overPatch(app,'false','false','false');
                end
            else
                app.fdrButton.Value = 0;
                uialert(app.brainSurferUIFigure,'You need to select a single overlay and not have already performed Bonf. correction.','Action unavailable');
            end
        end

        % Value changed function: bonfButton
        function bonfCorrButtonPush(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1}) & app.fdrButton.Value ~= 1
                [~,~,si] = intersect(app.overlayListBox.Value,app.overlayListBox.Items);
                if app.bonfButton.Value == 1
                    if ~isempty(app.overlay.settings{si}.pMap)
                        [pVecCorr,~] = bonf_holm(app.overlay.settings{si}.pMap,0.05);
                        app.overlay.settings{si}.pMap = pVecCorr;
                        app.overlay.settings{si}.pBonf = 1;
                    end
                else
                    app.overlay.settings{si}.pMap =  app.overlay.settings{si}.pMapB;
                    app.overlay.settings{si}.pBonf = 0;
                end
                if app.pThreshBox.Value ~= 1
                    app = overPatch(app,'false','false','false');
                end
            else
                app.bonfButton.Value = 0;
                uialert(app.brainSurferUIFigure,'You need to select a single overlay and not have already performed FDR correction.','Action unavailable');
            end
        end

        % Menu selected function: RandomizecolorsMenu
        function randomizeColorsButton(app, event)
            if isgraphics(app.brainFig,'figure')  & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if ~isempty(app.atlas.overlay.left)
                    c1 = unique(app.atlas.overlay.left.FaceVertexCData,'Rows');
                    c2 = c1(randperm(size(c1,1)),:);

                    [~, id] = ismember(app.atlas.overlay.left.FaceVertexCData,c1,'rows');
                    app.atlas.overlay.left.FaceVertexCData = c2(id,:);

                    cbar = squeeze(app.atlas.cBar.CData);
                    %[~, ia,ib] = intersect(cbar,c1,'rows');
                    [~, id1] = ismember(c1,cbar,'rows');
                    rcbar = cbar;
                    rcbar(id1,:) = c2;
                end
                if ~isempty(app.atlas.overlay.right)
                    c1 = unique(app.atlas.overlay.right.FaceVertexCData,'Rows');

                    if (~isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)) & (isequal(unique(app.atlas.regions.left),unique(app.atlas.regions.right)) & isequal(unique(app.atlas.bm.left),unique(app.atlas.bm.right)))
                        %if (~isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)) & isequal(unique(app.atlas.bm.left),unique(app.atlas.bm.right))
                        [~, id] = ismember(app.atlas.overlay.right.FaceVertexCData,c1,'rows');
                        app.atlas.overlay.right.FaceVertexCData = c2(id,:);
                        rcbar2 = rcbar;
                    else
                        c2 = c1(randperm(size(c1,1)),:);
                        [~, id] = ismember(app.atlas.overlay.right.FaceVertexCData,c1,'rows');
                        app.atlas.overlay.right.FaceVertexCData = c2(id,:);

                        cbar = squeeze(app.atlas.cBar.CData);
                        %[~, ia,ib] = intersect(cbar,c1,'rows');
                        [~, id2] = ismember(c1,cbar,'rows');
                        rcbar2 = cbar;
                        rcbar2(id2,:) = c2;
                    end
                end

                if ~isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)
                    if ~isequal(unique(app.atlas.regions.left),unique(app.atlas.regions.right)) | ~isequal(unique(app.atlas.bm.left),unique(app.atlas.bm.right))
                        cbarf = cbar;
                        cbarf(id1,:) = rcbar(id1,:);
                        cbarf(id2,:) = rcbar2(id2,:);
                    else
                        cbarf = rcbar;
                    end
                elseif ~isempty(app.atlas.overlay.left) & isempty(app.atlas.overlay.right)
                    cbarf = rcbar;

                elseif isempty(app.atlas.overlay.left) & ~isempty(app.atlas.overlay.right)
                    cbarf = rcbar2;
                end

                tmp = zeros([1,size(cbarf)]);
                tmp(1,:,:) = cbarf;

                app.atlas.cBar.CData = tmp;
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Menu selected function: 
        % UsecolorsfromannotationCIFTIheaderforatlasMenu, 
        % UsedefaultcolorsfromatlasifavailableMenu
        function useDefaultColorsAtlas(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))

                if strcmpi(app.borderToggleAtlas.Value,'off')
                    if isempty(app.atlas.defaultColors.left) & isempty(app.atlas.defaultColors.right)
                        uialert(app.brainSurferUIFigure,'It looks like your atlas does not have any default colors we could use','Action unavailable');
                    else
                        %                     if strcmpi(app.borderToggleAtlas.Value,'on')
                        %                         app.borderToggleAtlas.Value = 'Off';
                        %                         updateAtlas(app,'true')
                        %                         redo = 1;
                        %                     else
                        %                         redo = 0;
                        %                     end

                        if ~isempty(app.atlas.defaultCbar.left) & ~isempty(app.atlas.defaultCbar.right)
                            if ~isequal(app.atlas.defaultCbar.left,app.atlas.defaultCbar.right)
                                cbar = [app.atlas.defaultCbar.left; app.atlas.defaultCbar.right];
                            else
                                cbar = [];
                            end
                        end

                        cd = 0;
                        if ~isempty(app.atlas.defaultColors.left)
                            if isempty(cbar)
                                cbar = app.atlas.defaultCbar.left;
                            end
                            if max(app.atlas.defaultColors.left) > 1
                                id = find(app.atlas.bm.left ~= 0);
                                if size(app.atlas.overlay.left.FaceVertexCData,1) < size(app.atlas.defaultColors.left,1)
                                    app.atlas.overlay.left.FaceVertexCData = app.atlas.defaultColors.left(id,:)./256;
                                else
                                    app.atlas.overlay.left.FaceVertexCData = app.atlas.defaultColors.left./256;
                                end
                                cbar = cbar./256;
                                cd = 1;
                            else
                                app.atlas.overlay.left.FaceVertexCData = app.atlas.defaultColors.left;
                            end
                        end
                        if ~isempty(app.atlas.defaultColors.right)
                            if isempty(cbar)
                                cbar = app.atlas.defaultCbar.right;
                            end
                            if max(app.atlas.defaultColors.right) > 1
                                id = find(app.atlas.bm.right ~= 0);
                                if size(app.atlas.overlay.right.FaceVertexCData,1) < size(app.atlas.defaultColors.right,1)
                                    app.atlas.overlay.right.FaceVertexCData = app.atlas.defaultColors.right(id,:)./256;
                                else
                                    app.atlas.overlay.right.FaceVertexCData = app.atlas.defaultColors.right./256;
                                end
                                if cd == 0
                                    cbar = cbar./256;
                                end
                            else
                                app.atlas.overlay.right.FaceVertexCData = app.atlas.defaultColors.right;
                            end
                        end
                        % now patch cbar
                        tmp = zeros([1,size(cbar)]);
                        tmp(1,:,:) = cbar;
                        app.atlas.cBar.CData = tmp;

                        %                     if redo
                        %                         app.borderToggleAtlas.Value = 'On';
                        %                         updateAtlas(app,'true');
                        %                     end
                    end
                else
                    uialert(app.brainSurferUIFigure,'Turn off borders first','Action unavailable');
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Value changed function: invertToggleAtlas
        function invertToggleAtlasChange(app, event)
            updateAtlas(app,'true')
        end

        % Value changed function: toggleColorbarAtlas
        function colorbarToggleChange(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if app.toggleColorbarAtlas.Value == 0
                    app.atlas.cBar.Visible = 'off';
                    app.atlas.cBarAx.Visible = 'off';
                    app.atlas.cBarText.Visible = 'off';
                else
                    app.atlas.cBar.AlphaData = ones(size(app.atlas.cBar.AlphaData)).*app.opacityAtlas.Value;
                    app.atlas.cBar.Visible = 'on';
                    app.atlas.cBarAx.Visible = 'on';
                    app.atlas.cBarText.Visible = 'on';
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Value changed function: selectSpacingAtlas
        function spacingAtlasChange(app, event)
            updateAtlas(app,'true')
        end

        % Value changed function: colormapSelectAtlas
        function colormapAtlasChange(app, event)
            updateAtlas(app,'true')
        end

        % Value changed function: upperLimAtlas
        function upperLimAtlasChange(app, event)
            updateAtlas(app,'true')
        end

        % Value changed function: lowerLimAtlas
        function lowerLimAtlasChange(app, event)
            updateAtlas(app,'true')
        end

        % Value changed function: binAtlas
        function binAtlasChange(app, event)
            updateAtlas(app,'true')
        end

        % Value changed function: opacityAtlas
        function opacityAtlasChange(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if ~isempty(app.atlas.overlay.left)
                    app.atlas.overlay.left.FaceVertexAlphaData = ones(size(app.atlas.overlay.left.FaceVertexAlphaData)) * app.opacityAtlas.Value;
                end
                if ~isempty(app.atlas.overlay.right)
                    app.atlas.overlay.right.FaceVertexAlphaData =  ones(size(app.atlas.overlay.right.FaceVertexAlphaData)) * app.opacityAtlas.Value;
                end
                app.atlas.cBar.AlphaData = ones(size(app.atlas.cBar.AlphaData))*app.opacityAtlas.Value;
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
            %updateAtlas(app,'true')
        end

        % Value changed function: shrinkGrowAtlas
        function shrinkGrowAtlasChange(app, event)
            if strcmpi(app.borderToggleAtlas.Value,'On')
                updateAtlas(app,'true')
            else
                app.shrinkGrowAtlas.Value = 0;
                uialert(app.brainSurferUIFigure,'You can only use this button if borders are on','Action unavailable');
            end
        end

        % Value changed function: borderToggleAtlas
        function borderToggleAtlasChange(app, event)
            updateAtlas(app,'true')
        end

        % Menu selected function: CreatecolormapMenu
        function createColormapButton(app, event)
            app.colorbarCreate = cbarCreator(app);
        end

        % Callback function
        function loadGUIState(app, event)
            
        end

        % Callback function
        function saveGUIState(app, event)
            
        end

        % Callback function
        function importVol2FS(app, event)
            
        end

        % Callback function
        function importVol2s900(app, event)
            
        end

        % Button pushed function: reloadAtlas
        function reloadAtlasButton(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                if ~isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                    f{1} = [app.atlas.path.left app.atlas.name.left]; f{2} = [app.atlas.path.right app.atlas.name.right];
                elseif ~isempty(app.atlas.name.left) & isempty(app.atlas.name.right)
                    f{1} = [app.atlas.path.left app.atlas.name.left];
                elseif isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                    f{1} = [app.atlas.path.right app.atlas.name.right];
                end
                l = app.atlas.regions.left;
                r = app.atlas.regions.right;

                importAtlas(app,f,[]);

                app.atlas.regions.left = l;
                app.atlas.regions.right = r;
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Button pushed function: loadAtlas
        function loadAtlasButton(app, event)
            if isgraphics(app.brainFig,'figure') & (~isempty(app.atlas.bm.left) | ~isempty(app.atlas.bm.right))
                % Ask for files

                [f,p,~] = uigetfile('*','MultiSelect','on');

                % Check if any file was selected
                if p ~= 0
                    if ~iscell(f) % if one file, it will not be in cell array
                        f = {f};
                    end
                    for i = 1:length(f)
                        f{i} = [p f{i}];
                    end
                    if length(f) > 2
                        uialert(app.brainSurferUIFigure,'You can only select two files to load as an atlas (one left and one right hemisphere).','Action unavailable');
                    else

                        importAtlas(app,f,[]);

                    end
                else
                    uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
                end
            end
        end

        % Button pushed function: loadLabels
        function loadLabelsButton(app, event)
            if isgraphics(app.brainFig,'figure')
                % Ask for files
                [f,p,~] = uigetfile({'*.txt;*.xml' 'Text file or Word document';'*.txt' 'Text file'; '*.xml' 'XML document'});

                % Check if any file was selected
                if p > 0
                    [~,n,e] = fileparts([p f]);
                    disp(['Trying to load file: '])

                    if ~isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                        hemi{1} = 'left'; hemi{2} = 'right';
                        %f{1} = [app.atlas.path.left app.atlas.name.left]; f{2} = [app.atlas.path.right app.atlas.name.right];
                    elseif ~isempty(app.atlas.name.left) & isempty(app.atlas.name.right)
                        %f{1} = [app.atlas.path.left app.atlas.name.left];
                        hemi{1} = 'left';
                    elseif isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                        %f{1} = [app.atlas.path.right app.atlas.name.right];
                        hemi{2} = 'right';
                    end

                    for i = 1:length(hemi)
                        % get atlas names
                        switch e
                            case '.xml'
                                if i == 1
                                    xml = xml2struct([p f]);
                                    name = [];
                                    key = [];
                                    for j = 1:length(xml.Children(4).Children)
                                        if ~isempty(xml.Children(4).Children(j).Children)
                                            name = [name; {xml.Children(4).Children(j).Children.Data}];
                                            key = [key; str2double(xml.Children(4).Children(j).Attributes(1).Value)];
                                        end
                                    end
                                end
                                id = find(app.atlas.bm.(hemi{i}) ~= 0);
                                id2 = find(app.atlas.bm.(hemi{i}) == 0);
                                [~, is2] = ismember(app.atlas.bm.(hemi{i}),key);

                                app.atlas.regions.(hemi{i}) = cell(size(app.atlas.bm.(hemi{i})));
                                app.atlas.regions.(hemi{i})(id) = name(is2(id));
                                app.atlas.regions.(hemi{i})(id2) =  {'No region information'};;
                            case '.txt'
                                if i == 1
                                    fid = fopen([p f]);
                                    tline = fgetl(fid);
                                    tlines = cell(0,1);
                                    while ischar(tline)
                                        tlines{end+1,1} = tline;
                                        tline = fgetl(fid);
                                    end
                                    fclose(fid);
                                end
                                id = find(app.atlas.bm.(hemi{i}) ~= 0);
                                id2 = find(app.atlas.bm.(hemi{i}) == 0);
                                app.atlas.regions.(hemi{i}) = cell(size(app.atlas.bm.(hemi{i})));
                                app.atlas.regions.(hemi{i})(id) = tlines(app.atlas.bm.(hemi{i})(id));
                                app.atlas.regions.(hemi{i})(id2) = {'No region information'};;
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Menu selected function: EditlightlocationsMenu
        function editLightLocationsButton(app, event)
            uialert(app.brainSurferUIFigure,'This button does not work yet but will in the next update. If you would like to edit lights, you can do so manullay by changing them in ./scripts/defaultLights.txt','Action unavailable');
        end

        % Value changed function: clustDataKind
        function clustDataKindChange(app, event)
            %value = app.clustDataKind.Value;
            [si,s] = getListSelect(app,'overlayListBox',1);

            if length(si) > 1
                bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                app.clustSurf.YLabel.String = 'Multiple overlays selected';
                app.clustTable.Data = [];

                % else update GUI parameters
            elseif length(si) == 1
                if ~isempty(app.overlay.settings{si}.clusters)
                    clst = app.overlay.settings{si}.clusters;
                    cSz = cellfun('length',clst);
                    id = find(cSz < app.overlay.settings{si}.clusterThreshBox);
                    cSz(id) = [];
                    clst(id) = [];
                    dt = app.overlay.bm{si};
                    id = find(ismember(app.underlay.(app.overlay.settings{si}.hemi).Vertices,app.currOver{1}.Vertices,'rows'));
                    s = setdiff(1:size(app.underlay.(app.overlay.settings{si}.hemi).Vertices,1),id);

                    dt(s) = NaN;
                    tb(:,5) = cSz;
                    for clusti = 1:length(cSz)
                        verts = clst{clusti};
                        vertsC = app.underlay.((app.overlay.settings{si}.hemi)).Vertices(verts,:);
                        %dt = app.overlay.bm{si}(clst{clusti});
                        [mVal,mId] = max(dt(clst{clusti}));
                        tb(clusti,1) = verts(mId);
                        tb(clusti,2) = vertsC(mId,1);
                        tb(clusti,3) = vertsC(mId,2);
                        tb(clusti,4) = vertsC(mId,3);
                        tb(clusti,6) = mean(dt(clst{clusti}),'omitnan');
                        tb(clusti,7) = mVal;
                    end
                    app.clustTable.Data = tb;
                    if strfind(app.clustDataKind.Value,'size')
                        bar(app.clustSurf,cSz,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                        app.clustSurf.YLabel.String = 'Size of cluster';
                    elseif strfind(app.clustDataKind.Value,'mean')
                        bar(app.clustSurf,tb(:,6),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                        app.clustSurf.YLabel.String = 'Mean of cluster';
                    elseif strfind(app.clustDataKind.Value,'max')
                        bar(app.clustSurf,tb(:,7),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                        app.clustSurf.YLabel.String = 'Max of cluster';
                    end
                else
                    bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                    app.clustSurf.YLabel.String = 'Clusters not yet generated for overlay';
                    app.clustTable.Data = [];
                end
            end
        end

        % Button pushed function: saveTableButton
        function saveTableButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                [si,s] = getListSelect(app,'overlayListBox',1);

                if si ~= 1
                    [f,p,e] = fileparts([app.overlay.paths{si} app.overlay.names{si}]);
                    if strcmpi(e,'.gz')
                        tmpO = [app.overlay.paths{si} p(1:end-4) '_thr_' num2str(app.overlay.settings{si}.lowerThreshBox) '_' num2str(app.overlay.settings{si}.upperThreshBox) '_clustThresh_' num2str(app.overlay.settings{si}.clusterThreshBox) '_TABLE.txt'];
                    else
                        tmpO = [app.overlay.paths{si} p '_thr_' num2str(app.overlay.settings{si}.lowerThreshBox) '_' num2str(app.overlay.settings{si}.upperThreshBox) '_clustThresh_' num2str(app.overlay.settings{si}.clusterThreshBox) '_TABLE.txt'];
                    end

                    if length(si) == 1
                        [f,p] = uiputfile(tmpO);
                        tmptbl = array2table(app.clustTable.Data,'VariableNames',app.clustTable.ColumnName);
                        writetable(tmptbl,[p f],'Delimiter','tab')
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Button pushed function: deleteClustButton
        function deleteClustButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                [si,s] = getListSelect(app,'overlayListBox',1);

                dcm_obj = datacursormode(app.brainFig);
                is = getCursorInfo(dcm_obj);
                if ~isempty(is)
                    for fi = 1:length(si)
                        for j = 1:length(is)
                            if isempty(app.overlay.settings{si(fi)}.clusters)
                                dt = app.overlay.bm{si(fi)};
                                id = find(ismember(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,app.currOver{fi}.Vertices,'rows'));
                                s = setdiff(1:size(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,1),id);
                                dt(s) = 0;

                                atv = find(dt ~= 0);
                                [clusters, ~] = getClusters(atv, app.underlay.(app.overlay.settings{si(fi)}.hemi).Faces);
                                app.overlay.settings{si(fi)}.clusters = clusters;
                            end

                            %[ism1,ism2] = ismember(is(end).Position,is(end).Target.Vertices);

                            allV = app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices;
                            id = find(ismember(allV,is(j).Position,'rows'));
                            c = cellfun(@(x)(ismember(id,x)),app.overlay.settings{si(fi)}.clusters,'UniformOutput',false);
                            c = cell2mat(c);
                            cid = find(c == 1);

                            if ~isempty(cid)
                                toR = app.overlay.settings{si(fi)}.clusters{cid};
                                app.overlay.bm{si(fi)}(toR) = 0;
                                toRV = allV(toR,:);
                                id2 = find(ismember(app.currOver{1}.Vertices,toRV,'rows'));
                                app.currOver{j}.FaceVertexAlphaData(id2) = 0;
                                app.overlay.settings{si(fi)}.clusters(cid) = [];
                            end
                        end
                    end

                    if length(si) > 1
                        bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                        app.clustSurf.YLabel.String = 'Multiple overlays selected';
                        app.clustTable.Data = [];

                        % else update GUI parameters
                    elseif length(si) == 1
                        if ~isempty(app.overlay.settings{si}.clusters)
                            clst = app.overlay.settings{si}.clusters;
                            cSz = cellfun('length',clst);
                            id = find(cSz < app.overlay.settings{si}.clusterThreshBox);
                            cSz(id) = [];
                            clst(id) = [];
                            dt = app.overlay.bm{si};
                            id = find(ismember(app.underlay.(app.overlay.settings{si}.hemi).Vertices,app.currOver{1}.Vertices,'rows'));
                            s = setdiff(1:size(app.underlay.(app.overlay.settings{si}.hemi).Vertices,1),id);

                            dt(s) = NaN;
                            tb(:,5) = cSz;
                            for clusti = 1:length(cSz)
                                verts = clst{clusti};
                                vertsC = app.underlay.((app.overlay.settings{si}.hemi)).Vertices(verts,:);
                                %dt = app.overlay.bm{si}(clst{clusti});
                                [mVal,mId] = max(dt(clst{clusti}));
                                tb(clusti,1) = verts(mId);
                                tb(clusti,2) = vertsC(mId,1);
                                tb(clusti,3) = vertsC(mId,2);
                                tb(clusti,4) = vertsC(mId,3);
                                tb(clusti,6) = mean(dt(clst{clusti}),'omitnan');
                                tb(clusti,7) = mVal;
                            end
                            app.clustTable.Data = tb;
                            if strfind(app.clustDataKind.Value,'size')
                                bar(app.clustSurf,cSz,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Size of cluster';
                            elseif strfind(app.clustDataKind.Value,'mean')
                                bar(app.clustSurf,tb(:,6),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Mean of cluster';
                            elseif strfind(app.clustDataKind.Value,'max')
                                bar(app.clustSurf,tb(:,7),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Max of cluster';
                            end
                        else
                            bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                            app.clustSurf.YLabel.String = 'Clusters not yet generated for overlay';
                            app.clustTable.Data = [];
                        end
                    end
                else
                    uialert(app.brainSurferUIFigure,'You need to place a data tip on the brain first');
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Button pushed function: deleteClustExceptButton
        function deleteAllExceptButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                [si,s] = getListSelect(app,'overlayListBox',1);

                dcm_obj = datacursormode(app.brainFig);
                is = getCursorInfo(dcm_obj);

                if ~isempty(is)
                    for fi = 1:length(si)
                        toRAll = [];
                        toRRaw = [];
                        cidAll = [];
                        allV = app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices;
                        if isempty(app.overlay.settings{si(fi)}.clusters)
                            dt = app.overlay.bm{si(fi)};
                            id = find(ismember(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,app.currOver{fi}.Vertices,'rows'));
                            s = setdiff(1:size(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,1),id);
                            dt(s) = 0;

                            atv = find(dt ~= 0);
                            [clusters, ~] = getClusters(atv, app.underlay.(app.overlay.settings{si(fi)}.hemi).Faces);
                            app.overlay.settings{si(fi)}.clusters = clusters;
                        end
                        for j = 1:length(is)
                            id = find(ismember(allV,is(j).Position,'rows'));
                            c = cellfun(@(x)(ismember(id,x)),app.overlay.settings{si(fi)}.clusters,'UniformOutput',false);
                            c = cell2mat(c);
                            cid = find(c == 1);

                            if ~isempty(cid)
                                toR = app.overlay.settings{si(fi)}.clusters{cid};
                                toRV = allV(toR,:);
                                id2 = find(ismember(app.currOver{fi}.Vertices,toRV,'rows'));
                                toRAll = [toRAll; id2];
                                toRRaw = [toRRaw; toR];
                                cidAll = [cidAll; cid];
                            end
                        end
                        sd = setdiff([1:size(app.currOver{fi}.Vertices,1)],toRAll);
                        sd2 = setdiff([1:size(allV,1)],toRRaw);
                        app.overlay.settings{si(fi)}.clusters = app.overlay.settings{si(fi)}.clusters(cidAll);
                        app.currOver{fi}.FaceVertexAlphaData(sd) = 0;
                        app.overlay.bm{si(fi)}(sd2) = 0;
                    end

                    if length(si) > 1
                        bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                        app.clustSurf.YLabel.String = 'Multiple overlays selected';
                        app.clustTable.Data = [];

                        % else update GUI parameters
                    elseif length(si) == 1
                        if ~isempty(app.overlay.settings{si}.clusters)
                            clst = app.overlay.settings{si}.clusters;
                            cSz = cellfun('length',clst);
                            id = find(cSz < app.overlay.settings{si}.clusterThreshBox);
                            cSz(id) = [];
                            clst(id) = [];
                            dt = app.overlay.bm{si};
                            id = find(ismember(app.underlay.(app.overlay.settings{si}.hemi).Vertices,app.currOver{1}.Vertices,'rows'));
                            s = setdiff(1:size(app.underlay.(app.overlay.settings{si}.hemi).Vertices,1),id);

                            dt(s) = NaN;
                            tb(:,5) = cSz;
                            for clusti = 1:length(cSz)
                                verts = clst{clusti};
                                vertsC = app.underlay.((app.overlay.settings{si}.hemi)).Vertices(verts,:);
                                %dt = app.overlay.bm{si}(clst{clusti});
                                [mVal,mId] = max(dt(clst{clusti}));
                                tb(clusti,1) = verts(mId);
                                tb(clusti,2) = vertsC(mId,1);
                                tb(clusti,3) = vertsC(mId,2);
                                tb(clusti,4) = vertsC(mId,3);
                                tb(clusti,6) = mean(dt(clst{clusti}),'omitnan');
                                tb(clusti,7) = mVal;
                            end
                            app.clustTable.Data = tb;
                            if strfind(app.clustDataKind.Value,'size')
                                bar(app.clustSurf,cSz,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Size of cluster';
                            elseif strfind(app.clustDataKind.Value,'mean')
                                bar(app.clustSurf,tb(:,6),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Mean of cluster';
                            elseif strfind(app.clustDataKind.Value,'max')
                                bar(app.clustSurf,tb(:,7),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Max of cluster';
                            end
                        else
                            bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                            app.clustSurf.YLabel.String = 'Clusters not yet generated for overlay';
                            app.clustTable.Data = [];
                        end
                        tog = updateGUI(app,'overlay','false','false');
                    end
                else
                    uialert(app.brainSurferUIFigure,'You need to place a data tip on the brain first');
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Button pushed function: assignClustValButton
        function assignClustValButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                [si,s] = getListSelect(app,'overlayListBox',1);

                dcm_obj = datacursormode(app.brainFig);
                is = getCursorInfo(dcm_obj);

                if ~isempty(is)
                    val = inputdlg('Enter value you would like to assign to cluster','Cluster value change',1,{'0'});
                    for fi = 1:length(si)
                        for j = 1:length(is)
                            if isempty(app.overlay.settings{si(fi)}.clusters)
                                dt = app.overlay.bm{si(fi)};
                                id = find(ismember(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,app.currOver{fi}.Vertices,'rows'));
                                s = setdiff(1:size(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,1),id);
                                dt(s) = 0;

                                atv = find(dt ~= 0);
                                [clusters, ~] = getClusters(atv, app.underlay.(app.overlay.settings{si(fi)}.hemi).Faces);
                                app.overlay.settings{si(fi)}.clusters = clusters;
                            end

                            %[ism1,ism2] = ismember(is(end).Position,is(end).Target.Vertices);

                            allV = app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices;
                            id = find(ismember(allV,is(j).Position,'rows'));
                            c = cellfun(@(x)(ismember(id,x)),app.overlay.settings{si(fi)}.clusters,'UniformOutput',false);
                            c = cell2mat(c);
                            cid = find(c == 1);

                            if ~isempty(cid) & ~isempty(val)
                                toR = app.overlay.settings{si(fi)}.clusters{cid};
                                app.overlay.bm{si(fi)}(toR) = str2double(val{1});
                                toRV = allV(toR,:);
                                id2 = find(ismember(app.currOver{1}.Vertices,toRV,'rows'));

                                cMap = squeeze(app.cBar{j}.CData);

                                switch app.overlay.settings{si(fi)}.selectSpacing
                                    % this is the default option. All values between limits will get a
                                    % color from your colormap.
                                    case 'even'
                                        cbData = linspace(app.overlay.settings{si(fi)}.limMinOver,app.overlay.settings{si(fi)}.limMaxOver,app.overlay.settings{si(fi)}.binBox);

                                    case 'center on zero'
                                        cbDataNeg = linspace(app.overlay.settings{si(fi)}.limMinOver,0,(app.overlay.settings{si(fi)}.binBox/2)+1);
                                        cbDataPos = linspace(0,app.overlay.settings{si(fi)}.limMaxOver,app.overlay.settings{si(fi)}.binBox/2);
                                        cbData = horzcat(cbDataNeg,cbDataPos);
                                        cbData(round((length(cbData)/2)+1)) = [];

                                    case 'center on threshold'
                                        % find out if you have both positive and negative values
                                        posTest = find(app.overlay.bm{si(fi)} > 0);
                                        negTest = find(app.overlay.bm{si(fi)} < 0);

                                        cbDataNeg = linspace(app.overlay.settings{si(fi)}.limMinOver,app.overlay.settings{si(fi)}.lowerThreshBox,(app.overlay.settings{si(fi)}.binBox/2)+1);
                                        cbDataPos = linspace(app.overlay.settings{si(fi)}.upperThreshBox,app.overlay.settings{si(fi)}.limMaxOver,app.overlay.settings{si(fi)}.binBox/2);
                                        cbData = horzcat(cbDataNeg,cbDataPos);
                                        cbData(round((length(cMap)/2)+1)) = [];

                                end
                                id = find(cbData >= str2double(val{1}));
                                %tmpC = cMap(id(1),:);
                                app.currOver{j}.FaceVertexCData(id2,1) = cMap(id(1),1);
                                app.currOver{j}.FaceVertexCData(id2,2) = cMap(id(1),2);
                                app.currOver{j}.FaceVertexCData(id2,3) = cMap(id(1),3);
                                %app.currOver{j}.FaceVertexAlphaData(id2) = 0;
                                %app.overlay.settings{si(fi)}.clusters(cid) = [];
                            end
                            app.overlay.settings{si(fi)}.lowerThreshBox = 0;
                            app.overlay.settings{si(fi)}.upperThreshBox = 0;
                        end
                    end

                    if length(si) > 1
                        bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                        app.clustSurf.YLabel.String = 'Multiple overlays selected';
                        app.clustTable.Data = [];

                        % else update GUI parameters
                    elseif length(si) == 1
                        if ~isempty(app.overlay.settings{si}.clusters)
                            clst = app.overlay.settings{si}.clusters;
                            cSz = cellfun('length',clst);
                            id = find(cSz < app.overlay.settings{si}.clusterThreshBox);
                            cSz(id) = [];
                            clst(id) = [];
                            dt = app.overlay.bm{si};
                            id = find(ismember(app.underlay.(app.overlay.settings{si}.hemi).Vertices,app.currOver{1}.Vertices,'rows'));
                            s = setdiff(1:size(app.underlay.(app.overlay.settings{si}.hemi).Vertices,1),id);

                            dt(s) = NaN;
                            tb(:,5) = cSz;
                            for clusti = 1:length(cSz)
                                verts = clst{clusti};
                                vertsC = app.underlay.((app.overlay.settings{si}.hemi)).Vertices(verts,:);
                                %dt = app.overlay.bm{si}(clst{clusti});
                                [mVal,mId] = max(dt(clst{clusti}));
                                tb(clusti,1) = verts(mId);
                                tb(clusti,2) = vertsC(mId,1);
                                tb(clusti,3) = vertsC(mId,2);
                                tb(clusti,4) = vertsC(mId,3);
                                tb(clusti,6) = mean(dt(clst{clusti}),'omitnan');
                                tb(clusti,7) = mVal;
                            end
                            app.clustTable.Data = tb;
                            if strfind(app.clustDataKind.Value,'size')
                                bar(app.clustSurf,cSz,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Size of cluster';
                            elseif strfind(app.clustDataKind.Value,'mean')
                                bar(app.clustSurf,tb(:,6),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Mean of cluster';
                            elseif strfind(app.clustDataKind.Value,'max')
                                bar(app.clustSurf,tb(:,7),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Max of cluster';
                            end
                        else
                            bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                            app.clustSurf.YLabel.String = 'Clusters not yet generated for overlay';
                            app.clustTable.Data = [];
                        end
                        updateGUI(app,'overlay','false','false');
                    end
                else
                    uialert(app.brainSurferUIFigure,'You need to place a data tip on the brain first');
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Button pushed function: assignClustColButton
        function assignClustColButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                [si,s] = getListSelect(app,'overlayListBox',1);

                dcm_obj = datacursormode(app.brainFig);
                is = getCursorInfo(dcm_obj);

                if ~isempty(is)
                    clr = uisetcolor;

                    for fi = 1:length(si)
                        for j = 1:length(is)
                            if isempty(app.overlay.settings{si(fi)}.clusters)
                                dt = app.overlay.bm{si(fi)};
                                id = find(ismember(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,app.currOver{fi}.Vertices,'rows'));
                                s = setdiff(1:size(app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices,1),id);
                                dt(s) = 0;

                                atv = find(dt ~= 0);
                                [clusters, ~] = getClusters(atv, app.underlay.(app.overlay.settings{si(fi)}.hemi).Faces);
                                app.overlay.settings{si(fi)}.clusters = clusters;
                            end

                            %[ism1,ism2] = ismember(is(end).Position,is(end).Target.Vertices);

                            allV = app.underlay.(app.overlay.settings{si(fi)}.hemi).Vertices;
                            id = find(ismember(allV,is(j).Position,'rows'));
                            c = cellfun(@(x)(ismember(id,x)),app.overlay.settings{si(fi)}.clusters,'UniformOutput',false);
                            c = cell2mat(c);
                            cid = find(c == 1);

                            if ~isempty(cid) & ~isempty(clr)
                                toR = app.overlay.settings{si(fi)}.clusters{cid};
                                %app.overlay.bm{si(fi)}(toR) = 0;
                                toRV = allV(toR,:);
                                id2 = find(ismember(app.currOver{1}.Vertices,toRV,'rows'));
                                %app.currOver{j}.FaceVertexAlphaData(id2) = 0;
                                app.currOver{j}.FaceVertexCData(id2,1) = clr(:,1);
                                app.currOver{j}.FaceVertexCData(id2,2) = clr(:,2);
                                app.currOver{j}.FaceVertexCData(id2,3) = clr(:,3);
                                %app.overlay.settings{si(fi)}.clusters(cid) = [];
                            end
                        end
                    end

                    if length(si) > 1
                        bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                        app.clustSurf.YLabel.String = 'Multiple overlays selected';
                        app.clustTable.Data = [];

                        % else update GUI parameters
                    elseif length(si) == 1
                        if ~isempty(app.overlay.settings{si}.clusters)
                            clst = app.overlay.settings{si}.clusters;
                            cSz = cellfun('length',clst);
                            id = find(cSz < app.overlay.settings{si}.clusterThreshBox);
                            cSz(id) = [];
                            clst(id) = [];
                            dt = app.overlay.bm{si};
                            id = find(ismember(app.underlay.(app.overlay.settings{si}.hemi).Vertices,app.currOver{1}.Vertices,'rows'));
                            s = setdiff(1:size(app.underlay.(app.overlay.settings{si}.hemi).Vertices,1),id);

                            dt(s) = NaN;
                            tb(:,5) = cSz;
                            for clusti = 1:length(cSz)
                                verts = clst{clusti};
                                vertsC = app.underlay.((app.overlay.settings{si}.hemi)).Vertices(verts,:);
                                %dt = app.overlay.bm{si}(clst{clusti});
                                [mVal,mId] = max(dt(clst{clusti}));
                                tb(clusti,1) = verts(mId);
                                tb(clusti,2) = vertsC(mId,1);
                                tb(clusti,3) = vertsC(mId,2);
                                tb(clusti,4) = vertsC(mId,3);
                                tb(clusti,6) = mean(dt(clst{clusti}),'omitnan');
                                tb(clusti,7) = mVal;
                            end
                            app.clustTable.Data = tb;
                            if strfind(app.clustDataKind.Value,'size')
                                bar(app.clustSurf,cSz,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Size of cluster';
                            elseif strfind(app.clustDataKind.Value,'mean')
                                bar(app.clustSurf,tb(:,6),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Mean of cluster';
                            elseif strfind(app.clustDataKind.Value,'max')
                                bar(app.clustSurf,tb(:,7),'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                                app.clustSurf.YLabel.String = 'Max of cluster';
                            end
                        else
                            bar(app.clustSurf,0,'FaceColor',[0.850980392156863 0.325490196078431 0.0980392156862745]);
                            app.clustSurf.YLabel.String = 'Clusters not yet generated for overlay';
                            app.clustTable.Data = [];
                        end
                    end
                else
                    uialert(app.brainSurferUIFigure,'You need to place a data tip on the brain first');
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Menu selected function: LighterModeMenu
        function lighterModeButton(app, event)
            props = properties(app);

            for i = 1:length(props)
                thisprop = props{i};
                if ~strcmp(thisprop,'NooverlayPanel') || ~strcmp(thisprop,'lowerThreshBoxSurf') || ~strcmp(thisprop,'upperThreshBoxSurf') ||  ~strcmp(thisprop,'sgThresh') || ~strcmp(thisprop,'valueSelectionSurf') || ~strcmp(thisprop,'sulcButton') || ~strcmp(thisprop,'gyrButton') || ~strcmp(thisprop,'limMinSurf') || ~strcmp(thisprop,'limMaxSurf') || ~strcmp(thisprop,'binBoxSurf')|| ~strcmp(thisprop,'opacityBoxSurf')|| ~strcmp(thisprop,'smoothAreaUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')|| ~strcmp(thisprop,'smoothStepsUnder')
                    %%%Add logic here if you want to work with select properties
                    if isprop(app.(thisprop),'BackgroundColor') == 1
                        app.(thisprop).BackgroundColor = [1,1,1];
                    end
                    if isprop(app.(thisprop),'ForegroundColor') == 1
                        if ~contains(lower(thisprop),'menu')
                            app.(thisprop).ForegroundColor = [0 0 0];
                        end
                    end
                    if isprop(app.(thisprop),'FontColor') == 1
                        %if ~strfind(thisprop,'menu')
                        app.(thisprop).FontColor = [0 0 0];
                        %end
                    end
                else
                    app.(thisprop).BackgroundColor = [1 1 1];
                    app.(thisprop).ForegroundColor = [0,0,0];
                end
            end
            app.axSurf.XAxis.Color = [0 0 0];
            app.axOver.XAxis.Color = [0 0 0];
            app.histSurf.XColor = [0 0 0];
            app.histSurf.YColor = [0 0 0];
            app.histSurf.Color = [1 1 1];
            set(app.brainFig,'color','w');
        end

        % Menu selected function: 
        % CreateoverlayfromareaonlastdatatipMenu_2, 
        % CreateoverlayfromatlasareaonlastdatatipMenu
        function overlayFromROI(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                dcm_obj = datacursormode(app.brainFig);
                is = getCursorInfo(dcm_obj);
                if ~isempty(is)
                    if ~isempty(app.atlas.overlay.left)
                        [~,~,vId] = intersect(is(1).Position,app.underlay.left.Vertices,'rows');
                        if ~isempty(vId)
                            d = zeros(size(app.atlas.bm.left));
                            id = find(app.atlas.bm.left == app.atlas.bm.left(vId));
                            d(id) = 1;
                            nm = app.atlas.regions.left(vId);
                            hemi = 'left';
                        end
                    else
                        nm =[];
                    end
                    if isempty(vId)
                        if ~isempty(app.atlas.overlay.left)
                            [~,~,vId] = intersect(is(1).Position,app.underlay.right.Vertices,'rows');
                            if ~isempty(vId)
                                d = zeros(size(app.atlas.bm.right));
                                id = find(app.atlas.bm.right == app.atlas.bm.right(vId));
                                d(id) = 1;
                                nm = app.atlas.regions.right(vId);
                                hemi = 'right';
                            end
                        end
                    end
                    if ~isempty(nm)
                        app.overlayListBox.Items = horzcat(app.overlayListBox.Items, nm);
                        app.modOverlaySelect.Items = app.overlayListBox.Items;
                        app.TwoD1.Items = app.overlayListBox.Items;
                        app.TwoD2.Items = app.overlayListBox.Items;
                        app.ThreeD1.Items = app.overlayListBox.Items;
                        app.ThreeD2.Items = app.overlayListBox.Items;
                        app.ThreeD3.Items = app.overlayListBox.Items;

                        app.overlay.names = horzcat(app.overlay.names, {nm{1}});
                        app.overlay.paths = horzcat(app.overlay.paths, pwd);
                        app.overlay.bm = horzcat(app.overlay.bm, d);
                        app.overlay.bmB = horzcat(app.overlay.bmB, d);
                        app.overlay.bm = horzcat(app.overlay.settings, d);
                        app.overlay.settings = horzcat(app.overlay.settings,app.defaultSetOver);
                        app.overlay.settings{end}.hemi = hemi;
                        app.overlay.settings{end}.limMinOver = min(d);
                        app.overlay.settings{end}.limMaxOver = max(d);
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
            end
        end

        % Value changed function: modOverlaySelect
        function modOverlaySelectChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                if strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    app.modOverlaySelect.Value = app.modOverlaySelect.Items{1};
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    [app] = transpModInteraction(app);
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.modOverlaySelect.Value = app.modOverlaySelect.Items{1};
            end
        end

        % Value changed function: maxAlphaMod
        function maxAlphaModChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                if strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    app.maxAlphaMod.Value = 1;
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    [app] = transpModInteraction(app);
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.minAlphaMod.Value = 1;
            end
        end

        % Value changed function: minAlphaMod
        function minAlphaModChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                if strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    app.minAlphaMod.Value = 0;
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    [app] = transpModInteraction(app);
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.minAlphaMod.Value = 0;
            end
        end

        % Value changed function: TwoD1
        function TwoD1Change(app, event)
            %value = app.TwoD1.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [si,s] = getListSelect(app,'overlayListBox',1);

                if si == 1
                    app.TwoD1.Value = app.TwoD1.Items{1};
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    if (~strcmpi(app.TwoD1.Value,app.TwoD1.Items{1}) & ~strcmpi(app.TwoD2.Value,app.TwoD2.Items{1})) & (~strcmpi(app.TwoD1.Value,app.TwoD2.Value))
                        [~,~,si2] = intersect(app.TwoD1.Value,app.TwoD1.Items);
                        [~,~,si3] = intersect(app.TwoD2.Value,app.TwoD2.Items);
                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1))
                            app.hist3D.Title.String = '';
                            delete(app.hist3D.Children);
                            app.hist3D.XLabel.String = '';
                            app.hist3D.YLabel.String = '';
                            app.hist3D.ZLabel.String = '';
                            app.hist3D.Title.String = '';
                            try
                                app.hist3D.XTickLabels = [];
                                app.hist3D.YTickLabels = [];
                                app.hist3D.ZTickLabels = [];
                            catch
                                app.hist3D.XTickLabel = [];
                                app.hist3D.YTickLabel = [];
                                app.hist3D.ZTickLabel = [];
                            end
                            app.degreeDrop.Value =  app.degreeDrop.Items{1};
                            app.axisDrop.Value =  app.axisDrop.Items{1};
                            app.overlay.settings{si}.multiCmapTicks = [];
                            app.overlay.settings{si}.multiCbData  = [];
                            app.overlay.settings{si}.multiData = [];
                            app.overlay.settings{si}.ThreeDim1Name = [];
                            app.overlay.settings{si}.ThreeDim2Name = [];
                            app.overlay.settings{si}.ThreeDim3Name = [];
                            app.ThreeD1.Value = app.ThreeD1.Items{1};
                            app.ThreeD2.Value = app.ThreeD2.Items{1};
                            app.ThreeD3.Value = app.ThreeD3.Items{1};

                            [app] = twoDMap(app,'false');
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end

                    if (~strcmpi(app.TwoD1.Value,app.TwoD1.Items{1}) & strcmpi(app.TwoD2.Value,app.TwoD2.Items{1})) | (strcmpi(app.TwoD1.Value,app.TwoD1.Items{1}) & ~strcmpi(app.TwoD2.Value,app.TwoD2.Items{1}))
                        app.hist2D.Title.String = '';
                        delete(app.hist2D.Children);
                        app.hist2D.XLabel.String = '';
                        app.hist2D.YLabel.String = '';
                        app.hist2D.Title.String = '';
                        
                        try
                            app.hist2D.XTickLabels = [];
                            app.hist2D.YTickLabels = [];
                        catch
                            app.hist2D.XTickLabel = [];
                            app.hist2D.YTickLabel = [];
                        end
                        
                        if ~isempty(app.overlay.settings{si}.multiData)
                            % repatch
                            app.overlay.settings{si}.multiCmapTicks = [];
                            app.overlay.settings{si}.multiCbData  = [];
                            app.overlay.settings{si}.multiData = [];
                            app.overlay.settings{si}.TwoDim1Name = [];
                            app = overPatch(app,'false','false','false');
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.TwoD1.Value = app.TwoD1.Items{1};
            end
        end

        % Value changed function: TwoD2
        function TwoD2Change(app, event)
            %value = app.TwoD2.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [si,s] = getListSelect(app,'overlayListBox',1);

                if si == 1
                    app.TwoD2.Value = app.TwoD2.Items{1};
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    if (~strcmpi(app.TwoD1.Value,app.TwoD1.Items{1}) & ~strcmpi(app.TwoD2.Value,app.TwoD2.Items{1})) & (~strcmpi(app.TwoD1.Value,app.TwoD2.Value))
                        [~,~,si2] = intersect(app.TwoD1.Value,app.TwoD1.Items);
                        [~,~,si3] = intersect(app.TwoD2.Value,app.TwoD2.Items);
                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1))

                            app.hist3D.Title.String = '';
                            delete(app.hist3D.Children);
                            app.hist3D.XLabel.String = '';
                            app.hist3D.YLabel.String = '';
                            app.hist3D.ZLabel.String = '';
                            app.hist3D.Title.String = '';
                            try
                                app.hist3D.XTickLabels = [];
                                app.hist3D.YTickLabels = [];
                                app.hist3D.ZTickLabels = [];
                            catch
                                app.hist3D.XTickLabel = [];
                                app.hist3D.YTickLabel = [];
                                app.hist3D.ZTickLabel = [];
                            end
                            app.degreeDrop.Value =  app.degreeDrop.Items{1};
                            app.axisDrop.Value =  app.axisDrop.Items{1};
                            app.overlay.settings{si}.multiCmapTicks = [];
                            app.overlay.settings{si}.multiCbData  = [];
                            app.overlay.settings{si}.multiData = [];
                            app.overlay.settings{si}.ThreeDim1Name = [];
                            app.overlay.settings{si}.ThreeDim2Name = [];
                            app.overlay.settings{si}.ThreeDim3Name = [];
                            app.ThreeD1.Value = app.ThreeD1.Items{1};
                            app.ThreeD2.Value = app.ThreeD2.Items{1};
                            app.ThreeD3.Value = app.ThreeD3.Items{1};

                            [app] = twoDMap(app,'false');
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end

                    if (~strcmpi(app.TwoD1.Value,app.TwoD1.Items{1}) & strcmpi(app.TwoD2.Value,app.TwoD2.Items{1})) | (strcmpi(app.TwoD1.Value,app.TwoD1.Items{1}) & ~strcmpi(app.TwoD2.Value,app.TwoD2.Items{1}))
                        app.hist2D.Title.String = '';
                        delete(app.hist2D.Children);
                        app.hist2D.XLabel.String = '';
                        app.hist2D.YLabel.String = '';
                        app.hist2D.Title.String = '';
                        
                        try
                            app.hist2D.XTickLabels = [];
                            app.hist2D.YTickLabels = [];
                        catch
                            app.hist2D.XTickLabel = [];
                            app.hist2D.YTickLabel = [];
                        end
                        
                        if ~isempty(app.overlay.settings{si}.multiData)
                            % repatch
                            app.overlay.settings{si}.multiCmapTicks = [];
                            app.overlay.settings{si}.multiCbData  = [];
                            app.overlay.settings{si}.multiData = [];
                            app.overlay.settings{si}.TwoDim2Name = [];
                            app = overPatch(app,'false','false','false');
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.TwoD2.Value = app.TwoD2.Items{1};
            end
        end

        % Callback function
        function TwoDim2MaxChange(app, event)
            
        end

        % Callback function
        function TwoDim2MinChange(app, event)
            
        end

        % Callback function
        function TwoDim1MaxChange(app, event)
            
        end

        % Value changed function: absToggleMod
        function absToggleModPush(app, event)
            %value = app.absToggleMod.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                if strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    app.absToggleMod.Value = 'On';
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    [app] = transpModInteraction(app);
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.absToggleMod.Value = 'On';
            end
        end

        % Value changed function: modLimMin
        function modLimMinChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                if strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    app.modLimMin.Value = 0;
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    [app] = transpModInteraction(app);
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.modLimMin.Value = 0;
            end
        end

        % Value changed function: modLimMax
        function modLimMaxChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                if strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    app.modLimMax.Value = 0;
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    [app] = transpModInteraction(app);
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.modLimMax.Value = 0;
            end
        end

        % Callback function
        function TwoDim1MinChange(app, event)
            
        end

        % Value changed function: ndcolorbaronxaxisButton
        function secondColorbarChange(app, event)
            %value = app.ndcolorbaronxaxisButton.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                [si,s] = getListSelect(app,'overlayListBox',1);

                if strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                    app.ndcolorbaronxaxisButton.Value = 0;
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    if (~strcmpi(app.TwoD1.Value,app.TwoD1.Items{1}) & ~strcmpi(app.TwoD2.Value,app.TwoD2.Items{1})) & (~strcmpi(app.TwoD1.Value,app.TwoD2.Value))
                        [~,~,si2] = intersect(app.TwoD1.Value,app.TwoD1.Items);
                        [~,~,si3] = intersect(app.TwoD2.Value,app.TwoD2.Items);
                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1))
                            [app] = twoDMap(app,'false');
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.ndcolorbaronxaxisButton.Value = 0;
            end
        end

        % Button pushed function: rotatecolorsButton
        function rotateColorbarChange(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                [si,s] = getListSelect(app,'overlayListBox',1);

                if ~isempty(app.overlay.settings{si}.multiDataColorMapped)
                    [app] = twoDMap(app,'true');
                else
                    uialert(app.brainSurferUIFigure,'You need to have just patched a 2D colormap for this overlay','Action unavailable');
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
            end
        end

        % Menu selected function: 
        % AllfiguresexceptthoseactivelypatchedbybrainSurferMenu
        function deleteBrainFigs(app, event)
            af = findobj(0, 'type', 'figure');
            if isgraphics(app.brainFig,'figure')
                delete(setdiff(af, app.brainFig.Number));
            else
                delete(af);
            end
        end

        % Callback function
        function saveGUIStateButton(app, event)
            
        end

        % Menu selected function: SaveGUIstateMenu
        function saveGUIPress(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                for i = 1:length(app.saveProp)
                    saveState.(app.saveProp{i}) = app.(app.saveProp{i});
                end
                saveState.list = app.overlayListBox.Items;
                [f, p] = uiputfile({'*.mat'},'Pick a name for your GUI save state',pwd);
                if p ~= 0
                    save([p f]','saveState')
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas to use this button','Action unavailable');
            end
        end

        % Menu selected function: LoadGUIstateMenu
        function loadGUIPress(app, event)
            [f,p,~] = uigetfile('*.mat','Select a GUI save state file','MultiSelect','off');
            if p ~= 0
                l = load([p f]);
                f = fieldnames(l);

                for i = 1:length(app.saveProp)
                    app.(app.saveProp{i}) = l.(f{1}).(app.saveProp{i});
                end
                app.overlayListBox.Items = l.(f{1}).list;

                % remove previous bars, etc
                if ~isempty(app.currOver)
                    for i = 1:length(app.currOver)
                        try
                            delete(app.currOver{i})
                            delete(app.cBar{i})
                            delete(app.cBarAx{i})
                            delete(app.cBarText{i})
                        catch
                        end
                    end
                    app.currOver = [];
                    app.cBar = [];
                    app.cBarAx = [];
                end

                if isfield(app.underlay,'left') & isfield(app.underlay,'right')
                    [app.underlay, app.brainFig, ~, ~] = patchUnderlay(app.underlay.left.Vertices, app.underlay.left.Faces, app.underlay.right.Vertices, app.underlay.right.Faces,'lights',app.dlights);
                    [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sgB.left, 'rh', app.sgB.right);
                elseif isfield(app.underlay,'left') & ~isfield(app.underlay,'right')
                    [app.underlay, app.brainFig, ~, ~] = patchUnderlay(app.underlay.left.Vertices, app.underlay.left.Faces,'lights',app.dlights);
                    [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay, 'lh', app.sgB.left);
                elseif ~isfield(app.underlay,'left') & isfield(app.underlay,'right')
                    [app.underlay, app.brainFig, ~, ~] = patchUnderlay(app.underlay.right.Vertices, app.underlay.right.Faces,'lights',app.dlights);
                    [app.underlay, app.colormapSG, app.ticksSG] = patchUnderlaySG(app.underlay,'rh', app.sgB.right);
                end

                app.overlayListBox.Value = app.overlayListBox.Items{1};
                tog = updateGUI(app,'underlay','true','false');

                if ~isempty(app.atlas.bm.left) & ~isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.left; app.atlas.bm.right];
                elseif isempty(app.atlas.bm.left) & ~isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.right];
                elseif ~isempty(app.atlas.bm.left) & isempty(app.atlas.bm.right)
                    tog = [app.atlas.bm.left];
                end

                % update GUI
                app.upperLimAtlas.Value = max(tog);
                app.lowerLimAtlas.Value = min(tog);
                app.colormapSelectAtlas.Value = 'jet';
                app.selectSpacingAtlas.Value = 'even';
                app.binAtlas.Value = (max(tog) - min(tog) +1);
                app.shrinkGrowAtlas.Value = 0;
                app.toggleColorbarAtlas.Value = 0;
                app.invertToggleAtlas.Value = 'Off';
                app.opacityAtlas.Value = 0;
                app.borderToggleAtlas.Value = 'Off';
                app.TwoD1.Items = app.overlayListBox.Items;
                app.TwoD2.Items = app.overlayListBox.Items;
                app.ThreeD1.Items = app.overlayListBox.Items;
                app.ThreeD2.Items = app.overlayListBox.Items;
                app.ThreeD3.Items = app.overlayListBox.Items;
                app.modOverlaySelect.Items = app.overlayListBox.Items;

                updateAtlas(app,'false')
                datacursormode(app.brainFig,'on');
                dc = datacursormode(app.brainFig);
                dc.Enable = 'on';
                dc.UpdateFcn = @(src,evnt)dcDisplay(app,src,evnt);

                if ~isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                    [s,id] = setdiff(app.atlas.name.left,app.atlas.name.right,'stable');
                    tmp = app.atlas.name.left;
                    tmp(id) = [];
                    atNm = tmp;
                elseif isempty(app.atlas.name.left) & ~isempty(app.atlas.name.right)
                    atNm = app.atlas.name.right;
                elseif ~isempty(app.atlas.name.left) & isempty(app.atlas.name.right)
                    atNm = app.atlas.name.left;
                end

                id = strfind(atNm,'_');
                atNm(id) = ' ';
                if length(atNm) > 50
                    atNm = atNm(1:50);
                end

                app.LoadedatlasLabel.Text = [app.LoadedatlasLabel.Text atNm];
            end
        end

        % Menu selected function: 
        % SavecurrentcolorbaregrandomizeddefaultfromatlasMenu
        function saveAtlasCBar(app, event)
            customColor = squeeze(app.atlas.cBar.CData);
            [oFile, oPath] = uiputfile({'*.mat'},'Pick a name for your colormap',[app.pths.clrmpPth app.pths.sep 'colormap']);
            save([oPath oFile]','customColor');
            app.colormapSelector.Items = [app.colormapSelector.Items oFile(1:end-4)];
            app.colormapSelectorSurf.Items = [app.colormapSelectorSurf.Items oFile(1:end-4)];
            app.colormapSelectAtlas.Items = [app.colormapSelectAtlas.Items oFile(1:end-4)];
            app.colormapSelectAtlas.Value = app.colormapSelectAtlas.Items{end};
            app.userColor = [app.userColor customColor];
        end

        % Menu selected function: ReassignvalueonlastdatatipMenu
        function reassignValButton(app, event)
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Items) > 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [si,s] = getListSelect(app,'overlayListBox',1);

                dcm_obj = datacursormode(app.brainFig);
                is = getCursorInfo(dcm_obj);
                if ~isempty(is)
                    [~,~,vId] = intersect(is(1).Position,app.underlay.(app.overlay.settings{si}.hemi).Vertices,'rows');
                    if ~isempty(app.overlay.settings{si}.threshSmoothData)
                        o = app.overlay.settings{si}.threshSmoothData(vId);
                        d = app.overlay.settings{si}.threshSmoothData;
                    else
                        o = app.overlay.bm{si}(vId);
                        d = app.overlay.bm{si};
                    end

                    n = inputdlg(['What should the new value be? (current value is: ' num2str(o) ')'],'Reassign value');
                    if ~isempty(n)
                        id = find(d == o);
                        if ~isempty(app.overlay.settings{si}.threshSmoothData)
                            app.overlay.settings{si}.threshSmoothData(id) = str2double(n{1});
                        end
                        app.overlay.bm{si}(id) = str2double(n{1});
                    end
                    [app] = overPatch(app,'false','false','false');
                else
                    uialert(app.brainSurferUIFigure,'You need to set a data tip','Action unavailable');
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and load an atlas and select an overlay to use this button','Action unavailable');
            end
        end

        % Menu selected function: Fsaveragetocaretfs_LRMenu
        function fsaverageToCaretButton(app, event)
            % load the fsaverage files, convert them to gifti, then pass
            % them into fsaverage2fsLR
            [f,p,~] = uigetfile('*','Select fsaverage files to register to caret fs_LR','MultiSelect','on');
            oP = p;

            % Check if any file was selected
            if p ~= 0
                if ~iscell(f) % if one file, it will not be in cell array
                    f = {f};
                end

                % load files now...
                for i = 1:length(f)
                    [hemi,hemi2,d,d2,sz,ci] = loadBrainData(app,[p  app.pths.sep f{i}]);
                    if ~isempty(hemi) & isempty(hemi2)
                        template = gifti([app.pths.scrptPth app.pths.sep 'fileTemplate' app.pths.sep 'template.gii']);
                        template.cdata = d;

                        [p n ~] = fileparts([oP f{i}]);
                        e = '.gii';
                        gF = [oP n e];
                        save(template,gF);

                        [hemi,d,tmpOut] = fsaverage2fsLR(app,gF,hemi,d);
                    elseif ~isempty(hemi) & ~isempty(hemi2)
                        template = gifti([app.pths.scrptPth app.pths.sep 'fileTemplate' app.pths.sep 'template.gii']);
                        template.cdata = d;

                        [p n ~] = fileparts([oP f{i}]);
                        e = '.gii';
                        gF = [oP n '.' hemi(1) '' e];
                        save(template,gF);

                        [hemi,d,tmpOut] = fsaverage2fsLR(app,gF,hemi,d);

                        template.cdata = d2;
                        e = '.gii';
                        gF = [oP n '.' hemi2(1) '' e];
                        save(template,gF);

                        [hemi,d,tmpOut] = fsaverage2fsLR(app,gF,hemi2,d2);


                    end
                end
            end
        end

        % Menu selected function: Caretfs_LRtofsaverageMenu
        function caretToFsaverageButton(app, event)
            [f,p,~] = uigetfile('*','Select fsaverage files to register to caret fs_LR','MultiSelect','on');
            oP = p;

            % Check if any file was selected
            if p ~= 0
                if ~iscell(f) % if one file, it will not be in cell array
                    f = {f};
                end

                % load files now...
                for i = 1:length(f)
                    [hemi,hemi2,d,d2,sz,ci] = loadBrainData(app,[p  app.pths.sep f{i}]);

                    if ~isempty(hemi) & isempty(hemi2)
                        template = gifti([app.pths.scrptPth app.pths.sep 'fileTemplate' app.pths.sep 'template.gii']);
                        template.cdata = d;

                        [p n ~] = fileparts([oP f{i}]);
                        e = '.gii';
                        gF = [oP n e];
                        save(template,gF);

                        [hemi,d,tmpOut] = LR2fsaverage(app,gF,hemi,d);
                    elseif ~isempty(hemi) & ~isempty(hemi2)
                        template = gifti([app.pths.scrptPth app.pths.sep 'fileTemplate' app.pths.sep 'template.gii']);
                        template.cdata = d;

                        [p n ~] = fileparts([oP f{i}]);
                        e = '.gii';
                        gF = [oP n '.' hemi(1) '' e];
                        save(template,gF);
                        [hemi,d,tmpOut] = LR2fsaverage(app,gF,hemi,d);

                        template.cdata = d2;
                        gF = [oP n '.' hemi2(1) '' e];
                        save(template,gF);
                        [hemi2,d2,tmpOut2] = LR2fsaverage(app,gF,hemi2,d2);
                    end
                end
            end

        end

        % Menu selected function: Freesurfernativetocaretfs_LRMenu
        function fsNativeToCaretButton(app, event)
            t = 'freesurfer native to caret (fs_LR) conversion';
            fs_white_ok = uiconfirm(app.brainSurferUIFigure,'This conversion option requires a number of native-level surface files before we can even start. Click continue to select the first file, which is the subject-level native white surface (e.g., ?h.white)',t,...
                'Options',{'Continue','Cancel'},'DefaultOption',1,'CancelOption',2);
            if strcmpi(fs_white_ok,'Continue')
                [fs_whiteF,fs_whiteP,~] = uigetfile('*','Select subject-level white surface','MultiSelect','off');
                fs_white = [fs_whiteP fs_whiteF];
                fs_pial_ok = uiconfirm(app.brainSurferUIFigure,'Click continue to select the second file, which is the subject-level pial surface (e.g., ?h.pial)',t,...
                    'Options',{'Continue','Cancel'},'DefaultOption',1,'CancelOption',2);
                if strcmpi(fs_pial_ok,'Continue')
                    [fs_pialF,fs_pialP,~] = uigetfile('*','Select subject-level pial surface','MultiSelect','off');
                    fs_pial = [fs_pialP fs_pialF];
                    free_currSphere_ok = uiconfirm(app.brainSurferUIFigure,'Click continue to select the third file, which is the subject-level sphere (e.g., ?h.sphere.reg)',t,...
                        'Options',{'Continue','Cancel'},'DefaultOption',1,'CancelOption',2);
                    if strcmpi(free_currSphere_ok,'Continue')
                        [free_currSphereF,free_currSphereP,~] = uigetfile('*','Select subject-level sphere','MultiSelect','off');
                        free_currSphere = [free_currSphereP free_currSphereF];
                        midCurrOut_ok = uiconfirm(app.brainSurferUIFigure,'Click continue to specify a file name for the current midthickness file we are generating (e.g., ?h.midthickness.surf.gii)',t,...
                            'Options',{'Continue','Cancel'},'DefaultOption',1,'CancelOption',2);
                        if strcmpi(midCurrOut_ok,'Continue')
                            [midCurrOutF,midCurrOutP,~] = uiputfile('*','Specify name for current midthickness surface we generate');
                            midCurrOut = [midCurrOutP midCurrOutF];
                            midNewOut_ok = uiconfirm(app.brainSurferUIFigure,'Click continue to specify a file name for the new midthickness file we are generating (e.g., ?h.midthickness.???k_fs_LR.surf.gii)',t,...
                                'Options',{'Continue','Cancel'},'DefaultOption',1,'CancelOption',2);
                            if strcmpi(midNewOut_ok,'Continue')
                                [midNewOutF,midNewOutP,~] = uiputfile('*','Specify name for the new midthickness surface we generate');
                                midNewOut = [midNewOutP midNewOutF];
                                currSphereOut_ok = uiconfirm(app.brainSurferUIFigure,'Click continue to specify a file name for the gifti sphere we are generating (e.g., ?h.sphere.reg.surf.gii)',t,...
                                    'Options',{'Continue','Cancel'},'DefaultOption',1,'CancelOption',2);
                                if strcmpi(currSphereOut_ok,'Continue')
                                    [currSphereOutF,currSphereOutP,~] = uiputfile('*','Specify name for gifti sphere we generate');
                                    currSphereOut = [currSphereOutP currSphereOutF];
                                    toCon_ok = uiconfirm(app.brainSurferUIFigure,'Click continue to specify the file you would like to convert to caret (fs_LR)',t,...
                                        'Options',{'Continue','Cancel'},'DefaultOption',1,'CancelOption',2);
                                    if strcmpi(toCon_ok,'Continue')
                                        [toConF,toConP,~] = uigetfile('*','Specify the file you want to convert to fs_LR','MultiSelect','off');
                                        toCon = [toConP toConF];
                                        conF_ok = uiconfirm(app.brainSurferUIFigure,'Finally, click continue to specify an output file name for the converted file',t,...
                                            'Options',{'Continue','Cancel'},'DefaultOption',1,'CancelOption',2);
                                        if strcmpi(conF_ok,'Continue')
                                            [conF,conP,~] = uiputfile('*.gii','Specify the output file name for the converted file (fs_LR)');
                                            con = [conP conF];
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end

            [~,n,e] = fileparts(con);
            if ~strcmpi(e,'.gii')
                [hemi,hemi2,d,d2,sz,ci] = loadBrainData(app,con);
                if ~isempty(hemi) & isempty(hemi2)
                    template = gifti([app.pths.scrptPth app.pths.sep 'fileTemplate' app.pths.sep 'template.gii']);
                    template.cdata = d;
                    e = '.gii';
                    gF = [conP n e];
                    save(template,gF);

                    app = fsNative2fsLR(app,con,toCon,currSphereOut,midNewOut,midCurrOut,free_currSphere,fs_pial,fs_white);
                elseif ~isempty(hemi) & ~isempty(hemi2)
                    template = gifti([app.pths.scrptPth app.pths.sep 'fileTemplate' app.pths.sep 'template.gii']);
                    template.cdata = d;

                    e = '.gii';
                    gF = [conP n '.' hemi(1) '' e];
                    save(template,gF);
                    fsNative2fsLR(app,gF,toCon,currSphereOut,midNewOut,midCurrOut,free_currSphere,fs_pial,fs_white);

                    template.cdata = d2;
                    gF = [oP n '.' hemi2(1) '' e];
                    save(template,gF);

                    fsNative2fsLR(app,gF,toCon,currSphereOut,midNewOut,midCurrOut,free_currSphere,fs_pial,fs_white);
                end
            else
                fsNative2fsLR(app,con,toCon,currSphereOut,midNewOut,midCurrOut,free_currSphere,fs_pial,fs_white);
            end
        end

        % Menu selected function: Nativefs_LRtofsaverageMenu
        function caretNativeToFsaverageButton(app, event)
            
        end

        % Value changed function: ThreeD1
        function threeD1Change(app, event)
            %value = app.ThreeD1.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [si,s] = getListSelect(app,'overlayListBox',1);

                if si == 1
                    app.ThreeD1.Value = app.ThreeD1.Items{1};
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    if (~strcmpi(app.ThreeD1.Value,app.ThreeD1.Items{1}) & ~strcmpi(app.ThreeD2.Value,app.ThreeD2.Items{1}) & ~strcmpi(app.ThreeD3.Value,app.ThreeD3.Items{1})) & (~strcmpi(app.ThreeD1.Value,app.ThreeD2.Value) & ~strcmpi(app.ThreeD2.Value,app.ThreeD3.Value) & ~strcmpi(app.ThreeD1.Value,app.ThreeD3.Value))
                        [~,~,si2] = intersect(app.ThreeD1.Value,app.ThreeD1.Items);
                        [~,~,si3] = intersect(app.ThreeD2.Value,app.ThreeD2.Items);
                        [~,~,si4] = intersect(app.ThreeD3.Value,app.ThreeD3.Items);

                        app.hist2D.Title.String = '';
                        delete(app.hist2D.Children);
                        app.hist2D.XLabel.String = '';
                        app.hist2D.YLabel.String = '';
                        app.hist2D.Title.String = '';
                        try
                            app.hist2D.XTickLabels = [];
                            app.hist2D.YTickLabels = [];
                        catch
                            app.hist2D.XTickLabel = [];
                            app.hist2D.YTickLabel = [];
                        end
                        app.overlay.settings{si}.multiCmapTicks = [];
                        app.overlay.settings{si}.multiCbData  = [];
                        app.overlay.settings{si}.multiData = [];
                        app.overlay.settings{si}.TwoDim1Name = [];
                        app.overlay.settings{si}.TwoDim2Name = [];
                        app.TwoD1.Value = app.TwoD1.Items{1};
                        app.TwoD2.Value = app.TwoD2.Items{1};

                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3}) & length(app.overlay.bm{si2}) == length(app.overlay.bm{si4}) & length(app.overlay.bm{si3}) == length(app.overlay.bm{si4})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi) & strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si4}.hemi) & strcmp(app.overlay.settings{si3}.hemi,app.overlay.settings{si4}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1)) & (length(app.overlay.bm{si4}) == size(app.underlay.(app.overlay.settings{si4}.hemi).Vertices,1))
                            [app] = threeDMap(app,'false');
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end

                    if strcmpi(app.ThreeD1.Value,app.ThreeD1.Items{1})
                        app.hist3D.Title.String = '';
                        delete(app.hist3D.Children);
                        app.hist3D.XLabel.String = '';
                        app.hist3D.YLabel.String = '';
                        app.hist3D.ZLabel.String = '';
                        app.hist3D.Title.String = '';
                        app.degreeDrop.Value =  app.degreeDrop.Items{1};
                        app.axisDrop.Value =  app.axisDrop.Items{1};
                        try
                            app.hist3D.XTickLabels = [];
                            app.hist3D.YTickLabels = [];
                            app.hist3D.ZTickLabels = [];
                        catch
                            app.hist3D.XTickLabel = [];
                            app.hist3D.YTickLabel = [];
                            app.hist3D.ZTickLabel = [];
                        end

                        if ~isempty(app.overlay.settings{si}.multiData)
                            % repatch
                            app.overlay.settings{si}.multiCmapTicks = [];
                            app.overlay.settings{si}.multiCbData  = [];
                            app.overlay.settings{si}.multiData = [];
                            app.overlay.settings{si}.ThreeDim1Name = [];
                            app = overPatch(app,'false','false','false');
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.ThreeD1.Value = app.ThreeD1.Items{1};
            end
        end

        % Value changed function: ThreeD2
        function threeD2Change(app, event)
            %value = app.ThreeD2.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [si,s] = getListSelect(app,'overlayListBox',1);

                if si == 1
                    app.ThreeD2.Value = app.ThreeD2.Items{1};
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    if (~strcmpi(app.ThreeD1.Value,app.ThreeD1.Items{1}) & ~strcmpi(app.ThreeD2.Value,app.ThreeD2.Items{1}) & ~strcmpi(app.ThreeD3.Value,app.ThreeD3.Items{1})) & (~strcmpi(app.ThreeD1.Value,app.ThreeD2.Value) & ~strcmpi(app.ThreeD2.Value,app.ThreeD3.Value) & ~strcmpi(app.ThreeD1.Value,app.ThreeD3.Value))
                        [~,~,si2] = intersect(app.ThreeD1.Value,app.ThreeD1.Items);
                        [~,~,si3] = intersect(app.ThreeD2.Value,app.ThreeD2.Items);
                        [~,~,si4] = intersect(app.ThreeD3.Value,app.ThreeD3.Items);

                        app.hist2D.Title.String = '';
                        delete(app.hist2D.Children);
                        app.hist2D.XLabel.String = '';
                        app.hist2D.YLabel.String = '';
                        app.hist2D.Title.String = '';
                        try
                            app.hist2D.XTickLabels = [];
                            app.hist2D.YTickLabels = [];
                        catch
                            app.hist2D.XTickLabel = [];
                            app.hist2D.YTickLabel = [];
                        end
                        app.overlay.settings{si}.multiCmapTicks = [];
                        app.overlay.settings{si}.multiCbData  = [];
                        app.overlay.settings{si}.multiData = [];
                        app.overlay.settings{si}.TwoDim1Name = [];
                        app.overlay.settings{si}.TwoDim2Name = [];
                        app.TwoD1.Value = app.TwoD1.Items{1};
                        app.TwoD2.Value = app.TwoD2.Items{1};

                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3}) & length(app.overlay.bm{si2}) == length(app.overlay.bm{si4}) & length(app.overlay.bm{si3}) == length(app.overlay.bm{si4})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi) & strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si4}.hemi) & strcmp(app.overlay.settings{si3}.hemi,app.overlay.settings{si4}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1)) & (length(app.overlay.bm{si4}) == size(app.underlay.(app.overlay.settings{si4}.hemi).Vertices,1))
                            [app] = threeDMap(app,'false');
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end

                    if strcmpi(app.ThreeD2.Value,app.ThreeD2.Items{1})
                        app.hist3D.Title.String = '';
                        delete(app.hist3D.Children);
                        app.hist3D.XLabel.String = '';
                        app.hist3D.YLabel.String = '';
                        app.hist3D.ZLabel.String = '';
                        app.hist3D.Title.String = '';
                        try
                            app.hist3D.XTickLabels = [];
                            app.hist3D.YTickLabels = [];
                            app.hist3D.ZTickLabels = [];
                        catch
                            app.hist3D.XTickLabel = [];
                            app.hist3D.YTickLabel = [];
                            app.hist3D.ZTickLabel = [];
                        end
                        app.degreeDrop.Value =  app.degreeDrop.Items{1};
                        app.axisDrop.Value =  app.axisDrop.Items{1};
                        if ~isempty(app.overlay.settings{si}.multiData)
                            % repatch
                            app.overlay.settings{si}.multiCmapTicks = [];
                            app.overlay.settings{si}.multiCbData  = [];
                            app.overlay.settings{si}.multiData = [];
                            app.overlay.settings{si}.ThreeDim2Name = [];
                            app = overPatch(app,'false','false','false');
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.ThreeD2.Value = app.ThreeD2.Items{1};
            end
        end

        % Value changed function: ThreeD3
        function threeD3Change(app, event)
            %value = app.ThreeD3.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [si,s] = getListSelect(app,'overlayListBox',1);

                if si == 1
                    app.ThreeD3.Value = app.ThreeD3.Items{1};
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    if (~strcmpi(app.ThreeD1.Value,app.ThreeD1.Items{1}) & ~strcmpi(app.ThreeD2.Value,app.ThreeD2.Items{1}) & ~strcmpi(app.ThreeD3.Value,app.ThreeD3.Items{1})) & (~strcmpi(app.ThreeD1.Value,app.ThreeD2.Value) & ~strcmpi(app.ThreeD2.Value,app.ThreeD3.Value) & ~strcmpi(app.ThreeD1.Value,app.ThreeD3.Value))
                        [~,~,si2] = intersect(app.ThreeD1.Value,app.ThreeD1.Items);
                        [~,~,si3] = intersect(app.ThreeD2.Value,app.ThreeD2.Items);
                        [~,~,si4] = intersect(app.ThreeD3.Value,app.ThreeD3.Items);

                        app.hist2D.Title.String = '';
                        delete(app.hist2D.Children);
                        app.hist2D.XLabel.String = '';
                        app.hist2D.YLabel.String = '';
                        app.hist2D.Title.String = '';
                        try
                            app.hist2D.XTickLabels = [];
                            app.hist2D.YTickLabels = [];
                        catch
                            app.hist2D.XTickLabel = [];
                            app.hist2D.YTickLabel = [];
                        end
                        app.overlay.settings{si}.multiCmapTicks = [];
                        app.overlay.settings{si}.multiCbData  = [];
                        app.overlay.settings{si}.multiData = [];
                        app.overlay.settings{si}.TwoDim1Name = [];
                        app.overlay.settings{si}.TwoDim2Name = [];
                        app.TwoD1.Value = app.TwoD1.Items{1};
                        app.TwoD2.Value = app.TwoD2.Items{1};

                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3}) & length(app.overlay.bm{si2}) == length(app.overlay.bm{si4}) & length(app.overlay.bm{si3}) == length(app.overlay.bm{si4})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi) & strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si4}.hemi) & strcmp(app.overlay.settings{si3}.hemi,app.overlay.settings{si4}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1)) & (length(app.overlay.bm{si4}) == size(app.underlay.(app.overlay.settings{si4}.hemi).Vertices,1))
                            [app] = threeDMap(app,'false');
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end

                    % you only want to repatch original data, if the
                    % current selection was changed to no overlay
                    if strcmpi(app.ThreeD3.Value,app.ThreeD3.Items{1})
                        app.hist3D.Title.String = '';
                        delete(app.hist3D.Children);
                        app.hist3D.XLabel.String = '';
                        app.hist3D.YLabel.String = '';
                        app.hist3D.ZLabel.String = '';
                        app.hist3D.Title.String = '';
                        try
                            app.hist3D.XTickLabels = [];
                            app.hist3D.YTickLabels = [];
                            app.hist3D.ZTickLabels = [];
                        catch
                            app.hist3D.XTickLabel = [];
                            app.hist3D.YTickLabel = [];
                            app.hist3D.ZTickLabel = [];
                        end
                        app.degreeDrop.Value =  app.degreeDrop.Items{1};
                        app.axisDrop.Value =  app.axisDrop.Items{1};
                        if ~isempty(app.overlay.settings{si}.multiData)
                            % repatch
                            app.overlay.settings{si}.multiCmapTicks = [];
                            app.overlay.settings{si}.multiCbData  = [];
                            app.overlay.settings{si}.multiData = [];
                            app.overlay.settings{si}.ThreeDim3Name = [];
                            app = overPatch(app,'false','false','false');
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.ThreeD3.Value = app.ThreeD3.Items{1};
            end
        end

        % Value changed function: numCubes
        function threeDNumCubesChange(app, event)
            %value = app.numCubes.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1 & ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1})
                [si,s] = getListSelect(app,'overlayListBox',1);

                if si == 1
                    app.ThreeD3.Value = app.ThreeD3.Items{1};
                    uialert(app.brainSurferUIFigure,'You need to select an overlay on the right first','Action unavailable');
                else
                    if (~strcmpi(app.ThreeD1.Value,app.ThreeD1.Items{1}) & ~strcmpi(app.ThreeD2.Value,app.ThreeD2.Items{1}) & ~strcmpi(app.ThreeD3.Value,app.ThreeD3.Items{1})) & (~strcmpi(app.ThreeD1.Value,app.ThreeD2.Value) & ~strcmpi(app.ThreeD2.Value,app.ThreeD3.Value) & ~strcmpi(app.ThreeD1.Value,app.ThreeD3.Value))
                        [~,~,si2] = intersect(app.ThreeD1.Value,app.ThreeD1.Items);
                        [~,~,si3] = intersect(app.ThreeD2.Value,app.ThreeD2.Items);
                        [~,~,si4] = intersect(app.ThreeD3.Value,app.ThreeD3.Items);

                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3}) & length(app.overlay.bm{si2}) == length(app.overlay.bm{si4}) & length(app.overlay.bm{si3}) == length(app.overlay.bm{si4})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi) & strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si4}.hemi) & strcmp(app.overlay.settings{si3}.hemi,app.overlay.settings{si4}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1)) & (length(app.overlay.bm{si4}) == size(app.underlay.(app.overlay.settings{si4}.hemi).Vertices,1))
                            app.hist3D.Title.String = '';
                            delete(app.hist3D.Children);
                            app.hist3D.XLabel.String = '';
                            app.hist3D.YLabel.String = '';
                            app.hist3D.ZLabel.String = '';
                            app.hist3D.Title.String = '';
                            try
                                app.hist3D.XTickLabels = [];
                                app.hist3D.YTickLabels = [];
                                app.hist3D.ZTickLabels = [];
                            catch
                                app.hist3D.XTickLabel = [];
                                app.hist3D.YTickLabel = [];
                                app.hist3D.ZTickLabel = [];
                            end
                            app.degreeDrop.Value =  app.degreeDrop.Items{1};
                            app.axisDrop.Value =  app.axisDrop.Items{1};

                            [app] = threeDMap(app,'false');
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
            end
        end

        % Callback function
        function cubeLeftPress(app, event)
            %             app.cubeSlider.Value = app.cubeSpacing.Value;
            %             if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
            %                 [si,s] = getListSelect(app,'overlayListBox',1);
            %
            %
            %                 % to rotate up go through each slice (y-bin) and rotate 90
            %                 % degrees..down is just -90
            %                 % to rotate left, do x-bin and rotate same amount
            %
            %
            %                 % rotate y-axis 90 degrees = right
            %
            %
            %                 strt = app.overlay.settings{si}.multiCmap;
            %                 for i = 1:app.numCubes.Value
            %                     tmp = squeeze(strt(:,i,:,:));
            %                     rt(:,i,:,:) = imrotate(tmp,180);
            %                 end
            %                 for i = 1:app.numCubes.Value
            %                     tmp = squeeze(strt(i,:,:,:));
            %                     rt(i,:,:,:) = imrotate(tmp,180);
            %                 end
            %                 for i = 1:app.numCubes.Value
            %                     tmp = squeeze(strt(:,:,i,:));
            %                     rt(:,:,i,:) = imrotate(tmp,90);
            %                 end
            %
            %                 strt = app.overlay.settings{si}.multiCmap;
            %                 for i = 1:app.numCubes.Value
            %                     tmp = squeeze(strt(i,:,:,:));
            %                     rt(i,:,:,:) = flipud(tmp);
            %                 end
            %
            %                 m = app.numCubes.Value - 1;
            %                 iLen = m:-1:0;
            %                 jLen = m:-1:0;
            %                 kLen = m:-1:0;
            %                 for i = iLen
            %                     for j = jLen
            %                         for k = kLen
            %                             id = sub2ind([m+1,m+1,m+1],i+1,j+1,k+1);
            %                             app.hist3D.Children(id).FaceColor = rt(i+1,j+1,k+1,:);
            %                         end
            %                     end
            %                 end
            %
            %             else
            %                 uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
            %                 app.cubeSpacing.Value = 0.6;
            %             end
        end

        % Menu selected function: SumcombinemapsMenu
        function sumButtonPressed(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to combine (i.e., sum)');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: AverageMenu
        function averageButtonPressed(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to average');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: StddeviationMenu
        function stdDevButtonPressed(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to get standard deviation for');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: StderrorofthemeanMenu
        function semButtonPressed(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to get standard error of the mean for');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: TakeabsolutevalueMenu
        function absValPress(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to get absolute value');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: SubtractMenu
        function subButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to subtract');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: MultiplyMenu
        function multButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to multiply');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: DivideMenu
        function divButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to divide');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: PCAMenu
        function pcaButtonPress(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to PCA');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: RenameoverlayMenu
        function changeOverlayNm(app, event)
            if ~strcmpi(app.overlayListBox.Value,app.overlayListBox.Items{1}) & length(app.overlayListBox.Value) == 1
                [si,s] = getListSelect(app,'overlayListBox',1);
                newNm = inputdlg(['What should the new name be for ' app.overlayListBox.Value{1} '?'],'Renaming overlay');
                if ~isempty(newNm)
                    app.overlayListBox.Items{si} = newNm{1};
                    app.overlayListBox.Value = newNm{1};
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot rename "no overlay" and you must select a single overlay','Action unavailable');
            end
        end

        % Menu selected function: 
        % AddROIsfromanotheroverlaytothiscolormapaspointsMenu
        function addROIsTo2D(app, event)
            if isgraphics(app.brainFig,'figure')
                [si,s] = getListSelect(app,'overlayListBox',1);
                cont = 0;
                for i = 1:length(si)
                    if ~isempty(app.overlay.settings{si(i)}.multiData)
                        cont = 1;
                    end
                end
                if cont
                    app.overlaySelectionApp = oSelect(app,' to use as ROIs for 2D scatter plot');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: InvertpositiveandnegativeMenu
        function invtPosNegPress(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to invert positive and negative values for');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Menu selected function: AlignclusteridentitiesMenu_2
        function alignClustButton(app, event)
            if isgraphics(app.brainFig,'figure')
                app.overlaySelectionApp = oSelect(app,' to align clusters for (must select two)');
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end

        % Value changed function: cubeSpacing
        function cubeSpacingChange(app, event)
            app.cubeSlider.Value = app.cubeSpacing.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1

                [x,y,z] = cube(app.cubeSpacing.Value);
                m = app.numCubes.Value - 1;
                iLen = m:-1:0;
                jLen = m:-1:0;
                kLen = m:-1:0;
                for i = iLen
                    for j = jLen
                        for k = kLen
                            id = sub2ind([m+1,m+1,m+1],i+1,j+1,k+1);
                            app.hist3D.Children(id).XData = i+x;
                            app.hist3D.Children(id).YData = j+y;
                            app.hist3D.Children(id).ZData = k+z;
                        end
                    end
                end

            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.cubeSpacing.Value = 0.6;
            end
        end

        % Value changed function: cubeSlider
        function cubeSpacingSlideChange(app, event)
            app.cubeSpacing.Value = app.cubeSlider.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                try
                    [x,y,z] = cube(app.cubeSpacing.Value);
                    m = app.numCubes.Value - 1;
                    iLen = m:-1:0;
                    jLen = m:-1:0;
                    kLen = m:-1:0;
                    for i = iLen
                        for j = jLen
                            for k = kLen
                                id = sub2ind([m+1,m+1,m+1],i+1,j+1,k+1);
                                app.hist3D.Children(id).XData = i+x;
                                app.hist3D.Children(id).YData = j+y;
                                app.hist3D.Children(id).ZData = k+z;
                            end
                        end
                    end
                catch
                end
            else
                uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                app.cubeSpacing.Value = 0.6;
            end
        end

        % Value changed function: axisDrop
        function axisSelection(app, event)
            %value = app.axisDrop.Value;
            %app.cubeSlider.Value = app.cubeSpacing.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                if ~strcmpi(app.degreeDrop.Value,app.degreeDrop.Items{1})
                    [si,s] = getListSelect(app,'overlayListBox',1);

                    d = str2double(app.degreeDrop.Value);
                    strt = app.overlay.settings{si}.multiCmap;
                    if strcmpi(app.axisDrop.Value,'x')
                        for i = 1:app.numCubes.Value
                            tmp = squeeze(strt(i,:,:,:));
                            rt(i,:,:,:) = imrotate(tmp,d);
                        end
                    elseif strcmpi(app.axisDrop.Value,'y')
                        for i = 1:app.numCubes.Value
                            tmp = squeeze(strt(:,i,:,:));
                            rt(:,i,:,:) = imrotate(tmp,d);
                        end
                    elseif strcmpi(app.axisDrop.Value,'z')
                        for i = 1:app.numCubes.Value
                            tmp = squeeze(strt(:,:,i,:));
                            rt(:,:,i,:) = imrotate(tmp,d);
                        end
                    end

                    %                     m = app.numCubes.Value - 1;
                    %                     iLen = m:-1:0;
                    %                     jLen = m:-1:0;
                    %                     kLen = m:-1:0;
                    %                     for i = iLen
                    %                         for j = jLen
                    %                             for k = kLen
                    %                                 id = sub2ind([m+1,m+1,m+1],i+1,j+1,k+1);
                    %                                 app.hist3D.Children(id).FaceColor = rt(i+1,j+1,k+1,:);
                    %                             end
                    %                         end
                    %                     end

                    % now repatch...
                    if (~strcmpi(app.ThreeD1.Value,app.ThreeD1.Items{1}) & ~strcmpi(app.ThreeD2.Value,app.ThreeD2.Items{1}) & ~strcmpi(app.ThreeD3.Value,app.ThreeD3.Items{1})) & (~strcmpi(app.ThreeD1.Value,app.ThreeD2.Value) & ~strcmpi(app.ThreeD2.Value,app.ThreeD3.Value) & ~strcmpi(app.ThreeD1.Value,app.ThreeD3.Value))
                        [~,~,si2] = intersect(app.ThreeD1.Value,app.ThreeD1.Items);
                        [~,~,si3] = intersect(app.ThreeD2.Value,app.ThreeD2.Items);
                        [~,~,si4] = intersect(app.ThreeD3.Value,app.ThreeD3.Items);
                        app.overlay.settings{si}.multiCmap = rt;
                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3}) & length(app.overlay.bm{si2}) == length(app.overlay.bm{si4}) & length(app.overlay.bm{si3}) == length(app.overlay.bm{si4})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi) & strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si4}.hemi) & strcmp(app.overlay.settings{si3}.hemi,app.overlay.settings{si4}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1)) & (length(app.overlay.bm{si4}) == size(app.underlay.(app.overlay.settings{si4}.hemi).Vertices,1))
                            %                             app.hist3D.Title.String = '';
                            %                             delete(app.hist3D.Children);
                            %                             app.hist3D.XLabel.String = '';
                            %                             app.hist3D.YLabel.String = '';
                            %                             app.hist3D.ZLabel.String = '';
                            %                             app.hist3D.Title.String = '';
                            %                             app.hist3D.XTickLabels = [];
                            %                             app.hist3D.YTickLabels = [];
                            %                             app.hist3D.ZTickLabels = [];
                            %                             app.degreeDrop.Value =  app.degreeDrop.Items{1};
                            %                             app.axisDrop.Value =  app.axisDrop.Items{1};

                            [app] = threeDMap(app,'true');
                            app.degreeDrop.Value =  app.degreeDrop.Items{1};
                            app.axisDrop.Value =  app.axisDrop.Items{1};
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end
                end
            else
                %                 uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                %                 app.cubeSpacing.Value = 0.6;
                app.axisDrop.Value =  app.axisDrop.Items{1};
            end
        end

        % Value changed function: degreeDrop
        function degreeSelection(app, event)
            %value = app.degreeDrop.Value;
            %app.cubeSlider.Value = app.cubeSpacing.Value;
            if isgraphics(app.brainFig,'figure') & length(app.overlayListBox.Value) == 1
                if ~strcmpi(app.axisDrop.Value,app.axisDrop.Items{1})
                    [si,s] = getListSelect(app,'overlayListBox',1);

                    d = str2double(app.degreeDrop.Value);
                    strt = app.overlay.settings{si}.multiCmap;
                    if strcmpi(app.axisDrop.Value,'x')
                        for i = 1:app.numCubes.Value
                            tmp = squeeze(strt(i,:,:,:));
                            rt(i,:,:,:) = imrotate(tmp,d);
                        end
                    elseif strcmpi(app.axisDrop.Value,'y')
                        for i = 1:app.numCubes.Value
                            tmp = squeeze(strt(:,i,:,:));
                            rt(:,i,:,:) = imrotate(tmp,d);
                        end
                    elseif strcmpi(app.axisDrop.Value,'z')
                        for i = 1:app.numCubes.Value
                            tmp = squeeze(strt(:,:,i,:));
                            rt(:,:,i,:) = imrotate(tmp,d);
                        end
                    end

                    %                     m = app.numCubes.Value - 1;
                    %                     iLen = m:-1:0;
                    %                     jLen = m:-1:0;
                    %                     kLen = m:-1:0;
                    %                     for i = iLen
                    %                         for j = jLen
                    %                             for k = kLen
                    %                                 id = sub2ind([m+1,m+1,m+1],i+1,j+1,k+1);
                    %                                 app.hist3D.Children(id).FaceColor = rt(i+1,j+1,k+1,:);
                    %                             end
                    %                         end
                    %                     end

                    % now repatch...
                    if (~strcmpi(app.ThreeD1.Value,app.ThreeD1.Items{1}) & ~strcmpi(app.ThreeD2.Value,app.ThreeD2.Items{1}) & ~strcmpi(app.ThreeD3.Value,app.ThreeD3.Items{1})) & (~strcmpi(app.ThreeD1.Value,app.ThreeD2.Value) & ~strcmpi(app.ThreeD2.Value,app.ThreeD3.Value) & ~strcmpi(app.ThreeD1.Value,app.ThreeD3.Value))
                        [~,~,si2] = intersect(app.ThreeD1.Value,app.ThreeD1.Items);
                        [~,~,si3] = intersect(app.ThreeD2.Value,app.ThreeD2.Items);
                        [~,~,si4] = intersect(app.ThreeD3.Value,app.ThreeD3.Items);
                        app.overlay.settings{si}.multiCmap = rt;
                        if (length(app.overlay.bm{si2}) == length(app.overlay.bm{si3}) & length(app.overlay.bm{si2}) == length(app.overlay.bm{si4}) & length(app.overlay.bm{si3}) == length(app.overlay.bm{si4})) & (strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si3}.hemi) & strcmp(app.overlay.settings{si2}.hemi,app.overlay.settings{si4}.hemi) & strcmp(app.overlay.settings{si3}.hemi,app.overlay.settings{si4}.hemi)) & (length(app.overlay.bm{si2}) == size(app.underlay.(app.overlay.settings{si2}.hemi).Vertices,1)) & (length(app.overlay.bm{si3}) == size(app.underlay.(app.overlay.settings{si3}.hemi).Vertices,1)) & (length(app.overlay.bm{si4}) == size(app.underlay.(app.overlay.settings{si4}.hemi).Vertices,1))
                            %                             app.hist3D.Title.String = '';
                            %                             delete(app.hist3D.Children);
                            %                             app.hist3D.XLabel.String = '';
                            %                             app.hist3D.YLabel.String = '';
                            %                             app.hist3D.ZLabel.String = '';
                            %                             app.hist3D.Title.String = '';
                            %                             app.hist3D.XTickLabels = [];
                            %                             app.hist3D.YTickLabels = [];
                            %                             app.hist3D.ZTickLabels = [];
                            %                             app.degreeDrop.Value =  app.degreeDrop.Items{1};
                            %                             app.axisDrop.Value =  app.axisDrop.Items{1};

                            [app] = threeDMap(app,'true');
                            app.degreeDrop.Value =  app.degreeDrop.Items{1};
                            app.axisDrop.Value =  app.axisDrop.Items{1};
                        else
                            uialert(app.brainSurferUIFigure,'Selected overlays are either shaped differently from each other, from the underlay, or belong to different hemispheres','Action unavailable');
                        end
                    end
                end
            else
                %                 uialert(app.brainSurferUIFigure,'You need to load in a surface and and select one overlay to use this button','Action unavailable');
                %                 app.cubeSpacing.Value = 0.6;
                app.degreeDrop.Value =  app.degreeDrop.Items{1};
            end
        end

        % Menu selected function: MakecubeopacityuniformMenu
        function cubeOpUni(app, event)
            try
            set(app.hist3D.Children,'FaceAlpha',app.opacityBox.Value)
            catch
            end    
            
        end

        % Menu selected function: Menu
        function addROIsTo3D(app, event)
            if isgraphics(app.brainFig,'figure')
                [si,s] = getListSelect(app,'overlayListBox',1);
                cont = 0;
                for i = 1:length(si)
                    if ~isempty(app.overlay.settings{si(i)}.multiData)
                        cont = 1;
                    end
                end
                if cont
                    app.overlaySelectionApp = oSelect(app,' to use as ROIs for 3D scatter plot');
                end
            else
                uialert(app.brainSurferUIFigure,'You cannot use this function until you load in a surface','Action unavailable');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.brainSurferUIFigure = uifigure('Visible', 'off');
            app.brainSurferUIFigure.Name = 'brainSurfer';
            app.brainSurferUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.brainSurferUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel_8 = uipanel(app.LeftPanel);
            app.AppearanceLabel_2 = uilabel(app.Panel_8);
            app.AppearanceLabel_2.Text = 'Appearance';
            app.TabGroup = uitabgroup(app.Panel_8);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.TabGroup_2 = uitabgroup(app.DataTab);
            app.UnderlayTab = uitab(app.TabGroup_2);
            app.UnderlayTab.Title = 'Underlay';
            app.Panel_10 = uipanel(app.UnderlayTab);
            app.ValuesPanel = uipanel(app.Panel_10);
            app.ValuesPanel.Title = 'Values';
            app.valueSelectionSurf = uidropdown(app.ValuesPanel);
            app.valueSelectionSurf.Items = {'Raw', 'Percentile', 'Normalized', 'Scaled (0 to 1)', 'Scaled (-1 to 1)', 'Percentile (absolute)', 'Normalized (absolute)', 'Scaled (absolute)', 'Percentile (separate for pos/neg)', 'Normalized (separate for pos/neg)', 'Scaled (separate for pos/neg)', 'Scaled (absolute and separate for pos/neg)'};
            app.valueSelectionSurf.ValueChangedFcn = createCallbackFcn(app, @changeValueSurf, true);
            app.valueSelectionSurf.Tooltip = {'Change the values of the sulcal/gyral data currently loaded on top of the underlay'};
            app.valueSelectionSurf.Value = 'Raw';
            app.posSwitchSurf = uiswitch(app.ValuesPanel, 'slider');
            app.posSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @changePosSwitchSurf, true);
            app.posSwitchSurf.Tooltip = {'Turn positive values in sulcal/gyral data patched on top of underlay on or off'};
            app.posSwitchSurf.Value = 'On';
            app.PositiveLabel = uilabel(app.ValuesPanel);
            app.PositiveLabel.Text = 'Positive';
            app.negSwitchSurf = uiswitch(app.ValuesPanel, 'slider');
            app.negSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @changeNegSwitchSurf, true);
            app.negSwitchSurf.Tooltip = {'Turn negative values in sulcal/gyral data patched on top of underlay on or off'};
            app.negSwitchSurf.Value = 'On';
            app.NegativeLabel = uilabel(app.ValuesPanel);
            app.NegativeLabel.Text = 'Negative';
            app.ZerosLabel = uilabel(app.ValuesPanel);
            app.ZerosLabel.Text = 'Zeros';
            app.remZeroSwitchSurf = uiswitch(app.ValuesPanel, 'slider');
            app.remZeroSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @changeZeroSwitchSurf, true);
            app.remZeroSwitchSurf.Tooltip = {'Turn zeros in sulcal/gyral data patched on top of underlay on or off'};
            app.remZeroSwitchSurf.Value = 'On';
            app.reloadButton2 = uibutton(app.ValuesPanel, 'push');
            app.reloadButton2.ButtonPushedFcn = createCallbackFcn(app, @reloadUnderlay, true);
            app.reloadButton2.Icon = 'reloadButton.jpg';
            app.reloadButton2.Tooltip = {'Reload the sulci/gyri loaded in for this underlay'};
            app.reloadButton2.Text = '';
            app.BinarizevaluessulciandgyriPanel = uipanel(app.Panel_10);
            app.BinarizevaluessulciandgyriPanel.Title = 'Binarize values (sulci and gyri)';
            app.binSwitchSurf = uiswitch(app.BinarizevaluessulciandgyriPanel, 'slider');
            app.binSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @changeBinSwitchSurf, true);
            app.binSwitchSurf.Tooltip = {'This binarizes your sulcal/gyral data based on the threshold to the right'};
            app.sgThresh = uispinner(app.BinarizevaluessulciandgyriPanel);
            app.sgThresh.ValueChangedFcn = createCallbackFcn(app, @changeSgThresh, true);
            app.sgThresh.Tooltip = {'Anything above this value will be gyri and anything below is sulci'};
            app.ThresholdLabel = uilabel(app.BinarizevaluessulciandgyriPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.DefinecolorforsulciandgyriindividuallyLabel = uilabel(app.BinarizevaluessulciandgyriPanel);
            app.DefinecolorforsulciandgyriindividuallyLabel.Text = 'Define color for sulci and gyri individually';
            app.sulcButton = uibutton(app.BinarizevaluessulciandgyriPanel, 'push');
            app.sulcButton.ButtonPushedFcn = createCallbackFcn(app, @changeSulcButton, true);
            app.sulcButton.Tooltip = {'This shows the color of sucli or gyri. Press the button to change this color on the brain. Right click for additional options'};
            app.sulcButton.Text = '';
            app.gyrButton = uibutton(app.BinarizevaluessulciandgyriPanel, 'push');
            app.gyrButton.ButtonPushedFcn = createCallbackFcn(app, @changeGyriButton, true);
            app.gyrButton.Tooltip = {'This shows the color of sucli or gyri. Press the button to change this color on the brain. Right click for additional options'};
            app.gyrButton.Text = '';
            app.SurfacePanel = uipanel(app.Panel_10);
            app.SurfacePanel.Title = 'Surface';
            app.opacityBoxSurf = uispinner(app.SurfacePanel);
            app.opacityBoxSurf.Step = 0.05;
            app.opacityBoxSurf.Limits = [1e-05 1];
            app.opacityBoxSurf.ValueChangedFcn = createCallbackFcn(app, @opacitySurfChange, true);
            app.opacityBoxSurf.Tooltip = {'Change the opacitiy of the entire surface'};
            app.opacityBoxSurf.Value = 1;
            app.opacityLabelSurf_2 = uilabel(app.SurfacePanel);
            app.opacityLabelSurf_2.Text = 'Opacity';
            app.leftHemiToggle = uibutton(app.SurfacePanel, 'state');
            app.leftHemiToggle.ValueChangedFcn = createCallbackFcn(app, @changeDisplayLH, true);
            app.leftHemiToggle.Tooltip = {'Hide the left hemisphere surface if there is one loaded'};
            app.leftHemiToggle.Text = 'Left';
            app.rightHemiToggle = uibutton(app.SurfacePanel, 'state');
            app.rightHemiToggle.ValueChangedFcn = createCallbackFcn(app, @changeDisplayRH, true);
            app.rightHemiToggle.Tooltip = {'Hide the right hemisphere surface if there is one loaded'};
            app.rightHemiToggle.Text = 'Right';
            app.opacityLabelSurf_3 = uilabel(app.SurfacePanel);
            app.opacityLabelSurf_3.Text = 'Hide';
            app.RangeofvaluestoexcludePanel = uipanel(app.UnderlayTab);
            app.RangeofvaluestoexcludePanel.Title = 'Range of values to exclude';
            app.lowerThreshBoxSurf = uispinner(app.RangeofvaluestoexcludePanel);
            app.lowerThreshBoxSurf.ValueChangedFcn = createCallbackFcn(app, @changeLowerThreshBoxSurf, true);
            app.lowerThreshBoxSurf.Tooltip = {'Threshold for sulci/gyri data patched on top of the underlay. Any data that falls in between these two thresholds will not be patched.'};
            app.upperThreshBoxSurf = uispinner(app.RangeofvaluestoexcludePanel);
            app.upperThreshBoxSurf.ValueChangedFcn = createCallbackFcn(app, @changeUpperThreshBoxSurf, true);
            app.upperThreshBoxSurf.Tooltip = {'Threshold for sulci/gyri data patched on top of the underlay. Any data that falls in between these two thresholds will not be patched.'};
            app.LowerlimitLabel = uilabel(app.RangeofvaluestoexcludePanel);
            app.LowerlimitLabel.Text = 'Lower limit';
            app.UpperlimitLabel = uilabel(app.RangeofvaluestoexcludePanel);
            app.UpperlimitLabel.Text = 'Upper limit';
            app.OverlayTab = uitab(app.TabGroup_2);
            app.OverlayTab.Title = 'Overlay';
            app.Panel_7 = uipanel(app.OverlayTab);
            app.ValuesPanel_2 = uipanel(app.Panel_7);
            app.ValuesPanel_2.Title = 'Values';
            app.valueSelection = uidropdown(app.ValuesPanel_2);
            app.valueSelection.Items = {'Raw', 'Percentile', 'Normalized', 'Scaled (0 to 1)', 'Scaled (-1 to 1)', 'Percentile (absolute)', 'Normalized (absolute)', 'Scaled (absolute)', 'Percentile (separate for pos/neg)', 'Normalized (separate for pos/neg)', 'Scaled (separate for pos/neg)', 'Scaled (absolute and separate for pos/neg)'};
            app.valueSelection.ValueChangedFcn = createCallbackFcn(app, @valueSelectionChange, true);
            app.valueSelection.Tooltip = {'Change the values of the currently selected overlay. Right click for more options.'};
            app.valueSelection.Value = 'Raw';
            app.posSwitch = uiswitch(app.ValuesPanel_2, 'slider');
            app.posSwitch.ValueChangedFcn = createCallbackFcn(app, @posSwitchChange, true);
            app.posSwitch.Tooltip = {'Turn positive values in overlay on or off'};
            app.posSwitch.Value = 'On';
            app.PositiveLabel_2 = uilabel(app.ValuesPanel_2);
            app.PositiveLabel_2.Text = 'Positive';
            app.negSwitch = uiswitch(app.ValuesPanel_2, 'slider');
            app.negSwitch.ValueChangedFcn = createCallbackFcn(app, @negSwitchChange, true);
            app.negSwitch.Tooltip = {'Turn negative values in overlay on or off'};
            app.negSwitch.Value = 'On';
            app.NegativeLabel_2 = uilabel(app.ValuesPanel_2);
            app.NegativeLabel_2.Text = 'Negative';
            app.ZerosLabel_2 = uilabel(app.ValuesPanel_2);
            app.ZerosLabel_2.Text = 'Zeros';
            app.remZeroSwitch = uiswitch(app.ValuesPanel_2, 'slider');
            app.remZeroSwitch.ValueChangedFcn = createCallbackFcn(app, @remZeroSwitchChange, true);
            app.remZeroSwitch.Tooltip = {'Turn zeros in overlay on or off'};
            app.reloadOver = uibutton(app.ValuesPanel_2, 'push');
            app.reloadOver.ButtonPushedFcn = createCallbackFcn(app, @reloadOverlay, true);
            app.reloadOver.Icon = 'reloadButton.jpg';
            app.reloadOver.Tooltip = {'Reload the currently selected overlay'};
            app.reloadOver.Text = '';
            app.pvaluesPanel_2 = uipanel(app.Panel_7);
            app.pvaluesPanel_2.Title = 'p-values';
            app.addpButton = uibutton(app.pvaluesPanel_2, 'state');
            app.addpButton.ValueChangedFcn = createCallbackFcn(app, @addpButtonChange, true);
            app.addpButton.Tooltip = {'Load p-values that can be thresholded on top of the other threshold options'};
            app.addpButton.Text = 'Load p-values';
            app.fdrButton = uibutton(app.pvaluesPanel_2, 'state');
            app.fdrButton.ValueChangedFcn = createCallbackFcn(app, @fdrCorrButtonPush, true);
            app.fdrButton.Tooltip = {'FDR correct the loaded p-values'};
            app.fdrButton.Text = {'FDR corr.'; ''};
            app.bonfButton = uibutton(app.pvaluesPanel_2, 'state');
            app.bonfButton.ValueChangedFcn = createCallbackFcn(app, @bonfCorrButtonPush, true);
            app.bonfButton.Tooltip = {'Bonferroni correct the loaded p-values'};
            app.bonfButton.Text = {'Bonf. corr.'; ''};
            app.pthresholdLabel_2 = uilabel(app.pvaluesPanel_2);
            app.pthresholdLabel_2.Text = 'p-threshold:';
            app.pThreshBox = uispinner(app.pvaluesPanel_2);
            app.pThreshBox.Step = 0.005;
            app.pThreshBox.Limits = [1e-200 1];
            app.pThreshBox.ValueChangedFcn = createCallbackFcn(app, @pThreshBoxChange, true);
            app.pThreshBox.Tooltip = {'Anything above this p-threshold will not be patched on the brain'};
            app.pThreshBox.Value = 1;
            app.RangeofvaluestoexcludePanel_2 = uipanel(app.Panel_7);
            app.RangeofvaluestoexcludePanel_2.Title = 'Range of values to exclude';
            app.LowerlimitLabel_2 = uilabel(app.RangeofvaluestoexcludePanel_2);
            app.LowerlimitLabel_2.Text = 'Lower limit';
            app.UpperlimitLabel_2 = uilabel(app.RangeofvaluestoexcludePanel_2);
            app.UpperlimitLabel_2.Text = 'Upper limit';
            app.lowerThreshBox = uispinner(app.RangeofvaluestoexcludePanel_2);
            app.lowerThreshBox.ValueChangedFcn = createCallbackFcn(app, @negBoxChange, true);
            app.lowerThreshBox.Tooltip = {'Threshold for patched overlay. Any data that falls in between these two thresholds will not be patched.'};
            app.upperThreshBox = uispinner(app.RangeofvaluestoexcludePanel_2);
            app.upperThreshBox.ValueChangedFcn = createCallbackFcn(app, @posBoxChange, true);
            app.upperThreshBox.Tooltip = {'Threshold for patched overlay. Any data that falls in between these two thresholds will not be patched.'};
            app.ClusterthresholdPanel = uipanel(app.Panel_7);
            app.ClusterthresholdPanel.Title = 'Cluster threshold';
            app.clusterThreshBox = uispinner(app.ClusterthresholdPanel);
            app.clusterThreshBox.Limits = [0 Inf];
            app.clusterThreshBox.ValueChangedFcn = createCallbackFcn(app, @clustChange, true);
            app.clusterThreshBox.Tooltip = {'Any cluster above this size will be removed'};
            app.SizethreshLabel = uilabel(app.ClusterthresholdPanel);
            app.SizethreshLabel.Text = 'Size thresh:';
            app.clusterSlider = uislider(app.ClusterthresholdPanel);
            app.clusterSlider.Limits = [0 1000];
            app.clusterSlider.ValueChangedFcn = createCallbackFcn(app, @clustSliderChange, true);
            app.clusterSlider.Tooltip = {'Any cluster above this size will be removed'};
            app.ClustcorrButton = uibutton(app.ClusterthresholdPanel, 'push');
            app.ClustcorrButton.Enable = 'off';
            app.ClustcorrButton.Visible = 'off';
            app.ClustcorrButton.Tooltip = {'Determine an appropriate cluster cutoff threshold'};
            app.ClustcorrButton.Text = 'Clust. corr.';
            app.LimitsTab = uitab(app.TabGroup);
            app.LimitsTab.Title = 'Limits';
            app.TabGroup_4 = uitabgroup(app.LimitsTab);
            app.UnderlayTab_2 = uitab(app.TabGroup_4);
            app.UnderlayTab_2.Title = 'Underlay';
            app.IntensityhistogramLabel = uilabel(app.UnderlayTab_2);
            app.IntensityhistogramLabel.Text = 'Intensity histogram';
            app.UpperlimitofcolormapLabel = uilabel(app.UnderlayTab_2);
            app.UpperlimitofcolormapLabel.Text = 'Upper limit of colormap:';
            app.LowerlimitofcolormapLabel = uilabel(app.UnderlayTab_2);
            app.LowerlimitofcolormapLabel.Text = 'Lower limit of colormap:';
            app.limMaxSurf = uispinner(app.UnderlayTab_2);
            app.limMaxSurf.ValueChangedFcn = createCallbackFcn(app, @limMaxSurfChange, true);
            app.limMaxSurf.Tooltip = {'This will be the top value of your underlay colorbar'};
            app.limMinSurf = uispinner(app.UnderlayTab_2);
            app.limMinSurf.ValueChangedFcn = createCallbackFcn(app, @limMinSurfChange, true);
            app.limMinSurf.Tooltip = {'This will be the bottom value of your underlay colorbar'};
            app.histSurf = uiaxes(app.UnderlayTab_2);
            xlabel(app.histSurf, {'value'; ''})
            ylabel(app.histSurf, 'density')
            app.histSurf.YTick = [];
            app.OverlayTab_3 = uitab(app.TabGroup_4);
            app.OverlayTab_3.Title = 'Overlay';
            app.IntensityhistogramLabel_2 = uilabel(app.OverlayTab_3);
            app.IntensityhistogramLabel_2.Text = 'Intensity histogram';
            app.UpperlimitofcolormapLabel_2 = uilabel(app.OverlayTab_3);
            app.UpperlimitofcolormapLabel_2.Text = 'Upper limit of colormap:';
            app.LowerlimitofcolormapLabel_2 = uilabel(app.OverlayTab_3);
            app.LowerlimitofcolormapLabel_2.Text = 'Lower limit of colormap:';
            app.limMinOver = uispinner(app.OverlayTab_3);
            app.limMinOver.ValueChangedFcn = createCallbackFcn(app, @limMinChange, true);
            app.limMinOver.Tooltip = {'This will be the top value of your overlay colorbar'};
            app.limMaxOver = uispinner(app.OverlayTab_3);
            app.limMaxOver.ValueChangedFcn = createCallbackFcn(app, @limMaxChange, true);
            app.limMaxOver.Tooltip = {'This will be the bottom value of your overlay colorbar'};
            app.histOver = uiaxes(app.OverlayTab_3);
            xlabel(app.histOver, {'value'; ''})
            ylabel(app.histOver, 'density')
            app.histOver.YTick = [];
            app.ColormapTab = uitab(app.TabGroup);
            app.ColormapTab.Title = 'Colormap';
            app.TabGroup_3 = uitabgroup(app.ColormapTab);
            app.UnderlayTab_3 = uitab(app.TabGroup_3);
            app.UnderlayTab_3.Title = 'Underlay';
            app.Panel = uipanel(app.UnderlayTab_3);
            app.colormapSelectorSurf = uidropdown(app.Panel);
            app.colormapSelectorSurf.Items = {'Select colormap', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.colormapSelectorSurf.ValueChangedFcn = createCallbackFcn(app, @colormapSurfChange, true);
            app.colormapSelectorSurf.Tooltip = {'Change the colormap of the currently loaded underlay'};
            app.colormapSelectorSurf.Value = 'Select colormap';
            app.binsLabelSurf = uilabel(app.Panel);
            app.binsLabelSurf.Text = 'Bins';
            app.opacityLabelSurf = uilabel(app.Panel);
            app.opacityLabelSurf.Text = 'Opacity';
            app.selectSpacingSurf = uidropdown(app.Panel);
            app.selectSpacingSurf.Items = {'Select color spacing', 'even', 'center on zero', 'center on threshold'};
            app.selectSpacingSurf.ValueChangedFcn = createCallbackFcn(app, @colorSpaceSurfChange, true);
            app.selectSpacingSurf.Tooltip = {'Change whether the colorbar spreads evenly between your limits, or whether you want the center to map onto a specific value (for underlay)'};
            app.selectSpacingSurf.Value = 'Select color spacing';
            app.binBoxSurf = uispinner(app.Panel);
            app.binBoxSurf.Step = 25;
            app.binBoxSurf.Limits = [1 Inf];
            app.binBoxSurf.ValueChangedFcn = createCallbackFcn(app, @binBoxSurfChange, true);
            app.binBoxSurf.Tooltip = {'Number of colors in the underlay colormap'};
            app.binBoxSurf.Value = 1000;
            app.InvertcolorsLabel = uilabel(app.Panel);
            app.InvertcolorsLabel.Text = 'Invert colors';
            app.invertSwitchSurf = uiswitch(app.Panel, 'slider');
            app.invertSwitchSurf.ValueChangedFcn = createCallbackFcn(app, @invertColorSurfChange, true);
            app.invertSwitchSurf.Tooltip = {'Invert colors in the colormap'};
            app.opacityBoxSG = uispinner(app.Panel);
            app.opacityBoxSG.Step = 0.05;
            app.opacityBoxSG.Limits = [1e-05 1];
            app.opacityBoxSG.ValueChangedFcn = createCallbackFcn(app, @changeOpacitySG, true);
            app.opacityBoxSG.Tooltip = {'Change the opacity of the sulci/gyri patched on top of the underlay. NOTE: THIS WILL TAKE SOME TIME TO COMPLETE.'};
            app.opacityBoxSG.Value = 1;
            app.OverlayTab_2 = uitab(app.TabGroup_3);
            app.OverlayTab_2.Title = 'Overlay';
            app.Panel_3 = uipanel(app.OverlayTab_2);
            app.colormapSelector = uidropdown(app.Panel_3);
            app.colormapSelector.Items = {'Select colormap', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.colormapSelector.ValueChangedFcn = createCallbackFcn(app, @colormapSelectorChange, true);
            app.colormapSelector.Tooltip = {'Change the colormap of the currently selected overlay'};
            app.colormapSelector.Value = 'Select colormap';
            app.BinsLabel_2 = uilabel(app.Panel_3);
            app.BinsLabel_2.Text = 'Bins';
            app.OpacityLabel_2 = uilabel(app.Panel_3);
            app.OpacityLabel_2.Text = 'Opacity';
            app.selectSpacing = uidropdown(app.Panel_3);
            app.selectSpacing.Items = {'Select color spacing', 'even', 'center on zero', 'center on threshold'};
            app.selectSpacing.ValueChangedFcn = createCallbackFcn(app, @selectSpacingChange, true);
            app.selectSpacing.Tooltip = {'Change whether the colorbar spreads evenly between your limits, or whether you want the center to map onto a specific value (for overlay)'};
            app.selectSpacing.Value = 'Select color spacing';
            app.binBox = uispinner(app.Panel_3);
            app.binBox.Step = 25;
            app.binBox.Limits = [1 Inf];
            app.binBox.ValueChangedFcn = createCallbackFcn(app, @binBoxChange, true);
            app.binBox.Tooltip = {'Number of colors in the overlay colormap'};
            app.binBox.Value = 1000;
            app.opacityBox = uispinner(app.Panel_3);
            app.opacityBox.Step = 0.05;
            app.opacityBox.Limits = [1e-05 1];
            app.opacityBox.ValueChangedFcn = createCallbackFcn(app, @opacityBoxChange, true);
            app.opacityBox.Tooltip = {'Change the opacity of the overlay. This one is efficient.'};
            app.opacityBox.Value = 1;
            app.invertSwitch = uiswitch(app.Panel_3, 'slider');
            app.invertSwitch.ValueChangedFcn = createCallbackFcn(app, @invertSwitchChange, true);
            app.invertSwitch.Tooltip = {'Invert colors in the colormap'};
            app.OutlineDropDownLabel = uilabel(app.Panel_3);
            app.OutlineDropDownLabel.Text = 'Outline';
            app.outlineDropDown = uidropdown(app.Panel_3);
            app.outlineDropDown.Items = {'None', 'Map', 'ROI'};
            app.outlineDropDown.ValueChangedFcn = createCallbackFcn(app, @outlineDropDownChange, true);
            app.outlineDropDown.Tooltip = {'Make an outline of the selected overlay based on the kind of data that is being outlined'};
            app.outlineDropDown.Value = 'None';
            app.GrowShrinkSlider_2Label = uilabel(app.Panel_3);
            app.GrowShrinkSlider_2Label.Text = 'Grow/Shrink';
            app.growShrinkSlider = uislider(app.Panel_3);
            app.growShrinkSlider.Limits = [-25 25];
            app.growShrinkSlider.ValueChangedFcn = createCallbackFcn(app, @growShrinkSliderChange, true);
            app.growShrinkSlider.Tooltip = {'Grow or shrink the map by a certain number of neighboring vertices'};
            app.growShrinkBox = uispinner(app.Panel_3);
            app.growShrinkBox.Limits = [-50 50];
            app.growShrinkBox.ValueChangedFcn = createCallbackFcn(app, @growShrinkBoxChange, true);
            app.growShrinkBox.Tooltip = {'Grow or shrink the map by a certain number of neighboring vertices'};
            app.binSwitch = uidropdown(app.Panel_3);
            app.binSwitch.Items = {'Off', 'Map', 'Clusters'};
            app.binSwitch.ValueChangedFcn = createCallbackFcn(app, @binSwitchChange, true);
            app.binSwitch.Tooltip = {'Binarize the selected overlay based on the kind of data that is being binarized'};
            app.binSwitch.Value = 'Off';
            app.InvertcolorsLabel_5 = uilabel(app.Panel_3);
            app.InvertcolorsLabel_5.Text = 'Invert colors';
            app.BinarizeLabel = uilabel(app.Panel_3);
            app.BinarizeLabel.Text = 'Binarize';
            app.SmoothingTab = uitab(app.TabGroup);
            app.SmoothingTab.Title = 'Smoothing';
            app.Panel_2 = uipanel(app.SmoothingTab);
            app.AreaLabel_2 = uilabel(app.Panel_2);
            app.AreaLabel_2.Text = 'Area';
            app.StepsLabel_2 = uilabel(app.Panel_2);
            app.StepsLabel_2.Text = 'Steps';
            app.assignSwitchUnder = uiswitch(app.Panel_2, 'slider');
            app.assignSwitchUnder.Items = {'thresholded', 'all'};
            app.assignSwitchUnder.Tooltip = {'Do you want to iterate over all vertices in the map or just thresholded ones'};
            app.assignSwitchUnder.Value = 'thresholded';
            app.smoothAreaUnder = uispinner(app.Panel_2);
            app.smoothAreaUnder.Tooltip = {'Number of vertices over which to smooth  (i.e., how many neighboring vertices should be smoothed for each vertex in the map)'};
            app.smoothStepsUnder = uispinner(app.Panel_2);
            app.smoothStepsUnder.Tooltip = {'Number of times to repeat the smoothing process'};
            app.smoothButtonSurf = uibutton(app.Panel_2, 'state');
            app.smoothButtonSurf.ValueChangedFcn = createCallbackFcn(app, @smoothButtonSurfPress, true);
            app.smoothButtonSurf.Tooltip = {'Smooth or revert'};
            app.smoothButtonSurf.Text = 'Smooth';
            app.VerticesbeingsmoothedLabel_3 = uilabel(app.Panel_2);
            app.VerticesbeingsmoothedLabel_3.Text = 'Vertices being smoothed:';
            app.SmoothsulcigyriLabel = uilabel(app.SmoothingTab);
            app.SmoothsulcigyriLabel.Text = 'Smooth sulci/gyri';
            app.Panel_5 = uipanel(app.SmoothingTab);
            app.AreaLabel_3 = uilabel(app.Panel_5);
            app.AreaLabel_3.Text = 'Area';
            app.StepsLabel_3 = uilabel(app.Panel_5);
            app.StepsLabel_3.Text = 'Steps';
            app.smoothArea = uispinner(app.Panel_5);
            app.smoothArea.Tooltip = {'Number of vertices over which to smooth  (i.e., how many neighboring vertices should be smoothed for each vertex in the map)'};
            app.smoothSteps = uispinner(app.Panel_5);
            app.smoothSteps.Tooltip = {'Number of times to repeat the smoothing process'};
            app.smoothButton = uibutton(app.Panel_5, 'state');
            app.smoothButton.ValueChangedFcn = createCallbackFcn(app, @smoothButtonPress, true);
            app.smoothButton.Tooltip = {'Smooth or revert'};
            app.smoothButton.Text = 'Smooth';
            app.assignSwitch = uiswitch(app.Panel_5, 'slider');
            app.assignSwitch.Items = {'thresholded', 'all'};
            app.assignSwitch.Tooltip = {'Do you want to iterate over all vertices in the map or just thresholded ones'};
            app.assignSwitch.Value = 'thresholded';
            app.VerticesbeingsmoothedLabel_2 = uilabel(app.Panel_5);
            app.VerticesbeingsmoothedLabel_2.Text = 'Vertices being smoothed:';
            app.SmoothoverlayLabel_2 = uilabel(app.SmoothingTab);
            app.SmoothoverlayLabel_2.Text = 'Smooth overlay';
            app.ClustersTab = uitab(app.TabGroup);
            app.ClustersTab.Title = 'Clusters';
            app.clustTable = uitable(app.ClustersTab);
            app.clustTable.ColumnName = {'Vertex'; 'X'; 'Y'; 'Z'; 'Sz'; 'Mean'; 'Max'};
            app.clustTable.RowName = {};
            app.clustTable.Tooltip = {'Data shown for peak coordinate (i.e., max value)'};
            app.ActiontoperformonlasttooltipPanel = uipanel(app.ClustersTab);
            app.ActiontoperformonlasttooltipPanel.Title = 'Action to perform on last tooltip:';
            app.deleteClustButton = uibutton(app.ActiontoperformonlasttooltipPanel, 'push');
            app.deleteClustButton.ButtonPushedFcn = createCallbackFcn(app, @deleteClustButtonPress, true);
            app.deleteClustButton.Text = 'Delete cluster';
            app.deleteClustExceptButton = uibutton(app.ActiontoperformonlasttooltipPanel, 'push');
            app.deleteClustExceptButton.ButtonPushedFcn = createCallbackFcn(app, @deleteAllExceptButtonPress, true);
            app.deleteClustExceptButton.Text = 'Delete all clusters except';
            app.assignClustColButton = uibutton(app.ActiontoperformonlasttooltipPanel, 'push');
            app.assignClustColButton.ButtonPushedFcn = createCallbackFcn(app, @assignClustColButtonPress, true);
            app.assignClustColButton.Text = 'Change cluster color';
            app.assignClustValButton = uibutton(app.ActiontoperformonlasttooltipPanel, 'push');
            app.assignClustValButton.ButtonPushedFcn = createCallbackFcn(app, @assignClustValButtonPress, true);
            app.assignClustValButton.Text = 'Change cluster value';
            app.Panel_12 = uipanel(app.ClustersTab);
            app.clustDataKind = uidropdown(app.Panel_12);
            app.clustDataKind.Items = {'Show cluster size', 'Show mean data', 'Show max value'};
            app.clustDataKind.ValueChangedFcn = createCallbackFcn(app, @clustDataKindChange, true);
            app.clustDataKind.Value = 'Show cluster size';
            app.saveTableButton = uibutton(app.Panel_12, 'push');
            app.saveTableButton.ButtonPushedFcn = createCallbackFcn(app, @saveTableButtonPress, true);
            app.saveTableButton.Text = 'Save table';
            app.clustSurf = uiaxes(app.ClustersTab);
            ylabel(app.clustSurf, 'Y')
            app.ComplexoverlaysTab = uitab(app.TabGroup);
            app.ComplexoverlaysTab.Title = 'Complex overlays';
            app.TabGroup_5 = uitabgroup(app.ComplexoverlaysTab);
            app.TransparencyTab = uitab(app.TabGroup_5);
            app.TransparencyTab.Title = 'Transparency';
            app.modOverlaySelect = uidropdown(app.TransparencyTab);
            app.modOverlaySelect.Items = {'No overlay', ''};
            app.modOverlaySelect.ValueChangedFcn = createCallbackFcn(app, @modOverlaySelectChange, true);
            app.modOverlaySelect.Tooltip = {'Select the overlay that will be used to modulate transparency'};
            app.modOverlaySelect.Value = 'No overlay';
            app.MaxopacityLabel = uilabel(app.TransparencyTab);
            app.MaxopacityLabel.Text = 'Max opacity:';
            app.MinopacityLabel = uilabel(app.TransparencyTab);
            app.MinopacityLabel.Text = 'Min opacity:';
            app.minAlphaMod = uispinner(app.TransparencyTab);
            app.minAlphaMod.Step = 0.1;
            app.minAlphaMod.Limits = [0 1];
            app.minAlphaMod.ValueChangedFcn = createCallbackFcn(app, @minAlphaModChange, true);
            app.minAlphaMod.Tooltip = {'Lowest opacity value that can be assigned'};
            app.maxAlphaMod = uispinner(app.TransparencyTab);
            app.maxAlphaMod.Step = 0.1;
            app.maxAlphaMod.Limits = [0 1];
            app.maxAlphaMod.ValueChangedFcn = createCallbackFcn(app, @maxAlphaModChange, true);
            app.maxAlphaMod.Tooltip = {'Highest opacity value that can be assigned'};
            app.maxAlphaMod.Value = 1;
            app.SelectoverlaytousetomodulatetransparencyLabel_2 = uilabel(app.TransparencyTab);
            app.SelectoverlaytousetomodulatetransparencyLabel_2.Text = 'Select overlay to use to modulate transparency:';
            app.SeparateposnegLabel = uilabel(app.TransparencyTab);
            app.SeparateposnegLabel.Text = 'Separate pos/neg';
            app.absToggleMod = uiswitch(app.TransparencyTab, 'slider');
            app.absToggleMod.ValueChangedFcn = createCallbackFcn(app, @absToggleModPush, true);
            app.absToggleMod.Tooltip = {'Should positive and negative values in the map be scaled separately?'};
            app.absToggleMod.Value = 'On';
            app.MaxlimitmodLabel = uilabel(app.TransparencyTab);
            app.MaxlimitmodLabel.Text = 'Max limit (mod):';
            app.MinlimitmodLabel = uilabel(app.TransparencyTab);
            app.MinlimitmodLabel.Text = 'Min limit (mod): ';
            app.modLimMin = uispinner(app.TransparencyTab);
            app.modLimMin.ValueChangedFcn = createCallbackFcn(app, @modLimMinChange, true);
            app.modLimMin.Tooltip = {'Lowest opacity value that can be assigned'};
            app.modLimMax = uispinner(app.TransparencyTab);
            app.modLimMax.ValueChangedFcn = createCallbackFcn(app, @modLimMaxChange, true);
            app.modLimMax.Tooltip = {'Highest opacity value that can be assigned'};
            app.histMod = uiaxes(app.TransparencyTab);
            app.DTab_3 = uitab(app.TabGroup_5);
            app.DTab_3.Tooltip = {'Patch two overlays on one brain using a single 2-D colormap. The two dimensions you select will be patched anytime you click the same overlay that is currently clicked in the overlay selection panel. Some settings from the currently selected overlay will be applied to the new 2D patch, including: cluster size threshold, opacity, the zero switch, the outline drop down and the grow/shrink box/slider.'};
            app.DTab_3.Title = '2D';
            app.TwoD1 = uidropdown(app.DTab_3);
            app.TwoD1.Items = {'No overlay', ''};
            app.TwoD1.ValueChangedFcn = createCallbackFcn(app, @TwoD1Change, true);
            app.TwoD1.Tooltip = {'First dimension on the 2-D colormap (this will appear on x-axis)'};
            app.TwoD1.Value = 'No overlay';
            app.TwoD2 = uidropdown(app.DTab_3);
            app.TwoD2.Items = {'No overlay', ''};
            app.TwoD2.ValueChangedFcn = createCallbackFcn(app, @TwoD2Change, true);
            app.TwoD2.Tooltip = {'Second dimension on the 2-D colormap (this will appear on y-axis)'};
            app.TwoD2.Value = 'No overlay';
            app.Dim1Label_4 = uilabel(app.DTab_3);
            app.Dim1Label_4.Text = 'Dim 1:';
            app.Dim2Label_4 = uilabel(app.DTab_3);
            app.Dim2Label_4.Text = 'Dim 2:';
            app.ndcolorbaronxaxisButton = uibutton(app.DTab_3, 'state');
            app.ndcolorbaronxaxisButton.ValueChangedFcn = createCallbackFcn(app, @secondColorbarChange, true);
            app.ndcolorbaronxaxisButton.Tooltip = {'By default colors are interpolated between two colorbars then the 2nd dim map is remapped on the x-axis of the resulting colormap (i.e., it''s assigned a new "colorbar"). Pressing this button will fix the 2nd dim map''s colorbar to the x-axis but interpolated colors will be muddier. Note, this will "unrotate" your map.'};
            app.ndcolorbaronxaxisButton.Text = '2nd colorbar on x-axis';
            app.rotatecolorsButton = uibutton(app.DTab_3, 'push');
            app.rotatecolorsButton.ButtonPushedFcn = createCallbackFcn(app, @rotateColorbarChange, true);
            app.rotatecolorsButton.Tooltip = {'Rotate the colormap that you see by 90 degrees.'};
            app.rotatecolorsButton.Text = 'rotate colors';
            app.hist2D = uiaxes(app.DTab_3);
            app.DTab_4 = uitab(app.TabGroup_5);
            app.DTab_4.Title = '3D';
            app.ThreeD1 = uidropdown(app.DTab_4);
            app.ThreeD1.Items = {'No overlay', ''};
            app.ThreeD1.ValueChangedFcn = createCallbackFcn(app, @threeD1Change, true);
            app.ThreeD1.Tooltip = {'Change the colormap of the currently loaded underlay'};
            app.ThreeD1.Value = 'No overlay';
            app.ThreeD2 = uidropdown(app.DTab_4);
            app.ThreeD2.Items = {'No overlay', ''};
            app.ThreeD2.ValueChangedFcn = createCallbackFcn(app, @threeD2Change, true);
            app.ThreeD2.Tooltip = {'Change the colormap of the currently loaded underlay'};
            app.ThreeD2.Value = 'No overlay';
            app.Dim1Label_5 = uilabel(app.DTab_4);
            app.Dim1Label_5.Text = 'Dim 1:';
            app.Dim2Label_5 = uilabel(app.DTab_4);
            app.Dim2Label_5.Text = 'Dim 2:';
            app.ThreeD3 = uidropdown(app.DTab_4);
            app.ThreeD3.Items = {'No overlay', ''};
            app.ThreeD3.ValueChangedFcn = createCallbackFcn(app, @threeD3Change, true);
            app.ThreeD3.Tooltip = {'Change the colormap of the currently loaded underlay'};
            app.ThreeD3.Value = 'No overlay';
            app.Dim3Label_2 = uilabel(app.DTab_4);
            app.Dim3Label_2.Text = 'Dim 3:';
            app.RotateeachcolorbinonLabel = uilabel(app.DTab_4);
            app.RotateeachcolorbinonLabel.Text = 'Rotate each color bin on ';
            app.numCubes = uispinner(app.DTab_4);
            app.numCubes.Limits = [0 50];
            app.numCubes.ValueChangedFcn = createCallbackFcn(app, @threeDNumCubesChange, true);
            app.numCubes.Tooltip = {'This will be the top value of your overlay colorbar'};
            app.numCubes.Value = 8;
            app.CubesinmapLabel = uilabel(app.DTab_4);
            app.CubesinmapLabel.Text = 'Cubes in map:';
            app.CubesizeLabel = uilabel(app.DTab_4);
            app.CubesizeLabel.Text = 'Cube size:';
            app.cubeSlider = uislider(app.DTab_4);
            app.cubeSlider.Limits = [0 1];
            app.cubeSlider.MajorTicks = [];
            app.cubeSlider.ValueChangedFcn = createCallbackFcn(app, @cubeSpacingSlideChange, true);
            app.cubeSlider.MinorTicks = [];
            app.cubeSlider.Value = 0.6;
            app.cubeSpacing = uispinner(app.DTab_4);
            app.cubeSpacing.Step = 0.1;
            app.cubeSpacing.Limits = [0 1];
            app.cubeSpacing.ValueChangedFcn = createCallbackFcn(app, @cubeSpacingChange, true);
            app.cubeSpacing.Tooltip = {'This will be the top value of your overlay colorbar'};
            app.cubeSpacing.Value = 0.4;
            app.axisDrop = uidropdown(app.DTab_4);
            app.axisDrop.Items = {'Select axis', 'x', 'y', 'z'};
            app.axisDrop.ValueChangedFcn = createCallbackFcn(app, @axisSelection, true);
            app.axisDrop.Value = 'Select axis';
            app.degreeDrop = uidropdown(app.DTab_4);
            app.degreeDrop.Items = {'Select degrees', '90', '180', '360'};
            app.degreeDrop.ValueChangedFcn = createCallbackFcn(app, @degreeSelection, true);
            app.degreeDrop.Value = 'Select degrees';
            app.byLabel = uilabel(app.DTab_4);
            app.byLabel.Text = 'by ';
            app.hist3D = uiaxes(app.DTab_4);
            zlabel(app.hist3D, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel_9 = uipanel(app.RightPanel);
            app.reloadButton = uibutton(app.Panel_9, 'push');
            app.reloadButton.ButtonPushedFcn = createCallbackFcn(app, @reloadOverlay, true);
            app.reloadButton.Icon = 'reloadButton.jpg';
            app.reloadButton.Tooltip = {'Reload the currently selected overlay. Right click for additional options'};
            app.reloadButton.Text = '';
            app.copyButton = uibutton(app.Panel_9, 'push');
            app.copyButton.ButtonPushedFcn = createCallbackFcn(app, @copyButtonPush, true);
            app.copyButton.Icon = 'copyButton.jpg';
            app.copyButton.Tooltip = {'Copy currently selected overlay settings into other overlay(s)'};
            app.copyButton.Text = '';
            app.deleteButton = uibutton(app.Panel_9, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteOverlay, true);
            app.deleteButton.Icon = 'deleteButton.jpg';
            app.deleteButton.Tooltip = {'Delete the currently selected overlay(s).  Right click for additional options'};
            app.deleteButton.Text = '';
            app.OverlayselectionLabel = uilabel(app.Panel_9);
            app.OverlayselectionLabel.Text = 'Overlay selection';
            app.upButton = uibutton(app.Panel_9, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPress, true);
            app.upButton.Icon = 'upButton.jpg';
            app.upButton.Tooltip = {'Move currently selected overlay(s) up'};
            app.upButton.Text = '';
            app.downButton = uibutton(app.Panel_9, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPress, true);
            app.downButton.Icon = 'downButton.jpg';
            app.downButton.Tooltip = {'Move currently selected overlay(s) down'};
            app.downButton.Text = '';
            app.lightsButton = uibutton(app.Panel_9, 'push');
            app.lightsButton.ButtonPushedFcn = createCallbackFcn(app, @lightsButtonEffect, true);
            app.lightsButton.Icon = 'lightingButton.jpg';
            app.lightsButton.Tooltip = {'Toggle the lighting on surface'};
            app.lightsButton.Text = '';
            app.screenshotButton = uibutton(app.Panel_9, 'push');
            app.screenshotButton.ButtonPushedFcn = createCallbackFcn(app, @screenshotButtonPush, true);
            app.screenshotButton.Icon = 'screenshotButton.jpg';
            app.screenshotButton.Tooltip = {'Take screenshot of current overlay with current viewing angle. Right click for additional options'};
            app.screenshotButton.Text = {''; ''};
            app.dupeButton = uibutton(app.Panel_9, 'push');
            app.dupeButton.ButtonPushedFcn = createCallbackFcn(app, @dupeButtonPush, true);
            app.dupeButton.Icon = 'duplicateButton.jpg';
            app.dupeButton.Tooltip = {'Duplicate the currently selected overlay(s)'};
            app.dupeButton.Text = '';
            app.maskButton = uibutton(app.Panel_9, 'push');
            app.maskButton.ButtonPushedFcn = createCallbackFcn(app, @maskButtonPush, true);
            app.maskButton.Icon = 'maskButton.jpg';
            app.maskButton.Tooltip = {'Use the currently selected overlay as a  mask for some other overlay(s)'};
            app.maskButton.Text = '';
            app.LHButton = uibutton(app.Panel_9, 'push');
            app.LHButton.ButtonPushedFcn = createCallbackFcn(app, @changeDisplayLHButton, true);
            app.LHButton.Icon = 'leftHemi2.jpg';
            app.LHButton.Tooltip = {'Toggle visibility of left hemisphere. Right click for additional options'; ''};
            app.LHButton.Text = '';
            app.RHButton = uibutton(app.Panel_9, 'push');
            app.RHButton.ButtonPushedFcn = createCallbackFcn(app, @changeDisplayRHButton, true);
            app.RHButton.Icon = 'rightHemi2.jpg';
            app.RHButton.Tooltip = {'Toggle visibility of right hemisphere. Right click for additional options'};
            app.RHButton.Text = '';
            app.overlayListBox = uilistbox(app.Panel_9);
            app.overlayListBox.Items = {'No overlay'};
            app.overlayListBox.Multiselect = 'on';
            app.overlayListBox.ValueChangedFcn = createCallbackFcn(app, @overlaySelected, true);
            app.overlayListBox.Tooltip = {'Select an overlay to patch. Right click for additional options'};
            app.overlayListBox.Value = {'No overlay'};
            app.Panel_11 = uipanel(app.Panel_9);
            app.colormapSelectAtlas = uidropdown(app.Panel_11);
            app.colormapSelectAtlas.Items = {'Select colormap', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.colormapSelectAtlas.ValueChangedFcn = createCallbackFcn(app, @colormapAtlasChange, true);
            app.colormapSelectAtlas.Tooltip = {'Change the colormap of the atlas. Right click for additional options.'};
            app.colormapSelectAtlas.Value = 'Select colormap';
            app.loadAtlas = uibutton(app.Panel_11, 'push');
            app.loadAtlas.ButtonPushedFcn = createCallbackFcn(app, @loadAtlasButton, true);
            app.loadAtlas.Tooltip = {'Load a new atlas '};
            app.loadAtlas.Text = 'Load atlas';
            app.LoadedatlasLabel = uilabel(app.Panel_11);
            app.LoadedatlasLabel.Tooltip = {'Currently loaded atlas'};
            app.LoadedatlasLabel.Text = 'Loaded atlas: ';
            app.loadLabels = uibutton(app.Panel_11, 'push');
            app.loadLabels.ButtonPushedFcn = createCallbackFcn(app, @loadLabelsButton, true);
            app.loadLabels.Tooltip = {'Load (a) new label(s)'};
            app.loadLabels.Text = 'Load labels';
            app.AreaonlastdatatipLabel = uilabel(app.Panel_11);
            app.AreaonlastdatatipLabel.Tooltip = {'Atlas area within which your last placed data tip fell. Right click for mor options.'};
            app.AreaonlastdatatipLabel.Text = 'Area on last data tip: ';
            app.selectSpacingAtlas = uidropdown(app.Panel_11);
            app.selectSpacingAtlas.Items = {'Select color spacing', 'even', 'center on zero', 'center on threshold'};
            app.selectSpacingAtlas.ValueChangedFcn = createCallbackFcn(app, @spacingAtlasChange, true);
            app.selectSpacingAtlas.Tooltip = {'Change whether the colorbar for the atlas spreads evenly between your limits, or whether you want the center to map onto a specific value (for overlay).'};
            app.selectSpacingAtlas.Value = 'Select color spacing';
            app.upperLimAtlas = uispinner(app.Panel_11);
            app.upperLimAtlas.ValueChangedFcn = createCallbackFcn(app, @upperLimAtlasChange, true);
            app.upperLimAtlas.Tooltip = {'This will be the top value of your atlas colorbar'};
            app.lowerLimAtlas = uispinner(app.Panel_11);
            app.lowerLimAtlas.ValueChangedFcn = createCallbackFcn(app, @lowerLimAtlasChange, true);
            app.lowerLimAtlas.Tooltip = {'This will be the bottom value of your atlas colorbar'};
            app.binAtlas = uispinner(app.Panel_11);
            app.binAtlas.Limits = [1 Inf];
            app.binAtlas.ValueChangedFcn = createCallbackFcn(app, @binAtlasChange, true);
            app.binAtlas.Tooltip = {'Number of colors in the atlas colormap'};
            app.binAtlas.Value = 1;
            app.BinsLabel_3 = uilabel(app.Panel_11);
            app.BinsLabel_3.Text = 'Bins';
            app.UpperlimitLabel_3 = uilabel(app.Panel_11);
            app.UpperlimitLabel_3.Text = 'Upper limit';
            app.LowerlimitLabel_3 = uilabel(app.Panel_11);
            app.LowerlimitLabel_3.Text = 'Lower limit';
            app.opacityAtlas = uispinner(app.Panel_11);
            app.opacityAtlas.Limits = [0 1];
            app.opacityAtlas.ValueChangedFcn = createCallbackFcn(app, @opacityAtlasChange, true);
            app.opacityAtlas.Tooltip = {'Change the opacity of the atlas. This one is efficient.'};
            app.OpacityLabel_3 = uilabel(app.Panel_11);
            app.OpacityLabel_3.Text = 'Opacity';
            app.GrowoutlineLabel = uilabel(app.Panel_11);
            app.GrowoutlineLabel.Text = 'Grow outline';
            app.shrinkGrowAtlas = uispinner(app.Panel_11);
            app.shrinkGrowAtlas.Limits = [0 50];
            app.shrinkGrowAtlas.ValueChangedFcn = createCallbackFcn(app, @shrinkGrowAtlasChange, true);
            app.shrinkGrowAtlas.Tooltip = {'Grow the atlas outline by a certain number of neighboring vertices'};
            app.toggleColorbarAtlas = uibutton(app.Panel_11, 'state');
            app.toggleColorbarAtlas.ValueChangedFcn = createCallbackFcn(app, @colorbarToggleChange, true);
            app.toggleColorbarAtlas.Tooltip = {'Toggle the visibility of the atlas colorbar'};
            app.toggleColorbarAtlas.Text = 'Colorbar';
            app.reloadAtlas = uibutton(app.Panel_11, 'push');
            app.reloadAtlas.ButtonPushedFcn = createCallbackFcn(app, @reloadAtlasButton, true);
            app.reloadAtlas.Icon = 'reloadButton.jpg';
            app.reloadAtlas.Tooltip = {'Reload the atlas'};
            app.reloadAtlas.Text = '';
            app.OutlineLabel = uilabel(app.Panel_11);
            app.OutlineLabel.Text = 'Outline';
            app.borderToggleAtlas = uiswitch(app.Panel_11, 'slider');
            app.borderToggleAtlas.ValueChangedFcn = createCallbackFcn(app, @borderToggleAtlasChange, true);
            app.borderToggleAtlas.Tooltip = {'Patch only the outline of eah ROI in the atlas by turning this switch on'};
            app.InvertcolorsLabel_4 = uilabel(app.Panel_11);
            app.InvertcolorsLabel_4.Text = 'Invert colors';
            app.invertToggleAtlas = uiswitch(app.Panel_11, 'slider');
            app.invertToggleAtlas.ValueChangedFcn = createCallbackFcn(app, @invertToggleAtlasChange, true);
            app.invertToggleAtlas.Tooltip = {'Invert colors in the atlas colormap'};
            app.AtlasLabel = uilabel(app.Panel_9);
            app.AtlasLabel.Text = 'Atlas';
            app.FileMenu = uimenu(app.brainSurferUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadsurfaceMenu = uimenu(app.FileMenu);
            app.LoadsurfaceMenu.Text = 'Load surface';
            app.NewfigureMenu = uimenu(app.LoadsurfaceMenu);
            app.NewfigureMenu.Tooltip = {'Patch a surface onto a new auto-generated figure'};
            app.NewfigureMenu.Text = 'New figure';
            app.FsaveragetemplateMenu_2 = uimenu(app.NewfigureMenu);
            app.FsaveragetemplateMenu_2.Tooltip = {'Automatically load a template surface'};
            app.FsaveragetemplateMenu_2.Text = 'Fsaverage template';
            app.LHRHMenu = uimenu(app.FsaveragetemplateMenu_2);
            app.LHRHMenu.MenuSelectedFcn = createCallbackFcn(app, @loadfsaveragelhrh, true);
            app.LHRHMenu.Text = 'LH + RH';
            app.LHMenu = uimenu(app.FsaveragetemplateMenu_2);
            app.LHMenu.MenuSelectedFcn = createCallbackFcn(app, @loadfsaveragelh, true);
            app.LHMenu.Text = 'LH';
            app.RHMenu = uimenu(app.FsaveragetemplateMenu_2);
            app.RHMenu.MenuSelectedFcn = createCallbackFcn(app, @loadfsaveragerh, true);
            app.RHMenu.Text = 'RH';
            app.Carettemplatefs_LRMenu = uimenu(app.NewfigureMenu);
            app.Carettemplatefs_LRMenu.Tooltip = {'Automatically load a template surface'};
            app.Carettemplatefs_LRMenu.Text = 'Caret template (fs_LR)';
            app.FlatMenu_3 = uimenu(app.Carettemplatefs_LRMenu);
            app.FlatMenu_3.Text = 'Flat';
            app.LHRHMenu_2 = uimenu(app.FlatMenu_3);
            app.LHRHMenu_2.MenuSelectedFcn = createCallbackFcn(app, @loadflatlhrh, true);
            app.LHRHMenu_2.Text = 'LH + RH';
            app.LHMenu_12 = uimenu(app.FlatMenu_3);
            app.LHMenu_12.MenuSelectedFcn = createCallbackFcn(app, @loadflatlh, true);
            app.LHMenu_12.Text = 'LH';
            app.RHMenu_12 = uimenu(app.FlatMenu_3);
            app.RHMenu_12.MenuSelectedFcn = createCallbackFcn(app, @loadflatrh, true);
            app.RHMenu_12.Text = 'RH';
            app.VeryinflatedMenu_3 = uimenu(app.Carettemplatefs_LRMenu);
            app.VeryinflatedMenu_3.Text = 'Very inflated';
            app.LHRHMenu_3 = uimenu(app.VeryinflatedMenu_3);
            app.LHRHMenu_3.MenuSelectedFcn = createCallbackFcn(app, @loadveryinflatedlhrh, true);
            app.LHRHMenu_3.Text = 'LH + RH';
            app.LHMenu_13 = uimenu(app.VeryinflatedMenu_3);
            app.LHMenu_13.MenuSelectedFcn = createCallbackFcn(app, @loadveryinflatedlh, true);
            app.LHMenu_13.Text = 'LH';
            app.RHMenu_13 = uimenu(app.VeryinflatedMenu_3);
            app.RHMenu_13.MenuSelectedFcn = createCallbackFcn(app, @loadveryinflatedrh, true);
            app.RHMenu_13.Text = 'RH';
            app.InflatedMenu_3 = uimenu(app.Carettemplatefs_LRMenu);
            app.InflatedMenu_3.Text = 'Inflated';
            app.LHRHMenu_4 = uimenu(app.InflatedMenu_3);
            app.LHRHMenu_4.MenuSelectedFcn = createCallbackFcn(app, @loadinflatedlhrh, true);
            app.LHRHMenu_4.Text = 'LH + RH';
            app.LHMenu_14 = uimenu(app.InflatedMenu_3);
            app.LHMenu_14.MenuSelectedFcn = createCallbackFcn(app, @loadinflatedlh, true);
            app.LHMenu_14.Text = 'LH';
            app.RHMenu_14 = uimenu(app.InflatedMenu_3);
            app.RHMenu_14.MenuSelectedFcn = createCallbackFcn(app, @loadinflatedrh, true);
            app.RHMenu_14.Text = 'RH';
            app.MidthicknessMenu_3 = uimenu(app.Carettemplatefs_LRMenu);
            app.MidthicknessMenu_3.Text = 'Midthickness';
            app.LHRHMenu_15 = uimenu(app.MidthicknessMenu_3);
            app.LHRHMenu_15.MenuSelectedFcn = createCallbackFcn(app, @loadmidthicknesslhrh, true);
            app.LHRHMenu_15.Text = 'LH + RH';
            app.LHMenu_15 = uimenu(app.MidthicknessMenu_3);
            app.LHMenu_15.MenuSelectedFcn = createCallbackFcn(app, @loadmidthicknesslh, true);
            app.LHMenu_15.Text = 'LH';
            app.RHMenu_15 = uimenu(app.MidthicknessMenu_3);
            app.RHMenu_15.MenuSelectedFcn = createCallbackFcn(app, @loadmidthicknessrh, true);
            app.RHMenu_15.Text = 'RH';
            app.LoadfilesMenu = uimenu(app.NewfigureMenu);
            app.LoadfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @loadSurface, true);
            app.LoadfilesMenu.Tooltip = {'Specify a surface file to load (gifti, freesurfer)'};
            app.LoadfilesMenu.Text = 'Load file(s)...';
            app.ExistingfigureMenu = uimenu(app.LoadsurfaceMenu);
            app.ExistingfigureMenu.Tooltip = {'Patch a surface onto an existing figure that contains surface data (can be used to overwrite an existing surface)'};
            app.ExistingfigureMenu.Text = 'Existing figure';
            app.FsaveragetemplateMenu_3 = uimenu(app.ExistingfigureMenu);
            app.FsaveragetemplateMenu_3.Tooltip = {'Automatically load a template surface'};
            app.FsaveragetemplateMenu_3.Text = 'Fsaverage template';
            app.LHMenu_7 = uimenu(app.FsaveragetemplateMenu_3);
            app.LHMenu_7.MenuSelectedFcn = createCallbackFcn(app, @addfsaveragelh, true);
            app.LHMenu_7.Text = 'LH';
            app.RHMenu_7 = uimenu(app.FsaveragetemplateMenu_3);
            app.RHMenu_7.MenuSelectedFcn = createCallbackFcn(app, @addfsaveragerh, true);
            app.RHMenu_7.Text = 'RH';
            app.Carettemplatefs_LRMenu_2 = uimenu(app.ExistingfigureMenu);
            app.Carettemplatefs_LRMenu_2.Tooltip = {'Automatically load a template surface'};
            app.Carettemplatefs_LRMenu_2.Text = 'Caret template (fs_LR)';
            app.FlatMenu_2 = uimenu(app.Carettemplatefs_LRMenu_2);
            app.FlatMenu_2.Text = 'Flat';
            app.LHMenu_8 = uimenu(app.FlatMenu_2);
            app.LHMenu_8.MenuSelectedFcn = createCallbackFcn(app, @addflatlh, true);
            app.LHMenu_8.Text = 'LH';
            app.RHMenu_8 = uimenu(app.FlatMenu_2);
            app.RHMenu_8.MenuSelectedFcn = createCallbackFcn(app, @addflatrh, true);
            app.RHMenu_8.Text = 'RH';
            app.VeryinflatedMenu_2 = uimenu(app.Carettemplatefs_LRMenu_2);
            app.VeryinflatedMenu_2.Text = 'Very inflated';
            app.LHMenu_9 = uimenu(app.VeryinflatedMenu_2);
            app.LHMenu_9.MenuSelectedFcn = createCallbackFcn(app, @addveryinflatedlh, true);
            app.LHMenu_9.Text = 'LH';
            app.RHMenu_9 = uimenu(app.VeryinflatedMenu_2);
            app.RHMenu_9.MenuSelectedFcn = createCallbackFcn(app, @addveryinflatedrh, true);
            app.RHMenu_9.Text = 'RH';
            app.InflatedMenu_2 = uimenu(app.Carettemplatefs_LRMenu_2);
            app.InflatedMenu_2.Text = 'Inflated';
            app.LHMenu_10 = uimenu(app.InflatedMenu_2);
            app.LHMenu_10.MenuSelectedFcn = createCallbackFcn(app, @addinflatedlh, true);
            app.LHMenu_10.Text = 'LH';
            app.RHMenu_10 = uimenu(app.InflatedMenu_2);
            app.RHMenu_10.MenuSelectedFcn = createCallbackFcn(app, @addinflatedrh, true);
            app.RHMenu_10.Text = 'RH';
            app.MidthicknessMenu_2 = uimenu(app.Carettemplatefs_LRMenu_2);
            app.MidthicknessMenu_2.Text = 'Midthickness';
            app.LHMenu_11 = uimenu(app.MidthicknessMenu_2);
            app.LHMenu_11.MenuSelectedFcn = createCallbackFcn(app, @addmidthicknesslh, true);
            app.LHMenu_11.Text = 'LH';
            app.RHMenu_11 = uimenu(app.MidthicknessMenu_2);
            app.RHMenu_11.MenuSelectedFcn = createCallbackFcn(app, @addmidthicknessrh, true);
            app.RHMenu_11.Text = 'RH';
            app.AddfilesMenu_2 = uimenu(app.ExistingfigureMenu);
            app.AddfilesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @addSurface, true);
            app.AddfilesMenu_2.Tooltip = {'Specify a surface file to load (gifti, freesurfer)'};
            app.AddfilesMenu_2.Text = 'Add file(s)...';
            app.LoadsulcigyriMenu = uimenu(app.FileMenu);
            app.LoadsulcigyriMenu.MenuSelectedFcn = createCallbackFcn(app, @loadsg, true);
            app.LoadsulcigyriMenu.Tooltip = {'Load information to display about sulcal and gyral features onto a patched surface (nifti, cifti, gifti, freesurfer morphology files)'};
            app.LoadsulcigyriMenu.Text = 'Load sulci/gyri...';
            app.LoadoverlaysMenu = uimenu(app.FileMenu);
            app.LoadoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @loadOverlay, true);
            app.LoadoverlaysMenu.Tooltip = {'Select a cifti, gifti, nifti, label, annot, or freesurfer morphology file (e.g., curv, thickness, etc). Nifti file can be in volume space, in which case they will be converted to the space of the template you have already loaded (if you have loaded a template)'};
            app.LoadoverlaysMenu.Separator = 'on';
            app.LoadoverlaysMenu.Text = 'Load overlay(s)...';
            app.SaveoverlaysMenu = uimenu(app.FileMenu);
            app.SaveoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @saveOverlay, true);
            app.SaveoverlaysMenu.Tooltip = {'Save currently loaded overlays into any file type supported by the loading function'};
            app.SaveoverlaysMenu.Text = 'Save overlay(s)...';
            app.CreateoverlayfromatlasareaonlastdatatipMenu = uimenu(app.FileMenu);
            app.CreateoverlayfromatlasareaonlastdatatipMenu.MenuSelectedFcn = createCallbackFcn(app, @overlayFromROI, true);
            app.CreateoverlayfromatlasareaonlastdatatipMenu.Tooltip = {'Use the ROI of the last data tip to create an overlay that will be placed in the overlay selection box '};
            app.CreateoverlayfromatlasareaonlastdatatipMenu.Text = 'Create overlay from atlas area on last data tip';
            app.CombinedsavedCIFTIoverlaysMenu = uimenu(app.FileMenu);
            app.CombinedsavedCIFTIoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @combineCIFTI, true);
            app.CombinedsavedCIFTIoverlaysMenu.Tooltip = {'Combine cifti files with single hemisphere information.'};
            app.CombinedsavedCIFTIoverlaysMenu.Text = 'Combined saved CIFTI overlays...';
            app.LoadGUIstateMenu = uimenu(app.FileMenu);
            app.LoadGUIstateMenu.MenuSelectedFcn = createCallbackFcn(app, @loadGUIPress, true);
            app.LoadGUIstateMenu.Tooltip = {'Load the app structure from a previous session'};
            app.LoadGUIstateMenu.Separator = 'on';
            app.LoadGUIstateMenu.Text = 'Load GUI state';
            app.SaveGUIstateMenu = uimenu(app.FileMenu);
            app.SaveGUIstateMenu.MenuSelectedFcn = createCallbackFcn(app, @saveGUIPress, true);
            app.SaveGUIstateMenu.Tooltip = {'save all GUI settings and loaded overlays for loading later'};
            app.SaveGUIstateMenu.Text = 'Save GUI state...';
            app.DeleteMenu = uimenu(app.FileMenu);
            app.DeleteMenu.Separator = 'on';
            app.DeleteMenu.Text = 'Delete';
            app.AhemisphereMenu = uimenu(app.DeleteMenu);
            app.AhemisphereMenu.Tooltip = {'Remove a hemisphere from the current brain figure'};
            app.AhemisphereMenu.Text = 'A hemisphere';
            app.LeftMenu_2 = uimenu(app.AhemisphereMenu);
            app.LeftMenu_2.MenuSelectedFcn = createCallbackFcn(app, @deleteLH, true);
            app.LeftMenu_2.Text = 'Left';
            app.RightMenu_2 = uimenu(app.AhemisphereMenu);
            app.RightMenu_2.MenuSelectedFcn = createCallbackFcn(app, @deleteRH, true);
            app.RightMenu_2.Text = 'Right';
            app.AspecificoverlayMenu = uimenu(app.DeleteMenu);
            app.AspecificoverlayMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteSomeOverlay, true);
            app.AspecificoverlayMenu.Text = 'A specific overlay...';
            app.AllfiguresexceptthoseactivelypatchedbybrainSurferMenu = uimenu(app.DeleteMenu);
            app.AllfiguresexceptthoseactivelypatchedbybrainSurferMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteBrainFigs, true);
            app.AllfiguresexceptthoseactivelypatchedbybrainSurferMenu.Text = 'All figures except those actively patched by brainSurfer';
            app.ConvertMenu = uimenu(app.brainSurferUIFigure);
            app.ConvertMenu.Text = 'Convert';
            app.Fsaveragetocaretfs_LRMenu = uimenu(app.ConvertMenu);
            app.Fsaveragetocaretfs_LRMenu.MenuSelectedFcn = createCallbackFcn(app, @fsaverageToCaretButton, true);
            app.Fsaveragetocaretfs_LRMenu.Tooltip = {'Batch convert fsaverage files to fs_LR'};
            app.Fsaveragetocaretfs_LRMenu.Text = 'Fsaverage to caret (fs_LR)';
            app.Caretfs_LRtofsaverageMenu = uimenu(app.ConvertMenu);
            app.Caretfs_LRtofsaverageMenu.MenuSelectedFcn = createCallbackFcn(app, @caretToFsaverageButton, true);
            app.Caretfs_LRtofsaverageMenu.Tooltip = {'Batch convert fs_LR (group) files '; 'to fsaverage'};
            app.Caretfs_LRtofsaverageMenu.Text = 'Caret (fs_LR) to fsaverage';
            app.Freesurfernativetocaretfs_LRMenu = uimenu(app.ConvertMenu);
            app.Freesurfernativetocaretfs_LRMenu.MenuSelectedFcn = createCallbackFcn(app, @fsNativeToCaretButton, true);
            app.Freesurfernativetocaretfs_LRMenu.Visible = 'off';
            app.Freesurfernativetocaretfs_LRMenu.Enable = 'off';
            app.Freesurfernativetocaretfs_LRMenu.Text = 'Freesurfer native to caret (fs_LR)';
            app.Nativefs_LRtofsaverageMenu = uimenu(app.ConvertMenu);
            app.Nativefs_LRtofsaverageMenu.MenuSelectedFcn = createCallbackFcn(app, @caretNativeToFsaverageButton, true);
            app.Nativefs_LRtofsaverageMenu.Visible = 'off';
            app.Nativefs_LRtofsaverageMenu.Enable = 'off';
            app.Nativefs_LRtofsaverageMenu.Text = 'Native fs_LR to fsaverage';
            app.OperationsMenu = uimenu(app.brainSurferUIFigure);
            app.OperationsMenu.Text = 'Operations';
            app.TakeabsolutevalueMenu = uimenu(app.OperationsMenu);
            app.TakeabsolutevalueMenu.MenuSelectedFcn = createCallbackFcn(app, @absValPress, true);
            app.TakeabsolutevalueMenu.Text = 'Take absolute value';
            app.InvertpositiveandnegativeMenu = uimenu(app.OperationsMenu);
            app.InvertpositiveandnegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @invtPosNegPress, true);
            app.InvertpositiveandnegativeMenu.Text = 'Invert positive and negative';
            app.AlignclusteridentitiesMenu_2 = uimenu(app.OperationsMenu);
            app.AlignclusteridentitiesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @alignClustButton, true);
            app.AlignclusteridentitiesMenu_2.Visible = 'off';
            app.AlignclusteridentitiesMenu_2.Enable = 'off';
            app.AlignclusteridentitiesMenu_2.Text = 'Align cluster identities';
            app.SumcombinemapsMenu = uimenu(app.OperationsMenu);
            app.SumcombinemapsMenu.MenuSelectedFcn = createCallbackFcn(app, @sumButtonPressed, true);
            app.SumcombinemapsMenu.Separator = 'on';
            app.SumcombinemapsMenu.Text = 'Sum (combine maps)';
            app.AverageMenu = uimenu(app.OperationsMenu);
            app.AverageMenu.MenuSelectedFcn = createCallbackFcn(app, @averageButtonPressed, true);
            app.AverageMenu.Text = 'Average';
            app.StddeviationMenu = uimenu(app.OperationsMenu);
            app.StddeviationMenu.MenuSelectedFcn = createCallbackFcn(app, @stdDevButtonPressed, true);
            app.StddeviationMenu.Text = 'Std. deviation';
            app.StderrorofthemeanMenu = uimenu(app.OperationsMenu);
            app.StderrorofthemeanMenu.MenuSelectedFcn = createCallbackFcn(app, @semButtonPressed, true);
            app.StderrorofthemeanMenu.Text = 'Std. error of the mean';
            app.SubtractMenu = uimenu(app.OperationsMenu);
            app.SubtractMenu.MenuSelectedFcn = createCallbackFcn(app, @subButtonPress, true);
            app.SubtractMenu.Visible = 'off';
            app.SubtractMenu.Enable = 'off';
            app.SubtractMenu.Text = 'Subtract';
            app.MultiplyMenu = uimenu(app.OperationsMenu);
            app.MultiplyMenu.MenuSelectedFcn = createCallbackFcn(app, @multButtonPress, true);
            app.MultiplyMenu.Visible = 'off';
            app.MultiplyMenu.Enable = 'off';
            app.MultiplyMenu.Text = 'Multiply';
            app.DivideMenu = uimenu(app.OperationsMenu);
            app.DivideMenu.MenuSelectedFcn = createCallbackFcn(app, @divButtonPress, true);
            app.DivideMenu.Visible = 'off';
            app.DivideMenu.Enable = 'off';
            app.DivideMenu.Text = 'Divide';
            app.PCAMenu = uimenu(app.OperationsMenu);
            app.PCAMenu.MenuSelectedFcn = createCallbackFcn(app, @pcaButtonPress, true);
            app.PCAMenu.Visible = 'off';
            app.PCAMenu.Enable = 'off';
            app.PCAMenu.Text = 'PCA';
            app.AlignclusteridentitiesMenu = uimenu(app.OperationsMenu);
            app.AlignclusteridentitiesMenu.Visible = 'off';
            app.AlignclusteridentitiesMenu.Enable = 'off';
            app.AlignclusteridentitiesMenu.Text = 'Align cluster identities';
            app.ViewMenu = uimenu(app.brainSurferUIFigure);
            app.ViewMenu.Text = 'View';
            app.LeftlateralMenu = uimenu(app.ViewMenu);
            app.LeftlateralMenu.MenuSelectedFcn = createCallbackFcn(app, @viewLateralLeft, true);
            app.LeftlateralMenu.Text = 'Left lateral';
            app.RightlateralMenu = uimenu(app.ViewMenu);
            app.RightlateralMenu.MenuSelectedFcn = createCallbackFcn(app, @viewLateralRight, true);
            app.RightlateralMenu.Text = 'Right lateral';
            app.InferiorMenu_3 = uimenu(app.ViewMenu);
            app.InferiorMenu_3.MenuSelectedFcn = createCallbackFcn(app, @viewInferior, true);
            app.InferiorMenu_3.Text = 'Inferior';
            app.SuperiorMenu_3 = uimenu(app.ViewMenu);
            app.SuperiorMenu_3.MenuSelectedFcn = createCallbackFcn(app, @viewSuperior, true);
            app.SuperiorMenu_3.Text = 'Superior';
            app.SpecificlocationMenu = uimenu(app.ViewMenu);
            app.SpecificlocationMenu.MenuSelectedFcn = createCallbackFcn(app, @viewSpecific, true);
            app.SpecificlocationMenu.Separator = 'on';
            app.SpecificlocationMenu.Text = 'Specific location...';
            app.SettingsMenu = uimenu(app.brainSurferUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ColorsMenu = uimenu(app.SettingsMenu);
            app.ColorsMenu.Text = 'Colors';
            app.ColorbarMenu = uimenu(app.ColorsMenu);
            app.ColorbarMenu.Text = 'Colorbar';
            app.VisibilityMenu = uimenu(app.ColorbarMenu);
            app.VisibilityMenu.Text = 'Visibility';

            app.ShowMenu_4 = uimenu(app.VisibilityMenu);
            app.ShowMenu_4.MenuSelectedFcn = createCallbackFcn(app, @showColorbar, true);
            app.ShowMenu_4.Text = 'Show';
            app.HideMenu_4 = uimenu(app.VisibilityMenu);
            app.HideMenu_4.MenuSelectedFcn = createCallbackFcn(app, @hideColorbar, true);
            app.HideMenu_4.Text = 'Hide';
            app.OrientationMenu = uimenu(app.ColorbarMenu);
            app.OrientationMenu.Text = 'Orientation';
            app.VerticalMenu = uimenu(app.OrientationMenu);
            app.VerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @makeColorbarVert, true);
            app.VerticalMenu.Text = 'Vertical';
            app.HorizontalMenu = uimenu(app.OrientationMenu);
            app.HorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @makeColorbarHoriz, true);
            app.HorizontalMenu.Text = 'Horizontal';
            app.TitlesonlyformultipleselectionsMenu = uimenu(app.ColorbarMenu);
            app.TitlesonlyformultipleselectionsMenu.Text = 'Titles (only for multiple selections)';
            app.ShowMenu_5 = uimenu(app.TitlesonlyformultipleselectionsMenu);
            app.ShowMenu_5.MenuSelectedFcn = createCallbackFcn(app, @showMapTitles, true);
            app.ShowMenu_5.Text = 'Show';
            app.HideMenu_5 = uimenu(app.TitlesonlyformultipleselectionsMenu);
            app.HideMenu_5.MenuSelectedFcn = createCallbackFcn(app, @hideMapTitles, true);
            app.HideMenu_5.Text = 'Hide';
            app.ChangespacingbetweencolorbarsMenu = uimenu(app.ColorbarMenu);
            app.ChangespacingbetweencolorbarsMenu.MenuSelectedFcn = createCallbackFcn(app, @changeSpacingBetweenColorbars, true);
            app.ChangespacingbetweencolorbarsMenu.Text = 'Change spacing between colorbars...';
            app.CreatecolormapMenu = uimenu(app.ColorsMenu);
            app.CreatecolormapMenu.MenuSelectedFcn = createCallbackFcn(app, @createColormapButton, true);
            app.CreatecolormapMenu.Tooltip = {'Bring up a seperate GUI for creating colormaps '};
            app.CreatecolormapMenu.Text = 'Create colormap...';
            app.ChangedefaultsurfacecolorMenu = uimenu(app.ColorsMenu);
            app.ChangedefaultsurfacecolorMenu.MenuSelectedFcn = createCallbackFcn(app, @changeSurfC, true);
            app.ChangedefaultsurfacecolorMenu.Tooltip = {'Change the color of the surface (does not change sulci/gyri).'};
            app.ChangedefaultsurfacecolorMenu.Separator = 'on';
            app.ChangedefaultsurfacecolorMenu.Text = 'Change default surface color...';
            app.ChangebackgroundofbrainfigureMenu = uimenu(app.ColorsMenu);
            app.ChangebackgroundofbrainfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @changeBackBrain, true);
            app.ChangebackgroundofbrainfigureMenu.Tooltip = {'Change the background color of the figure being patched'};
            app.ChangebackgroundofbrainfigureMenu.Text = 'Change background of brain figure...';
            app.UsecolorsfromannotationCIFTIheaderforatlasMenu = uimenu(app.ColorsMenu);
            app.UsecolorsfromannotationCIFTIheaderforatlasMenu.MenuSelectedFcn = createCallbackFcn(app, @useDefaultColorsAtlas, true);
            app.UsecolorsfromannotationCIFTIheaderforatlasMenu.Separator = 'on';
            app.UsecolorsfromannotationCIFTIheaderforatlasMenu.Text = 'Use colors from annotation/CIFTI header for atlas';
            app.GUIMenu = uimenu(app.SettingsMenu);
            app.GUIMenu.Text = 'GUI';
            app.DarkModeMenu_3 = uimenu(app.GUIMenu);
            app.DarkModeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @darkMode, true);
            app.DarkModeMenu_3.Text = 'Dark Mode';
            app.LightModeMenu_3 = uimenu(app.GUIMenu);
            app.LightModeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @lightMode, true);
            app.LightModeMenu_3.Text = 'Light Mode';
            app.LighterModeMenu = uimenu(app.GUIMenu);
            app.LighterModeMenu.MenuSelectedFcn = createCallbackFcn(app, @lighterModeButton, true);
            app.LighterModeMenu.Text = 'Lighter Mode';
            app.ContextMenu = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu2 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu2
            app.lightsButton.ContextMenu = app.ContextMenu2;
            app.EditlightlocationsMenu = uimenu(app.ContextMenu2);
            app.EditlightlocationsMenu.MenuSelectedFcn = createCallbackFcn(app, @editLightLocationsButton, true);
            app.EditlightlocationsMenu.Tooltip = {'Edit where each light is placed.'};
            app.EditlightlocationsMenu.Visible = 'off';
            app.EditlightlocationsMenu.Enable = 'off';
            app.EditlightlocationsMenu.Text = 'Edit light locations';
            app.ContextMenu3 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu4 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu4
            app.screenshotButton.ContextMenu = app.ContextMenu4;
            app.TakemultiplestandardizedscreenshotsofselectedoverlaysMenu = uimenu(app.ContextMenu4);
            app.TakemultiplestandardizedscreenshotsofselectedoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @screenshotButtonSelectedPush, true);
            app.TakemultiplestandardizedscreenshotsofselectedoverlaysMenu.Text = 'Take multiple standardized screenshots of selected overlay(s). Data tips will be deleted.';
            app.TakemultiplestandardizedscreenshotsofsomeoverlaysMenu = uimenu(app.ContextMenu4);
            app.TakemultiplestandardizedscreenshotsofsomeoverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @screenshotButtonSomePush, true);
            app.TakemultiplestandardizedscreenshotsofsomeoverlaysMenu.Tooltip = {'Opens listbox to select overlays to screenshot'};
            app.TakemultiplestandardizedscreenshotsofsomeoverlaysMenu.Text = 'Take multiple standardized screenshots of some overlay(s). Data tips will be deleted.';
            app.TakemultiplestandardizedscreenshotsofalloverlaysMenu = uimenu(app.ContextMenu4);
            app.TakemultiplestandardizedscreenshotsofalloverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @screenshotButtonAllPush, true);
            app.TakemultiplestandardizedscreenshotsofalloverlaysMenu.Tooltip = {'Automatically take screenshots of all overlays in listbox'};
            app.TakemultiplestandardizedscreenshotsofalloverlaysMenu.Text = 'Take multiple standardized screenshots of all overlays.  Data tips will be deleted.';
            app.ContextMenu5 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu5
            app.deleteButton.ContextMenu = app.ContextMenu5;
            app.DeleteselectoverlayMenu = uimenu(app.ContextMenu5);
            app.DeleteselectoverlayMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteSomeOverlay, true);
            app.DeleteselectoverlayMenu.Tooltip = {'Open new listbox to select overlays to delete'};
            app.DeleteselectoverlayMenu.Text = 'Delete select overlay';
            app.DeletealloverlaysMenu = uimenu(app.ContextMenu5);
            app.DeletealloverlaysMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteAllOverlay, true);
            app.DeletealloverlaysMenu.Tooltip = {'Delete very overlay in listbox'};
            app.DeletealloverlaysMenu.Text = 'Delete all overlays';
            app.ContextMenu6 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu9 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu9
            app.LHButton.ContextMenu = app.ContextMenu9;
            app.RHButton.ContextMenu = app.ContextMenu9;
            app.ViewleftlateralMenu = uimenu(app.ContextMenu9);
            app.ViewleftlateralMenu.MenuSelectedFcn = createCallbackFcn(app, @viewLateralLeft, true);
            app.ViewleftlateralMenu.Text = 'View left lateral';
            app.ViewrightlateralMenu = uimenu(app.ContextMenu9);
            app.ViewrightlateralMenu.MenuSelectedFcn = createCallbackFcn(app, @viewLateralRight, true);
            app.ViewrightlateralMenu.Text = 'View right lateral';
            app.ViewinferiorMenu = uimenu(app.ContextMenu9);
            app.ViewinferiorMenu.MenuSelectedFcn = createCallbackFcn(app, @viewInferior, true);
            app.ViewinferiorMenu.Text = 'View inferior';
            app.ViewSuperiorMenu = uimenu(app.ContextMenu9);
            app.ViewSuperiorMenu.MenuSelectedFcn = createCallbackFcn(app, @viewSuperior, true);
            app.ViewSuperiorMenu.Text = 'View Superior';
            app.ContextMenu10 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu11 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu11
            app.sulcButton.ContextMenu = app.ContextMenu11;
            app.gyrButton.ContextMenu = app.ContextMenu11;
            app.MakedefaultsulcalandgyralcolorsMenu_2 = uimenu(app.ContextMenu11);
            app.MakedefaultsulcalandgyralcolorsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @defaultSGColors, true);
            app.MakedefaultsulcalandgyralcolorsMenu_2.Text = 'Make default sulcal and gyral colors';
            app.InvertsulcalandgyralcolorsMenu_2 = uimenu(app.ContextMenu11);
            app.InvertsulcalandgyralcolorsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @invertSGColors, true);
            app.InvertsulcalandgyralcolorsMenu_2.Text = 'Invert sulcal and gyral colors';
            app.ContextMenu12 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu13 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu13
            app.overlayListBox.ContextMenu = app.ContextMenu13;
            app.ChangeFontSizeMenu = uimenu(app.ContextMenu13);
            app.ChangeFontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @changeListBxSz, true);
            app.ChangeFontSizeMenu.Tooltip = {'Change listbox font size'};
            app.ChangeFontSizeMenu.Text = 'Change Font Size';
            app.TrimoverlaynamelengthsMenu = uimenu(app.ContextMenu13);
            app.TrimoverlaynamelengthsMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOverlayLngth, true);
            app.TrimoverlaynamelengthsMenu.Tooltip = {'Trim name lengths in listbox'};
            app.TrimoverlaynamelengthsMenu.Text = 'Trim overlay name lengths';
            app.RenameoverlayMenu = uimenu(app.ContextMenu13);
            app.RenameoverlayMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOverlayNm, true);
            app.RenameoverlayMenu.Text = 'Rename overlay';
            app.ContextMenu15 = uicontextmenu(app.brainSurferUIFigure);
            app.ContextMenu15.ContextMenuOpeningFcn = createCallbackFcn(app, @reloadSomeOverlay, true);
            % Assign app.ContextMenu15
            app.reloadButton.ContextMenu = app.ContextMenu15;
            app.reloadAtlas.ContextMenu = app.ContextMenu15;
            app.ReloadsomeoverlaysMenu = uimenu(app.ContextMenu15);
            app.ReloadsomeoverlaysMenu.Tooltip = {'Open a new listbox to select overlays to reload'};
            app.ReloadsomeoverlaysMenu.Text = 'Reload some overlay(s)';
            app.ContextMenu16 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu16
            app.colormapSelectAtlas.ContextMenu = app.ContextMenu16;
            app.selectSpacingAtlas.ContextMenu = app.ContextMenu16;
            app.RandomizecolorsMenu = uimenu(app.ContextMenu16);
            app.RandomizecolorsMenu.MenuSelectedFcn = createCallbackFcn(app, @randomizeColorsButton, true);
            app.RandomizecolorsMenu.Tooltip = {'Randomize the colors in the atlas'};
            app.RandomizecolorsMenu.Text = 'Randomize colors';
            app.UsedefaultcolorsfromatlasifavailableMenu = uimenu(app.ContextMenu16);
            app.UsedefaultcolorsfromatlasifavailableMenu.MenuSelectedFcn = createCallbackFcn(app, @useDefaultColorsAtlas, true);
            app.UsedefaultcolorsfromatlasifavailableMenu.Tooltip = {'Some atlases will come with colors for ROIs. Use these default colors if available.'};
            app.UsedefaultcolorsfromatlasifavailableMenu.Text = 'Use default colors from atlas if available';
            app.SavecurrentcolorbaregrandomizeddefaultfromatlasMenu = uimenu(app.ContextMenu16);
            app.SavecurrentcolorbaregrandomizeddefaultfromatlasMenu.MenuSelectedFcn = createCallbackFcn(app, @saveAtlasCBar, true);
            app.SavecurrentcolorbaregrandomizeddefaultfromatlasMenu.Tooltip = {'Save the colormap of the atlas as it is currently being displayed (allows you to save a randomized colorbar, or a default colorbar from an atlas)'};
            app.SavecurrentcolorbaregrandomizeddefaultfromatlasMenu.Text = 'Save current colorbar (e.g., randomized, default from atlas)';
            app.ContextMenu17 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu17
            app.AreaonlastdatatipLabel.ContextMenu = app.ContextMenu17;
            app.CreateoverlayfromareaonlastdatatipMenu_2 = uimenu(app.ContextMenu17);
            app.CreateoverlayfromareaonlastdatatipMenu_2.MenuSelectedFcn = createCallbackFcn(app, @overlayFromROI, true);
            app.CreateoverlayfromareaonlastdatatipMenu_2.Tooltip = {'Take ROI from last data tip and turn it into an overlay that can be selected'};
            app.CreateoverlayfromareaonlastdatatipMenu_2.Text = 'Create overlay from area on last data tip';
            app.ContextMenu18 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu18
            app.valueSelection.ContextMenu = app.ContextMenu18;
            app.ReassignvalueonlastdatatipMenu = uimenu(app.ContextMenu18);
            app.ReassignvalueonlastdatatipMenu.MenuSelectedFcn = createCallbackFcn(app, @reassignValButton, true);
            app.ReassignvalueonlastdatatipMenu.Tooltip = {'Take the value on the last data tip and replace it with some other value in the whole overlay.'};
            app.ReassignvalueonlastdatatipMenu.Text = 'Reassign value on last data tip';
            app.ContextMenu19 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu19
            app.TwoD1.ContextMenu = app.ContextMenu19;
            app.histMod.ContextMenu = app.ContextMenu19;
            app.hist2D.ContextMenu = app.ContextMenu19;
            app.TwoD2.ContextMenu = app.ContextMenu19;
            app.ndcolorbaronxaxisButton.ContextMenu = app.ContextMenu19;
            app.rotatecolorsButton.ContextMenu = app.ContextMenu19;
            app.AddROIsfromanotheroverlaytothiscolormapaspointsMenu = uimenu(app.ContextMenu19);
            app.AddROIsfromanotheroverlaytothiscolormapaspointsMenu.MenuSelectedFcn = createCallbackFcn(app, @addROIsTo2D, true);
            app.AddROIsfromanotheroverlaytothiscolormapaspointsMenu.Text = 'Make scatterplot using these dimensions and an overlay containing ROIs';
            app.ContextMenu20 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu20
            app.clustSurf.ContextMenu = app.ContextMenu20;
            app.ContextMenu21 = uicontextmenu(app.brainSurferUIFigure);
            % Assign app.ContextMenu21
            app.ThreeD1.ContextMenu = app.ContextMenu21;
            app.ThreeD2.ContextMenu = app.ContextMenu21;
            app.ThreeD3.ContextMenu = app.ContextMenu21;
            app.numCubes.ContextMenu = app.ContextMenu21;
            app.cubeSpacing.ContextMenu = app.ContextMenu21;
            app.axisDrop.ContextMenu = app.ContextMenu21;
            app.degreeDrop.ContextMenu = app.ContextMenu21;
            app.MakecubeopacityuniformMenu = uimenu(app.ContextMenu21);
            app.MakecubeopacityuniformMenu.MenuSelectedFcn = createCallbackFcn(app, @cubeOpUni, true);
            app.MakecubeopacityuniformMenu.Text = 'Make cube opacity uniform';
            app.Menu = uimenu(app.ContextMenu21);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @addROIsTo3D, true);
            app.Menu.Text = 'Make 3D scatterplot using these dimensions and an overlay containing ROIs';
            % Show the figure after all components are created
            app.brainSurferUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brainSurfer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.brainSurferUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.brainSurferUIFigure)
        end
    end
end